classdef About < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Developer  matlab.ui.Figure
        text6_6    matlab.ui.control.Label
        Image2     matlab.ui.control.Image
        text6_5    matlab.ui.control.Label
        text6_4    matlab.ui.control.Label
        text6_3    matlab.ui.control.Label
        text6_2    matlab.ui.control.Label
        text4_2    matlab.ui.control.Label
        Image      matlab.ui.control.Image
        text6      matlab.ui.control.Label
        text3      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function About_OpeningFcn(app, varargin)
            screenSize = get(0,"ScreenSize");
            windowWidth = app.Developer.Position(3);
            windowHeight = app.Developer.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.Developer.Position = [centerX, centerY, windowWidth, windowHeight];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Developer = uifigure('Visible', 'off');
            app.Developer.Icon = 'Main.png';
            app.Developer.Tag = 'figure1';
            app.text3 = uilabel(app.Developer);
            app.text3.Tag = 'text3';
            app.text3.Text = 'About PrideGTS';
            app.text6 = uilabel(app.Developer);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Institution: Guilin University of Technology';
            app.Image = uiimage(app.Developer);
            app.Image.ImageSource = 'Main.png';
            app.text4_2 = uilabel(app.Developer);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = {'GNSS Coordinate Time-Series '; 'Analysis Software'};
            app.text6_2 = uilabel(app.Developer);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'Developer:';
            app.text6_3 = uilabel(app.Developer);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'Xianpeng Huang';
            app.text6_4 = uilabel(app.Developer);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = 'Contact:';
            app.text6_5 = uilabel(app.Developer);
            app.text6_5.Tag = 'text6';
            app.text6_5.Text = 'Email: gnsshxp20@glut.edu.cn';
            app.Image2 = uiimage(app.Developer);
            app.Image2.ImageSource = 'line.png';
            app.text6_6 = uilabel(app.Developer);
            app.text6_6.Tag = 'text6';
            app.text6_6.Text = 'WeChat: HXPDMW2002';
            % Show the figure after all components are created
            app.Developer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = About(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Developer)

                % Execute the startup function
                runStartupFcn(app, @(app)About_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Developer)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Developer)
        end
    end
end