classdef DataFormatting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataFormattingUIFigure         matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        LoadPushTool                   matlab.ui.container.toolbar.PushTool
        SavePushTool                   matlab.ui.container.toolbar.PushTool
        Tree                           matlab.ui.container.Tree
        ControlsPanel                  matlab.ui.container.Panel
        CoordinateNormalizationButton  matlab.ui.control.StateButton
        MATFormatButton                matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        ViewButton                     matlab.ui.control.Button
        CEAFormatButton                matlab.ui.control.Button
        NGLFormatButton                matlab.ui.control.Button
        PBOFormatButton                matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        LogPanel                       matlab.ui.container.Panel
    end

    properties (Access = private)
        %% completed on 2025/1/11 %%
        SavePath              % Output directory for converted files (default: same as LoadPath).
        LoadPath              % Directory containing input data files.
        folderNode            % UI tree node for displaying time-series folders.
        SetsavePath           % User-selected output directory (overrides default SavePath).
        IsSavePathSetManually % Flag indicating whether SavePath was set manually by the user.

    end

    methods (Access = public)

        function [Mneudata,offsetMJDs] = LoadData(app,Path, filename)
            % This function is to Load three component .mom file data.
            Mneudata = [];
            offsetMJDs = cell(1, 3);
            try
                counter = 0;
                neudata = [];
                offsetMJDs = {};

                % Initialize min and max MJD
                bmaxMJD = -inf;
                bminMJD = inf;

                % Extract three components data.
                for i = 1:3
                    % textscan is more robust and fast than importdata
                    path1 = sprintf("%s\\%s_%d.mom", Path, filename, counter);
                    fileID = fopen(path1,'r');

                    if fileID == -1
                        msgbox("No files","Warning","warn");
                        return
                    end

                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);

                    data = [momdata{1}];

                    bminMJD = min(bminMJD, min(data));
                    bmaxMJD = max(bmaxMJD, max(data));

                    counter = counter+1;
                end

                counter = 0;
                for file = 1:3
                    path1 = sprintf("%s\\%s_%d.mom", Path, filename, counter);
                    fileID = fopen(path1,'r');

                    if fileID == -1
                        msgbox("No files","Warning","warn");
                        return
                    end

                    offsetMJD = [];
                    % Read all # offset lines
                    while ~feof(fileID)
                        line = fgetl(fileID);
                        if contains(line, "# offset")
                            parts = strsplit(line);
                            offsetMJD = [offsetMJD,str2double(parts{3})];
                        end
                    end

                    % If no offset found, store an empty array
                    if isempty(offsetMJD)
                        offsetMJDs{file} = [];
                    else
                        offsetMJDs{file} = offsetMJD;
                    end

                    % Reset file pointer and read data
                    frewind(fileID);
                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);
                    if ~isempty(momdata{3}) && all(isnan(momdata{3}))
                        data = [momdata{1},momdata{2}];
                    else
                        data = [momdata{1},momdata{2},momdata{3}];
                    end

                    MJDfull = (bminMJD : 1 : bmaxMJD);
                    if size(data,2) < 3
                        fullData = nan(length(MJDfull),2);
                    else
                        fullData = nan(length(MJDfull),3);
                    end
                    [~,loc] = ismember(data(:,1),MJDfull);
                    fullData(loc,2:end) = data(:,2:end);

                    if size(data,2) < 3
                        neudata = [neudata,fullData(:,2)];
                    else
                        neudata = [neudata,fullData(:,2),fullData(:,3)];
                    end

                    counter = counter + 1;
                end

                Mneudata = [MJDfull',neudata];

            catch ME
                Mneudata = [];
                offsetMJDs = cell(1, 3);
                errMsg = sprintf("Error: %s",ME.message);
                h = msgbox(errMsg,'Error','error');
                waitfor(h)
            end
        end

        function batchRecords = addBatchRecord(~, batchRecords, siteName, day, longitude, latitude, neuData)
            if isempty(day) || isempty(neuData) || size(neuData, 2) < 3
                return
            end

            day = day(:);
            rowCount = min(numel(day), size(neuData, 1));
            day = day(1:rowCount);
            neuData = neuData(1:rowCount, 1:3);

            validDay = isfinite(day);
            day = day(validDay);
            neuData = neuData(validDay, :);

            if isempty(day)
                return
            end

            siteName = char(string(siteName));
            if numel(siteName) < 4
                siteName = [siteName, repmat(' ', 1, 4 - numel(siteName))];
            else
                siteName = siteName(1:4);
            end

            nextIndex = numel(batchRecords) + 1;
            batchRecords(nextIndex).site = siteName;
            batchRecords(nextIndex).day = day;
            batchRecords(nextIndex).lon = longitude;
            batchRecords(nextIndex).lat = latitude;
            batchRecords(nextIndex).neu = neuData;
        end

        function batchStruct = buildBatchStruct(~, batchRecords)
            batchStruct = struct();
            batchStruct.day = [];
            batchStruct.lat = [];
            batchStruct.lon = [];
            batchStruct.site = repmat(' ', 0, 4);
            batchStruct.dn = [];
            batchStruct.de = [];
            batchStruct.du = [];

            if isempty(batchRecords)
                return
            end

            allDays = vertcat(batchRecords.day);
            allDays = allDays(isfinite(allDays));
            if isempty(allDays)
                return
            end

            uniqueDays = sort(unique(allDays(:)));
            nDay = numel(uniqueDays);
            nSta = numel(batchRecords);

            dnData = NaN(nDay, nSta);
            deData = NaN(nDay, nSta);
            duData = NaN(nDay, nSta);
            longitude = NaN(nSta, 1);
            latitude = NaN(nSta, 1);
            siteNames = cell(nSta, 1);

            for iSta = 1:nSta
                siteNames{iSta} = batchRecords(iSta).site;
                longitude(iSta) = batchRecords(iSta).lon;
                latitude(iSta) = batchRecords(iSta).lat;

                stationDay = batchRecords(iSta).day(:);
                stationNeu = batchRecords(iSta).neu;
                if isempty(stationDay) || isempty(stationNeu)
                    continue
                end

                [stationDay, idxLast] = unique(stationDay, 'last');
                stationNeu = stationNeu(idxLast, :);

                [stationDay, order] = sort(stationDay);
                stationNeu = stationNeu(order, :);

                [tf, loc] = ismember(stationDay, uniqueDays);
                loc = loc(tf);
                stationNeu = stationNeu(tf, :);

                if isempty(loc)
                    continue
                end

                dnData(loc, iSta) = stationNeu(:, 1);
                deData(loc, iSta) = stationNeu(:, 2);
                duData(loc, iSta) = stationNeu(:, 3);
            end

            batchStruct.day = uniqueDays;
            batchStruct.lat = latitude;
            batchStruct.lon = longitude;
            batchStruct.site = char(siteNames);
            batchStruct.dn = dnData;
            batchStruct.de = deData;
            batchStruct.du = duData;
        end

        function saved = saveBatchMat(app, batchStruct, variableName, defaultFileName)
            saved = false;
            if isempty(batchStruct.day) || isempty(batchStruct.site)
                uialert(app.DataFormattingUIFigure, ...
                    "No valid batch data found for MAT output.", ...
                    "Warning", ...
                    "Icon", "warning");
                return
            end

            defaultFolder = app.SavePath;
            if isempty(defaultFolder) || ~isfolder(defaultFolder)
                defaultFolder = app.LoadPath;
            end

            [filename, pathname] = uiputfile('*.mat', ...
                'Save as', fullfile(defaultFolder, defaultFileName));

            if isequal(filename, 0)
                uialert(app.DataFormattingUIFigure, ...
                    'Save operation canceled.', ...
                    'Info', ...
                    'Icon', 'info');
                return
            end

            variableName = char(variableName);
            outputData = struct();
            outputData.(variableName) = batchStruct;
            save(fullfile(pathname, filename), '-struct', 'outputData');
            saved = true;
        end

        function longitude = normalizeLongitude(~, longitude)
            if isempty(longitude) || ~isfinite(longitude)
                longitude = NaN;
                return
            end

            longitude = mod(longitude, 360);
        end

        function [longitude, latitude] = readCeaReference(~, fullpath)
            longitude = NaN;
            latitude = NaN;

            fid = fopen(fullpath, 'r');
            if fid == -1
                return
            end
            cleanupObj = onCleanup(@() fclose(fid)); %#ok<NASGU>

            numberPattern = '([+-]?\d*\.?\d+(?:[eE][+-]?\d+)?)';
            referencePattern = ['Reference position\s+', numberPattern, '\s+', numberPattern];

            while ~feof(fid)
                line = fgetl(fid);
                if ~ischar(line)
                    continue
                end

                token = regexp(line, referencePattern, 'tokens', 'once');
                if ~isempty(token)
                    longitude = str2double(token{1});
                    latitude = str2double(token{2});
                    return
                end

                trimmedLine = strtrim(line);
                if ~isempty(trimmedLine) && ~startsWith(trimmedLine, '#')
                    return
                end
            end
        end
    end
    methods (Access = private)

        function resourcePath = resolveResource(app, varargin) %#ok<INUSD>
            classDir = fileparts(mfilename('fullpath'));
            parentDir = fileparts(classDir);
            candidates = { ...
                fullfile(classDir, varargin{:}), ...
                fullfile(classDir, 'DataFormatting', varargin{:}), ...
                fullfile(parentDir, 'DataFormatting', varargin{:})};
            resourcePath = candidates{1};
            for candidateIndex = 1:numel(candidates)
                if isfile(candidates{candidateIndex}) || isfolder(candidates{candidateIndex})
                    resourcePath = candidates{candidateIndex};
                    return
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.DataFormattingUIFigure.Position(3);
            windowHeight = app.DataFormattingUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.DataFormattingUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

        end

        % Callback function: LoadPushTool
        function LoadPushToolClicked(app, event)
            % Load TimeSeries files.
            loadpath = uigetdir();

            if loadpath == 0 % User cancels operation,Stop function running.
                return
            end

            app.LoadPath = loadpath;

            % Check if the save path has been set.
            if isempty(app.SetsavePath)
                app.SavePath = loadpath;
            end

            [~,folderName] = fileparts(app.LoadPath);
            filelist = dir(fullfile(app.LoadPath,"*.*"));
            filelist = filelist(~[filelist.isdir]); % Filter out directories.
            try
                if ~isempty(folderName)
                    app.folderNode = uitreenode(app.Tree,'Text',folderName,'NodeData',loadpath);

                    % Traverse the files in the folder.
                    for i = 1:length(filelist)
                        if ~ismember(filelist(i).name, {'.', '..'})
                            uitreenode('Text', filelist(i).name, 'Parent', app.folderNode);
                        end
                    end
                    app.TextArea.Value = "Loading successful";
                else
                    return
                end
            catch
                msgbox("No file selected.","Warning",'warn');
            end

        end

        % Callback function: SavePushTool
        function SavePushToolClicked(app, event)
            % Get the save path.
            app.SetsavePath = uigetdir();

            if isequal(app.SetsavePath, 0)
                return
            end

            % Set and display SavePath.
            if ~isequal(app.SetsavePath, 0)
                app.SavePath = app.SetsavePath;
                app.IsSavePathSetManually = true; % Confirm to manually set save folder.
                app.TextArea.Value = ["SavePath Setting:",app.SavePath];
            else
                return
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Clear Loadpath and delete folderNode.
            try
                if ~isempty(app.LoadPath)
                    app.LoadPath=[];

                    if ~isempty(app.Tree.Children)
                        delete(app.Tree.Children); % Delete all child nodes of the tree.
                    end

                    app.folderNode = []; % Clear node data.
                    app.TextArea.Value = "Clear successfully";
                else
                    app.TextArea.Value = "No file loading path set";
                end
            catch ME
                app.TextArea.Value = sprintf('Error during clearing: %s', ME.message);
            end

        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            try
                if ~isempty(app.SavePath)
                    winopen(app.SavePath);
                    app.TextArea.Value = ["File save path:",app.SavePath];
                elseif ~isempty(app.LoadPath)
                    winopen(app.LoadPath);
                    app.TextArea.Value = ["File save path:",app.LoadPath];
                else
                    app.TextArea.Value="No SavePath or LoadPath available";
                end
            catch ME
                app.TextArea.Value = sprintf('Error: %s', ME.message);
            end

        end

        % Button pushed function: PBOFormatButton
        function PBOFormatButtonPushed(app, event)
            try
                if isempty(app.LoadPath) || ~isfolder(app.LoadPath)
                    h = msgbox("No LoadPath avaliable","Warning","warn",'modal');
                    waitfor(h);
                    return
                end

                if isempty(app.SavePath) || ~isfolder(app.SavePath)
                    h = msgbox("No valid SavePath available","Warning","warn",'modal');
                    waitfor(h);
                    return
                end

                PBOlist = dir(fullfile(app.LoadPath,"*.pos"));
                if isempty(PBOlist)
                    h = msgbox("File type mismatch, Must be '.pos'.","Warning","warn","modal");
                    waitfor(h);
                    return
                end

                fig = app.DataFormattingUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');

                p = uiprogressdlg(fig,"Icon",iconPath, ...
                    "Message","Processing...", ...
                    "Cancelable","on", ...
                    "Value",0);

                cProg = onCleanup(@() safeCloseProgress(p)); %#ok<NASGU>

                % SD tolerance UI
                SDToleranceApp = SD_Tolerence;
                uiwait(SDToleranceApp.SDToleranceUIFigure);
                try
                    NTolerance = SDToleranceApp.Ntolerance.Value;
                    ETolerance = SDToleranceApp.Etolerance.Value;
                    UTolerance = SDToleranceApp.Utolerance.Value;
                catch
                    if isvalid(SDToleranceApp); delete(SDToleranceApp); end
                    return
                end
                if isvalid(SDToleranceApp); delete(SDToleranceApp); end

                format = '%f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %s';
                coeff = 1000;
                n = length(PBOlist);
                batchRecords = struct('site', {}, 'day', {}, 'lon', {}, 'lat', {}, 'neu', {});

                for i = 1:n
                    if p.CancelRequested
                        uialert(fig, "Operation canceled by user.", "Canceled", "Icon", "info");
                        return
                    end

                    fullpath = fullfile(app.LoadPath,PBOlist(i).name);
                    fileID = fopen(fullpath,'r');
                    if fileID == -1
                        warning("Failed to open: %s (skipped)", fullpath);
                        p.Value = i/n;
                        continue
                    end
                    cFile = onCleanup(@() fclose(fileID)); %#ok<NASGU>

                    source = textscan(fileID, format, 'CollectOutput', true, 'HeaderLines', 37);
                    if isempty(source) || isempty(source{1}) || size(source{1},2) < 21
                        warning("Unexpected format: %s (skipped)", fullpath);
                        p.Value = i/n;
                        continue
                    end

                    latitude = median(source{1}(:,13), 'omitnan');
                    longitude = normalizeLongitude(app, median(source{1}(:,14), 'omitnan'));

                    data = [source{1}(:,3), source{1}(:,16:21)];

                    % sigma thresholds (same logic)
                    data(data(:,5)*coeff > NTolerance, 2) = NaN;
                    data(data(:,6)*coeff > ETolerance, 3) = NaN;
                    data(data(:,7)*coeff > UTolerance, 4) = NaN;

                    mjd = floor(data(:,1));
                    neudata = data(:,2:end)*coeff;     % Convert to mm
                    data = [mjd, neudata];

                    if ~isempty(data)
                        pos_name = PBOlist(i).name;
                        new_name = pos_name(1:min(4, numel(pos_name)));  % safer, same intention

                        batchNeuData = data(:, 2:4);
                        if app.CoordinateNormalizationButton.Value == 1
                            batchNeuData = batchNeuData - batchNeuData(1, :);
                        end
                        batchRecords = addBatchRecord(app, batchRecords, new_name, data(:,1), longitude, latitude, batchNeuData);

                        fileCounter = 0;
                        for colIdx = 2:4
                            if app.CoordinateNormalizationButton.Value == 1
                                momdata = [data(:,1), data(:,colIdx)-data(1,colIdx)];
                            else
                                momdata = [data(:,1), data(:,colIdx)];
                            end

                            newFileName = sprintf('%s_%d.mom', new_name, fileCounter);
                            newFilePath = fullfile(app.SavePath, newFileName);

                            fileID1 = fopen(newFilePath, 'w');
                            if fileID1 == -1
                                error('Failed to open file: %s', newFilePath);
                            end
                            cOut = onCleanup(@() fclose(fileID1)); %#ok<NASGU>

                            fprintf(fileID1, '%.0f %.6f\n', momdata');
                            fileCounter = fileCounter + 1;
                        end
                    end

                    p.Value = i/n;
                end

                safeCloseProgress(p);
                pbo = buildBatchStruct(app, batchRecords);
                saveBatchMat(app, pbo, 'pbo', 'pbo.mat');

                uialert(app.DataFormattingUIFigure, ...
                    "Processing completed", ...
                    "Tip", ...
                    "Icon", "success");


            catch ME
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.DataFormattingUIFigure, errMsg, 'Error', 'Icon', 'error');
            end

            function safeCloseProgress(p)
                try
                    if exist('p','var') && ~isempty(p) && isvalid(p)
                        close(p);
                    end
                catch
                end
            end

        end

        % Button pushed function: NGLFormatButton
        function NGLFormatButtonPushed(app, event)
            p = [];
            try
                if isempty(app.LoadPath)
                    h = msgbox("No LoadPath avaliable","Warning","warn","modal");
                    waitfor(h);
                    return
                end

                % Determine if the file type exists.
                % Pay attention to the order of priority.
                NGLlist_tenv3_txt = dir(fullfile(app.LoadPath,"*tenv3.txt"));
                NGLlist_tenv3 = dir(fullfile(app.LoadPath,"*tenv3"));
                NGLlist = [NGLlist_tenv3;NGLlist_tenv3_txt];

                if isempty(NGLlist)
                    h = msgbox("File type mismatch, Must be '.tenv3.txt' or '.tenv3'.","Warning","warn","modal");
                    waitfor(h);
                    return
                end

                fig = app.DataFormattingUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig,"Icon",iconPath);
                p.Message = "Processing...";

                n = length(NGLlist);
                batchRecords = struct('site', {}, 'day', {}, 'lon', {}, 'lat', {}, 'neu', {});


                % Standard Deviation(sigma) Tolerance Setting.
                SDToleranceApp = SD_Tolerence;
                uiwait(SDToleranceApp.SDToleranceUIFigure);
                try
                    NTolerance = SDToleranceApp.Ntolerance.Value;
                    ETolerance = SDToleranceApp.Etolerance.Value;
                    UTolerance = SDToleranceApp.Utolerance.Value;
                catch
                    return
                end
                delete(SDToleranceApp)

                for i = 1:n
                    fullpath = fullfile(app.LoadPath,NGLlist(i).name); % ./NASA.txt

                    % cell is 23 column but double is 21 column.
                    alldata = importdata(fullpath," "); % tenv3.txt data.

                    if isstruct(alldata)
                        tempdata = alldata.data; % tenv3.txt numerical data.
                    else
                        tempdata = alldata;
                    end

                    % round up
                    mjd = floor(tempdata(:,2));
                    neu_data = tempdata(:,[9,7,11,14,13,15]) * 1000;

                    coeff = 1;  % already converted to mm
                   
                    neu_data(neu_data(:,4)*coeff > NTolerance, 1) = NaN;  
                    neu_data(neu_data(:,5)*coeff > ETolerance, 2) = NaN;  
                    neu_data(neu_data(:,6)*coeff > UTolerance, 3) = NaN;  

                    tenv3data = [mjd,neu_data]; % neu component time series data.

                    NGL_name = NGLlist(i).name; % Such as: BURN.tenv3.txt.
                    new_name = NGL_name(1:4); % Such as: BURN.
                    latitude = median(tempdata(:,19), 'omitnan');
                    longitude = normalizeLongitude(app, median(tempdata(:,20), 'omitnan'));

                    batchNeuData = tenv3data(:, 2:4);
                    if app.CoordinateNormalizationButton.Value == 1
                        batchNeuData = batchNeuData - batchNeuData(1, :);
                    end
                    batchRecords = addBatchRecord(app, batchRecords, new_name, tenv3data(:,1), longitude, latitude, batchNeuData);

                    fileCounter = 0;
                    for colIdx = 2:4
                        if app.CoordinateNormalizationButton.Value == 1
                            data = [tenv3data(:,1), tenv3data(:,colIdx)-tenv3data(1,colIdx)];
                        else
                            data = [tenv3data(:,1), tenv3data(:,colIdx)];
                        end

                        newFileName = sprintf('%s_%d.mom', new_name, fileCounter);
                        newFilePath = fullfile(app.SavePath, newFileName);

                        fileID1 = fopen(newFilePath, 'w');
                        if fileID1 == -1
                            error('Failed to open file: %s', newFilePath);
                        end

                        % Write data.
                        fprintf(fileID1, '%f %f\n', data');
                        fclose(fileID1);

                        % increase counter.
                        fileCounter = fileCounter + 1;
                    end
                    p.Value = (i/n);
                end
                close(p);
                ngl = buildBatchStruct(app, batchRecords);
                saveBatchMat(app, ngl, 'ngl', 'ngl.mat');
                uialert(app.DataFormattingUIFigure, ...
                    "Processing completed", ...
                    "Tip", ...
                    "Icon", "success");

            catch ME
                if ~isempty(p) && isvalid(p)
                    close(p);
                end
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.DataFormattingUIFigure, errMsg, 'Error', ...
                    'Icon', 'error');
            end
        end

        % Button pushed function: CEAFormatButton
        function CEAFormatButtonPushed(app, event)
            p = [];
            try
                if isempty(app.LoadPath)
                    h = msgbox("No LoadPath available","Warning","warn","modal");
                    waitfor(h);
                    return
                end

                % Determine if the file type exists.
                CEAList = dir(fullfile(app.LoadPath, "*.txt"));
                validFiles = CEAList(~endsWith({CEAList.name}, ".tenv3.txt")); % Exclude .tenv3.txt format

                if isempty(validFiles)
                    h = msgbox("File type mismatch, Must be standard '.txt'", "Warning", "warn", "modal");
                    waitfor(h);
                    return;
                end

                fig = app.DataFormattingUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig,"Icon",iconPath);
                p.Message = "Processing...";

                n = length(validFiles);
                batchRecords = struct('site', {}, 'day', {}, 'lon', {}, 'lat', {}, 'neu', {});

                % Standard Deviation(sigma) Tolerance Setting.
                SDToleranceApp = SD_Tolerence;
                uiwait(SDToleranceApp.SDToleranceUIFigure);
                try
                    NTolerance = SDToleranceApp.Ntolerance.Value;
                    ETolerance = SDToleranceApp.Etolerance.Value;
                    UTolerance = SDToleranceApp.Utolerance.Value;
                catch
                    return
                end
                delete(SDToleranceApp)

                for i = 1:n
                    fullpath = fullfile(app.LoadPath, validFiles(i).name);
                    [longitude, latitude] = readCeaReference(app, fullpath);
                    longitude = normalizeLongitude(app, longitude);

                    % Read CEA .txt file
                    fid = fopen(fullpath, 'r');
                    if fid == -1
                        error('Cannot open file: %s', fullpath);
                    end
                    fileCleanup = onCleanup(@() fclose(fid));
                    columns = textscan(fid, repmat('%f', 1, 9), ...
                        'CommentStyle', '#', 'MultipleDelimsAsOne', true, ...
                        'CollectOutput', true);
                    tempdata = columns{1};
                    clear fileCleanup
                    if isempty(tempdata) || size(tempdata, 2) < 9
                        error('CEA text file must contain at least 9 numeric columns: %s', fullpath);
                    end

                    year = tempdata(:,2);
                    doy = tempdata(:,3);
                    firstDay = datetime(year, 1, 1);
                    wantedDay = firstDay + (doy - 1);

                    % round up
                    mjd = ceil(days(wantedDay - datetime(1858, 11, 17)));
                    neu_data = tempdata(:,4:9) * 1000; % Unit mm

                    neu_data(neu_data(:,4) > NTolerance, 1) = NaN;
                    neu_data(neu_data(:,5) > ETolerance, 2) = NaN;
                    neu_data(neu_data(:,6) > UTolerance, 3) = NaN;
                    ceadata = [mjd, neu_data]; % CEA Time series

                    % Extract station name (first 4 characters)
                    CEA_name = validFiles(i).name;
                    new_name = CEA_name(1:4);

                    batchNeuData = ceadata(:, 2:4);
                    if app.CoordinateNormalizationButton.Value == 1
                        batchNeuData = batchNeuData - batchNeuData(1, :);
                    end
                    batchRecords = addBatchRecord(app, batchRecords, new_name, ceadata(:,1), longitude, latitude, batchNeuData);

                    fileCounter = 0;
                    % Write N, E, U components (columns 2:4) to separate .mom files
                    for colIdx = 2:4
                        if app.CoordinateNormalizationButton.Value == 1
                            data = [ceadata(:,1), ceadata(:,colIdx) - ceadata(1,colIdx)];
                        else
                            data = [ceadata(:,1), ceadata(:,colIdx)];
                        end

                        newFileName = sprintf('%s_%d.mom', new_name, fileCounter);
                        newFilePath = fullfile(app.SavePath, newFileName);

                        fileID1 = fopen(newFilePath, 'w');
                        if fileID1 == -1
                            error('Failed to open file: %s', newFilePath);
                        end

                        % Write data
                        fprintf(fileID1, '%f %f\n', data');
                        fclose(fileID1);

                        % Increase counter
                        fileCounter = fileCounter + 1;
                    end
                    p.Value = (i/n);
                end
                close(p);
                cea = buildBatchStruct(app, batchRecords);
                saveBatchMat(app, cea, 'cea', 'cea.mat');
                uialert(app.DataFormattingUIFigure, ...
                    "Processing completed", ...
                    "Tip", ...
                    "Icon", "success");

            catch ME
                if ~isempty(p) && isvalid(p)
                    close(p);
                end
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.DataFormattingUIFigure, errMsg, 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: MATFormatButton
        function MATFormatButtonPushed(app, event)
            try
                % Basic checks
                if isempty(app.LoadPath) || ~isfolder(app.LoadPath)
                    t = msgbox("No valid LoadPath available","Warning","warn","modal");
                    waitfor(t);
                    return;
                end

                ustations = getStationsFromPath(app.LoadPath, app.DataFormattingUIFigure);
                if isempty(ustations)
                    return;
                end

                nSta = numel(ustations);

                %Prepare UI progress dialog
                fig = app.DataFormattingUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');

                p = uiprogressdlg(fig, ...
                    "Icon", iconPath, ...
                    "Title", "Building GMIS struct", ...
                    "Message", "Scanning time range...", ...
                    "Value", 0, ...
                    "Cancelable", "on");

                cleanupObj = onCleanup(@() safeCloseProgress(p));

                % Determine global time axis characteristics
                % Collect per-station day vectors (cell) for axis decision without concatenation churn
                dayCells = cell(nSta, 1);

                minDay = inf;
                maxDay = -inf;
                anyNonIntegerMJD = false;

                for i = 1:nSta
                    if p.CancelRequested
                        uialert(fig, "Operation canceled by user.", "Canceled", "Icon", "info");
                        return;
                    end

                    p.Message = sprintf("Scanning station %d/%d: %s", i, nSta, string(ustations{i}));
                    p.Value = 0.05 * (i / nSta);

                    stationData = LoadData(app, app.LoadPath, ustations{i});
                    if isempty(stationData) || size(stationData,2) < 4
                        warning("Station %s returned empty/invalid data; it will be skipped in range scan.", ustations{i});
                        dayCells{i} = [];
                        continue;
                    end

                    dayColumn = stationData(:, 1);
                    % Remove invalid days
                    dayColumn = dayColumn(isfinite(dayColumn));

                    if isempty(dayColumn)
                        dayCells{i} = [];
                        continue;
                    end

                    % Detect non-integer MJD (irregular sampling)
                    fracPart = abs(dayColumn - round(dayColumn));
                    if any(fracPart > 1e-9)
                        anyNonIntegerMJD = true;
                    end

                    minDay = min(minDay, min(dayColumn));
                    maxDay = max(maxDay, max(dayColumn));

                    dayCells{i} = dayColumn;
                end

                if ~isfinite(minDay) || ~isfinite(maxDay)
                    uialert(fig, "No valid data found in the loaded .mom files.", "Error", "Icon", "error");
                    return;
                end

                % Decide time axis:
                % - If all days are essentially integers, build continuous daily axis [min:max] with step 1
                % - Otherwise, build sorted unique axis from all observed epochs (irregular)
                if ~anyNonIntegerMJD
                    % Force integer daily axis (robust to slight numeric noise)
                    minDayI = round(minDay);
                    maxDayI = round(maxDay);
                    uniqueDays = (minDayI:1:maxDayI).';
                else
                    % Irregular axis: union of all observed epochs
                    p.Message = "Building irregular time axis...";
                    allDaysVec = vertcat(dayCells{:});
                    allDaysVec = allDaysVec(isfinite(allDaysVec));
                    uniqueDays = unique(allDaysVec);
                    uniqueDays = sort(uniqueDays(:));
                end

                nDay = numel(uniqueDays);

                % Initialize output matrices with NaN
                dnData = NaN(nDay, nSta);
                deData = NaN(nDay, nSta);
                duData = NaN(nDay, nSta);

                % Fill matrices station-by-station (no full caching)
                for i = 1:nSta
                    if p.CancelRequested
                        uialert(fig, "Operation canceled by user.", "Canceled", "Icon", "info");
                        return;
                    end

                    p.Message = sprintf("Loading & mapping %d/%d: %s", i, nSta, string(ustations{i}));
                    p.Value = 0.05 + 0.90 * (i / nSta);

                    stationData = LoadData(app, app.LoadPath, ustations{i});
                    if isempty(stationData) || size(stationData,2) < 4
                        warning("Station %s returned empty/invalid data; leaving its column as NaN.", ustations{i});
                        continue;
                    end

                    dayColumn = stationData(:, 1);
                    neuData   = stationData(:, 2:4);

                    % Filter invalid rows
                    validMask = isfinite(dayColumn) & all(isfinite(neuData) | isnan(neuData), 2);
                    dayColumn = dayColumn(validMask);
                    neuData   = neuData(validMask, :);

                    if isempty(dayColumn)
                        continue;
                    end

                    % If axis is daily-integer, map by rounded days
                    if ~anyNonIntegerMJD
                        dayColumnMapped = round(dayColumn);
                    else
                        dayColumnMapped = dayColumn;
                    end

                    % Handle duplicate days within one station:
                    % Keep the LAST occurrence of each day (common for concatenated files / overwrites)
                    [uDay, idxLast] = unique(dayColumnMapped, 'last');
                    neuDataU = neuData(idxLast, :);

                    % Sort by day (not strictly required for mapping, but nice for sanity)
                    [uDay, order] = sort(uDay);
                    neuDataU = neuDataU(order, :);

                    % Map station days to global axis indices
                    [tf, loc] = ismember(uDay, uniqueDays);

                    % Protect against loc==0 (unmapped)
                    if any(~tf)
                        unmapped = uDay(~tf);
                        if numel(unmapped) <= 10
                            warning("Station %s has %d unmapped epochs (showing up to 10): %s", ...
                                ustations{i}, sum(~tf), mat2str(unmapped(:).'));
                        else
                            warning("Station %s has %d unmapped epochs; they were skipped.", ustations{i}, sum(~tf));
                        end
                    end

                    loc = loc(tf);
                    neuDataU = neuDataU(tf, :);

                    if isempty(loc)
                        continue;
                    end

                    dnData(loc, i) = neuDataU(:, 1);
                    deData(loc, i) = neuDataU(:, 2);
                    duData(loc, i) = neuDataU(:, 3);
                end

                % Build output struct (site as char per requirement)

                GmisStruct = struct();
                % Use char to match the original implementation (note: pads with spaces if lengths differ)
                GmisStruct.site = char(ustations);
                GmisStruct.day  = uniqueDays;
                GmisStruct.lat = [];
                GmisStruct.lon = [];
                GmisStruct.dn   = dnData;
                GmisStruct.de   = deData;
                GmisStruct.du   = duData;

                % Finalize progress
                p.Message = "Done. Saving file...";
                p.Value = 1.0;


                % Save result to .mat (only GmisStruct)
                try
                    [filename, pathname] = uiputfile('*.mat', ...
                        'Save as', fullfile(app.LoadPath, 'GmisStruct.mat'));

                    % User canceled save
                    if isequal(filename, 0)
                        uialert(app.DataFormattingUIFigure, ...
                            'Save operation canceled.', ...
                            'Info', ...
                            'Icon', 'info');
                        return;
                    end

                    OutPutName = fullfile(pathname, filename);

                    % Save only GmisStruct
                    save(OutPutName, 'GmisStruct');

                    % Success message
                    uialert(app.DataFormattingUIFigure, ...
                        'File saved successfully.', ...
                        'Success', ...
                        'Icon', 'success');

                catch ME2
                    % Error message
                    uialert(app.DataFormattingUIFigure, ...
                        sprintf('Failed to save file: %s', ME2.message), ...
                        'Error', ...
                        'Icon', 'error');
                    return;
                end

            catch ME
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.DataFormattingUIFigure, errMsg, 'Error', 'Icon', 'error');
            end

            % Local helper functions
            function safeCloseProgress(p)
                % Safely close progress dialog if it exists and is valid
                try
                    if ~isempty(p) && isvalid(p)
                        close(p);
                    end
                catch
                    % No-op
                end
            end

            function ustations = getStationsFromPath(Path, parentFig)
                momfiles = dir(fullfile(Path, "*.mom"));

                if isempty(momfiles)
                    uialert(parentFig, ...
                        "Please Load '.mom' files", ...
                        "Warning", ...
                        "Icon", "warning");
                    ustations = {};
                    return
                end

                stations = cellfun(@(name) extractBefore(name, "_"), {momfiles.name}, 'UniformOutput', false);

                % Filter empty station names defensively
                stations = stations(~cellfun(@isempty, stations));

                ustations = unique(stations);
                ustations = sort(ustations); % Sort station name.
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            try
                selectedNodes = app.Tree.SelectedNodes;
                % Perform a single folder conversion.
                selectedNode = selectedNodes(1);

                if ~isempty(selectedNode.NodeData)
                    app.LoadPath = selectedNode.NodeData;

                    if ~app.IsSavePathSetManually
                        app.SavePath = selectedNode.NodeData;
                    end

                    % Show Tip.
                    app.TextArea.Value = sprintf("Convert folder path:%s",selectedNode.NodeData);
                end
            catch ME
                errMsg = sprintf("Error:%s",ME.message);
                t = msgbox(errMsg,"Error","error","modal");
                waitfor(t);
            end
        end

        % Window key press function: DataFormattingUIFigure
        function DataFormattingUIFigureWindowKeyPress(app, event)
            key = event.Key;

            % Set accelerator 'delete' delete selected node.
            node = app.Tree.SelectedNodes;
            if strcmpi(key, 'delete')
                if isempty(node) || ~isvalid(node)
                    return
                end
                choice = questdlg(['Are you sure you want to delete the node: ', node.Text, '?'], ...
                    'Delete Node', 'Yes', 'No', 'No');

                if strcmp(choice, 'Yes')
                    try
                        % Delete Node.
                        delete(node);

                        % Delete loadpath.
                        app.LoadPath=[];

                    catch ME
                        msgbox(['Error deleting node: ', ME.message], 'Error', 'error');
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataFormattingUIFigure = uifigure('Visible', 'off');
            app.DataFormattingUIFigure.Name = 'DataFormatting';
            app.DataFormattingUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'convert-shapes.png');
            app.DataFormattingUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @DataFormattingUIFigureWindowKeyPress, true);
            app.Toolbar = uitoolbar(app.DataFormattingUIFigure);
            app.LoadPushTool = uipushtool(app.Toolbar);
            app.LoadPushTool.Tooltip = {'Load TimeSeries File'};
            app.LoadPushTool.ClickedCallback = createCallbackFcn(app, @LoadPushToolClicked, true);
            app.LoadPushTool.Icon = fullfile(pathToMLAPP, 'logo', 'file-import.png');
            app.SavePushTool = uipushtool(app.Toolbar);
            app.SavePushTool.Tooltip = {'Set Save folders'};
            app.SavePushTool.ClickedCallback = createCallbackFcn(app, @SavePushToolClicked, true);
            app.SavePushTool.Icon = fullfile(pathToMLAPP, 'logo', 'folder-download.png');
            app.LogPanel = uipanel(app.DataFormattingUIFigure);
            app.LogPanel.Title = 'Log Panel';
            app.TextArea = uitextarea(app.DataFormattingUIFigure);
            app.ControlsPanel = uipanel(app.DataFormattingUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.PBOFormatButton = uibutton(app.ControlsPanel, 'push');
            app.PBOFormatButton.ButtonPushedFcn = createCallbackFcn(app, @PBOFormatButtonPushed, true);
            app.PBOFormatButton.Text = 'PBO Format';
            app.NGLFormatButton = uibutton(app.ControlsPanel, 'push');
            app.NGLFormatButton.ButtonPushedFcn = createCallbackFcn(app, @NGLFormatButtonPushed, true);
            app.NGLFormatButton.Text = 'NGL Format';
            app.CEAFormatButton = uibutton(app.ControlsPanel, 'push');
            app.CEAFormatButton.ButtonPushedFcn = createCallbackFcn(app, @CEAFormatButtonPushed, true);
            app.CEAFormatButton.Text = 'CEA Format';
            app.ViewButton = uibutton(app.ControlsPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.ClearButton = uibutton(app.ControlsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MATFormatButton = uibutton(app.ControlsPanel, 'push');
            app.MATFormatButton.ButtonPushedFcn = createCallbackFcn(app, @MATFormatButtonPushed, true);
            app.MATFormatButton.Text = 'MAT Format';
            app.CoordinateNormalizationButton = uibutton(app.ControlsPanel, 'state');
            app.CoordinateNormalizationButton.Text = 'Coordinate Normalization';
            app.CoordinateNormalizationButton.Value = true;
            app.Tree = uitree(app.DataFormattingUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            % Show the figure after all components are created
            app.DataFormattingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataFormatting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataFormattingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataFormattingUIFigure)
        end
    end
end