classdef FormatConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FormatConverterUIFigure  matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        InputFilesPanel          matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        SaveButton               matlab.ui.control.Button
        AddFilesButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.FormatConverterUIFigure.Position(3);
            windowHeight = app.FormatConverterUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.FormatConverterUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FormatConverterUIFigure = uifigure('Visible', 'off');
            app.FormatConverterUIFigure.Name = 'GNSS Time Series Formatter';
            app.AddFilesButton = uibutton(app.FormatConverterUIFigure, 'push');
            app.SaveButton = uibutton(app.FormatConverterUIFigure, 'push');
            app.Panel = uipanel(app.FormatConverterUIFigure);
            app.InputFilesPanel = uipanel(app.Panel);
            app.InputFilesPanel.Title = 'Input Files';
            app.TextArea = uitextarea(app.InputFilesPanel);
            % Show the figure after all components are created
            app.FormatConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FormatConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FormatConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FormatConverterUIFigure)
        end
    end
end