classdef SD_Tolerence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SDToleranceUIFigure  matlab.ui.Figure
        ConfirmButton        matlab.ui.control.Button
        UmmLabel             matlab.ui.control.Label
        EmmLabel             matlab.ui.control.Label
        NmmLabel             matlab.ui.control.Label
        UEditField           matlab.ui.control.NumericEditField
        EEditField           matlab.ui.control.NumericEditField
        NEditField           matlab.ui.control.NumericEditField
        UButton              matlab.ui.control.Button
        EButton              matlab.ui.control.Button
        NButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        Ntolerance 
        Etolerance 
        Utolerance 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.SDToleranceUIFigure.Position(3);
            windowHeight = app.SDToleranceUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.SDToleranceUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.Ntolerance = app.NEditField;
            app.Etolerance = app.EEditField;
            app.Utolerance = app.UEditField;
            uiresume(app.SDToleranceUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SDToleranceUIFigure = uifigure('Visible', 'off');
            app.SDToleranceUIFigure.Name = 'SDTolerance';
            app.SDToleranceUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'settings.png');
            app.NButton = uibutton(app.SDToleranceUIFigure, 'push');
            app.NButton.Text = 'N';
            app.EButton = uibutton(app.SDToleranceUIFigure, 'push');
            app.EButton.Text = 'E';
            app.UButton = uibutton(app.SDToleranceUIFigure, 'push');
            app.UButton.Text = 'U';
            app.NEditField = uieditfield(app.SDToleranceUIFigure, 'numeric');
            app.NEditField.Limits = [0 Inf];
            app.NEditField.ValueDisplayFormat = '%.0f';
            app.NEditField.Value = 10;
            app.EEditField = uieditfield(app.SDToleranceUIFigure, 'numeric');
            app.EEditField.Limits = [0 Inf];
            app.EEditField.ValueDisplayFormat = '%.0f';
            app.EEditField.Value = 10;
            app.UEditField = uieditfield(app.SDToleranceUIFigure, 'numeric');
            app.UEditField.Limits = [0 Inf];
            app.UEditField.ValueDisplayFormat = '%.0f';
            app.UEditField.Value = 20;
            app.NmmLabel = uilabel(app.SDToleranceUIFigure);
            app.NmmLabel.Text = 'mm';
            app.EmmLabel = uilabel(app.SDToleranceUIFigure);
            app.EmmLabel.Text = 'mm';
            app.UmmLabel = uilabel(app.SDToleranceUIFigure);
            app.UmmLabel.Text = 'mm';
            app.ConfirmButton = uibutton(app.SDToleranceUIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Icon = fullfile(pathToMLAPP, 'logo', 'confirmation.png');
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.SDToleranceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SD_Tolerence

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SDToleranceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SDToleranceUIFigure)
        end
    end
end