classdef Hector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HectorUIFigure                  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadAnalysisFileMenu            matlab.ui.container.Menu
        LoadOptimalNoiseModelmatMenu    matlab.ui.container.Menu
        FunctionMenu                    matlab.ui.container.Menu
        OffsetMenu                      matlab.ui.container.Menu
        FindOffsetMenu                  matlab.ui.container.Menu
        ApplyOffsetCorrectionsMenu      matlab.ui.container.Menu
        AddOffsetHeadersMenu            matlab.ui.container.Menu
        PSDEstimationMenu               matlab.ui.container.Menu
        ParameterEstimationMenu         matlab.ui.container.Menu
        NoiseModelSelectionMenu         matlab.ui.container.Menu
        GenerateResidualTimeSeriesMenu  matlab.ui.container.Menu
        StationListPanel                matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        NorthUIAxes                     matlab.ui.control.UIAxes
        EastUIAxes                      matlab.ui.control.UIAxes
        VerticalUIAxes                  matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                1.Dirctory can not contain Chinese.                %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    properties (Access = public)
        selectedData % The single station three component data from UItree.

        momlist % Load '.mom' file list.
        loadpath % Load '.mom' file path.


        FindoffsetNoiseModel % FindOffset_Auto.mlapp attribution.
        FindoffsetMaxIterations

        OptimalNoiseModel % Minmum AIC or BIC find out best noise model.

        ManualOffsets % Used to record the manually entered offset value.
        Manualmjds % Used to record the manually entered mjd value.
        Manualsite

        WoffsetMJDs % Write to files
        WoffsetFiles % Write to files


    end


    methods (Access = private)

        function resourcePath = resolveResource(app, varargin) %#ok<INUSD>
            classDir = fileparts(mfilename('fullpath'));
            parentDir = fileparts(classDir);
            candidates = { ...
                fullfile(classDir, varargin{:}), ...
                fullfile(classDir, 'Hector', varargin{:}), ...
                fullfile(parentDir, 'Hector', varargin{:})};
            resourcePath = candidates{1};
            for candidateIndex = 1:numel(candidates)
                if isfile(candidates{candidateIndex}) || isfolder(candidates{candidateIndex})
                    resourcePath = candidates{candidateIndex};
                    return
                end
            end
        end

        function exePath = resolveExecutable(app, executableName)
            exePath = resolveResource(app, 'exe', executableName);
            if ~isfile(exePath)
                error('Required Hector executable not found: %s', exePath);
            end
        end

        function [Mneudata,offsetMJDs] = LoadData(~,Path, filename)
            % This function is to Load three component .mom file data.
            Mneudata = [];
            offsetMJDs = cell(1, 3);
            try
                counter = 0;
                neudata = [];
                offsetMJDs = {};


                % Initialize min and max MJD
                bmaxMJD = -inf;
                bminMJD = inf;

                % Extract three components data.
                for i = 1:3
                    % textscan is more robust and fast than importdata
                    path1 = fullfile(Path, sprintf('%s_%d.mom', filename, counter));
                    fileID = fopen(path1,'r');

                    if fileID == -1
                        msgbox("No files","Warning","warn");
                        return
                    end

                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);

                    data = [momdata{1}];

                    bminMJD = min(bminMJD, min(data));
                    bmaxMJD = max(bmaxMJD, max(data));

                    counter = counter+1;
                end

                counter = 0;
                for file = 1:3
                    path1 = fullfile(Path, sprintf('%s_%d.mom', filename, counter));
                    fileID = fopen(path1,'r');

                    if fileID == -1
                        msgbox("No files","Warning","warn");
                        return
                    end

                    offsetMJD = [];
                    % Read all # offset lines
                    while ~feof(fileID)
                        line = fgetl(fileID);
                        if contains(line, "# offset")
                            parts = strsplit(line);
                            offsetMJD = [offsetMJD,str2double(parts{3})];
                        end
                    end

                    % If no offset found, store an empty array
                    if isempty(offsetMJD)
                        offsetMJDs{file} = [];
                    else
                        offsetMJDs{file} = offsetMJD;
                    end

                    % Reset file pointer and read data
                    frewind(fileID);
                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);
                    if ~isempty(momdata{3}) && all(isnan(momdata{3}))
                        data = [momdata{1},momdata{2}];
                    else
                        data = [momdata{1},momdata{2},momdata{3}];
                    end

                    MJDfull = (bminMJD : 1 : bmaxMJD);
                    if size(data,2) < 3
                        fullData = nan(length(MJDfull),2);
                    else
                        fullData = nan(length(MJDfull),3);
                    end
                    [~,loc] = ismember(data(:,1),MJDfull);
                    fullData(loc,2:end) = data(:,2:end);

                    if size(data,2) < 3
                        neudata = [neudata,fullData(:,2)];
                    else
                        neudata = [neudata,fullData(:,2),fullData(:,3)];
                    end

                    counter = counter + 1;
                end

                Mneudata = [MJDfull',neudata];

            catch ME
                Mneudata = [];
                offsetMJDs = cell(1, 3);
                errMsg = sprintf("Error: %s",ME.message);
                h = msgbox(errMsg,'Error','error');
                waitfor(h)
            end
        end


        function [Mneudata, offsetMJDs] = ResidualData(app, Path, filename)
            % ResidualData loads three-component .mom files and builds aligned residual series.
            %
            % Output:
            %   Mneudata   - [MJD, residual_0, residual_1, residual_2] aligned on daily grid
            %   offsetMJDs - 1x3 cell array, offsets parsed from each component file

            % ---------------------------------------------------------------------
            % Always initialize outputs to safe defaults.
            % This guarantees outputs are assigned even if we early-return or error.
            % ---------------------------------------------------------------------
            Mneudata = [];
            offsetMJDs = cell(1, 3);

            try
                counter = 0;
                neudata = [];

                % Initialize global min and max MJD
                bmaxMJD = -inf;
                bminMJD = inf;

                % -----------------------------------------------------------------
                % Pass 1: Determine global time bounds across three component files.
                % -----------------------------------------------------------------
                for i = 1:3 %#ok<NASGU>
                    path1 = fullfile(Path, sprintf('%s_%d.mom', filename, counter));
                    fileID = fopen(path1, 'r');

                    if fileID == -1
                        msgbox("No files", "Warning", "warn");
                        return;  % Outputs already set to defaults.
                    end

                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);

                    data = momdata{1};
                    if ~isempty(data)
                        bminMJD = min(bminMJD, min(data));
                        bmaxMJD = max(bmaxMJD, max(data));
                    end

                    counter = counter + 1;
                end

                % No valid MJD found in any file.
                if isinf(bminMJD) || isinf(bmaxMJD)
                    return;
                end

                % Build the daily common grid once.
                MJDfull = (bminMJD : 1 : bmaxMJD)';

                % -----------------------------------------------------------------
                % Pass 2: Load each component, parse offsets, align to MJDfull,
                % and compute residual = value - error (kept identical to your logic).
                % -----------------------------------------------------------------
                counter = 0;
                for file = 1:3
                    path1 = fullfile(Path, sprintf('%s_%d.mom', filename, counter));

                    [offsetMJD, fullData] = ReadAndAlignOneComponent_(app, path1, MJDfull);

                    % If file missing, helper returns [] and we keep the original behavior:
                    % show warning and exit early.
                    if isempty(fullData) && isempty(offsetMJD)
                        msgbox("No files", "Warning", "warn");
                        return;
                    end

                    % Store offsets (empty if none).
                    if isempty(offsetMJD)
                        offsetMJDs{file} = [];
                    else
                        offsetMJDs{file} = offsetMJD;
                    end

                    % If no samples, fullData will be NaNs, so residual becomes NaN.
                    neudata = [neudata, fullData(:, 1) - fullData(:, 2)]; %#ok<AGROW>

                    counter = counter + 1;
                end

                % Assemble final output matrix.
                Mneudata = [MJDfull, neudata];

            catch ME
                % Ensure outputs are assigned even on exception.
                Mneudata = [];
                offsetMJDs = cell(1, 3);

                errMsg = sprintf("Error: %s", ME.message);
                h = msgbox(errMsg, 'Error', 'error');
                waitfor(h);
            end
        end

        % ======================================================================
        % Helper functions (file-local)
        % ======================================================================

        function [offsetMJD, fullData] = ReadAndAlignOneComponent_(app, path1, MJDfull)
            % ReadAndAlignOneComponent_ reads one .mom component file and aligns it to MJDfull.
            %
            % Input:
            %   path1   - full file path to "<station>_<k>.mom"
            %   MJDfull - daily grid vector (Nx1)
            %
            % Output:
            %   offsetMJD - row vector of parsed offsets; empty if none
            %   fullData  - Nx2 aligned matrix: [value, error], NaN where missing
            %
            % Notes:
            %   - If file cannot be opened, returns offsetMJD=[] and fullData=[].
            %   - Data parsing uses textscan with CommentStyle '#'.

            offsetMJD = [];
            fullData = [];

            fileID = fopen(path1, 'r');
            if fileID == -1
                return;
            end

            % --------------------------------------------------------------
            % Parse all "# offset ..." lines (if present).
            % --------------------------------------------------------------
            while ~feof(fileID)
                line = fgetl(fileID);
                if ischar(line) && contains(line, "# offset")
                    parts = strsplit(strtrim(line));
                    % Defensive: ensure expected token exists.
                    if numel(parts) >= 3
                        offsetMJD = [offsetMJD, str2double(parts{3})]; %#ok<AGROW>
                    end
                end
            end

            % --------------------------------------------------------------
            % Reset file pointer and read numeric data:
            % columns: [MJD, value, error] (third column can be NaN if absent)
            % --------------------------------------------------------------
            frewind(fileID);
            momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
            fclose(fileID);

            data = [momdata{1}, momdata{2}, momdata{3}];

            % Pre-allocate aligned output: [value, error]
            fullData = nan(length(MJDfull), 2);

            if isempty(data)
                return;
            end

            % Align using exact match on MJD (assumes daily integer MJDs).
            [found, loc] = ismember(data(:, 1), MJDfull);
            loc = loc(found);

            if ~isempty(loc)
                fullData(loc, :) = data(found, 2:3);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.HectorUIFigure.Position(3);
            windowHeight = app.HectorUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.HectorUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

        end

        % Menu selected function: LoadAnalysisFileMenu
        function LoadAnalysisFileMenuSelected(app, event)
            % Load Time series files
            try
                % Prompt user to select a directory
                app.loadpath = uigetdir("*.*");
                if app.loadpath == 0
                    return % If the user cancels
                end
            catch ME
                % Display error message using uialert
                uialert(app.HectorUIFigure, sprintf('Error loading files: %s', ME.message), 'Error', ...
                    'Icon', 'error');
                return
            end

            try
                % Clear existing tree nodes if any
                if ~isempty(app.Tree.Children)
                    delete(app.Tree.Children);
                end

                % Get list of .mom files
                app.momlist = dir(fullfile(app.loadpath, "*.mom"));
                [~, folderName] = fileparts(app.loadpath);

                if ~isempty(app.momlist)
                    % Initialize container for file groups
                    fileGroups = containers.Map();

                    % Create parent node for the folder
                    fatertree = uitreenode(app.Tree, 'Text', folderName, ...
                        'NodeData', app.loadpath);

                    % Group files by base name
                    for i = 1:length(app.momlist)
                        baseName = extractBefore(app.momlist(i).name, '_');
                        if isKey(fileGroups, baseName)
                            fileGroups(baseName) = [fileGroups(baseName), app.momlist(i)]; % Append to existing group
                        else
                            fileGroups(baseName) = app.momlist(i); % Create new group
                        end
                    end

                    % Create tree nodes for each group and its files
                    groupNames = keys(fileGroups);
                    for i = 1:length(groupNames)
                        groupName = groupNames{i};
                        filesInGroup = fileGroups(groupName);

                        firstFile = filesInGroup(1);
                        firstFilePath = fullfile(firstFile.folder, firstFile.name);
                        groupNode = uitreenode(fatertree, 'Text', groupName, 'NodeData', firstFilePath);

                        for j = 1:length(filesInGroup)
                            file = filesInGroup(j);
                            uitreenode(groupNode, 'Text', file.name, ...
                                'NodeData', fullfile(file.folder, file.name));
                        end
                    end
                else
                    % Display warning if no .mom files found
                    uialert(app.HectorUIFigure, 'File type mismatch, must be ''.mom''.', 'Warning', ...
                        'Icon', 'warning');
                    return
                end
            catch ME
                % Display error message using uialert
                uialert(app.HectorUIFigure, sprintf('Error: %s', ME.message), 'Error', ...
                    'Icon', 'error');
                return
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            if isempty(selectedNodes) || isempty(selectedNodes.NodeData)
                return
            end

            filePath = selectedNodes.NodeData; % Such as:C:\Users\xx\Desktop\gx\station_1.mom
            % Load three componet data, is a [MJD North East Up] matrix.
            % Passing to global variable: app.selectedData.
            if isfile(filePath)
                try

                    [Path,filename,~] = fileparts(filePath);% Path :C:\Users\xx\Desktop\gx  filename :BJFS_1.mom
                    filename = extractBefore(filename, '_'); % Extract the base file name before '-' char.

                    [full_neudata,offsetMJDs] = LoadData(app,Path,filename);

                    app.selectedData = full_neudata;

                catch ME
                    errMsg = sprintf('Failed to load file: %s', ME.message);
                    uialert(app.HectorUIFigure, errMsg, 'Error', 'Icon', 'error', 'Modal', true);
                    return
                end
            else
                return
            end

            if isempty(full_neudata) || size(full_neudata, 2) < 4
                uialert(app.HectorUIFigure, 'The selected station does not have a complete three-component data set.', ...
                    'Invalid Data', 'Icon', 'warning', 'Modal', true);
                return
            end

            % Recall function.
            plotData(app,full_neudata,offsetMJDs);

            function plotData(app, full_neudata, offsetMJDs)
                if size(full_neudata, 2) == 4
                    % Basic plot when there are 4 columns
                    plotBasicData(app, full_neudata);

                    % If offsetMJDs is provided, draw vertical lines
                    if nargin > 2 && ~isempty(offsetMJDs)
                        plotOffsetLines(app, offsetMJDs,full_neudata);
                    end

                else
                    % Clear the axes before plotting new data
                    cla(app.NorthUIAxes);
                    cla(app.EastUIAxes);
                    cla(app.VerticalUIAxes);

                    hold(app.NorthUIAxes, 'on');
                    hold(app.EastUIAxes, 'on');
                    hold(app.VerticalUIAxes, 'on');

                    % Plot origin data (first 2, 4, 6 columns)
                    plotOriginData(app, full_neudata);

                    % Plot model fix data (3, 5, 7 columns)
                    plotModelFixData(app, full_neudata);

                    % If offsetMJDs is provided, draw vertical lines
                    if nargin > 2 && ~isempty(offsetMJDs)
                        plotOffsetLines(app, offsetMJDs, full_neudata);
                    end

                    hold(app.NorthUIAxes, 'off');
                    hold(app.EastUIAxes, 'off');
                    hold(app.VerticalUIAxes, 'off');
                end
            end

            % 1970 + (MJD - 40587 + 0.5) / 365.25;
            % Function to plot basic data when there are 4 columns
            function plotBasicData(app, full_neudata)
                plot(app.NorthUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 2), 'black.');
                plot(app.EastUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 3), 'black.');
                plot(app.VerticalUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 4), 'black.');
            end

            % Function to plot origin data (first 2, 4, 6 columns)
            function plotOriginData(app, full_neudata)
                plot(app.NorthUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 2), 'black.');
                plot(app.EastUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 4), 'black.');
                plot(app.VerticalUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 6), 'black.');
            end

            % Function to plot model fix data (3, 5, 7 columns)
            function plotModelFixData(app, full_neudata)
                % Plot the model fix data
                plot(app.NorthUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 3), 'Color', 'red', 'LineWidth', 2);
                plot(app.EastUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 5), 'Color', 'red', 'LineWidth', 2);
                plot(app.VerticalUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 7), 'Color', 'red', 'LineWidth', 2);
            end


            % Function to plot offset MJD lines
            function plotOffsetLines(app, offsetMJDs, ~)
                % Loop through each axis (North, East, Vertical)
                axes = {app.NorthUIAxes, app.EastUIAxes, app.VerticalUIAxes};

                if ~iscell(offsetMJDs)
                    offsetMJDs = {offsetMJDs};
                end

                % Loop through the 3 components
                for i = 1:min(3, length(offsetMJDs))
                    if ~isempty(offsetMJDs{i}) && isvalid(axes{i})
                        mjd = offsetMJDs{i};
                        xValues = 1970 + (mjd - 40587 + 0.5) / 365.25;
                        for x = xValues(:)'
                            xline(axes{i}, x, 'Color', 'blue', 'LineWidth', 1, 'LineStyle', '--');
                        end
                    end
                end
            end
        end

        % Menu selected function: ApplyOffsetCorrectionsMenu
        function ApplyOffsetCorrectionsMenuSelected(app, event)
            try
                if isempty(app.momlist)
                    uialert(app.HectorUIFigure, 'No processing data input', 'Warning', ...
                        'Icon', 'warning');
                    return
                end

                outputdir = app.loadpath;

                corrected_dir = fullfile(outputdir, 'corrected');
                if ~isfolder(corrected_dir)
                    mkdir(corrected_dir);
                end

                fig = app.HectorUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig,  "Icon", iconPath, "Cancelable", "on");

                p.Message = "Correcting offsets...";
                p.Value = 0;

                logRows = cell(0, 6);
                nFiles = numel(app.momlist);

                for file = 1:nFiles
                    if p.CancelRequested
                        break
                    end

                    Fname = app.momlist(file).name;
                    inputPath = fullfile(outputdir, Fname);
                    outputPath = fullfile(corrected_dir, Fname);

                    p.Message = sprintf('Correcting %s (%d/%d)', Fname, file, nFiles);

                    try
                        [mjd, obs, offsetMJDs] = readMomFile(inputPath);

                        if isempty(offsetMJDs)
                            writeCorrectedMom(outputPath, mjd, obs);
                            addLog(Fname, NaN, NaN, NaN, 'no_offset_header', ...
                                'No # offset header found; numeric data copied without correction.');
                        else
                            cmdout = runEstimateTrend(Fname, inputPath);
                            estimates = parseOffsetEstimates(cmdout);
                            correctedObs = obs;

                            for k = 1:numel(offsetMJDs)
                                idx = findOffsetEstimate(estimates, offsetMJDs(k));
                                if isempty(idx)
                                    addLog(Fname, offsetMJDs(k), NaN, NaN, 'missing_estimate', ...
                                        'No matching estimatetrend offset estimate found in stdout.');
                                    continue
                                end

                                amplitude = estimates(idx).Amplitude;
                                sigma = estimates(idx).Sigma;
                                correctedObs = correctedObs - amplitude .* haveside(mjd - offsetMJDs(k));
                                addLog(Fname, offsetMJDs(k), amplitude, sigma, 'corrected', '');
                            end

                            writeCorrectedMom(outputPath, mjd, correctedObs);
                        end

                    catch fileME
                        try
                            [mjd, obs] = readMomFile(inputPath);
                            writeCorrectedMom(outputPath, mjd, obs);
                            addLog(Fname, NaN, NaN, NaN, 'failed_copied_uncorrected', fileME.message);
                        catch
                            addLog(Fname, NaN, NaN, NaN, 'failed', fileME.message);
                        end
                    end

                    p.Value = file / nFiles;
                    throttleMemory();
                end

                writeCorrectionLog(fullfile(corrected_dir, 'OffsetCorrectionLog.csv'));

                if exist('p', 'var') && isvalid(p)
                    close(p);
                end

                uialert(app.HectorUIFigure, sprintf('Offset correction completed.\nOutput folder:\n%s', corrected_dir), ...
                    'Tip', 'Icon', 'success');
            catch Me
                if exist('p', 'var') && isvalid(p)
                    close(p);
                end
                errMsg = sprintf('Error: %s', Me.message);
                uialert(app.HectorUIFigure, errMsg, 'Error', 'Icon', 'error');
            end


            function [mjd, obs, offsetMJDs] = readMomFile(filePath)
                if ~isfile(filePath)
                    error('Input .mom file not found: %s', filePath);
                end

                text = fileread(filePath);
                lines = regexp(text, '\r\n|\n|\r', 'split');

                offsetMJDs = [];
                numExpr = '([-+]?\d*\.?\d+(?:[eE][-+]?\d+)?)';
                offsetExpr = ['^\s*#\s*offset\s+' numExpr];
                for iLine = 1:numel(lines)
                    tok = regexp(lines{iLine}, offsetExpr, 'tokens', 'once');
                    if ~isempty(tok)
                        offsetMJDs(end+1) = str2double(tok{1}); %#ok<AGROW>
                    end
                end
                offsetMJDs = sort(unique(offsetMJDs(~isnan(offsetMJDs))));

                fid = fopen(filePath, 'r');
                if fid == -1
                    error('Failed to open file: %s', filePath);
                end
                cleaner = onCleanup(@() fclose(fid));

                momdata = textscan(fid, '%f %f %*[^\n]', ...
                    'CommentStyle', '#', ...
                    'MultipleDelimsAsOne', true);

                mjd = momdata{1};
                obs = momdata{2};

                if isempty(mjd) || isempty(obs)
                    error('No numeric MJD/value data found in file: %s', filePath);
                end

                valid = ~isnan(mjd) & ~isnan(obs);
                mjd = mjd(valid);
                obs = obs(valid);
                clear cleaner;
            end

            function cmdout = runEstimateTrend(fileName, inputPath)
                runDir = tempname;
                mkdir(runDir);
                cleanupObj = onCleanup(@() cleanupRunDir(runDir)); %#ok<NASGU>

                tempInputName = 'input.mom';
                tempOutputName = 'output.mom';
                copyfile(inputPath, fullfile(runDir, tempInputName), 'f');
                writeCtlFile(fullfile(runDir, 'estimatetrend.ctl'), tempInputName, tempOutputName);

                NET.addAssembly('System');
                proc = System.Diagnostics.Process();
                proc.StartInfo.FileName = resolveEstimateTrendExe();
                proc.StartInfo.Arguments = 'estimatetrend.ctl';
                proc.StartInfo.WorkingDirectory = runDir;
                proc.StartInfo.UseShellExecute = false;
                proc.StartInfo.RedirectStandardOutput = true;
                proc.StartInfo.RedirectStandardError = true;
                proc.StartInfo.CreateNoWindow = true;

                proc.Start();

                outReader = proc.StandardOutput;
                errReader = proc.StandardError;
                cmdout = char(outReader.ReadToEnd());
                errout = char(errReader.ReadToEnd());

                proc.WaitForExit();
                exitCode = proc.ExitCode;
                outReader.Close();
                errReader.Close();
                proc.Close();

                clear proc outReader errReader;

                if ~isempty(strtrim(errout))
                    cmdout = sprintf('%s\n\n[STDERR]\n%s', cmdout, errout);
                end

                if exitCode ~= 0
                    details = strtrim(cmdout);
                    if numel(details) > 1200
                        details = [details(1:1200) '...'];
                    end
                    error('estimatetrend failed for %s (exit code %d): %s', fileName, exitCode, details);
                end

                if ~isfile(fullfile(runDir, tempOutputName))
                    error('estimatetrend did not create expected output file for %s.', fileName);
                end
            end

            function writeCtlFile(ctlPath, inputName, outputName)
                fid = fopen(ctlPath, 'w');
                if fid == -1
                    error('Unable to create control file: %s', ctlPath);
                end
                fprintf(fid, 'DataFile            %s\n', inputName);
                fprintf(fid, 'OutputFile          %s\n', outputName);
                fprintf(fid, 'DataDirectory       .\n');
                fprintf(fid, 'interpolate         no\n');
                fprintf(fid, 'ScaleFactor         1.0\n');
                fprintf(fid, 'NoiseModels         Powerlaw FlickerGGM\n');
                fprintf(fid, 'estimateoffsets     yes\n');
                fprintf(fid, 'seasonalsignal      yes\n');
                fprintf(fid, 'halfseasonalsignal  yes\n');
                fprintf(fid, 'DegreePolynomial    1\n');
                fprintf(fid, 'PhysicalUnit        mm\n');
                fprintf(fid, 'GGM_1mphi           6.9e-06\n');
                fclose(fid);
            end

            function exePath = resolveEstimateTrendExe()
                exePath = resolveExecutable(app, 'estimatetrend.exe');
            end

            function estimates = parseOffsetEstimates(cmdout)
                estimates = struct('MJD', {}, 'Amplitude', {}, 'Sigma', {});
                numExpr = '([-+]?\d*\.?\d+(?:[eE][-+]?\d+)?)';
                offsetExpr = ['offset\s+at\s+' numExpr '\s*:\s*' numExpr '\s*\+/-\s*' numExpr];
                lines = splitlines(string(cmdout));

                for iLine = 1:numel(lines)
                    tok = regexp(char(lines(iLine)), offsetExpr, 'tokens', 'once');
                    if isempty(tok)
                        continue
                    end

                    estimates(end+1).MJD = str2double(tok{1}); %#ok<AGROW>
                    estimates(end).Amplitude = str2double(tok{2});
                    estimates(end).Sigma = str2double(tok{3});
                end
            end

            function idx = findOffsetEstimate(estimates, offsetMJD)
                idx = [];
                if isempty(estimates)
                    return
                end

                estimateMJDs = [estimates.MJD];
                [delta, bestIdx] = min(abs(estimateMJDs - offsetMJD));
                if delta <= 1.0e-2
                    idx = bestIdx;
                end
            end

            function y = haveside(x)
                y = double(x >= 0);
            end

            function writeCorrectedMom(outputPath, mjd, values)
                fid = fopen(outputPath, 'w');
                if fid == -1
                    error('Failed to open file: %s', outputPath);
                end
                cleaner = onCleanup(@() fclose(fid));
                fprintf(fid, '%.6f %.6f\n', [mjd(:), values(:)]');
                clear cleaner;
            end

            function addLog(fileName, offsetMJD, amplitude, sigma, status, message)
                message = regexprep(char(message), '\s+', ' ');
                logRows(end+1, :) = {char(fileName), offsetMJD, amplitude, sigma, char(status), message}; %#ok<AGROW>
            end

            function writeCorrectionLog(logPath)
                varNames = {'FileName', 'OffsetMJD', 'Amplitude', 'Sigma', 'Status', 'Message'};
                if isempty(logRows)
                    T = table(strings(0,1), zeros(0,1), zeros(0,1), zeros(0,1), strings(0,1), strings(0,1), ...
                        'VariableNames', varNames);
                else
                    T = cell2table(logRows, 'VariableNames', varNames);
                end
                writetable(T, logPath);
            end

            function cleanupRunDir(runDir)
                if isfolder(runDir)
                    try
                        rmdir(runDir, 's');
                    catch
                    end
                end
            end

            function throttleMemory()
                pause(0.1);
                try
                    java.lang.System.gc();
                catch
                end

                try
                    [~, sys] = memory;
                    if isfield(sys, 'PhysicalMemory') && sys.PhysicalMemory.Available < 1e9
                        pause(1);
                    end
                catch
                end
            end
        end

        % Menu selected function: ParameterEstimationMenu
        function ParameterEstimationMenuSelected(app, event)
            try
                % -------------------- Select input directory --------------------
                selectedDir = uigetdir("*.*");
                if selectedDir == 0
                    return; % user cancelled
                end

                % Collect .mom files
                momFiles = builtin('dir', fullfile(selectedDir, "*.mom"));
                if isempty(momFiles)
                    uialert(app.HectorUIFigure, 'No .mom files found in selected directory.', ...
                        'No Files', 'Icon', 'warning', 'Modal', true);
                    return;
                end

                outputDir = fullfile(selectedDir, 'estimatetrend');
                if ~isfolder(outputDir)
                    mkdir(outputDir);
                end

                % -------------------- Ask user for ctl options (once) --------------------
                ctlOpt = promptCtlOptions(app);
                if isempty(ctlOpt) || ~isstruct(ctlOpt)
                    return; % user cancelled
                end

                % Station list (assumes file name like STATION_0.mom / STATION_1.mom / STATION_2.mom)
                stationNames   = cellfun(@(fn) parseStation(fn), {momFiles.name}, 'UniformOutput', false);
                uniqueStations = unique(stationNames(:));
                numStations    = numel(uniqueStations);

                % Parameters to extract
                fields = { ...
                    'trend','trend_sigma','bias','bias_sigma','STD', ...
                    'cos_365','cos_365_sigma','sin_365','sin_365_sigma', ...
                    'amp_365','amp_365_sigma','pha_365','pha_365_sigma', ...
                    'cos_182','cos_182_sigma','sin_182','sin_182_sigma', ...
                    'amp_182','amp_182_sigma','pha_182','pha_182_sigma'};

                % Preallocate results table
                varNames = {'Station'};
                for f = fields
                    for d = {'N','E','U'}
                        varNames{end+1} = sprintf('%s_%s', f{1}, d{1}); %#ok<AGROW>
                    end
                end

                dataTable = table(uniqueStations, 'VariableNames', {'Station'});
                for k = 2:numel(varNames)
                    dataTable.(varNames{k}) = NaN(numStations, 1);
                end

                % -------------------- Progress dialog --------------------
                fig     = app.HectorUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');

                p = uiprogressdlg(fig, ...
                    'Title', 'Processing Files', ...
                    'Message', 'Calculating...', ...
                    'Icon', iconPath, ...
                    'Cancelable', 'on');

                % -------------------- Batch processing --------------------
                batchSize = 10;
                numFiles  = numel(momFiles);

                for batchStart = 1:batchSize:numFiles
                    if p.CancelRequested
                        break;
                    end

                    batchEnd = min(batchStart + batchSize - 1, numFiles);

                    for i = batchStart:batchEnd
                        if p.CancelRequested
                            break;
                        end

                        fileName = momFiles(i).name;

                        % Noise model selection
                        if strcmpi(ctlOpt.NoiseMode, 'fixed')
                            noiseModels = ctlOpt.FixedNoiseModels;  % user fixed
                        else
                            if isempty(app.OptimalNoiseModel)
                                noiseModels = 'FlickerGGM White';    % fallback
                            else
                                noiseModels = app.OptimalNoiseModel{i, 2}; % per-file/per-station
                            end
                        end

                        % Parse station/component
                        station   = parseStation(fileName);
                        component = parseComponent(fileName); % '0','1','2'

                        outputFileName = fullfile(outputDir, fileName);

                        % Run estimation
                        cmdout  = processData(fileName, outputFileName, noiseModels, outputDir, selectedDir, ctlOpt);
                        results = extractTrendFromCmdout(cmdout);

                        % Find station row
                        rowIdx = find(strcmp(dataTable.Station, station), 1);

                        % Component suffix
                        switch component
                            case '0', suffix = 'N';
                            case '1', suffix = 'E';
                            case '2', suffix = 'U';
                            otherwise
                                warning('Unknown component in file %s (parsed: %s). Skipped.', fileName, component);
                                continue;
                        end

                        % Write results
                        for f = fields
                            colName = sprintf('%s_%s', f{1}, suffix);
                            dataTable.(colName)(rowIdx) = results.(f{1});
                        end

                        % Progress
                        p.Value   = i / numFiles;
                        p.Message = sprintf('Processed %d / %d', i, numFiles);
                    end

                    % Basic memory pressure relief
                    clear cmdout;
                    [~, sys] = memory;
                    if isfield(sys, 'PhysicalMemory') && sys.PhysicalMemory.Available < 1e9
                        pause(1);
                    end
                end

                % Close progress dialog
                if exist('p', 'var') && isvalid(p)
                    close(p);
                end

                % Default CSV path
                csvPath = fullfile(outputDir, 'station_trends.csv');

                % Show results window (export inside)
                showResultsTable(dataTable, csvPath);

                uialert(app.HectorUIFigure, sprintf('Three-column .mom files saved to:\\n%s', outputDir), ...
                    'Processing Completed', 'Icon', 'success', 'Modal', false);

            catch ME
                if exist('p', 'var') && isvalid(p)
                    close(p);
                end
                uialert(app.HectorUIFigure, sprintf('Error: %s', ME.message), ...
                    'Error', 'Icon', 'error', 'Modal', true);
            end


            % ========================= Local functions =========================

            function station = parseStation(fileName)
                name = string(fileName);
                if contains(name, "_")
                    station = char(extractBefore(name, "_"));
                else
                    station = char(extractBefore(name, ".mom"));
                end
            end

            function comp = parseComponent(fileName)
                name = string(fileName);
                comp = "";
                if contains(name, "_")
                    tail = extractAfter(name, "_");     % "0.mom"
                    comp = extractBefore(tail, ".mom"); % "0"
                end
                comp = char(comp);
            end

            function cmdout = processData(fileName, outputFileName, noiseModels, outputDir, momDir, ctlOpt)
                runDir = tempname;
                mkdir(runDir);
                cleanupObj = onCleanup(@() cleanupRunDir(runDir)); %#ok<NASGU>

                inputFile = fullfile(momDir, fileName);
                if ~isfile(inputFile)
                    error('Input .mom file not found: %s', inputFile);
                end

                copyfile(inputFile, fullfile(runDir, fileName), 'f');

                ctlFileName = fullfile(runDir, 'estimatetrend.ctl');
                [~, baseNameForOutput, extNameForOutput] = fileparts(fileName);
                if isempty(extNameForOutput)
                    extNameForOutput = '.mom';
                end
                tempOutputFileName = ['hector_output_' baseNameForOutput extNameForOutput];
                writeCtlFile(ctlFileName, fileName, tempOutputFileName, '.', noiseModels, ctlOpt);

                NET.addAssembly('System');
                proc = System.Diagnostics.Process();
                proc.StartInfo.FileName               = resolveEstimateTrendExe();
                proc.StartInfo.Arguments              = 'estimatetrend.ctl';
                proc.StartInfo.WorkingDirectory       = runDir;
                proc.StartInfo.UseShellExecute        = false;
                proc.StartInfo.RedirectStandardOutput = true;
                proc.StartInfo.RedirectStandardError  = true;
                proc.StartInfo.CreateNoWindow         = true;

                proc.Start();
                outReader = proc.StandardOutput;
                errReader = proc.StandardError;
                cmdout = char(outReader.ReadToEnd());
                errout = char(errReader.ReadToEnd());
                proc.WaitForExit();
                exitCode = proc.ExitCode;

                outReader.Close();
                errReader.Close();
                proc.Close();
                clear proc outReader errReader;

                if ~isempty(strtrim(errout))
                    cmdout = sprintf('%s\n\n[STDERR]\n%s', cmdout, errout);
                end

                if exitCode ~= 0
                    details = strtrim(cmdout);
                    if numel(details) > 1200
                        details = [details(1:1200) '...'];
                    end
                    error('estimatetrend failed for %s (exit code %d): %s', fileName, exitCode, details);
                end

                tempOutputPath = fullfile(runDir, tempOutputFileName);
                if ~isfile(tempOutputPath)
                    details = strtrim(cmdout);
                    if numel(details) > 1200
                        details = [details(1:1200) '...'];
                    end
                    error('estimatetrend did not create expected output file for %s: %s', fileName, details);
                end

                copyfile(tempOutputPath, outputFileName, 'f');

                baseName     = erase(string(fileName), '.mom');
                outputRecord = fullfile(outputDir, sprintf('%s.txt', baseName));
                saveCmdOut(outputRecord, cmdout);

                pause(0.2);
            end

            function saveCmdOut(outputRecord, cmdout)
                fid = fopen(outputRecord, 'w');
                if fid == -1
                    error('Failed to open file: %s', outputRecord);
                end
                fprintf(fid, '%s', cmdout);
                fclose(fid);
            end

            function cleanupRunDir(runDir)
                if isfolder(runDir)
                    try
                        rmdir(runDir, 's');
                    catch
                    end
                end
            end

            function exePath = resolveEstimateTrendExe()
                exePath = resolveExecutable(app, 'estimatetrend.exe');
            end


            function writeCtlFile(ctlFileName, fileName, outputFileName, momDir, noiseModels, ctlOpt)
                fid = fopen(ctlFileName, 'w');
                if fid == -1
                    error('Unable to create control file: %s', ctlFileName);
                end

                fprintf(fid, 'DataFile            %s\n', fileName);
                fprintf(fid, 'OutputFile          %s\n', outputFileName);
                fprintf(fid, 'DataDirectory       %s\n', momDir);

                fprintf(fid, 'interpolate         %s\n', yn(ctlOpt.Interpolate));
                fprintf(fid, 'seasonalsignal      %s\n', yn(ctlOpt.Seasonal));
                fprintf(fid, 'halfseasonalsignal  %s\n', yn(ctlOpt.HalfSeasonal));
                fprintf(fid, 'estimateoffsets     %s\n', yn(ctlOpt.EstimateOffsets));

                fprintf(fid, 'DegreePolynomial    %d\n', ctlOpt.DegreePolynomial);
                fprintf(fid, 'ScaleFactor         %.12g\n', ctlOpt.ScaleFactor);
                fprintf(fid, 'PhysicalUnit        %s\n', ctlOpt.PhysicalUnit);

                if ~strcmpi(ctlOpt.LikelihoodMethod, 'Auto')
                    fprintf(fid, 'LikelihoodMethod    %s\n', ctlOpt.LikelihoodMethod);
                end

                if ~isempty(ctlOpt.ReferenceEpochYMD)
                    fprintf(fid, 'ReferenceEpoch      %d %d %d\n', ...
                        ctlOpt.ReferenceEpochYMD(1), ctlOpt.ReferenceEpochYMD(2), ctlOpt.ReferenceEpochYMD(3));
                end

                fprintf(fid, 'estimatemultitrend   %s\n', yn(ctlOpt.MultiTrend));
                fprintf(fid, 'estimatepostseismic  %s\n', yn(ctlOpt.PostSeismic));
                fprintf(fid, 'NoiseModels         %s\n', noiseModels);

                nm = string(noiseModels);
                if contains(nm, 'GGM', 'IgnoreCase', true) && ~isempty(ctlOpt.GGM_1mphi)
                    fprintf(fid, 'GGM_1mphi           %.8g\n', ctlOpt.GGM_1mphi);
                end

                if contains(nm, 'ARMA', 'IgnoreCase', true) || contains(nm, 'ARFIMA', 'IgnoreCase', true)
                    fprintf(fid, 'AR_p                %d\n', ctlOpt.AR_p);
                    fprintf(fid, 'MA_q                %d\n', ctlOpt.MA_q);
                end

                fclose(fid);

                function s = yn(tf)
                    if tf, s = 'yes'; else, s = 'no'; end
                end
            end
            function results = extractTrendFromCmdout(cmdout)
                % Parse estimatetrend stdout and extract parameters
                results = struct( ...
                    'trend',NaN,'trend_sigma',NaN, ...
                    'bias',NaN,'bias_sigma',NaN, ...
                    'STD',NaN, ...
                    'cos_365',NaN,'cos_365_sigma',NaN, ...
                    'sin_365',NaN,'sin_365_sigma',NaN, ...
                    'amp_365',NaN,'amp_365_sigma',NaN, ...
                    'pha_365',NaN,'pha_365_sigma',NaN, ...
                    'cos_182',NaN,'cos_182_sigma',NaN, ...
                    'sin_182',NaN,'sin_182_sigma',NaN, ...
                    'amp_182',NaN,'amp_182_sigma',NaN, ...
                    'pha_182',NaN,'pha_182_sigma',NaN);

                if isfile(cmdout)
                    file_content = fileread(cmdout);
                else
                    file_content = cmdout;
                end

                lines = splitlines(string(file_content));

                for k = 1:numel(lines)
                    line1 = char(lines(k));

                    if contains(line1, 'STD of the driving noise:')
                        tok = regexp(line1, 'STD of the driving noise:\s*([-]?\d*\.?\d+([eE][-+]?\d+)?)', 'tokens', 'once');
                        if ~isempty(tok), results.STD = str2double(tok{1}); end

                    elseif contains(line1, 'bias :')
                        tok1 = regexp(line1, 'bias :\s*([-]?\d*\.?\d+([eE][-+]?\d+)?)', 'tokens', 'once');
                        tok2 = regexp(line1, '\+/-\s*(\d*\.?\d+([eE][-+]?\d+)?)', 'tokens', 'once');
                        if ~isempty(tok1), results.bias = str2double(tok1{1}); end
                        if ~isempty(tok2), results.bias_sigma = str2double(tok2{1}); end

                    elseif contains(line1, 'trend:')
                        tok1 = regexp(line1, 'trend:\s*([-]?\d*\.?\d+([eE][-+]?\d+)?)', 'tokens', 'once');
                        tok2 = regexp(line1, '\+/-\s*(\d*\.?\d+([eE][-+]?\d+)?)', 'tokens', 'once');
                        if ~isempty(tok1), results.trend = str2double(tok1{1}); end
                        if ~isempty(tok2), results.trend_sigma = str2double(tok2{1}); end

                    elseif contains(line1, 'cos  365.250 :')
                        [results.cos_365, results.cos_365_sigma] = parseValueSigma(line1, 'cos\s+365\.250');

                    elseif contains(line1, 'sin  365.250 :')
                        [results.sin_365, results.sin_365_sigma] = parseValueSigma(line1, 'sin\s+365\.250');

                    elseif contains(line1, 'amp  365.250 :')
                        [results.amp_365, results.amp_365_sigma] = parseValueSigma(line1, 'amp\s+365\.250');

                    elseif contains(line1, 'pha  365.250 :')
                        [results.pha_365, results.pha_365_sigma] = parseValueSigma(line1, 'pha\s+365\.250');

                    elseif contains(line1, 'cos  182.625 :')
                        [results.cos_182, results.cos_182_sigma] = parseValueSigma(line1, 'cos\s+182\.625');

                    elseif contains(line1, 'sin  182.625 :')
                        [results.sin_182, results.sin_182_sigma] = parseValueSigma(line1, 'sin\s+182\.625');

                    elseif contains(line1, 'amp  182.625 :')
                        [results.amp_182, results.amp_182_sigma] = parseValueSigma(line1, 'amp\s+182\.625');

                    elseif contains(line1, 'pha  182.625 :')
                        [results.pha_182, results.pha_182_sigma] = parseValueSigma(line1, 'pha\s+182\.625');
                    end
                end
            end

            function [val, sig] = parseValueSigma(line1, keyExpr)
                val = NaN; sig = NaN;
                tok1 = regexp(line1, [keyExpr '\s*:\s*([-]?\d*\.?\d+([eE][-+]?\d+)?)'], 'tokens', 'once');
                tok2 = regexp(line1, '\+/-\s*(\d*\.?\d+([eE][-+]?\d+)?)', 'tokens', 'once');
                if ~isempty(tok1), val = str2double(tok1{1}); end
                if ~isempty(tok2), sig = str2double(tok2{1}); end
            end

            function showResultsTable(dataTable, defaultCsvPath)
                % Popup window to view results and export CSV
                f = uifigure('Name', 'Parameter Estimation Results', 'Position', [100 100 1200 650]);

                gl = uigridlayout(f, [2 1]);
                gl.RowHeight   = {'1x', 45};
                gl.ColumnWidth = {'1x'};
                gl.Padding     = [10 10 10 10];
                gl.RowSpacing  = 8;

                t = uitable(gl);
                t.Data = dataTable;
                t.ColumnName = dataTable.Properties.VariableNames;
                t.RowName = {};
                t.Multiselect = true;

                pnl = uipanel(gl, 'BorderType', 'none');
                bl = uigridlayout(pnl, [1 4]);
                bl.ColumnWidth = {160, 180, '1x', 120};
                bl.Padding = [0 0 0 0];

                uibutton(bl, 'Text', 'Export CSV...', 'ButtonPushedFcn', @(~,~) exportCsv());
                uibutton(bl, 'Text', 'Quick Save CSV', 'ButtonPushedFcn', @(~,~) quickSave());

                lbl = uilabel(bl, 'Text', sprintf('Default: %s', defaultCsvPath), 'Interpreter', 'none');
                lbl.Layout.Column = 3;

                uibutton(bl, 'Text', 'Close', 'ButtonPushedFcn', @(~,~) close(f));

                function exportCsv()
                    [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save results as CSV', defaultCsvPath);
                    if isequal(file, 0), return; end
                    outPath = fullfile(path, file);
                    try
                        writetable(dataTable, outPath);
                        lbl.Text = sprintf('Saved: %s', outPath);
                        uialert(f, 'CSV exported successfully.', 'Success', 'Icon', 'success', 'Modal', false);
                    catch ME
                        uialert(f, ME.message, 'Export Failed', 'Icon', 'error', 'Modal', true);
                    end
                end

                function quickSave()
                    try
                        writetable(dataTable, defaultCsvPath);
                        lbl.Text = sprintf('Saved: %s', defaultCsvPath);
                        uialert(f, 'CSV saved successfully.', 'Success', 'Icon', 'success', 'Modal', false);
                    catch ME
                        uialert(f, ME.message, 'Save Failed', 'Icon', 'error', 'Modal', true);
                    end
                end
            end

            function ctlOpt = promptCtlOptions(app)
                % Control window for estimatetrend.ctl options (centered, Times New Roman, 14)

                ctlOpt = [];

                w = 820; h = 560;
                ss = get(groot, 'ScreenSize'); % [left bottom width height]
                x = ss(1) + (ss(3) - w)/2;
                y = ss(2) + (ss(4) - h)/2;

                dlg = uifigure('Name','Hector Control Parameters', ...
                    'Position',[x y w h], ...
                    'WindowStyle','modal', ...
                    'Resize','off');

                gl = uigridlayout(dlg, [4 1]);
                gl.RowHeight = {170, 220, '1x', 55};
                gl.Padding = [12 12 12 12];
                gl.RowSpacing = 10;

                % -------- Model panel --------
                pModel = uipanel(gl, 'Title', 'Model');
                g1 = uigridlayout(pModel, [3 4]);
                g1.RowHeight = {30, 30, 30};
                g1.ColumnWidth = {160, 160, 160, '1x'};
                g1.Padding = [10 10 10 10];

                uilabel(g1, 'Text','DegreePolynomial (0–6):');
                spDeg = uispinner(g1, 'Limits',[0 6], 'Step',1, 'Value',1); % default=1
                uilabel(g1, 'Text','ScaleFactor:');
                efScale = uieditfield(g1, 'numeric', 'Value',1.0, 'LowerLimit',-Inf, 'UpperLimit',Inf);

                uilabel(g1, 'Text','Seasonal signal (annual):');
                cbAnnual = uicheckbox(g1, 'Value',true);
                uilabel(g1, 'Text','Half-seasonal (semi-annual):');
                cbSemi = uicheckbox(g1, 'Value',false);

                uilabel(g1, 'Text','Estimate offsets:');
                cbOff = uicheckbox(g1, 'Value',true);
                uilabel(g1, 'Text','Interpolate missing:');
                ddInterp = uidropdown(g1, 'Items',{'no','yes'}, 'Value','no');

                % -------- Noise/Likelihood panel --------
                pNoise = uipanel(gl, 'Title', 'Noise / Likelihood');
                g2 = uigridlayout(pNoise, [6 4]);
                g2.RowHeight = {30, 30, 30, 30, 30, '1x'};
                g2.ColumnWidth = {190, 240, 160, '1x'};
                g2.Padding = [10 10 10 10];

                uilabel(g2, 'Text','Noise mode:');
                ddNoiseMode = uidropdown(g2, ...
                    'Items',{'Use app.OptimalNoiseModel (per file)','Fixed for all files'}, ...
                    'Value','Use app.OptimalNoiseModel (per file)');

                uilabel(g2, 'Text','LikelihoodMethod:');
                ddLik = uidropdown(g2, 'Items',{'Auto','AmmarGrag','FullCov'}, 'Value','Auto');
                ddLik.Tooltip = 'Auto: AmmarGrag if missing<50%, else FullCov (Hector default).';

                uilabel(g2, 'Text','PhysicalUnit:');
                ddUnit = uidropdown(g2, 'Items',{'mm','m'}, 'Value','mm');

                uilabel(g2, 'Text','ReferenceEpoch (YYYY-MM-DD, optional):');
                efRef = uieditfield(g2, 'text', 'Value','');
                efRef.Tooltip = 'If empty, Hector uses midpoint between start/end (default).';

                % Fixed Noise controls
                uilabel(g2, 'Text','Fixed NoiseModels (space-separated):');
                efNoise = uieditfield(g2, 'text', 'Value','FlickerGGM White');
                efNoise.Tooltip = 'Examples: "ARMA", "GGM White", "FlickerGGM White".';

                uilabel(g2, 'Text','GGM_1mphi (only if GGM):');
                ef1mphi = uieditfield(g2, 'numeric', 'Value',6.9e-06);
                ef1mphi.Tooltip = 'Recommended stable value in manual: 6.9e-06.';

                uilabel(g2, 'Text','AR_p (ARMA/ARFIMA):');
                spARp = uispinner(g2, 'Limits',[0 20], 'Value',1, 'Step',1);

                uilabel(g2, 'Text','MA_q (ARMA/ARFIMA):');
                spMAq = uispinner(g2, 'Limits',[0 20], 'Value',0, 'Step',1);

                % Enable/disable fixed noise controls based on mode
                setFixedNoiseEnable();

                ddNoiseMode.ValueChangedFcn = @(~,~) setFixedNoiseEnable();

                function setFixedNoiseEnable()
                    tf = strcmpi(ddNoiseMode.Value, 'Fixed for all files');
                    efNoise.Enable  = tf;
                    ef1mphi.Enable  = tf;
                    spARp.Enable    = tf;
                    spMAq.Enable    = tf;
                end

                % -------- Advanced panel (keep minimal) --------
                pAdv = uipanel(gl, 'Title', 'Advanced (optional)');
                g3 = uigridlayout(pAdv, [2 4]);
                g3.RowHeight = {30, 30};
                g3.ColumnWidth = {220, 160, 220, '1x'};
                g3.Padding = [10 10 10 10];

                uilabel(g3, 'Text','estimatemultitrend:');
                cbMulti = uicheckbox(g3, 'Value',false);

                uilabel(g3, 'Text','estimatepostseismic:');
                cbPost = uicheckbox(g3, 'Value',false);

                uilabel(g3, 'Text','(Both require headers: break/offset/exp/log etc.)');
                uilabel(g3, 'Text','');

                % -------- Buttons --------
                btnPanel = uipanel(gl, 'BorderType','none');
                gb = uigridlayout(btnPanel, [1 3]);
                gb.ColumnWidth = {'1x', 140, 140};
                gb.Padding = [0 0 0 0];

                lblHint = uilabel(gb, 'Text','Set parameters and click OK to continue. Cancel to abort.');
                btnCancel = uibutton(gb, 'Text','Cancel', 'ButtonPushedFcn', @(~,~) onCancel());
                btnOK     = uibutton(gb, 'Text','OK',     'ButtonPushedFcn', @(~,~) onOK());

                % Apply font (Times New Roman, 14) to all components
                applyFontRecursive(dlg, 'Times New Roman', 14);

                % Block until user acts
                uiwait(dlg);

                function onCancel()
                    ctlOpt = [];
                    uiresume(dlg);
                    delete(dlg);
                end

                function onOK()
                    % Validate ReferenceEpoch
                    refYMD = [];
                    refTxt = strtrim(efRef.Value);
                    if ~isempty(refTxt)
                        try
                            dt = datetime(refTxt, 'InputFormat','yyyy-MM-dd');
                            refYMD = [year(dt) month(dt) day(dt)];
                        catch
                            uialert(dlg, 'ReferenceEpoch must be YYYY-MM-DD or empty.', ...
                                'Invalid Input', 'Icon','warning', 'Modal', true);
                            return;
                        end
                    end

                    % Build options struct
                    ctlOpt = struct();
                    ctlOpt.DegreePolynomial  = spDeg.Value;
                    ctlOpt.ScaleFactor       = efScale.Value;
                    ctlOpt.Seasonal          = cbAnnual.Value;
                    ctlOpt.HalfSeasonal      = cbSemi.Value;
                    ctlOpt.EstimateOffsets   = cbOff.Value;
                    ctlOpt.Interpolate       = strcmpi(ddInterp.Value, 'yes');
                    ctlOpt.PhysicalUnit      = ddUnit.Value;
                    ctlOpt.LikelihoodMethod  = ddLik.Value; % Auto/AmmarGrag/FullCov
                    ctlOpt.ReferenceEpochYMD = refYMD;

                    ctlOpt.MultiTrend        = cbMulti.Value;
                    ctlOpt.PostSeismic       = cbPost.Value;

                    if strcmpi(ddNoiseMode.Value, 'Fixed for all files')
                        ctlOpt.NoiseMode        = 'fixed';
                        ctlOpt.FixedNoiseModels = strtrim(efNoise.Value);
                        ctlOpt.GGM_1mphi        = ef1mphi.Value;
                        ctlOpt.AR_p             = spARp.Value;
                        ctlOpt.MA_q             = spMAq.Value;
                    else
                        ctlOpt.NoiseMode        = 'best';
                        ctlOpt.FixedNoiseModels = '';
                        ctlOpt.GGM_1mphi        = 6.9e-06; % harmless default
                        ctlOpt.AR_p             = 1;
                        ctlOpt.MA_q             = 0;
                    end

                    uiresume(dlg);
                    delete(dlg);
                end
            end

            function applyFontRecursive(rootObj, fontName, fontSize)
                % Apply font to all UI components that have FontName/FontSize
                objs = findall(rootObj);
                for i = 1:numel(objs)
                    o = objs(i);
                    if isprop(o, 'FontName'); o.FontName = fontName; end
                    if isprop(o, 'FontSize'); o.FontSize = fontSize; end
                end
            end
        end

        % Menu selected function: FindOffsetMenu
        function FindOffsetMenuSelected(app, event)
            try
                % -------------------- Guard --------------------
                if isempty(app.momlist)
                    uialert(app.HectorUIFigure, 'No processing data input', 'Warning', 'Icon', 'warning');
                    return;
                end

                % -------------------- Settings UI --------------------
                [cfg, isOk] = getFindOffsetSettings();
                if ~isOk
                    return;
                end

                % Resolve selected noise model source
                if strcmp(cfg.NoiseSource, "OptimalNoiseModel")
                    NoiseValue = "OptimalNoiseModel";
                else
                    NoiseValue = char(cfg.FixedNoiseModels);
                end

                offsets_3DValue    = logical(cfg.offsets_3D);
                maxiter            = cfg.MaxIterations;
                BIC_c_ExtraPenalty = cfg.BIC_cExtraPenalty;

                FIX_VARYING_PHI  = cfg.FixVaryingPhi;
                GGM_1MPHI        = cfg.GGM_1mphi;
                AR_p             = cfg.AR_p;
                MA_q             = cfg.MA_q;
                beta_spacing     = cfg.beta_spacing;
                beta_size        = cfg.beta_size;
                JSONFlag         = cfg.JSON;
                LikelihoodMethod = cfg.LikelihoodMethod;

                if strcmpi(LikelihoodMethod, "FullCov")
                    uialert(app.HectorUIFigure, ...
                        ['Hector offset detection calls compute_BIC_cs. In this code base it is implemented for AmmarGrag, ' ...
                        'while FullCov::compute_BIC_cs exits with an error.'], ...
                        'Notice', 'Icon', 'warning', 'Modal', true);
                end

                % -------------------- File preparation --------------------
                momItems = build_mom_file_items(app.momlist, app.loadpath);
                temp_output_dir = fullfile(app.loadpath, 'findoffset_temp');
                if ~exist(temp_output_dir, 'dir')
                    mkdir(temp_output_dir);
                end

                if offsets_3DValue
                    tasks = build_3d_groups(momItems);
                    if isempty(tasks)
                        uialert(app.HectorUIFigure, ...
                            ['3D offset detection requires complete component triplets named like station_0.mom, ' ...
                            'station_1.mom and station_2.mom. No complete triplet was found.'], ...
                            'Warning', 'Icon', 'warning', 'Modal', true);
                        return;
                    end

                    nGroupedFiles = 3 * numel(tasks);
                    if nGroupedFiles < numel(momItems)
                        uialert(app.HectorUIFigure, ...
                            sprintf('3D mode will process %d complete triplet(s). %d file(s) are not part of a complete _0/_1/_2 triplet and will be skipped.', ...
                            numel(tasks), numel(momItems) - nGroupedFiles), ...
                            'Notice', 'Icon', 'warning', 'Modal', true);
                    end
                else
                    tasks = momItems;
                end

                % -------------------- Progress dialog --------------------
                fig      = app.HectorUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig, "Icon", iconPath, "Cancelable", "on");
                p.Title   = 'Find Offsets';
                p.Message = 'Searching for offset epochs...';

                % -------------------- Output struct --------------------
                offset_times = struct('StationName', {}, 'OffsetTime', {});
                nTasks = numel(tasks);

                % -------------------- Main workflow --------------------
                for k = 1:nTasks
                    if p.CancelRequested
                        break;
                    end

                    if offsets_3DValue
                        group = tasks(k);
                        p.Message = sprintf('3D group %d of %d: %s', k, nTasks, group.base);

                        detectedOffsets = run_3d_offset_detection(group);

                        for j = 1:numel(detectedOffsets)
                            for comp = 1:3
                                offset_times(end+1).StationName = group.items(comp).name; %#ok<AGROW>
                                offset_times(end).OffsetTime    = detectedOffsets(j);
                            end
                        end
                    else
                        item = tasks(k);
                        p.Message = sprintf('1D file %d of %d: %s', k, nTasks, item.name);

                        noise_str = resolve_noise_model_for_file(item.name, item.index);
                        detectedOffsets = run_1d_offset_detection(item, noise_str);

                        for j = 1:numel(detectedOffsets)
                            offset_times(end+1).StationName = item.name; %#ok<AGROW>
                            offset_times(end).OffsetTime    = detectedOffsets(j);
                        end
                    end

                    p.Value = k / nTasks;
                end

                if exist('p','var') && isvalid(p)
                    close(p);
                end

                save(fullfile(app.loadpath, 'offset_times.mat'), 'offset_times');

                if offsets_3DValue
                    doneMsg = '3D offset epochs have been written to the _0/_1/_2 MOM headers.';
                else
                    doneMsg = '1D offset epochs have been written to MOM headers.';
                end
                uialert(app.HectorUIFigure, doneMsg, 'Done', 'Icon', 'success', 'Modal', true);

            catch ME
                if exist('p','var') && isvalid(p)
                    close(p);
                end
                fprintf(2, 'FindOffset error details:\n%s\n', getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.HectorUIFigure, sprintf('Error: %s', ME.message), ...
                    'Error', 'Icon', 'error');
            end


            % =====================================================================
            % Local functions
            % =====================================================================

            function [cfg, ok] = getFindOffsetSettings()
                ok = false;

                cfg = struct();
                cfg.NoiseSource       = "Fixed NoiseModels string";
                cfg.FixedNoiseModels  = "Powerlaw FlickerGGM";
                cfg.LikelihoodMethod  = "AmmarGrag";
                cfg.offsets_3D        = true;
                cfg.MaxIterations     = 8;
                cfg.BIC_cExtraPenalty = 4.0;
                cfg.beta_spacing      = 8.5;
                cfg.beta_size         = 5.0;
                cfg.AR_p              = 1;
                cfg.MA_q              = 0;
                cfg.GGM_1mphi         = 6.9e-06;
                cfg.JSON              = false;
                cfg.FixVaryingPhi     = false;

                FONT = "Times New Roman";
                FS   = 14;

                w = 720; h = 520;
                ss = get(groot, 'ScreenSize');
                x  = ss(1) + (ss(3) - w)/2;
                y  = ss(2) + (ss(4) - h)/2;

                f = uifigure('Name','FindOffset Control Parameters', ...
                    'Position',[x y w h], ...
                    'WindowStyle','modal', ...
                    'Resize','off');

                root = uigridlayout(f, [3 1]);
                root.RowHeight = {40, '1x', 56};
                root.Padding   = [14 14 14 12];
                root.RowSpacing = 10;

                hdr = uilabel(root, ...
                    'Text','findoffset.ctl Parameters', ...
                    'FontName', FONT, 'FontSize', FS+2, 'FontWeight','bold');
                hdr.Layout.Row = 1;

                tg = uitabgroup(root);
                tg.Layout.Row = 2;

                tabBasic = uitab(tg, 'Title', 'Basic');
                tabAdv   = uitab(tg, 'Title', 'Advanced');

                gb = uigridlayout(tabBasic, [8 2]);
                gb.Padding = [12 12 12 12];
                gb.RowHeight = {32,32,32,32,32,32,'1x','1x'};
                gb.ColumnWidth = {260,'1x'};
                gb.RowSpacing = 10;
                gb.ColumnSpacing = 12;

                uilabel(gb,'Text','Noise model source', 'FontName',FONT,'FontSize',FS);
                ddNoiseSrc = uidropdown(gb, ...
                    'Items', {'OptimalNoiseModel','Fixed NoiseModels string'}, ...
                    'Value', 'Fixed NoiseModels string', ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(gb,'Text','Fixed NoiseModels', 'FontName',FONT,'FontSize',FS);
                edNoise = uieditfield(gb,'text', ...
                    'Value', char(cfg.FixedNoiseModels), ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(gb,'Text','LikelihoodMethod', 'FontName',FONT,'FontSize',FS);
                ddLike = uidropdown(gb, ...
                    'Items', {'AmmarGrag','FullCov'}, ...
                    'Value', char(cfg.LikelihoodMethod), ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(gb,'Text','offsets_3D (joint E/N/U)', 'FontName',FONT,'FontSize',FS);
                cb3D = uicheckbox(gb, ...
                    'Text','Enable', 'Value', cfg.offsets_3D, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(gb,'Text','MaxIterations', 'FontName',FONT,'FontSize',FS);
                spIter = uispinner(gb, ...
                    'Limits',[1 50], 'Value', cfg.MaxIterations, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(gb,'Text','BIC_c_ExtraPenalty', 'FontName',FONT,'FontSize',FS);
                edPenalty = uieditfield(gb,'numeric', ...
                    'Value', cfg.BIC_cExtraPenalty, ...
                    'LowerLimit', 0, ...
                    'FontName',FONT,'FontSize',FS);

                note = uitextarea(gb, ...
                    'Editable','off', ...
                    'Value', { ...
                    'Notes:', ...
                    '1) 1D mode processes every MOM file independently.', ...
                    '2) 3D mode requires station_0.mom, station_1.mom and station_2.mom.', ...
                    '3) In 3D mode, each component runs findoffset separately and the BIC_c curves are summed at the same MJD.', ...
                    '4) Offset detection is implemented for AmmarGrag in the Hector C++ code.'}, ...
                    'FontName',FONT,'FontSize',FS);
                note.Layout.Row = [7 8];
                note.Layout.Column = [1 2];

                ga = uigridlayout(tabAdv, [7 2]);
                ga.Padding = [12 12 12 12];
                ga.RowHeight = {32,32,32,32,32,32,'1x'};
                ga.ColumnWidth = {260,'1x'};
                ga.RowSpacing = 10;
                ga.ColumnSpacing = 12;

                uilabel(ga,'Text','beta_spacing', 'FontName',FONT,'FontSize',FS);
                edBetaSpacing = uieditfield(ga,'numeric', ...
                    'Value', cfg.beta_spacing, ...
                    'LowerLimit', 0, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(ga,'Text','beta_size', 'FontName',FONT,'FontSize',FS);
                edBeta = uieditfield(ga,'numeric', ...
                    'Value', cfg.beta_size, ...
                    'LowerLimit', 0, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(ga,'Text','AR_p (ARMA/ARFIMA)', 'FontName',FONT,'FontSize',FS);
                spARp = uispinner(ga, ...
                    'Limits',[0 20], 'Value', cfg.AR_p, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(ga,'Text','MA_q (ARMA/ARFIMA)', 'FontName',FONT,'FontSize',FS);
                spMAq = uispinner(ga, ...
                    'Limits',[0 20], 'Value', cfg.MA_q, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(ga,'Text','GGM_1mphi (GGM)', 'FontName',FONT,'FontSize',FS);
                edGGM = uieditfield(ga,'numeric', ...
                    'Value', cfg.GGM_1mphi, ...
                    'LowerLimit', 0, ...
                    'FontName',FONT,'FontSize',FS);

                uilabel(ga,'Text','JSON output', 'FontName',FONT,'FontSize',FS);
                cbJSON = uicheckbox(ga, ...
                    'Text','Enable', 'Value', cfg.JSON, ...
                    'FontName',FONT,'FontSize',FS);

                btnBar = uigridlayout(root, [1 4]);
                btnBar.Layout.Row = 3;
                btnBar.ColumnWidth = {'1x', 110, 110, 110};
                btnBar.Padding = [0 0 0 0];
                btnBar.ColumnSpacing = 10;

                lblState = uilabel(btnBar,'Text','', 'FontName',FONT,'FontSize',FS);
                lblState.Layout.Column = 1;

                btnCancel = uibutton(btnBar,'Text','Cancel', ...
                    'FontName',FONT,'FontSize',FS, ...
                    'ButtonPushedFcn', @(~,~) onCancel());

                btnOK = uibutton(btnBar,'Text','OK', ...
                    'FontName',FONT,'FontSize',FS, ...
                    'ButtonPushedFcn', @(~,~) onOK());

                btnHelp = uibutton(btnBar,'Text','Help', ...
                    'FontName',FONT,'FontSize',FS, ...
                    'ButtonPushedFcn', @(~,~) onHelp());

                btnCancel.Layout.Row = 1; btnCancel.Layout.Column = 2;
                btnOK.Layout.Row     = 1; btnOK.Layout.Column     = 3;
                btnHelp.Layout.Row   = 1; btnHelp.Layout.Column   = 4;

                ddNoiseSrc.ValueChangedFcn = @(~,~) refreshEnableState();
                edNoise.ValueChangedFcn    = @(~,~) refreshEnableState();
                refreshEnableState();

                uiwait(f);

                function refreshEnableState()
                    isFixed = strcmpi(ddNoiseSrc.Value, 'Fixed NoiseModels string');
                    edNoise.Enable = ternary(isFixed, 'on', 'off');

                    if isFixed
                        nstr = string(edNoise.Value);
                        enaARMA = contains(nstr, "ARMA", "IgnoreCase", true) || contains(nstr, "ARFIMA", "IgnoreCase", true);
                        enaGGM  = contains(nstr, "GGM",  "IgnoreCase", true);
                    else
                        enaARMA = true;
                        enaGGM  = true;
                    end

                    spARp.Enable = ternary(enaARMA, 'on', 'off');
                    spMAq.Enable = ternary(enaARMA, 'on', 'off');
                    edGGM.Enable = ternary(enaGGM,  'on', 'off');
                end

                function onHelp()
                    uialert(f, ...
                        ['Typical NoiseModels examples:' newline ...
                        '  Powerlaw White' newline ...
                        '  FlickerGGM White' newline ...
                        '  RandomWalkGGM FlickerGGM White' newline ...
                        '  ARMA White' newline ...
                        '3D mode sums BIC_c values from _0/_1/_2 components at the same MJD.'], ...
                        'Help', 'Icon','info', 'Modal', true);
                end

                function onCancel()
                    ok = false;
                    uiresume(f);
                    delete(f);
                end

                function onOK()
                    if strcmpi(ddNoiseSrc.Value, 'Fixed NoiseModels string')
                        if strlength(strtrim(string(edNoise.Value))) == 0
                            lblState.Text = 'Fixed NoiseModels cannot be empty.';
                            return;
                        end
                    end

                    cfg.NoiseSource       = string(ddNoiseSrc.Value);
                    cfg.FixedNoiseModels  = string(edNoise.Value);
                    cfg.LikelihoodMethod  = string(ddLike.Value);
                    cfg.offsets_3D        = logical(cb3D.Value);
                    cfg.MaxIterations     = round(spIter.Value);
                    cfg.BIC_cExtraPenalty = edPenalty.Value;
                    cfg.beta_spacing      = edBetaSpacing.Value;
                    cfg.beta_size         = edBeta.Value;
                    cfg.AR_p              = round(spARp.Value);
                    cfg.MA_q              = round(spMAq.Value);
                    cfg.GGM_1mphi         = edGGM.Value;
                    cfg.JSON              = logical(cbJSON.Value);

                    ok = true;
                    uiresume(f);
                    delete(f);
                end

                function s = ternary(cond, a, b)
                    if cond
                        s = a;
                    else
                        s = b;
                    end
                end
            end


            function detectedOffsets = run_1d_offset_detection(item, noise_str)
                currentOffsets = [];
                bestOffsets    = [];
                prevModelBICc  = Inf;

                safeStem = sanitize_file_stem(item.name);

                for nOffsets = 0:maxiter
                    workName = sprintf('%s_1D_%02d.mom', safeStem, nOffsets);
                    workPath = fullfile(temp_output_dir, workName);
                    outPath  = fullfile(temp_output_dir, sprintf('%s_1D_%02d_output.mom', safeStem, nOffsets));

                    write_mom_with_offsets(item.fullpath, workPath, currentOffsets);

                    cmdout = FindOffsets(workName, outPath, temp_output_dir, noise_str, false, ...
                        BIC_c_ExtraPenalty, FIX_VARYING_PHI, GGM_1MPHI, AR_p, MA_q, ...
                        beta_spacing, beta_size, JSONFlag, LikelihoodMethod);

                    [~, ~, modelBICc] = parseFindOffsetConsole(cmdout);
                    if modelBICc >= prevModelBICc
                        break;
                    end

                    bestOffsets   = currentOffsets;
                    prevModelBICc = modelBICc;

                    if numel(bestOffsets) >= maxiter
                        break;
                    end

                    candidates = read_findoffset_candidates(fullfile(temp_output_dir, 'findoffset.out'));
                    [candidateMJD, ~] = find_minimum_1d(candidates);
                    if ~isfinite(candidateMJD) || candidateMJD <= 1.0 || offset_already_exists(candidateMJD, item.fullpath, bestOffsets)
                        break;
                    end

                    currentOffsets = [bestOffsets(:); candidateMJD]; %#ok<AGROW>
                end

                detectedOffsets = bestOffsets(:);
                write_mom_with_offsets(item.fullpath, item.fullpath, detectedOffsets);
            end


            function detectedOffsets = run_3d_offset_detection(group)
                [commonMJD, commonObs] = make_common_3d_data(group.items);
                if numel(commonMJD) < 3
                    error('3D group %s has fewer than 3 common epochs after matching _0/_1/_2 files.', group.base);
                end

                currentOffsets = [];
                bestOffsets    = [];
                prevModelBICc  = Inf;
                safeBase       = sanitize_file_stem(group.base);

                for nOffsets = 0:maxiter
                    modelBICcSum = 0.0;
                    candidateTables = cell(3, 1);

                    for comp = 1:3
                        item = group.items(comp);
                        workName = sprintf('%s_%d_3D_%02d.mom', safeBase, comp-1, nOffsets);
                        workPath = fullfile(temp_output_dir, workName);
                        outPath  = fullfile(temp_output_dir, sprintf('%s_%d_3D_%02d_output.mom', safeBase, comp-1, nOffsets));

                        write_mom_numeric_with_offsets(item.fullpath, workPath, commonMJD, commonObs{comp}, currentOffsets);

                        noise_str = resolve_noise_model_for_file(item.name, item.index);
                        cmdout = FindOffsets(workName, outPath, temp_output_dir, noise_str, true, ...
                            BIC_c_ExtraPenalty, FIX_VARYING_PHI, GGM_1MPHI, AR_p, MA_q, ...
                            beta_spacing, beta_size, JSONFlag, LikelihoodMethod);

                        [~, ~, modelBICc] = parseFindOffsetConsole(cmdout);
                        modelBICcSum = modelBICcSum + modelBICc;
                        candidateTables{comp} = read_findoffset_candidates(fullfile(temp_output_dir, 'findoffset.out'));
                    end

                    if modelBICcSum >= prevModelBICc
                        break;
                    end

                    bestOffsets   = currentOffsets;
                    prevModelBICc = modelBICcSum;

                    if numel(bestOffsets) >= maxiter
                        break;
                    end

                    [candidateMJD, ~] = find_minimum_3d(candidateTables);
                    if ~isfinite(candidateMJD) || candidateMJD <= 1.0 || offset_already_exists(candidateMJD, group.items(1).fullpath, bestOffsets)
                        break;
                    end

                    currentOffsets = [bestOffsets(:); candidateMJD]; %#ok<AGROW>
                end

                detectedOffsets = bestOffsets(:);
                for comp = 1:3
                    write_mom_with_offsets(group.items(comp).fullpath, group.items(comp).fullpath, detectedOffsets);
                end
            end


            function momItems = build_mom_file_items(momlist, loadpath)
                n = numel(momlist);
                momItems = struct('name', {}, 'fullpath', {}, 'index', {}, 'base', {}, 'component', {});

                for i = 1:n
                    fileName = momlist(i).name;
                    [baseName, componentId] = parse_component_name(fileName);

                    momItems(end+1).name      = fileName; %#ok<AGROW>
                    momItems(end).fullpath    = fullfile(loadpath, fileName);
                    momItems(end).index       = i;
                    momItems(end).base        = baseName;
                    momItems(end).component   = componentId;
                end
            end


            function groups = build_3d_groups(momItems)
                groups = struct('base', {}, 'items', {});
                if isempty(momItems)
                    return;
                end

                valid = ~isnan([momItems.component]);
                bases = unique(string({momItems(valid).base}), 'stable');

                for i = 1:numel(bases)
                    base = char(bases(i));
                    idx = nan(1, 3);

                    for comp = 0:2
                        hit = find(strcmp({momItems.base}, base) & [momItems.component] == comp, 1, 'first');
                        if ~isempty(hit)
                            idx(comp+1) = hit;
                        end
                    end

                    if all(~isnan(idx))
                        groups(end+1).base  = base; %#ok<AGROW>
                        groups(end).items   = momItems(idx);
                    end
                end
            end


            function [baseName, componentId] = parse_component_name(fileName)
                [~, stem, ~] = fileparts(fileName);
                token = regexp(stem, '^(.*)_([012])$', 'tokens', 'once');

                if isempty(token)
                    baseName = stem;
                    componentId = NaN;
                else
                    baseName = token{1};
                    componentId = str2double(token{2});
                end
            end


            function noise_str = resolve_noise_model_for_file(fileName, itemIndex)
                if ~strcmpi(string(NoiseValue), "OptimalNoiseModel")
                    noise_str = char(NoiseValue);
                    return;
                end

                if isempty(app.OptimalNoiseModel)
                    error('There is currently no optimal noise model data available.');
                end

                data = app.OptimalNoiseModel;
                nRows = size(data, 1);

                for r = 1:nRows
                    rowName = string(get_table_or_cell_value(data, r, 1));
                    if strcmpi(rowName, string(fileName))
                        noise_str = char(string(get_table_or_cell_value(data, r, 2)));
                        return;
                    end
                end

                if itemIndex <= nRows
                    noise_str = char(string(get_table_or_cell_value(data, itemIndex, 2)));
                    return;
                end

                error('Cannot find optimal noise model for %s.', fileName);
            end


            function value = get_table_or_cell_value(data, row, col)
                value = data{row, col};
                if iscell(value)
                    value = value{1};
                end
            end


            function [commonMJD, commonObs] = make_common_3d_data(items)
                mjd = cell(3, 1);
                obs = cell(3, 1);
                key = cell(3, 1);

                for comp = 1:3
                    [~, ~, mjd{comp}, obs{comp}] = read_mom_file_parts(items(comp).fullpath);
                    finiteRows = isfinite(mjd{comp}) & isfinite(obs{comp});
                    mjd{comp} = mjd{comp}(finiteRows);
                    obs{comp} = obs{comp}(finiteRows);
                    key{comp} = round(mjd{comp} * 1.0e6);
                end

                commonKeySet = intersect(intersect(key{1}, key{2}), key{3});
                keep0 = ismember(key{1}, commonKeySet);
                commonKeys = key{1}(keep0);
                commonMJD  = mjd{1}(keep0);

                commonObs = cell(3, 1);
                for comp = 1:3
                    [tf, loc] = ismember(commonKeys, key{comp});
                    if any(~tf)
                        error('Internal error while matching common 3D epochs.');
                    end
                    commonObs{comp} = obs{comp}(loc);
                end
            end


            function [headerLines, bodyLines, mjd, obs] = read_mom_file_parts(filePath)
                [fid, errmsg] = fopen(filePath, 'r');
                if fid == -1
                    error('Cannot open MOM file: %s\nError: %s', filePath, errmsg);
                end

                cleaner = onCleanup(@() fclose(fid));
                headerLines = {};
                bodyLines = {};
                mjd = [];
                obs = [];
                inHeader = true;

                while ~feof(fid)
                    line = fgetl(fid);
                    if ~ischar(line)
                        break;
                    end

                    if inHeader && ~isempty(line) && line(1) == '#'
                        headerLines{end+1, 1} = line; %#ok<AGROW>
                    else
                        inHeader = false;
                        bodyLines{end+1, 1} = line; %#ok<AGROW>

                        values = sscanf(line, '%f');
                        if numel(values) >= 2
                            mjd(end+1, 1) = values(1); %#ok<AGROW>
                            obs(end+1, 1) = values(2); %#ok<AGROW>
                        end
                    end
                end

                clear cleaner;
            end


            function write_mom_with_offsets(sourceFile, destFile, newOffsets)
                [headerLines, bodyLines] = read_mom_file_parts(sourceFile);
                [nonOffsetHeader, oldOffsets] = split_offset_header_lines(headerLines);
                allOffsets = unique_offset_times([oldOffsets(:); newOffsets(:)]);

                tempFile = destFile;
                if strcmpi(sourceFile, destFile)
                    tempFile = [destFile, '.tmp'];
                end

                [fid, errmsg] = fopen(tempFile, 'w');
                if fid == -1
                    error('Cannot write MOM file: %s\nError: %s', tempFile, errmsg);
                end

                cleaner = onCleanup(@() fclose(fid));

                for i = 1:numel(nonOffsetHeader)
                    fprintf(fid, '%s\n', nonOffsetHeader{i});
                end
                for i = 1:numel(allOffsets)
                    fprintf(fid, '# offset %.6f\n', allOffsets(i));
                end
                for i = 1:numel(bodyLines)
                    fprintf(fid, '%s\n', bodyLines{i});
                end

                clear cleaner;

                if strcmpi(sourceFile, destFile)
                    movefile(tempFile, destFile, 'f');
                end
            end


            function write_mom_numeric_with_offsets(sourceFile, destFile, mjd, obs, newOffsets)
                [headerLines, ~] = read_mom_file_parts(sourceFile);
                [nonOffsetHeader, oldOffsets] = split_offset_header_lines(headerLines);
                allOffsets = unique_offset_times([oldOffsets(:); newOffsets(:)]);

                [fid, errmsg] = fopen(destFile, 'w');
                if fid == -1
                    error('Cannot write MOM file: %s\nError: %s', destFile, errmsg);
                end

                cleaner = onCleanup(@() fclose(fid));

                for i = 1:numel(nonOffsetHeader)
                    fprintf(fid, '%s\n', nonOffsetHeader{i});
                end
                for i = 1:numel(allOffsets)
                    fprintf(fid, '# offset %.6f\n', allOffsets(i));
                end
                for i = 1:numel(mjd)
                    fprintf(fid, '%.6f %.6f\n', mjd(i), obs(i));
                end

                clear cleaner;
            end


            function [nonOffsetHeader, offsets] = split_offset_header_lines(headerLines)
                nonOffsetHeader = {};
                offsets = [];

                for i = 1:numel(headerLines)
                    line = headerLines{i};
                    token = regexpi(strtrim(line), '^#\s*offset\s+([-+]?\d*\.?\d+(?:[eEdD][-+]?\d+)?)', 'tokens', 'once');

                    if isempty(token)
                        nonOffsetHeader{end+1, 1} = line; %#ok<AGROW>
                    else
                        offsets(end+1, 1) = parse_double_token(token{1}); %#ok<AGROW>
                    end
                end
            end


            function out = unique_offset_times(values)
                values = values(isfinite(values) & values > 1.0);
                out = [];

                for i = 1:numel(values)
                    if isempty(out) || all(abs(out - values(i)) > 1.0e-5)
                        out(end+1, 1) = values(i); %#ok<AGROW>
                    end
                end
            end


            function existsFlag = offset_already_exists(candidateMJD, sourceFile, extraOffsets)
                [headerLines, ~] = read_mom_file_parts(sourceFile);
                [~, oldOffsets] = split_offset_header_lines(headerLines);
                allOffsets = [oldOffsets(:); extraOffsets(:)];
                existsFlag = any(abs(allOffsets - candidateMJD) <= 1.0e-5);
            end


            function candidates = read_findoffset_candidates(outFile)
                if ~isfile(outFile)
                    error('findoffset.out was not created by findoffset: %s', outFile);
                end

                fid = fopen(outFile, 'r');
                if fid == -1
                    error('Cannot open findoffset.out: %s', outFile);
                end

                cleaner = onCleanup(@() fclose(fid));
                C = textscan(fid, '%f %f', 'CommentStyle', '#');
                clear cleaner;

                if isempty(C{1})
                    candidates = zeros(0, 2);
                else
                    candidates = [C{1}, C{2}];
                    % Existing # offset epochs can make neighbouring trial
                    % epochs singular in Hector, which is written as Inf,
                    % -Inf or NaN.  These are invalid candidates, not a
                    % failure of the complete offset search.
                    validRows = isfinite(candidates(:, 1)) & candidates(:, 1) > 1.0 & ...
                        isfinite(candidates(:, 2));
                    candidates = candidates(validRows, :);
                end
            end


            function [mjd, value] = find_minimum_1d(candidates)
                if isempty(candidates) || size(candidates, 2) < 2
                    mjd = NaN;
                    value = NaN;
                    return;
                end

                % findoffset.out can contain Inf/-Inf/NaN at singular
                % candidate epochs (for example next to an existing offset).
                % Such rows are not valid model candidates.
                validRows = isfinite(candidates(:, 1)) & candidates(:, 1) > 1.0 & ...
                    isfinite(candidates(:, 2));
                candidates = candidates(validRows, :);

                if isempty(candidates)
                    mjd = NaN;
                    value = NaN;
                    return;
                end

                [value, idx] = min(candidates(:, 2));
                mjd = candidates(idx, 1);
            end


            function [mjd, value] = find_minimum_3d(candidateTables)
                base = candidateTables{1};
                if isempty(base)
                    mjd = NaN;
                    value = NaN;
                    return;
                end

                baseKeys = round(base(:, 1) * 1.0e6);
                commonKeys = baseKeys;
                for comp = 2:3
                    commonKeys = intersect(commonKeys, round(candidateTables{comp}(:, 1) * 1.0e6));
                end

                if isempty(commonKeys)
                    mjd = NaN;
                    value = NaN;
                    return;
                end

                keepBase = ismember(baseKeys, commonKeys);
                scanMJD  = base(keepBase, 1);
                scanKeys = baseKeys(keepBase);
                sumValues = base(keepBase, 2);

                for comp = 2:3
                    table = candidateTables{comp};
                    keys = round(table(:, 1) * 1.0e6);
                    [tf, loc] = ismember(scanKeys, keys);
                    if any(~tf)
                        error('Internal error while summing 3D BIC_c curves.');
                    end
                    sumValues = sumValues + table(loc, 2);
                end

                % A single non-finite component makes the joint BIC_c invalid.
                % Ignore those epochs instead of selecting -Inf as the minimum.
                validRows = isfinite(scanMJD) & isfinite(sumValues);
                scanMJD = scanMJD(validRows);
                sumValues = sumValues(validRows);

                if isempty(scanMJD)
                    mjd = NaN;
                    value = NaN;
                    return;
                end

                [value, idx] = min(sumValues);
                mjd = scanMJD(idx);
            end

            function [mjd, candidateBICc, modelBICc] = parseFindOffsetConsole(cmdout)
                mjd = NaN;
                candidateBICc = NaN;
                modelBICc = NaN;

                numberToken = '[-+]?(?:\d*\.?\d+(?:[eEdD][-+]?\d+)?|inf(?:inity)?|nan)';

                tokM = regexpi(cmdout, ['FindOffset\s*MJD\s*=\s*(' numberToken ')'], 'tokens', 'once');
                if ~isempty(tokM)
                    mjd = parse_double_token(tokM{1});
                end

                tokB = regexpi(cmdout, ['FindOffset\s*BIC_c\s*=\s*(' numberToken ')'], 'tokens', 'once');
                if ~isempty(tokB)
                    candidateBICc = parse_double_token(tokB{1});
                end

                tokModel = regexpi(cmdout, ['^BIC_c\s*=\s*(' numberToken ')'], ...
                    'tokens', 'once', 'lineanchors');
                if ~isempty(tokModel)
                    modelBICc = parse_double_token(tokModel{1});
                end

                % The workflow only needs the current-model BIC_c here; the
                % candidate epoch/value are selected from findoffset.out.
                % Therefore a printed NaN/Inf candidate is not a parse error.
                if isempty(tokModel)
                    error('Current model BIC_c value not found in the findoffset console output.');
                end
                if ~isfinite(modelBICc)
                    % A numerically singular trial should stop this file's
                    % iteration cleanly instead of aborting the complete
                    % 1D/3D batch.
                    modelBICc = Inf;
                end
            end


            function value = parse_double_token(token)
                token = strrep(char(token), 'D', 'E');
                token = strrep(token, 'd', 'E');
                value = str2double(token);
            end


            function cmdout = FindOffsets(station_name, output_name, loadpath, noise_model, ...
                    offsets_3DValue, extra_penalty, fix_varying_phi, ggm_1mphi, ...
                    AR_p, MA_q, beta_spacing, beta_size, ...
                    JSONFlag, LikelihoodMethod)

                ctlName = 'findoffset.ctl';
                ctlPath = fullfile(loadpath, ctlName);
                candidatePath = fullfile(loadpath, 'findoffset.out');
                if isfile(candidatePath)
                    delete(candidatePath);
                end

                create_findoffset_ctl_file(ctlPath, station_name, output_name, loadpath, noise_model, ...
                    offsets_3DValue, extra_penalty, fix_varying_phi, ggm_1mphi, ...
                    AR_p, MA_q, beta_spacing, beta_size, ...
                    JSONFlag, LikelihoodMethod);

                NET.addAssembly('System');
                proc = System.Diagnostics.Process();
                proc.StartInfo.FileName               = findoffset_executable();
                proc.StartInfo.Arguments              = ctlName;
                proc.StartInfo.WorkingDirectory       = loadpath;
                proc.StartInfo.UseShellExecute        = false;
                proc.StartInfo.RedirectStandardOutput = true;
                proc.StartInfo.RedirectStandardError  = true;
                proc.StartInfo.CreateNoWindow         = true;

                proc.Start();

                stdoutTxt = char(proc.StandardOutput.ReadToEnd());
                stderrTxt = char(proc.StandardError.ReadToEnd());

                proc.WaitForExit();
                exitCode = double(proc.ExitCode);

                proc.Close();
                clear proc;

                cmdout = strtrim([stdoutTxt newline stderrTxt]);

                if exitCode ~= 0
                    error('findoffset failed with exit code %d:\n%s', exitCode, cmdout);
                end
                if ~isfile(candidatePath)
                    error('findoffset completed but did not create its candidate file: %s', candidatePath);
                end
            end

            function exeName = findoffset_executable()
                if ispc
                    executableName = 'findoffset.exe';
                else
                    executableName = 'findoffset';
                end
                exeName = resolveExecutable(app, executableName);
            end

            function create_findoffset_ctl_file(ctlPath, station_name, output_name, loadpath, noise_model, ...
                    offsets_3DValue, extra_penalty, fix_varying_phi, ggm_1mphi, ...
                    AR_p, MA_q, beta_spacing, beta_size, ...
                    JSONFlag, LikelihoodMethod)

                fid = fopen(ctlPath, 'w');
                if fid == -1
                    error('Unable to create control file: %s', ctlPath);
                end

                cleaner = onCleanup(@() fclose(fid));

                fprintf(fid, 'DataFile            %s\n', char(station_name));
                fprintf(fid, 'OutputFile          %s\n', char(output_name));
                fprintf(fid, 'DataDirectory       %s\n', char(strrep(loadpath, '/', filesep)));

                fprintf(fid, 'interpolate         no\n');
                fprintf(fid, 'ScaleFactor         1.0\n');
                fprintf(fid, 'NoiseModels         %s\n', char(noise_model));
                fprintf(fid, 'LikelihoodMethod    %s\n', char(LikelihoodMethod));
                fprintf(fid, 'estimateoffsets     yes\n');
                fprintf(fid, 'seasonalsignal      yes\n');
                fprintf(fid, 'halfseasonalsignal  yes\n');
                fprintf(fid, 'PhysicalUnit        mm\n');

                fprintf(fid, 'beta_spacing        %.8g\n', beta_spacing);
                fprintf(fid, 'beta_size           %.8g\n', beta_size);
                fprintf(fid, 'offsets_3D          %s\n', bool_to_yesno(offsets_3DValue));
                fprintf(fid, 'BIC_c_ExtraPenalty  %.8g\n', extra_penalty);
                fprintf(fid, 'JSON                %s\n', bool_to_yesno(JSONFlag));

                nm = string(noise_model);

                if contains(nm, "GGM", "IgnoreCase", true)
                    fprintf(fid, 'GGM_1mphi           %.8g\n', ggm_1mphi);
                end

                if contains(nm, "ARMA", "IgnoreCase", true) || contains(nm, "ARFIMA", "IgnoreCase", true)
                    fprintf(fid, 'AR_p                %d\n', round(AR_p));
                    fprintf(fid, 'MA_q                %d\n', round(MA_q));
                end

                if contains(nm, "VaryingAnnual", "IgnoreCase", true) || contains(nm, "VaryingSemiAnnual", "IgnoreCase", true)
                    if fix_varying_phi
                        fprintf(fid, 'phi_varying_fixed   0.999\n');
                    end
                end

                clear cleaner;
            end


            function answer = bool_to_yesno(value)
                if logical(value)
                    answer = 'yes';
                else
                    answer = 'no';
                end
            end


            function safeStem = sanitize_file_stem(fileName)
                [~, stem, ~] = fileparts(fileName);
                safeStem = regexprep(stem, '[^\w.-]', '_');
            end
        end

        % Menu selected function: AddOffsetHeadersMenu
        function AddOffsetHeadersMenuSelected(app, event)
            % ====== Load offset file and write offsets into .mom headers ======
            [fileName, filePath] = uigetfile("*.mat", "Select offset file to load");
            if isequal(fileName, 0)
                return; % user cancelled
            end

            matPath = fullfile(filePath, fileName);
            if ~exist(matPath, 'file')
                uialert(app.HectorUIFigure, 'File not found.', 'Error', 'Icon', 'error', 'Modal', true);
                return;
            end

            % -------- Step 2: Load + validate + group --------
            try
                S = load(matPath, '-mat');

                if ~isfield(S, 'offset_times') || ~isstruct(S.offset_times)
                    error("Invalid file: Expected struct variable 'offset_times'.");
                end

                offsetStruct = S.offset_times;

                if ~isfield(offsetStruct, 'StationName') || ~isfield(offsetStruct, 'OffsetTime')
                    error("Invalid structure: Missing fields 'StationName' or 'OffsetTime'.");
                end

                stationNames = string({offsetStruct.StationName});
                offsetMJDs   = double([offsetStruct.OffsetTime]);

                % Basic filtering
                valid = ~isnan(offsetMJDs) & offsetMJDs > 1.0 & strlength(stationNames) > 0;
                stationNames = stationNames(valid);
                offsetMJDs   = offsetMJDs(valid);

                if isempty(offsetMJDs)
                    uialert(app.HectorUIFigure, 'No valid offsets found in the selected file.', ...
                        'Warning', 'Icon', 'warning', 'Modal', true);
                    return;
                end

                % Group offsets by station
                [uniqStations, ~, idx] = unique(stationNames, 'stable');
                groupedMJDs = accumarray(idx(:), offsetMJDs(:), [], @(v){sort(unique(v(:)))});

            catch ME
                uialert(app.HectorUIFigure, ...
                    sprintf('Error loading file:\n%s\n\nDetails:\n%s', matPath, ME.message), ...
                    'Error', 'Icon', 'error', 'Modal', true);
                return;
            end

            % -------- Step 3: Select directory to write --------
            selectedDir = uigetdir('', 'Select Directory Containing .mom Files');
            if isequal(selectedDir, 0)
                return; % user cancelled
            end

            % -------- Step 4: Write offsets --------
            p = [];
            try
                successCount = 0;
                skippedCount = 0;
                notFoundList = strings(0,1);

                % FIX 1: define numStations
                numStations = numel(uniqStations);

                % cache mom list for faster matching
                momFiles = dir(fullfile(selectedDir, '*.mom'));
                momNames = string({momFiles.name});

                p = uiprogressdlg(app.HectorUIFigure, ...
                    'Title', 'Writing Offsets', ...
                    'Message', 'Processing files...', ...
                    'Cancelable', 'on');

                for i = 1:numStations
                    if p.CancelRequested
                        break;
                    end

                    st = uniqStations(i);
                    mjds = groupedMJDs{i}; % vector of MJDs for this station

                    % ---- Find matching mom file ----
                    momPath = find_station_mom(selectedDir, momNames, st);

                    if isempty(momPath) || ~exist(momPath, 'file')
                        skippedCount = skippedCount + 1;
                        notFoundList(end+1,1) = st; %#ok<AGROW>
                    else
                        if ~isempty(mjds)
                            append_offset_header_lines(momPath, mjds);
                            successCount = successCount + 1;
                        else
                            skippedCount = skippedCount + 1;
                        end
                    end

                    p.Value   = i / max(1, numStations);
                    p.Message = sprintf('Processed %d of %d files', i, numStations);
                end

                if isvalid(p), close(p); end

                % Summary message
                if ~isempty(notFoundList)
                    sample = notFoundList(1:min(10,numel(notFoundList)));
                    tailNote = "";
                    if numel(notFoundList) > 10
                        tailNote = sprintf("\n... and %d more", numel(notFoundList)-10);
                    end
                    nfText = sprintf("\n\nNot found (first %d):\n%s%s", ...
                        numel(sample), strjoin(sample, newline), tailNote);
                else
                    nfText = "";
                end

                msg = sprintf('Successfully wrote offsets to %d files.\nSkipped %d files (not found or no offsets).%s', ...
                    successCount, skippedCount, nfText);

                uialert(app.HectorUIFigure, msg, 'Done', 'Icon', 'success', 'Modal', true);

            catch ME
                if ~isempty(p) && isvalid(p), close(p); end
                uialert(app.HectorUIFigure, ...
                    sprintf('Error writing offsets:\n%s', ME.message), ...
                    'Error', 'Icon', 'error', 'Modal', true);
            end


            % ===================== Local functions =====================

            function momPath = find_station_mom(selectedDir, momNames, station)
                % Try to find a .mom file for station name.
                % Priority:
                % 1) any file that starts with station name (case-insensitive), e.g. HKKS_0.mom, HKKS.mom
                % 2) exact match station + "_0.mom"
                % 3) exact match station + ".mom"
                %
                % Return full path, or "" if not found.

                station = string(station);
                momPath = "";

                if isempty(momNames)
                    return;
                end

                % Case-insensitive startsWith
                stUpper = upper(station);
                momUpper = upper(momNames);

                hits = startsWith(momUpper, stUpper);
                if any(hits)
                    % If multiple hits, prefer *_0.mom, else take first
                    candidates = momNames(hits);
                    idx0 = find(endsWith(upper(candidates), "_0.MOM"), 1, 'first');
                    if ~isempty(idx0)
                        chosen = candidates(idx0);
                    else
                        chosen = candidates(1);
                    end
                    momPath = fullfile(selectedDir, char(chosen));
                    return;
                end

                % Fallback exact
                f1 = station + "_0.mom";
                if any(strcmpi(momNames, f1))
                    momPath = fullfile(selectedDir, char(f1));
                    return;
                end

                f2 = station + ".mom";
                if any(strcmpi(momNames, f2))
                    momPath = fullfile(selectedDir, char(f2));
                    return;
                end
            end


            function append_offset_header_lines(input_filepath, mjd_values)
                % Append multiple '# offset MJD' lines into header, avoiding duplicates.

                mjd_values = double(mjd_values(:));
                mjd_values = mjd_values(~isnan(mjd_values) & mjd_values > 1.0);
                mjd_values = sort(unique(mjd_values));

                if isempty(mjd_values)
                    return;
                end

                [fid, errmsg] = fopen(input_filepath, 'rb');
                if fid == -1
                    error('Cannot open file: %s\nError: %s', input_filepath, errmsg);
                end

                % Read header lines
                header_lines = {};
                header_end = 0;

                while ~feof(fid)
                    pos = ftell(fid);
                    line = fgetl(fid);
                    if ~ischar(line) || isempty(line) || line(1) ~= '#'
                        header_end = pos; % data begins at last saved position
                        break;
                    end
                    header_lines{end+1} = line; %#ok<AGROW>
                end

                % Extract existing MJDs
                existing_mjds = [];
                for j = 1:numel(header_lines)
                    line = header_lines{j};
                    if startsWith(line, '# offset ')
                        v = sscanf(line, '# offset %f');
                        if ~isempty(v)
                            existing_mjds(end+1,1) = v; %#ok<AGROW>
                        end
                    end
                end

                new_mjds = setdiff(mjd_values, unique(existing_mjds));

                if isempty(new_mjds)
                    fclose(fid);
                    return;
                end

                temp_filepath = [input_filepath, '.tmp'];
                fid_temp = fopen(temp_filepath, 'wb');
                if fid_temp == -1
                    fclose(fid);
                    error('Cannot create temp file: %s', temp_filepath);
                end

                % Write original header
                for j = 1:numel(header_lines)
                    fprintf(fid_temp, '%s\n', header_lines{j});
                end

                % Append new offset lines
                for mjd = new_mjds(:)'
                    fprintf(fid_temp, '# offset %.6f\n', mjd);
                end

                % Copy the rest of file (data section)
                fseek(fid, header_end, 'bof');
                while ~feof(fid)
                    chunk = fread(fid, 8192, '*uint8');
                    if ~isempty(chunk)
                        fwrite(fid_temp, chunk, 'uint8');
                    end
                end

                fclose(fid);
                fclose(fid_temp);

                movefile(temp_filepath, input_filepath, 'f');
            end

        end

        % Menu selected function: NoiseModelSelectionMenu
        function NoiseModelSelectionMenuSelected(app, event)
            % ===================== Select optimal noise model by BIC/AIC using Hector =====================

            if isempty(app.momlist)
                uialert(app.HectorUIFigure, 'No processing data input', 'Warning', 'Icon', 'warning');
                return
            end

            output_dir = fullfile(app.loadpath, "OptimalNoiseCombination");
            if ~isfolder(output_dir)
                mkdir(output_dir);
            end

            % Get GUI params
            def = defaultHectorCtl();
            [params, ok] = promptHectorCtl(def);
            if ~ok
                return
            end

            try
                fig = app.HectorUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig, "Icon", iconPath, "Cancelable", "on");
                p.Message = "Calculating...";

                nFiles = numel(app.momlist);

                % Prepare CSV
                csvPath = fullfile(output_dir, 'AnalysisResults.csv');
                if ~exist(csvPath, 'file')
                    initTable = table({}, {}, [], {}, 'VariableNames', ...
                        {'FileName', 'NoiseModel', 'ICValue', 'OptimalNoiseModel'});
                    writetable(initTable, csvPath);
                end

                % Running mode
                if params.autoBIC
                    noise_combinations = params.noiseCombinations;
                    nCombs = numel(noise_combinations);
                else
                    nCombs = 1;
                end

                OptimalNoiseModel_temp = cell(nFiles, 2);
                batch_results = cell(nFiles * nCombs, 4);
                batch_result_idx = 1;

                for momfile = 1:nFiles
                    input_filename = app.momlist(momfile).name;
                    output_fileName = fullfile(output_dir, input_filename);

                    best_ic = Inf;
                    best_noise_model = '';
                    temp_results = cell(nCombs, 3);
                    batch_cmdouts = cell(nCombs, 1);

                    for k = 1:nCombs

                        if params.autoBIC
                            current_noise = strtrim(string(noise_combinations{k}));
                        else
                            current_noise = strjoin(string(params.noiseModels), " ");
                        end

                        cmdout = processData(input_filename, output_fileName, app.loadpath, current_noise, params);
                        ic_value = extractIC(cmdout, params.ICType);

                        temp_results{k, 1} = input_filename;
                        temp_results{k, 2} = char(current_noise);
                        temp_results{k, 3} = ic_value;
                        batch_cmdouts{k} = cmdout;

                        if ic_value < best_ic
                            best_ic = ic_value;
                            best_noise_model = char(current_noise);
                        end

                        pause(0.05);
                    end

                    OptimalNoiseModel_temp{momfile, 1} = input_filename;
                    OptimalNoiseModel_temp{momfile, 2} = best_noise_model;

                    % Save cmdouts
                    outTxt = fullfile(output_dir, sprintf("%s.txt", input_filename(1:min(6,end))));
                    saveBatchCmdOuts(outTxt, batch_cmdouts);

                    % Store rows
                    for k = 1:nCombs
                        batch_results{batch_result_idx, 1} = temp_results{k, 1};
                        batch_results{batch_result_idx, 2} = temp_results{k, 2};
                        batch_results{batch_result_idx, 3} = temp_results{k, 3};
                        if k == nCombs
                            batch_results{batch_result_idx, 4} = best_noise_model;
                        else
                            batch_results{batch_result_idx, 4} = '';
                        end
                        batch_result_idx = batch_result_idx + 1;
                    end

                    p.Value = momfile / nFiles;

                    if p.CancelRequested
                        close(p);
                        return
                    end
                end

                % Append CSV
                batchTable = cell2table(batch_results, ...
                    'VariableNames', {'FileName', 'NoiseModel', 'ICValue', 'OptimalNoiseModel'});
                writetable(batchTable, csvPath, 'WriteMode', 'append');

                close(p);

                % Transfer result
                app.OptimalNoiseModel = OptimalNoiseModel_temp;

                % Save MAT
                if ~isempty(app.OptimalNoiseModel)
                    fileName = 'OptimalNoiseModel.mat';
                    fullSavePath = fullfile(output_dir, fileName);
                    OptimalNoiseCombination = app.OptimalNoiseModel; %#ok<NASGU>
                    save(fullSavePath, 'OptimalNoiseCombination');
                    msg = sprintf('Result successfully saved to:\n%s', fullSavePath);
                    uialert(app.HectorUIFigure, msg, 'Success', 'Icon', 'success', 'Modal', true);
                else
                    uialert(app.HectorUIFigure, "No optimal noise model data.", ...
                        'Error', 'Icon', 'error', 'Modal', true);
                end

            catch ME
                if exist('p','var') && isvalid(p)
                    close(p);
                end
                uialert(app.HectorUIFigure, sprintf("Error: %s", ME.message), ...
                    'Error', 'Icon', 'error', 'Modal', true);
            end


            % =============================================================================================
            % Helpers
            % =============================================================================================

            function cmdout = processData(input_filename, output_fileName, loadpath, noise_model, params)
                % Run Hector once

                writeCtlFile(input_filename, output_fileName, loadpath, noise_model, params);
                ctlFullPath = fullfile(pwd, 'estimatetrend.ctl');

                NET.addAssembly('System');
                proc = System.Diagnostics.Process();
                proc.StartInfo.FileName = resolveExecutable(app, 'estimatetrend.exe');

                % Pass ctl file as argument
                proc.StartInfo.Arguments = sprintf('"%s"', ctlFullPath);

                proc.StartInfo.UseShellExecute = false;
                proc.StartInfo.RedirectStandardOutput = true;
                proc.StartInfo.RedirectStandardError = true;
                proc.StartInfo.CreateNoWindow = true;

                proc.Start();
                out = char(proc.StandardOutput.ReadToEnd());
                err = char(proc.StandardError.ReadToEnd());
                proc.WaitForExit();

                cmdout = out;
                if ~isempty(strtrim(err))
                    cmdout = sprintf("%s\n\n[STDERR]\n%s", out, err);
                end

                proc.Close();

                if exist('estimatetrend.ctl', 'file')
                    delete('estimatetrend.ctl');
                end
                pause(0.1);
            end


            function saveBatchCmdOuts(output_record, cmdouts)
                % Save cmdouts to txt

                fid = fopen(output_record, 'w');
                if fid == -1
                    error("Failed to open file: %s", output_record);
                end

                chunk_size = 4096;
                for i = 1:numel(cmdouts)
                    cmdout = cmdouts{i};
                    for j = 1:chunk_size:length(cmdout)
                        chunk_end = min(j + chunk_size - 1, length(cmdout));
                        fwrite(fid, cmdout(j:chunk_end), 'char');
                    end
                    fprintf(fid, '\n-------------------- Noise Model Separator --------------------\n');
                end
                fclose(fid);
            end


            function IC_value = extractIC(cmdout, icType)
                % Parse IC value (BIC/AIC)

                icType = upper(string(icType));
                if icType ~= "BIC" && icType ~= "AIC"
                    error("IC type must be BIC or AIC.");
                end

                pat = icType + "\s*=\s*([-\d.]+)";
                match = regexp(cmdout, pat, 'tokens', 'once');

                if ~isempty(match)
                    IC_value = str2double(match{1});
                else
                    error("%s value not found in the command output.", icType);
                end
            end


            % =============================================================================================
            % Defaults (aligned to Hector manual conventions; key ones adjusted)
            % =============================================================================================

            function defaults = defaultHectorCtl()
                % Default values for Hector GUI

                defaults.interpolate = false;
                defaults.physicalUnit = "mm";
                defaults.scaleFactor = 1.0;
                defaults.likelihoodMethod = "Auto";

                % Hector manual: DegreePolynomial default is 1 (range 0-6)
                defaults.degreePolynomial = 1;
                defaults.estimatemultitrend = false;

                defaults.seasonalsignal = true;
                defaults.halfseasonalsignal = true;
                defaults.periodicsignals = "";
                defaults.estimateoffsets = true;
                defaults.estimatepostseismic = false;
                defaults.estimateslowslipevent = false;

                % Manual-style common default combination
                defaults.noiseModels = ["Powerlaw","White"];

                % GGM-family parameter (also used by FlickerGGM / RandomWalkGGM)
                defaults.GGM_1mphi = 6.9e-06;

                % Optional “fixed” parameters: leave blank by default (do not write to ctl unless user sets)
                defaults.kappa_fixed = "";   % text (blank means not set)
                defaults.lambda_fixed = "";  % text (blank means not set)

                defaults.AR_p = 1;
                defaults.MA_q = 0;

                % Manual default: no
                defaults.randomiseFirstGuess = false;

                defaults.estimatevaryingseasonal = false;
                defaults.varyingseasonal_N = 3;

                defaults.estimatemultivariate = false;
                defaults.multiVariateFile = "";

                defaults.subtractsignal = false;
                defaults.signalFile = "";

                defaults.json = false;

                defaults.autoBIC = true;
                defaults.ICType = "BIC"; % BIC|AIC

                % You can edit/extend this list
                defaults.noiseCombinations = { ...
                    'FlickerGGM White', ...
                    'RandomWalkGGM White', ...
                    'FlickerGGM RandomWalkGGM White', ...
                    'Powerlaw White', ...
                    'ARMA RandomWalkGGM White', ...
                    'Powerlaw White VaryingAnnual VaryingSemiAnnual', ...
                    'FlickerGGM VaryingAnnual VaryingSemiAnnual', ...
                    };
            end


            % =============================================================================================
            % GUI
            % =============================================================================================

            function [params, ok] = promptHectorCtl(defaults)
                % Modal GUI for Hector ctl parameters

                ok = false;
                params = defaults;

                dlg = uifigure('Name','Hector .ctl Parameters', ...
                    'WindowStyle','modal','Position',[200 120 1040 760]);
                % movegui(dlg, 'center');

                root = uigridlayout(dlg,[2 1]);
                root.RowHeight = {'1x', 60};

                tg = uitabgroup(root);

                % ---------------- Basic ----------------
                tabA = uitab(tg,'Title','Basic');
                gA = uigridlayout(tabA,[4 2]);
                gA.RowHeight = {40,40,40,40};
                gA.ColumnWidth = {280,'1x'};
                gA.Padding = [10 10 10 10];
                gA.RowSpacing = 8;
                gA.ColumnSpacing = 10;

                cbInterp = uicheckbox(gA,'Text','interpolate', 'Value', defaults.interpolate);
                cbInterp.Layout.Row = 1; cbInterp.Layout.Column = [1 2];
                cbInterp.Tooltip = "If yes: fill missing data by Hector internal interpolation.";

                lbl = uilabel(gA,'Text','PhysicalUnit');
                lbl.Layout.Row = 2; lbl.Layout.Column = 1;
                lbl.Tooltip = "Output unit (mm/cm/m).";
                ddUnit = uidropdown(gA,'Items',{'mm','cm','m'},'Value', char(defaults.physicalUnit));
                ddUnit.Layout.Row = 2; ddUnit.Layout.Column = 2;
                ddUnit.Tooltip = "Physical unit used in output and reporting.";

                lbl = uilabel(gA,'Text','ScaleFactor');
                lbl.Layout.Row = 3; lbl.Layout.Column = 1;
                lbl.Tooltip = "Scale factor applied to input series.";
                efScale = uieditfield(gA,'numeric','Value', defaults.scaleFactor, 'LowerLimit', 0);
                efScale.Layout.Row = 3; efScale.Layout.Column = 2;
                efScale.Tooltip = "Multiply observations by this factor before estimation.";

                lbl = uilabel(gA,'Text','LikelihoodMethod');
                lbl.Layout.Row = 4; lbl.Layout.Column = 1;
                lbl.Tooltip = "Likelihood implementation (Auto recommended).";
                ddLike = uidropdown(gA,'Items',{'Auto','AmmarGrag','FullCov'},'Value', char(defaults.likelihoodMethod));
                ddLike.Layout.Row = 4; ddLike.Layout.Column = 2;
                ddLike.Tooltip = "Auto: choose best implementation by model size and options.";

                % ---------------- Model ----------------
                tabB = uitab(tg,'Title','Model');
                gB = uigridlayout(tabB,[8 2]);
                gB.RowHeight = repmat({40},1,8);
                gB.ColumnWidth = {320,'1x'};
                gB.Padding = [10 10 10 10];
                gB.RowSpacing = 8;
                gB.ColumnSpacing = 10;

                lbl = uilabel(gB,'Text','DegreePolynomial (0-6)');
                lbl.Layout.Row = 1; lbl.Layout.Column = 1;
                lbl.Tooltip = "Polynomial degree (if multitrend = no).";
                spDeg = uispinner(gB,'Limits',[0 6],'Value', defaults.degreePolynomial);
                spDeg.Layout.Row = 1; spDeg.Layout.Column = 2;
                spDeg.Tooltip = "Degree of polynomial trend.";

                cbMultiTrend = uicheckbox(gB,'Text','estimatemultitrend', 'Value', defaults.estimatemultitrend);
                cbMultiTrend.Layout.Row = 2; cbMultiTrend.Layout.Column = [1 2];
                cbMultiTrend.Tooltip = "If yes: estimate multiple trend segments.";

                cbSeason = uicheckbox(gB,'Text','seasonalsignal', 'Value', defaults.seasonalsignal);
                cbSeason.Layout.Row = 3; cbSeason.Layout.Column = [1 2];
                cbSeason.Tooltip = "If yes: estimate annual sinusoid.";

                cbHSeason = uicheckbox(gB,'Text','halfseasonalsignal', 'Value', defaults.halfseasonalsignal);
                cbHSeason.Layout.Row = 4; cbHSeason.Layout.Column = [1 2];
                cbHSeason.Tooltip = "If yes: estimate semi-annual sinusoid.";

                lbl = uilabel(gB,'Text','periodicsignals (days)');
                lbl.Layout.Row = 5; lbl.Layout.Column = 1;
                lbl.Tooltip = "Additional periods in days (space-separated).";
                efPeriodic = uieditfield(gB,'text','Value', char(defaults.periodicsignals), ...
                    'Placeholder','e.g., 13.66 182.625');
                efPeriodic.Layout.Row = 5; efPeriodic.Layout.Column = 2;
                efPeriodic.Tooltip = "Example: '13.66 182.625'.";

                cbOffsets = uicheckbox(gB,'Text','estimateoffsets', 'Value', defaults.estimateoffsets);
                cbOffsets.Layout.Row = 6; cbOffsets.Layout.Column = [1 2];
                cbOffsets.Tooltip = "If yes: estimate offsets.";

                cbPost = uicheckbox(gB,'Text','estimatepostseismic', 'Value', defaults.estimatepostseismic);
                cbPost.Layout.Row = 7; cbPost.Layout.Column = [1 2];

                cbSSE = uicheckbox(gB,'Text','estimateslowslipevent', 'Value', defaults.estimateslowslipevent);
                cbSSE.Layout.Row = 8; cbSSE.Layout.Column = [1 2];

                cbMultiTrend.ValueChangedFcn = @(~,~) setEnable(spDeg, ~cbMultiTrend.Value);
                setEnable(spDeg, ~defaults.estimatemultitrend);

                % ---------------- Noise ----------------
                tabC = uitab(tg,'Title','Noise');

                pC = uipanel(tabC, 'Scrollable','on');
                pC.BorderType = 'none';

                gC = uigridlayout(pC,[13 2]);
                gC.RowHeight = {32,32,120,120,32,32,32,32,32,32,32,32,'1x'};
                gC.ColumnWidth = {360,'1x'};
                gC.Padding = [10 10 10 10];
                gC.RowSpacing = 8;
                gC.ColumnSpacing = 10;

                cbAuto = uicheckbox(gC,'Text','Auto selection (use combinations below)', ...
                    'Value', defaults.autoBIC);
                cbAuto.Layout.Row = 1; cbAuto.Layout.Column = [1 2];
                cbAuto.Tooltip = "If enabled: try combinations and select by IC.";

                lbl = uilabel(gC,'Text','InformationCriterion');
                lbl.Layout.Row = 2; lbl.Layout.Column = 1;
                lbl.Tooltip = "Choose metric for model selection (lower is better).";

                ddIC = uidropdown(gC,'Items',{'BIC','AIC'},'Value', char(defaults.ICType));
                ddIC.Layout.Row = 2; ddIC.Layout.Column = 2;
                ddIC.Tooltip = "BIC or AIC used for model selection.";

                lbl = uilabel(gC,'Text','Noise combinations (one per line)');
                lbl.Layout.Row = 3; lbl.Layout.Column = 1;

                taCombs = uitextarea(gC, 'Value', defaults.noiseCombinations);
                taCombs.Layout.Row = 3; taCombs.Layout.Column = 2;

                lbl = uilabel(gC,'Text','NoiseModels (manual mode)');
                lbl.Layout.Row = 4; lbl.Layout.Column = 1;

                lbNoise = uilistbox(gC,'Items',{'White','FlickerGGM','RandomWalkGGM','Powerlaw','PowerlawApprox','Matern','ARFIMA','ARMA','GGM'}, ...
                    'Multiselect','on','Value', cellstr(defaults.noiseModels));
                lbNoise.Layout.Row = 4; lbNoise.Layout.Column = 2;

                lbl = uilabel(gC,'Text','GGM_1mphi (GGM/FlickerGGM/RandomWalkGGM)');
                lbl.Layout.Row = 5; lbl.Layout.Column = 1;
                ef1mphi = uieditfield(gC,'numeric','Value', defaults.GGM_1mphi);
                ef1mphi.Layout.Row = 5; ef1mphi.Layout.Column = 2;

                lbl = uilabel(gC,'Text','kappa_fixed (optional: Powerlaw/Matern/GGM-family)');
                lbl.Layout.Row = 6; lbl.Layout.Column = 1;
                efKappa = uieditfield(gC,'text','Value', char(defaults.kappa_fixed), 'Placeholder', 'blank = not set');
                efKappa.Layout.Row = 6; efKappa.Layout.Column = 2;

                lbl = uilabel(gC,'Text','lambda_fixed (optional: Matern only)');
                lbl.Layout.Row = 7; lbl.Layout.Column = 1;
                efLambda = uieditfield(gC,'text','Value', char(defaults.lambda_fixed), 'Placeholder', 'blank = not set');
                efLambda.Layout.Row = 7; efLambda.Layout.Column = 2;

                lbl = uilabel(gC,'Text','AR_p (ARMA/ARFIMA)');
                lbl.Layout.Row = 8; lbl.Layout.Column = 1;
                efARp = uieditfield(gC,'numeric','RoundFractionalValues','on','Value', defaults.AR_p, 'LowerLimit', 0);
                efARp.Layout.Row = 8; efARp.Layout.Column = 2;

                lbl = uilabel(gC,'Text','MA_q (ARMA/ARFIMA)');
                lbl.Layout.Row = 9; lbl.Layout.Column = 1;
                efMAq = uieditfield(gC,'numeric','RoundFractionalValues','on','Value', defaults.MA_q, 'LowerLimit', 0);
                efMAq.Layout.Row = 9; efMAq.Layout.Column = 2;

                cbRand = uicheckbox(gC,'Text','RandomiseFirstGuess', 'Value', defaults.randomiseFirstGuess);
                cbRand.Layout.Row = 11; cbRand.Layout.Column = [1 2];

                lbNoise.ValueChangedFcn = @(~,~) refreshNoise();
                cbAuto.ValueChangedFcn  = @(~,~) refreshMode();
                refreshMode();
                refreshNoise();

                % ---------------- Advanced ----------------
                tabD = uitab(tg,'Title','Advanced');
                gD = uigridlayout(tabD,[7 2]);
                gD.RowHeight = repmat({40},1,7);
                gD.ColumnWidth = {380,'1x'};
                gD.Padding = [10 10 10 10];
                gD.RowSpacing = 8;
                gD.ColumnSpacing = 10;

                cbVarSeason = uicheckbox(gD,'Text','estimatevaryingseasonal', 'Value', defaults.estimatevaryingseasonal);
                cbVarSeason.Layout.Row = 1; cbVarSeason.Layout.Column = [1 2];

                lbl = uilabel(gD,'Text','varyingseasonal_N');
                lbl.Layout.Row = 2; lbl.Layout.Column = 1;
                efVarN = uieditfield(gD,'numeric','RoundFractionalValues','on', ...
                    'Value', defaults.varyingseasonal_N, 'LowerLimit', 1, 'UpperLimit', 50);
                efVarN.Layout.Row = 2; efVarN.Layout.Column = 2;

                cbMulti = uicheckbox(gD,'Text','estimatemultivariate', 'Value', defaults.estimatemultivariate);
                cbMulti.Layout.Row = 3; cbMulti.Layout.Column = [1 2];

                lbl = uilabel(gD,'Text','MultiVariateFile');
                lbl.Layout.Row = 4; lbl.Layout.Column = 1;
                efMVF = uieditfield(gD,'text','Value', char(defaults.multiVariateFile));
                efMVF.Layout.Row = 4; efMVF.Layout.Column = 2;

                cbSub = uicheckbox(gD,'Text','subtractsignal', 'Value', defaults.subtractsignal);
                cbSub.Layout.Row = 5; cbSub.Layout.Column = [1 2];

                lbl = uilabel(gD,'Text','SignalFile');
                lbl.Layout.Row = 6; lbl.Layout.Column = 1;
                efSig = uieditfield(gD,'text','Value', char(defaults.signalFile));
                efSig.Layout.Row = 6; efSig.Layout.Column = 2;

                cbJSON = uicheckbox(gD,'Text','JSON', 'Value', defaults.json);
                cbJSON.Layout.Row = 7; cbJSON.Layout.Column = [1 2];

                cbVarSeason.ValueChangedFcn = @(~,~) refreshAdvanced();
                cbMulti.ValueChangedFcn     = @(~,~) refreshAdvanced();
                cbSub.ValueChangedFcn       = @(~,~) refreshAdvanced();
                refreshAdvanced();

                % ---------------- Buttons ----------------
                btnRow = uigridlayout(root,[1 4]);
                btnRow.ColumnWidth = {'1x', 200, 130, 130};

                uilabel(btnRow,'Text','');
                btnDefault = uibutton(btnRow,'Text','Restore Defaults','ButtonPushedFcn',@onDefaults);
                btnCancel  = uibutton(btnRow,'Text','Cancel','ButtonPushedFcn',@onCancel);
                btnOK      = uibutton(btnRow,'Text','OK','ButtonPushedFcn',@onOK);

                btnDefault.Layout.Column = 2;
                btnCancel.Layout.Column  = 3;
                btnOK.Layout.Column      = 4;

                applyFontAll(dlg, "Times New Roman", 18);

                uiwait(dlg);

                % ---------------- Nested ----------------
                function refreshMode()
                    setEnable(lbNoise, ~cbAuto.Value);
                    setEnable(taCombs, cbAuto.Value);
                    refreshNoise();
                end

                function refreshNoise()
                    ef1mphi.Enable  = 'on';
                    efKappa.Enable  = 'on';
                    efLambda.Enable = 'on';
                    efARp.Enable    = 'on';
                    efMAq.Enable    = 'on';
                end

                function refreshAdvanced()
                    setEnable(efVarN, cbVarSeason.Value);
                    setEnable(efMVF, cbMulti.Value);
                    setEnable(efSig, cbSub.Value);
                end

                function onDefaults(~,~)
                    d = defaults;

                    cbInterp.Value = d.interpolate;
                    ddUnit.Value   = char(d.physicalUnit);
                    efScale.Value  = d.scaleFactor;
                    ddLike.Value   = char(d.likelihoodMethod);

                    spDeg.Value         = d.degreePolynomial;
                    cbMultiTrend.Value  = d.estimatemultitrend;
                    cbSeason.Value      = d.seasonalsignal;
                    cbHSeason.Value     = d.halfseasonalsignal;
                    efPeriodic.Value    = char(d.periodicsignals);
                    cbOffsets.Value     = d.estimateoffsets;
                    cbPost.Value        = d.estimatepostseismic;
                    cbSSE.Value         = d.estimateslowslipevent;

                    cbAuto.Value = d.autoBIC;
                    ddIC.Value   = char(d.ICType);
                    taCombs.Value= d.noiseCombinations;
                    lbNoise.Value= cellstr(d.noiseModels);

                    ef1mphi.Value  = d.GGM_1mphi;
                    efKappa.Value  = char(d.kappa_fixed);
                    efLambda.Value = char(d.lambda_fixed);
                    efARp.Value    = d.AR_p;
                    efMAq.Value    = d.MA_q;
                    cbRand.Value   = d.randomiseFirstGuess;

                    cbVarSeason.Value = d.estimatevaryingseasonal;
                    efVarN.Value      = d.varyingseasonal_N;
                    cbMulti.Value     = d.estimatemultivariate;
                    efMVF.Value       = char(d.multiVariateFile);
                    cbSub.Value       = d.subtractsignal;
                    efSig.Value       = char(d.signalFile);
                    cbJSON.Value      = d.json;

                    setEnable(spDeg, ~cbMultiTrend.Value);
                    refreshMode();
                    refreshAdvanced();
                end

                function onCancel(~,~)
                    ok = false;
                    uiresume(dlg);
                    close(dlg);
                end

                function onOK(~,~)
                    % Validation
                    if cbAuto.Value
                        combs = string(taCombs.Value);
                        combs = strtrim(combs);
                        combs = combs(combs ~= "");
                        if isempty(combs)
                            uialert(dlg,'Noise combinations cannot be empty in auto mode.','Validation','Icon','warning');
                            return
                        end
                        params.noiseCombinations = cellstr(combs);
                    else
                        if isempty(lbNoise.Value)
                            uialert(dlg,'NoiseModels cannot be empty (manual mode).','Validation','Icon','warning');
                            return
                        end
                    end

                    if cbMulti.Value && strlength(string(efMVF.Value)) == 0
                        uialert(dlg,'MultiVariateFile is required when estimatemultivariate = yes.','Validation','Icon','warning');
                        return
                    end
                    if cbSub.Value && strlength(string(efSig.Value)) == 0
                        uialert(dlg,'SignalFile is required when subtractsignal = yes.','Validation','Icon','warning');
                        return
                    end

                    % Collect
                    params.interpolate      = cbInterp.Value;
                    params.physicalUnit     = string(ddUnit.Value);
                    params.scaleFactor      = efScale.Value;
                    params.likelihoodMethod = string(ddLike.Value);

                    params.degreePolynomial   = spDeg.Value;
                    params.estimatemultitrend = cbMultiTrend.Value;
                    params.seasonalsignal     = cbSeason.Value;
                    params.halfseasonalsignal = cbHSeason.Value;
                    params.periodicsignals    = strtrim(string(efPeriodic.Value));
                    params.estimateoffsets    = cbOffsets.Value;
                    params.estimatepostseismic= cbPost.Value;
                    params.estimateslowslipevent = cbSSE.Value;

                    params.autoBIC = cbAuto.Value;
                    params.ICType  = string(ddIC.Value);

                    params.noiseModels          = string(lbNoise.Value);
                    params.randomiseFirstGuess  = cbRand.Value;

                    params.GGM_1mphi      = ef1mphi.Value;
                    params.kappa_fixed    = strtrim(string(efKappa.Value));   % optional (blank => not set)
                    params.lambda_fixed   = strtrim(string(efLambda.Value));  % optional (blank => not set)
                    params.AR_p           = efARp.Value;
                    params.MA_q           = efMAq.Value;

                    params.estimatevaryingseasonal = cbVarSeason.Value;
                    params.varyingseasonal_N       = efVarN.Value;

                    params.estimatemultivariate = cbMulti.Value;
                    params.multiVariateFile     = string(efMVF.Value);

                    params.subtractsignal = cbSub.Value;
                    params.signalFile     = string(efSig.Value);

                    params.json = cbJSON.Value;

                    ok = true;
                    uiresume(dlg);
                    close(dlg);
                end

                function setEnable(h, tf)
                    if tf, h.Enable = 'on'; else, h.Enable = 'off'; end
                end

                function applyFontAll(fig, fontName, fontSize)
                    objs = findall(fig);
                    for i = 1:numel(objs)
                        h = objs(i);
                        if isprop(h, 'FontName')
                            try h.FontName = fontName; catch, end
                        end
                        if isprop(h, 'FontSize')
                            try h.FontSize = fontSize; catch, end
                        end
                    end
                end

            end


            % =============================================================================================
            % Write .ctl file
            % =============================================================================================

            function writeCtlFile(input_filename, output_fileName, loadpath, noise_model, params)
                % Write estimatetrend.ctl

                filename = "estimatetrend.ctl";
                fid = fopen(filename, 'w');
                if fid == -1
                    error("Unable to create control file");
                end

                fprintf(fid, 'DataFile            %s\n', input_filename);
                fprintf(fid, 'OutputFile          %s\n', output_fileName);
                fprintf(fid, 'DataDirectory       %s\n', loadpath);

                fprintf(fid, 'interpolate         %s\n', yesno(params.interpolate));
                fprintf(fid, 'ScaleFactor         %.12g\n', params.scaleFactor);
                fprintf(fid, 'PhysicalUnit        %s\n', params.physicalUnit);

                fprintf(fid, 'estimatemultitrend  %s\n', yesno(params.estimatemultitrend));
                if ~params.estimatemultitrend
                    fprintf(fid, 'DegreePolynomial    %d\n', params.degreePolynomial);
                end

                fprintf(fid, 'seasonalsignal      %s\n', yesno(params.seasonalsignal));
                fprintf(fid, 'halfseasonalsignal  %s\n', yesno(params.halfseasonalsignal));

                if strlength(params.periodicsignals) > 0
                    fprintf(fid, 'periodicsignals     %s\n', params.periodicsignals);
                end

                fprintf(fid, 'estimateoffsets     %s\n', yesno(params.estimateoffsets));
                fprintf(fid, 'estimatepostseismic %s\n', yesno(params.estimatepostseismic));
                fprintf(fid, 'estimateslowslipevent %s\n', yesno(params.estimateslowslipevent));

                if isfield(params,'likelihoodMethod') && params.likelihoodMethod ~= "Auto"
                    fprintf(fid, 'LikelihoodMethod    %s\n', params.likelihoodMethod);
                end

                fprintf(fid, 'NoiseModels         %s\n', char(noise_model));

                nm = string(strsplit(strtrim(string(noise_model))));

                % ARMA/ARFIMA parameters
                if any(nm=="ARMA") || any(nm=="ARFIMA")
                    fprintf(fid, 'AR_p               %d\n', params.AR_p);
                    fprintf(fid, 'MA_q               %d\n', params.MA_q);
                end

                % GGM-family: GGM / FlickerGGM / RandomWalkGGM all contain "GGM"
                hasAnyGGMfamily = any(contains(nm,"GGM"));
                if hasAnyGGMfamily && ~isempty(params.GGM_1mphi)
                    fprintf(fid, 'GGM_1mphi          %.12g\n', params.GGM_1mphi);
                end

                % Optional fixed parameters: only write if user provided a valid number
                kappa = parseOptionalNumber(params.kappa_fixed);
                if (any(nm=="Powerlaw") || any(nm=="Matern") || any(contains(nm,"GGM"))) && ~isempty(kappa)
                    fprintf(fid, 'kappa_fixed        %.12g\n', kappa);
                end

                lambda = parseOptionalNumber(params.lambda_fixed);
                if any(nm=="Matern") && ~isempty(lambda)
                    fprintf(fid, 'lambda_fixed       %.12g\n', lambda);
                end

                if isfield(params,'randomiseFirstGuess')
                    fprintf(fid, 'RandomiseFirstGuess %s\n', yesno(params.randomiseFirstGuess));
                end

                if isfield(params,'estimatevaryingseasonal') && params.estimatevaryingseasonal
                    fprintf(fid, 'estimatevaryingseasonal yes\n');
                    fprintf(fid, 'varyingseasonal_N   %d\n', params.varyingseasonal_N);
                end

                if isfield(params,'estimatemultivariate') && params.estimatemultivariate
                    fprintf(fid, 'estimatemultivariate yes\n');
                    fprintf(fid, 'MultiVariateFile    %s\n', params.multiVariateFile);
                end

                if isfield(params,'subtractsignal') && params.subtractsignal
                    fprintf(fid, 'subtractsignal      yes\n');
                    fprintf(fid, 'SignalFile          %s\n', params.signalFile);
                end

                if isfield(params,'json')
                    fprintf(fid, 'JSON                %s\n', yesno(params.json));
                end

                fclose(fid);

                % ---- local helpers ----
                function s = yesno(tf)
                    if tf, s = 'yes'; else, s = 'no'; end
                end

                function v = parseOptionalNumber(x)
                    % x is string-like; blank => []
                    v = [];
                    try
                        sx = strtrim(string(x));
                        if sx == ""
                            return
                        end
                        vv = str2double(sx);
                        if isfinite(vv)
                            v = vv;
                        end
                    catch
                        v = [];
                    end
                end

            end

        end

        % Menu selected function: GenerateResidualTimeSeriesMenu
        function GenerateResidualTimeSeriesMenuSelected(app, event)
            if isempty(app.momlist)
                uialert(app.HectorUIFigure, 'No processing data input', 'Warning', 'Icon', 'warning');
                return
            end

            % Get control options from UI
            ctlOpt = promptCtlOptions(app);
            if isempty(ctlOpt)
                return
            end

            % Noise selection mode
            useOptimal = isfield(ctlOpt,'NoiseMode') && strcmpi(ctlOpt.NoiseMode,'optimal');
            if useOptimal && isempty(app.OptimalNoiseModel)
                uialert(app.HectorUIFigure, ...
                    sprintf("There is currently no optimal\nnoise model data available."), ...
                    'Warning', 'Icon', 'warning', 'Modal', true);
                return
            end

            % Output folder depends on seasonal estimation
            output_dir = getOutputDir(app.loadpath, ctlOpt.Seasonal, ctlOpt.HalfSeasonal);
            if ~isfolder(output_dir)
                mkdir(output_dir);
            end

            try
                fig = app.HectorUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig, "Icon", iconPath, "Cancelable", "on");
                p.Message = "Calculating...";
                p.Value = 0;

                nFiles = numel(app.momlist);
                batchSize = 10;

                for b0 = 1:batchSize:nFiles
                    b1 = min(b0 + batchSize - 1, nFiles);

                    for i = b0:b1
                        if p.CancelRequested
                            break
                        end

                        [fname, noise_models] = resolveInput(app, ctlOpt, i, useOptimal);

                        outFile = fullfile(output_dir, fname);
                        cmdout  = runEstimateTrend(app, fname, outFile, noise_models, ctlOpt);

                        saveRunLog(output_dir, fname, cmdout);

                        % -------------------------------------------------------------
                        % Optional periodic export:
                        % Export only when station_0/1/2.mom are present in output_dir.
                        % This avoids "No files" and downstream indexing errors.
                        % -------------------------------------------------------------
                        if shouldExportNow(output_dir, fname)
                            writeProcessedFiles(app, output_dir, fname);
                        end

                        p.Value = i / nFiles;
                    end

                    throttleMemory();
                    if p.CancelRequested
                        break
                    end
                end

                close(p);

                deleteRunLogs(output_dir);
                uialert(app.HectorUIFigure, 'Processing Completed.', 'Tip', 'Icon', 'success');

            catch ME
                try close(p); catch, end %#ok<CTCH>
                uialert(app.HectorUIFigure, sprintf('Error: %s', ME.message), 'Error', 'Icon', 'error');
            end


            % =====================================================================
            % Local functions
            % =====================================================================

            function output_dir = getOutputDir(loadpath, seasonal, halfseasonal)
                % Map model choice to output folder
                if seasonal || halfseasonal
                    output_dir = fullfile(loadpath, "TrendAndSeasonal");
                else
                    output_dir = fullfile(loadpath, "TrendOnly");
                end
            end

            function [fname, noise_models] = resolveInput(app, ctlOpt, idx, useOptimal)
                % Select filename and noise model string for current file
                if useOptimal
                    % app.OptimalNoiseModel: {filename, noiseModels}
                    fname = app.OptimalNoiseModel{idx, 1};
                    noise_models = strtrim(app.OptimalNoiseModel{idx, 2});
                else
                    fname = app.momlist(idx).name;
                    noise_models = strtrim(ctlOpt.FixedNoiseModels);
                end
            end

            function cmdout = runEstimateTrend(app, fname, outFile, noise_models, ctlOpt)
                % Write ctl, run Hector, cleanup
                ctlName = "estimatetrend.ctl";
                writeCtlFile(app, ctlName, fname, outFile, noise_models, ctlOpt);

                NET.addAssembly('System');
                proc = System.Diagnostics.Process();
                proc.StartInfo.FileName = resolveExecutable(app, 'estimatetrend.exe');
                proc.StartInfo.Arguments = ctlName;
                proc.StartInfo.UseShellExecute = false;
                proc.StartInfo.RedirectStandardOutput = true;
                proc.StartInfo.CreateNoWindow = true;

                proc.Start();
                reader = proc.StandardOutput;
                cmdout = char(reader.ReadToEnd());
                proc.WaitForExit();

                reader.Close();
                proc.Close();
                clear proc reader;
            end

            function writeCtlFile(app, ctlName, fname, outFile, noise_models, ctlOpt)
                % Generate estimatetrend.ctl with manual-aligned defaults/behavior
                fid = fopen(ctlName, 'w');
                if fid == -1
                    error("Unable to create control file: %s", ctlName);
                end

                fprintf(fid, 'DataFile            %s\n', fname);
                fprintf(fid, 'DataDirectory       %s\n', app.loadpath);
                fprintf(fid, 'OutputFile          %s\n', outFile);

                fprintf(fid, 'interpolate         %s\n', yn(ctlOpt.Interpolate));
                fprintf(fid, 'estimateoffsets     %s\n', yn(ctlOpt.EstimateOffsets));
                fprintf(fid, 'ScaleFactor         %.16g\n', ctlOpt.ScaleFactor);
                fprintf(fid, 'PhysicalUnit        %s\n', ctlOpt.PhysicalUnit);

                fprintf(fid, 'estimatemultitrend  %s\n', yn(ctlOpt.MultiTrend));
                if ~ctlOpt.MultiTrend
                    fprintf(fid, 'DegreePolynomial    %d\n', round(ctlOpt.DegreePolynomial));
                end

                fprintf(fid, 'estimatepostseismic %s\n', yn(ctlOpt.PostSeismic));
                fprintf(fid, 'seasonalsignal      %s\n', yn(ctlOpt.Seasonal));
                fprintf(fid, 'halfseasonalsignal  %s\n', yn(ctlOpt.HalfSeasonal));

                if ~isempty(ctlOpt.ReferenceEpochYMD)
                    ymd = ctlOpt.ReferenceEpochYMD;
                    fprintf(fid, 'ReferenceEpoch      %d %d %d\n', ymd(1), ymd(2), ymd(3));
                end

                fprintf(fid, 'NoiseModels         %s\n', strtrim(noise_models));

                % LikelihoodMethod: omit when Auto to preserve Hector default logic
                if ~strcmpi(ctlOpt.LikelihoodMethod, 'Auto')
                    fprintf(fid, 'LikelihoodMethod    %s\n', ctlOpt.LikelihoodMethod);
                end

                % ARMA/ARFIMA parameters
                if hasToken(noise_models,'ARMA') || hasToken(noise_models,'ARFIMA')
                    fprintf(fid, 'AR_p               %d\n', round(ctlOpt.AR_p));
                    fprintf(fid, 'MA_q               %d\n', round(ctlOpt.MA_q));
                end

                % GGM parameter
                if hasToken(noise_models,'GGM')
                    fprintf(fid, 'GGM_1mphi           %.16g\n', ctlOpt.GGM_1mphi);
                end

                fclose(fid);

                function s = yn(tf)
                    if tf, s = 'yes'; else, s = 'no'; end
                end

                function tf = hasToken(str, token)
                    expr = ['(^|\s)' regexptranslate('escape', token) '(\s|$)'];
                    tf = ~isempty(regexp(str, expr, 'once'));
                end
            end

            function saveRunLog(output_dir, fname, cmdout)
                % Save stdout to per-station log
                logName = fullfile(output_dir, sprintf("%s.txt", fname(1:min(6,end))));
                fid = fopen(logName, "w");
                if fid == -1
                    error("Failed to open file: %s", logName);
                end
                fprintf(fid, '%s', cmdout);
                fclose(fid);
            end

            function deleteRunLogs(output_dir)
                % Cleanup temporary logs
                txt_files = dir(fullfile(output_dir, '*.txt'));
                for k = 1:numel(txt_files)
                    delete(fullfile(output_dir, txt_files(k).name));
                end
            end

            function throttleMemory()
                % Light throttling to reduce GUI freezing / memory pressure
                pause(0.3);
                [~, sys] = memory;
                if sys.PhysicalMemory.Available < 1e9
                    pause(1.5);
                end
            end

            function tf = shouldExportNow(output_dir, fname)
                tf = false;

                [~, base, ext] = fileparts(fname);
                if isempty(ext), ext = '.mom'; end

                % station name = part before last _digit
                tok = regexp(base, '^(.*)_\d+$', 'tokens', 'once');
                if ~isempty(tok)
                    st = tok{1};
                else
                    st = base;
                end

                f0 = fullfile(output_dir, sprintf('%s_0%s', st, ext));
                f1 = fullfile(output_dir, sprintf('%s_1%s', st, ext));
                f2 = fullfile(output_dir, sprintf('%s_2%s', st, ext));

                tf = isfile(f0) && isfile(f1) && isfile(f2);
            end


            function writeProcessedFiles(app, output_dir, fileName)

                [~, base, ~] = fileparts(fileName);
                tok = regexp(base, '^(.*)_\d+$', 'tokens', 'once');
                if ~isempty(tok)
                    StationName = tok{1};   % char
                else
                    StationName = base;
                end

                ProcessedData = ResidualData(app, output_dir, StationName);

                if isempty(ProcessedData) || size(ProcessedData,2) < 4
                    uialert(app.HectorUIFigure, ...
                        sprintf("ProcessedData is empty or incomplete for station: %s", StationName), ...
                        "Warning","Icon","warning","Modal",true);
                    return
                end

                for component = 2:4
                    momdata = [ProcessedData(:,1), ProcessedData(:,component)];
                    saveName = sprintf('%s_%d.mom', StationName, component-2);
                    saveFilePath = fullfile(output_dir, saveName);

                    fileID1 = fopen(saveFilePath,'w');
                    if fileID1 == -1, error('Failed to open file: %s', saveFilePath); end
                    fprintf(fileID1,'%.6f %.6f\n', momdata');
                    fclose(fileID1);
                end
            end


            function ctlOpt = promptCtlOptions(app)
                % UI for estimatetrend.ctl options (manual-aligned presets)

                ctlOpt = [];

                w = 860; h = 600;
                ss = get(groot, 'ScreenSize');
                x = ss(1) + (ss(3) - w)/2;
                y = ss(2) + (ss(4) - h)/2;

                dlg = uifigure('Name','Hector Control Parameters', ...
                    'Position',[x y w h], ...
                    'WindowStyle','modal', ...
                    'Resize','off');

                gl = uigridlayout(dlg, [4 1]);
                gl.RowHeight = {180, 260, '1x', 55};
                gl.Padding = [12 12 12 12];
                gl.RowSpacing = 10;

                % ---- Model ----
                pModel = uipanel(gl, 'Title', 'Model');
                g1 = uigridlayout(pModel, [4 4]);
                g1.RowHeight = {30, 30, 30, 30};
                g1.ColumnWidth = {190, 160, 200, '1x'};
                g1.Padding = [10 10 10 10];

                uilabel(g1, 'Text','DegreePolynomial (0–6):');
                spDeg = uispinner(g1, 'Limits',[0 6], 'Step',1, 'Value',1);
                spDeg.Tooltip = 'Optional. Manual default is 1.';

                uilabel(g1, 'Text','ScaleFactor:');
                efScale = uieditfield(g1, 'numeric', 'Value',1.0, 'LowerLimit',-Inf, 'UpperLimit',Inf);
                efScale.Tooltip = 'Optional. Manual default is 1.';

                uilabel(g1, 'Text','Seasonal (annual):');
                cbAnnual = uicheckbox(g1, 'Value',true);

                uilabel(g1, 'Text','Half-seasonal (semi-annual):');
                cbSemi = uicheckbox(g1, 'Value',false);

                uilabel(g1, 'Text','Estimate offsets:');
                cbOff = uicheckbox(g1, 'Value',true);

                uilabel(g1, 'Text','Interpolate missing:');
                ddInterp = uidropdown(g1, 'Items',{'no','yes'}, 'Value','no');

                uilabel(g1, 'Text','estimatemultitrend:');
                cbMulti = uicheckbox(g1, 'Value',false);

                uilabel(g1, 'Text','estimatepostseismic:');
                cbPost = uicheckbox(g1, 'Value',false);

                % ---- Noise / Likelihood ----
                pNoise = uipanel(gl, 'Title', 'Noise / Likelihood');
                g2 = uigridlayout(pNoise, [7 4]);
                g2.RowHeight = {30, 30, 30, 30, 30, 30, '1x'};
                g2.ColumnWidth = {210, 260, 170, '1x'};
                g2.Padding = [10 10 10 10];

                uilabel(g2, 'Text','Noise mode:');
                ddNoiseMode = uidropdown(g2, ...
                    'Items',{'Use app.OptimalNoiseModel (per file)','Fixed for all files'}, ...
                    'Value','Fixed for all files');

                uilabel(g2, 'Text','LikelihoodMethod:');
                ddLik = uidropdown(g2, 'Items',{'Auto','AmmarGrag','FullCov'}, 'Value','Auto');

                uilabel(g2, 'Text','PhysicalUnit:');
                ddUnit = uidropdown(g2, 'Items',{'mm','m'}, 'Value','mm');

                uilabel(g2, 'Text','ReferenceEpoch (YYYY-MM-DD):');
                efRef = uieditfield(g2, 'text', 'Value','');
                efRef.Tooltip = 'Empty => Hector uses midpoint reference epoch.';

                uilabel(g2, 'Text','Fixed NoiseModels:');
                efNoise = uieditfield(g2, 'text', 'Value','Powerlaw White');

                uilabel(g2, 'Text','GGM_1mphi:');
                ef1mphi = uieditfield(g2, 'numeric', 'Value',6.9e-06);

                uilabel(g2, 'Text','AR_p:');
                spARp = uispinner(g2, 'Limits',[0 50], 'Value',1, 'Step',1);

                uilabel(g2, 'Text','MA_q:');
                spMAq = uispinner(g2, 'Limits',[0 50], 'Value',0, 'Step',1);

                % ---- Footer ----
                btnPanel = uipanel(gl, 'BorderType','none');
                gb = uigridlayout(btnPanel, [1 3]);
                gb.ColumnWidth = {'1x', 140, 140};
                gb.Padding = [0 0 0 0];

                uilabel(gb, 'Text','Set parameters and click OK to continue.');
                uibutton(gb, 'Text','Cancel', 'ButtonPushedFcn', @(~,~) onCancel());
                uibutton(gb, 'Text','OK',     'ButtonPushedFcn', @(~,~) onOK());

                applyFontRecursive(dlg, 'Times New Roman', 14);
                uiwait(dlg);

                function onCancel()
                    ctlOpt = [];
                    uiresume(dlg);
                    delete(dlg);
                end

                function onOK()
                    refYMD = [];
                    refTxt = strtrim(efRef.Value);
                    if ~isempty(refTxt)
                        try
                            dt = datetime(refTxt, 'InputFormat','yyyy-MM-dd');
                            refYMD = [year(dt) month(dt) day(dt)];
                        catch
                            uialert(dlg, 'ReferenceEpoch must be YYYY-MM-DD or empty.', ...
                                'Invalid Input', 'Icon','warning', 'Modal', true);
                            return
                        end
                    end

                    ctlOpt = struct();
                    ctlOpt.DegreePolynomial  = spDeg.Value;
                    ctlOpt.ScaleFactor       = efScale.Value;
                    ctlOpt.Seasonal          = cbAnnual.Value;
                    ctlOpt.HalfSeasonal      = cbSemi.Value;
                    ctlOpt.EstimateOffsets   = cbOff.Value;
                    ctlOpt.Interpolate       = strcmpi(ddInterp.Value, 'yes');
                    ctlOpt.PhysicalUnit      = ddUnit.Value;
                    ctlOpt.LikelihoodMethod  = ddLik.Value;
                    ctlOpt.ReferenceEpochYMD = refYMD;

                    ctlOpt.MultiTrend        = cbMulti.Value;
                    ctlOpt.PostSeismic       = cbPost.Value;

                    if strcmpi(ddNoiseMode.Value, 'Fixed for all files')
                        ctlOpt.NoiseMode        = 'fixed';
                        ctlOpt.FixedNoiseModels = strtrim(efNoise.Value);
                    else
                        ctlOpt.NoiseMode        = 'optimal';
                        ctlOpt.FixedNoiseModels = '';
                    end

                    ctlOpt.GGM_1mphi = ef1mphi.Value;
                    ctlOpt.AR_p      = spARp.Value;
                    ctlOpt.MA_q      = spMAq.Value;

                    uiresume(dlg);
                    delete(dlg);
                end
            end

            function applyFontRecursive(rootObj, fontName, fontSize)
                objs = findall(rootObj);
                for i = 1:numel(objs)
                    o = objs(i);
                    if isprop(o, 'FontName'); o.FontName = fontName; end %#ok<SEPEX>
                    if isprop(o, 'FontSize'); o.FontSize = fontSize; end
                end
            end
        end

        % Menu selected function: LoadOptimalNoiseModelmatMenu
        function LoadOptimalNoiseModelmatMenuSelected(app, event)
            [fileName,filePath] = uigetfile("*.mat","Select result to load");

            if ~isequal(fileName, 0)
                fullfilePath = fullfile(filePath,fileName);

                try
                    Results = load(fullfilePath);
                    app.OptimalNoiseModel = Results.OptimalNoiseCombination;
                    msg = sprintf('File loaded successfully from:\n%s', fullfilePath);
                    uialert(app.HectorUIFigure, msg, 'Success', 'Icon', 'success', 'Modal', true);
                catch ME
                    errMsg = sprintf('Error: %s', ME.message);
                    uialert(app.HectorUIFigure, errMsg, 'Error', 'Icon', 'error', 'Modal', true);
                end

            else
                return
            end
        end

        % Menu selected function: PSDEstimationMenu
        function PSDEstimationMenuSelected(app, event)
            psdAppPath = resolveResource(app, 'PSDEstimation.mlapp');
            if ~isfile(psdAppPath)
                uialert(app.HectorUIFigure, ...
                    sprintf('PSDEstimation.mlapp not found: %s', psdAppPath), ...
                    'Module Error', 'Icon', 'error');
                return;
            end
            run(psdAppPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HectorUIFigure = uifigure('Visible', 'off');
            app.HectorUIFigure.Name = 'Hector';
            app.HectorUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'hector.png');
            app.FileMenu = uimenu(app.HectorUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadAnalysisFileMenu = uimenu(app.FileMenu);
            app.LoadAnalysisFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAnalysisFileMenuSelected, true);
            app.LoadAnalysisFileMenu.Text = 'Load Analysis File';
            app.LoadOptimalNoiseModelmatMenu = uimenu(app.FileMenu);
            app.LoadOptimalNoiseModelmatMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadOptimalNoiseModelmatMenuSelected, true);
            app.LoadOptimalNoiseModelmatMenu.Text = 'Load Optimal Noise Model (*.mat)';
            app.FunctionMenu = uimenu(app.HectorUIFigure);
            app.FunctionMenu.Text = 'Function';
            app.OffsetMenu = uimenu(app.FunctionMenu);
            app.OffsetMenu.Text = 'Offset';
            app.FindOffsetMenu = uimenu(app.OffsetMenu);
            app.FindOffsetMenu.MenuSelectedFcn = createCallbackFcn(app, @FindOffsetMenuSelected, true);
            app.FindOffsetMenu.Text = 'Find Offset';
            app.ApplyOffsetCorrectionsMenu = uimenu(app.OffsetMenu);
            app.ApplyOffsetCorrectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyOffsetCorrectionsMenuSelected, true);
            app.ApplyOffsetCorrectionsMenu.Text = 'Apply Offset Corrections';
            app.AddOffsetHeadersMenu = uimenu(app.OffsetMenu);
            app.AddOffsetHeadersMenu.MenuSelectedFcn = createCallbackFcn(app, @AddOffsetHeadersMenuSelected, true);
            app.AddOffsetHeadersMenu.Text = 'Add Offset Headers';
            app.PSDEstimationMenu = uimenu(app.FunctionMenu);
            app.PSDEstimationMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDEstimationMenuSelected, true);
            app.PSDEstimationMenu.Text = 'PSD Estimation';
            app.ParameterEstimationMenu = uimenu(app.FunctionMenu);
            app.ParameterEstimationMenu.MenuSelectedFcn = createCallbackFcn(app, @ParameterEstimationMenuSelected, true);
            app.ParameterEstimationMenu.Text = 'Parameter Estimation';
            app.NoiseModelSelectionMenu = uimenu(app.FunctionMenu);
            app.NoiseModelSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @NoiseModelSelectionMenuSelected, true);
            app.NoiseModelSelectionMenu.Text = 'Noise-Model Selection';
            app.GenerateResidualTimeSeriesMenu = uimenu(app.FunctionMenu);
            app.GenerateResidualTimeSeriesMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateResidualTimeSeriesMenuSelected, true);
            app.GenerateResidualTimeSeriesMenu.Text = 'Generate Residual Time Series';
            app.VerticalUIAxes = uiaxes(app.HectorUIFigure);
            ylabel(app.VerticalUIAxes, 'Up (mm)')
            app.EastUIAxes = uiaxes(app.HectorUIFigure);
            ylabel(app.EastUIAxes, 'East (mm)')
            app.NorthUIAxes = uiaxes(app.HectorUIFigure);
            ylabel(app.NorthUIAxes, 'North (mm)')
            app.StationListPanel = uipanel(app.HectorUIFigure);
            app.StationListPanel.Title = 'Station List';
            app.Tree = uitree(app.StationListPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            % Show the figure after all components are created
            app.HectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HectorUIFigure)
        end
    end
end