classdef PSDEstimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSDEstimationUIFigure           matlab.ui.Figure
        RunPSDEstimationButton          matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        OutputFileEditField             matlab.ui.control.EditField
        OutputFileLabel                 matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        InterpolateMissingDataCheckBox  matlab.ui.control.CheckBox
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorLabel                matlab.ui.control.Label
        NumberofSegmentsEditField       matlab.ui.control.NumericEditField
        NumberofSegementsLabel          matlab.ui.control.Label
        FractionEditField               matlab.ui.control.NumericEditField
        FractionEditFieldLabel          matlab.ui.control.Label
        WindowFunctionDropDown          matlab.ui.control.DropDown
        WindowFunctionDropDownLabel     matlab.ui.control.Label
        InputDataPanel                  matlab.ui.container.Panel
        DataDirectoryEditField          matlab.ui.control.EditField
        DataDirectoryEditFieldLabel     matlab.ui.control.Label
        DataFilemomEditField            matlab.ui.control.EditField
        DataFilemomLabel                matlab.ui.control.Label
        DataFileBrowseButton            matlab.ui.control.StateButton
        DataDirctoryBrowseButton        matlab.ui.control.StateButton
    end

    
    methods (Access = private)

        function resourcePath = resolveResource(app, varargin) %#ok<INUSD>
            classDir = fileparts(mfilename('fullpath'));
            parentDir = fileparts(classDir);
            candidates = { ...
                fullfile(classDir, varargin{:}), ...
                fullfile(classDir, 'Hector', varargin{:}), ...
                fullfile(parentDir, 'Hector', varargin{:})};
            resourcePath = candidates{1};
            for candidateIndex = 1:numel(candidates)
                if isfile(candidates{candidateIndex}) || isfolder(candidates{candidateIndex})
                    resourcePath = candidates{candidateIndex};
                    return
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.PSDEstimationUIFigure.Position(3);
            windowHeight = app.PSDEstimationUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.PSDEstimationUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];
        end

        % Value changed function: DataFileBrowseButton
        function DataFileBrowseButtonValueChanged(app, event)
            [file, path] = uigetfile( ...
                {'*.mom', 'MOM Files (*.mom)'; '*.*', 'All Files (*.*)'}, ...
                'Select Data File');
            if ~isequal(file, 0)
                app.DataFilemomEditField.Value = file;
                if endsWith(path, filesep)
                    path = path(1:end-1);
                end
                app.DataDirectoryEditField.Value = path;
            end
            app.DataFileBrowseButton.Value = false;
        end

        % Value changed function: DataDirctoryBrowseButton
        function DataDirctoryBrowseButtonValueChanged(app, event)
           startPath = app.DataDirectoryEditField.Value;
            if isempty(startPath) || ~isfolder(startPath)
                startPath = pwd;
            end
            folder = uigetdir(startPath, 'Select Data Directory');
            if ~isequal(folder, 0)
                app.DataDirectoryEditField.Value = folder;
            end
            app.DataDirctoryBrowseButton.Value = false;
        end

        % Button pushed function: RunPSDEstimationButton
        function RunPSDEstimationButtonPushed(app, event)
            try
                dataFile = strtrim(app.DataFilemomEditField.Value);
                dataDir  = strtrim(app.DataDirectoryEditField.Value);
                if isempty(dataFile) || isempty(dataDir)
                    uialert(app.PSDEstimationUIFigure, ...
                        'Please specify Data File and Data Directory.', 'Input Error');
                    return;
                end
                if ~isfile(fullfile(dataDir, dataFile))
                    uialert(app.PSDEstimationUIFigure, ...
                        sprintf('File not found: %s', fullfile(dataDir, dataFile)), 'File Error');
                    return;
                end

                exePath = resolveResource(app, 'exe', 'estimatespectrum.exe');
                if ~isfile(exePath)
                    uialert(app.PSDEstimationUIFigure, ...
                        sprintf('estimatespectrum.exe not found: %s', exePath), 'EXE Error');
                    return;
                end

                windowFunc    = app.WindowFunctionDropDown.Value;
                fraction      = app.FractionEditField.Value;
                numDiv        = app.NumberofSegmentsEditField.Value;
                scaleFactor   = app.ScaleFactorEditField.Value;
                doInterpolate = app.InterpolateMissingDataCheckBox.Value;
                outputFile    = strtrim(app.OutputFileEditField.Value);
                if isempty(outputFile)
                    outputFile = 'estimatespectrum.out';
                end

                if doInterpolate
                    interpStr = 'yes';
                else
                    interpStr = 'no';
                end

                ctlPath = fullfile(dataDir, 'estimatespectrum.ctl');
                fid = fopen(ctlPath, 'w');
                if fid == -1
                    error('Unable to create control file: %s', ctlPath);
                end
                ctlCleanup = onCleanup(@() fclose(fid));
                fprintf(fid, 'DataFile        %s\n', dataFile);
                fprintf(fid, 'DataDirectory   %s\n', dataDir);
                fprintf(fid, 'OutputFile      %s\n', fullfile(dataDir, outputFile));
                fprintf(fid, 'interpolate     %s\n', interpStr);
                fprintf(fid, 'ScaleFactor     %.6f\n', scaleFactor);
                fprintf(fid, 'WindowFunction  %s\n', windowFunc);
                fprintf(fid, 'Fraction        %.4f\n', fraction);
                clear ctlCleanup

                cmd = sprintf('cd /d "%s" && "%s" %d', ...
                    dataDir, exePath, numDiv);
                [status, cmdout] = system(cmd);
                disp(cmdout);

                if status ~= 0
                    error('estimatespectrum failed with exit code %d: %s', status, strtrim(cmdout));
                end

                outPath = fullfile(dataDir, outputFile);
                if ~isfile(outPath)
                    uialert(app.PSDEstimationUIFigure, ...
                        sprintf('Output file not generated:\n%s', cmdout), 'Run Error');
                    return;
                end

                outData = readmatrix(outPath, 'FileType', 'text');
                if size(outData, 2) < 2
                    error('PSD output must contain at least two numeric columns: %s', outPath);
                end
                secPerYear = 365.25 * 86400;
                freq_cpy = outData(:, 1) * secPerYear;
                psd_cpy  = outData(:, 2) / secPerYear;
                validPSD = isfinite(freq_cpy) & isfinite(psd_cpy) & freq_cpy > 0 & psd_cpy > 0;
                freq_cpy = freq_cpy(validPSD);
                psd_cpy = psd_cpy(validPSD);
                if numel(freq_cpy) < 2
                    error('PSD output contains fewer than two positive finite samples.');
                end

                comparisonPSDFile = '';
                spectrumLabels = {'Current PSD', 'Comparison PSD'};
                showSlopeReferences = true;
                markerFrequencies = [1 2];

                [freq_cpy, sortIdx] = sort(freq_cpy);
                psd_cpy = psd_cpy(sortIdx);
                comparisonFreq = [];
                comparisonPSD = [];
                if ~isempty(comparisonPSDFile)
                    comparisonData = readmatrix(comparisonPSDFile, 'FileType', 'text');
                    if size(comparisonData, 2) < 2
                        error('Comparison PSD must contain at least two numeric columns.');
                    end
                    comparisonFreq = comparisonData(:, 1) * secPerYear;
                    comparisonPSD = comparisonData(:, 2) / secPerYear;
                    validComparison = isfinite(comparisonFreq) & ...
                        isfinite(comparisonPSD) & comparisonFreq > 0 & comparisonPSD > 0;
                    comparisonFreq = comparisonFreq(validComparison);
                    comparisonPSD = comparisonPSD(validComparison);
                    if numel(comparisonFreq) < 2
                        error('Comparison PSD contains fewer than two positive finite samples.');
                    end
                    [comparisonFreq, sortIdx] = sort(comparisonFreq);
                    comparisonPSD = comparisonPSD(sortIdx);
                end

                fig = figure('Name', 'Power Spectral Density', ...
                    'NumberTitle', 'off', 'Color', 'w', ...
                    'Position', [250 100 860 510]);
                ax = axes('Parent', fig, 'Position', [0.13 0.16 0.83 0.80]);
                hold(ax, 'on');
                set(ax, 'XScale', 'log', 'YScale', 'log', ...
                    'Color', 'w', 'XColor', 'k', 'YColor', 'k', ...
                    'Box', 'on', 'LineWidth', 1.1, 'Layer', 'top', ...
                    'TickDir', 'in', 'TickLength', [0.012 0.012], ...
                    'XMinorTick', 'on', 'YMinorTick', 'on', ...
                    'XGrid', 'off', 'YGrid', 'off', ...
                    'XMinorGrid', 'off', 'YMinorGrid', 'off', ...
                    'FontName', 'Arial', 'FontSize', 11, 'FontWeight', 'normal');

                allFreq = [freq_cpy; comparisonFreq];
                allPSD = [psd_cpy; comparisonPSD];
                frequencyLimits = [min(allFreq), max(allFreq)];
                logPowerLimits = log10([min(allPSD), max(allPSD)]);
                logPadding = max(0.06 * diff(logPowerLimits), 0.1);
                xlim(ax, frequencyLimits);
                ylim(ax, 10 .^ (logPowerLimits + [-logPadding logPadding]));

                for k = 1:numel(markerFrequencies)
                    markerFreq = markerFrequencies(k);
                    if markerFreq >= frequencyLimits(1) && markerFreq <= frequencyLimits(2)
                        xline(ax, markerFreq, '--', 'Color', [0.65 0.43 0.19], ...
                            'LineWidth', 0.7, 'HandleVisibility', 'off');
                    end
                end

                if showSlopeReferences
                    fRef = logspace(log10(min(freq_cpy)), log10(max(freq_cpy)), 200)';
                    midIdx = round(numel(freq_cpy) / 2);
                    pRef = median(psd_cpy(max(1, midIdx-5):min(numel(psd_cpy), midIdx+5)));
                    fMid = freq_cpy(midIdx);
                    tailStart = max(1, numel(psd_cpy)-20);
                    h0 = plot(ax, fRef, repmat(median(psd_cpy(tailStart:end)), size(fRef)), ...
                        '--', 'Color', [0.25 0.50 0.25], 'LineWidth', 0.8);
                    hMinus1 = plot(ax, fRef, pRef * (fMid ./ fRef), ...
                        '--', 'Color', [0.25 0.40 0.70], 'LineWidth', 0.8);
                    hMinus2 = plot(ax, fRef, pRef * (fMid ./ fRef).^2, ...
                        ':', 'Color', [0.55 0.30 0.60], 'LineWidth', 0.8);
                    slopeHandles = [h0 hMinus1 hMinus2];
                    slopeLabels = {'Slope reference: 0', ...
                        'Slope reference: -1', 'Slope reference: -2'};
                end

                hPSD = plot(ax, freq_cpy, psd_cpy, '-', ...
                    'Color', [0.40 0.40 0.40], 'LineWidth', 0.65);
                spectrumHandles = hPSD;
                legendLabels = spectrumLabels(1);
                if ~isempty(comparisonFreq)
                    hComparison = plot(ax, comparisonFreq, comparisonPSD, '-', ...
                        'Color', [1 0 0], 'LineWidth', 0.65);
                    spectrumHandles = [spectrumHandles hComparison];
                    legendLabels = spectrumLabels;
                end

                xlabel(ax, 'Frequency (cpy)', 'FontName', 'Arial', 'FontSize', 12);
                ylabel(ax, 'PSD (mm^{2}/cpy)', 'Interpreter', 'tex', ...
                    'FontName', 'Arial', 'FontSize', 12);
                legend(ax, [spectrumHandles slopeHandles], [legendLabels slopeLabels], ...
                    'Location', 'northeast', 'Box', 'off', ...
                    'FontName', 'Arial', 'FontSize', 9, 'Interpreter', 'tex');
                hold(ax, 'off');

                [~, nameOnly, ~] = fileparts(dataFile);
                savePath = fullfile(dataDir, [nameOnly '_psd.png']);
                exportgraphics(fig, savePath, 'Resolution', 300);
                disp(['Figure saved: ' savePath]);

            catch ME
                uialert(app.PSDEstimationUIFigure, ME.message, 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PSDEstimationUIFigure = uifigure('Visible', 'off');
            app.PSDEstimationUIFigure.Name = 'PSDEstimation';
            app.InputDataPanel = uipanel(app.PSDEstimationUIFigure);
            app.InputDataPanel.Title = 'Input Data';
            app.DataDirctoryBrowseButton = uibutton(app.InputDataPanel, 'state');
            app.DataDirctoryBrowseButton.ValueChangedFcn = createCallbackFcn(app, @DataDirctoryBrowseButtonValueChanged, true);
            app.DataDirctoryBrowseButton.Text = 'Browse';
            app.DataFileBrowseButton = uibutton(app.InputDataPanel, 'state');
            app.DataFileBrowseButton.ValueChangedFcn = createCallbackFcn(app, @DataFileBrowseButtonValueChanged, true);
            app.DataFileBrowseButton.Text = 'Browse';
            app.DataFilemomLabel = uilabel(app.InputDataPanel);
            app.DataFilemomLabel.Text = 'Data File (.mom)';
            app.DataFilemomEditField = uieditfield(app.InputDataPanel, 'text');
            app.DataDirectoryEditFieldLabel = uilabel(app.InputDataPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.InputDataPanel, 'text');
            app.ParametersPanel = uipanel(app.PSDEstimationUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.WindowFunctionDropDownLabel = uilabel(app.ParametersPanel);
            app.WindowFunctionDropDownLabel.Text = 'Window Function:';
            app.WindowFunctionDropDown = uidropdown(app.ParametersPanel);
            app.WindowFunctionDropDown.Items = {'Parzen', 'Hann'};
            app.WindowFunctionDropDown.Value = 'Parzen';
            app.FractionEditFieldLabel = uilabel(app.ParametersPanel);
            app.FractionEditFieldLabel.Text = 'Fraction:';
            app.FractionEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.FractionEditField.Limits = [0 Inf];
            app.FractionEditField.ValueDisplayFormat = '%.2f';
            app.FractionEditField.Value = 0.1;
            app.NumberofSegementsLabel = uilabel(app.ParametersPanel);
            app.NumberofSegementsLabel.Text = 'Number of Segments:';
            app.NumberofSegmentsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NumberofSegmentsEditField.Limits = [0 Inf];
            app.NumberofSegmentsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofSegmentsEditField.Value = 4;
            app.ScaleFactorLabel = uilabel(app.ParametersPanel);
            app.ScaleFactorLabel.Text = 'Scale Factor:';
            app.ScaleFactorEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ScaleFactorEditField.Limits = [1 Inf];
            app.ScaleFactorEditField.ValueDisplayFormat = '%.0f';
            app.ScaleFactorEditField.Value = 1;
            app.InterpolateMissingDataCheckBox = uicheckbox(app.ParametersPanel);
            app.InterpolateMissingDataCheckBox.Text = 'Interpolate Missing Data';
            app.OutputPanel = uipanel(app.PSDEstimationUIFigure);
            app.OutputPanel.Title = 'Output';
            app.OutputFileLabel = uilabel(app.OutputPanel);
            app.OutputFileLabel.Text = 'Output File';
            app.OutputFileEditField = uieditfield(app.OutputPanel, 'text');
            app.OutputFileEditField.Value = 'estimatespectrum.out';
            app.RunPSDEstimationButton = uibutton(app.PSDEstimationUIFigure, 'push');
            app.RunPSDEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @RunPSDEstimationButtonPushed, true);
            app.RunPSDEstimationButton.Text = 'Run PSD Estimation';
            % Show the figure after all components are created
            app.PSDEstimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSDEstimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PSDEstimationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSDEstimationUIFigure)
        end
    end
end