classdef PrideGTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrideGTSUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadAnalysisFilesMenu           matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        DataFormattingModuleMenu        matlab.ui.container.Menu
        MissingDataReconstructionModuleMenu  matlab.ui.container.Menu
        KnownEventImportModuleMenu      matlab.ui.container.Menu
        ThreeComponentOffsetDetectionMenu  matlab.ui.container.Menu
        HectorModuleMenu                matlab.ui.container.Menu
        HelpMenu_2                      matlab.ui.container.Menu
        AboutMenu_2                     matlab.ui.container.Menu
        FeedbackMenu                    matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        HomePushTool                    matlab.ui.container.toolbar.PushTool
        ClearPushTool                   matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        TimeSeriesVisualizationandPreprocessingTab  matlab.ui.container.Tab
        DrawingSettingsPanel            matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        StatisticsButton                matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        LineStyleDropDown               matlab.ui.control.DropDown
        LineStyleLabel                  matlab.ui.control.Label
        ColorDropDownLabel              matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        MarkerDropDown                  matlab.ui.control.DropDown
        MarkerDropDownLabel             matlab.ui.control.Label
        XtickDropDownLabel              matlab.ui.control.Label
        XtickDropDown                   matlab.ui.control.DropDown
        EnvironmentalLoadingPanel       matlab.ui.container.Panel
        CorrectionPanel                 matlab.ui.container.Panel
        CorrectButton                   matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        HYDLSwitch                      matlab.ui.control.Switch
        HYDLButton                      matlab.ui.control.Button
        NTOLSwitch                      matlab.ui.control.Switch
        NTOLButton                      matlab.ui.control.Button
        NTALSwitch                      matlab.ui.control.Switch
        NTALButton                      matlab.ui.control.Button
        DownloadSettingsPanel           matlab.ui.container.Panel
        DownloadButton                  matlab.ui.control.Button
        SavePathButton                  matlab.ui.control.Button
        VerticalCheckBox                matlab.ui.control.CheckBox
        HYDLButton_2                    matlab.ui.control.StateButton
        EastWestCheckBox                matlab.ui.control.CheckBox
        NTOLButton_2                    matlab.ui.control.StateButton
        NorthSouthCheckBox              matlab.ui.control.CheckBox
        NTALButton_2                    matlab.ui.control.StateButton
        EndDateDatePicker               matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        ImportStationInfo               matlab.ui.control.StateButton
        Tree                            matlab.ui.container.Tree
        OutlierRemovalPanel             matlab.ui.container.Panel
        ImportButton_2                  matlab.ui.control.Button
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowsizeLabel                 matlab.ui.control.Label
        CoefficientEditField            matlab.ui.control.NumericEditField
        CoefficientEditField_2Label_2   matlab.ui.control.Label
        DetectionStrategyDropDown       matlab.ui.control.DropDown
        DetectionStrategyDropDownLabel  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ProcessingScopeDropDown         matlab.ui.control.DropDown
        ProcessingScopeDropDownLabel    matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        savepath                        matlab.ui.control.Button
        VisualPanel                     matlab.ui.container.TabGroup
        DataVisualizationTab            matlab.ui.container.Tab
        NorthUIAxes                     matlab.ui.control.UIAxes
        UpUIAxes                        matlab.ui.control.UIAxes
        EastUIAxes                      matlab.ui.control.UIAxes
        TimeSeriesFilesLabel            matlab.ui.control.Label
        CommonModeErrorExtractionTab    matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        psdUIAxes                       matlab.ui.control.UIAxes
        CMEAmplitudeDistributionPanel   matlab.ui.container.Panel
        CMEAmplitudeDistributionUIAxes  matlab.ui.control.UIAxes
        ExportPanel                     matlab.ui.container.Panel
        ViewButton_2                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        DistanceCorrelationPanel        matlab.ui.container.Panel
        DisplayHeatMapButton            matlab.ui.control.StateButton
        Discorr                         matlab.ui.control.Button
        RefStationSpinner               matlab.ui.control.Spinner
        RefStationSpinnerLabel          matlab.ui.control.Label
        VBICAPanel                      matlab.ui.container.Panel
        GaussModelSpinner               matlab.ui.control.Spinner
        GaussModelSpinnerLabel          matlab.ui.control.Label
        ARDCheckBox                     matlab.ui.control.CheckBox
        DisplayCheckBox_3               matlab.ui.control.CheckBox
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        SourceModelDropDown             matlab.ui.control.DropDown
        SourceModelDropDownLabel        matlab.ui.control.Label
        MaxItersSpinner_3               matlab.ui.control.Spinner
        MaxItersSpinner_3Label          matlab.ui.control.Label
        ReconstructedCMEPanel           matlab.ui.container.Panel
        ReconCME                        matlab.ui.control.Button
        PCsSpinner                      matlab.ui.control.Spinner
        PCsSpinnerLabel                 matlab.ui.control.Label
        VBRPCAPanel                     matlab.ui.container.Panel
        InitializationDropDown          matlab.ui.control.DropDown
        InitializationDropDownLabel     matlab.ui.control.Label
        DisplayCheckBox_2               matlab.ui.control.CheckBox
        MaxItersSpinner_2               matlab.ui.control.Spinner
        MaxItersSpinner_2Label          matlab.ui.control.Label
        AnalysisPanel                   matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        SelectFilterDropDown            matlab.ui.control.DropDown
        SelectFilterDropDownLabel       matlab.ui.control.Label
        ComponentDropDown               matlab.ui.control.DropDown
        ComponentDropDownLabel          matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        VBPCAPanel                      matlab.ui.container.Panel
        MaxItersSpinner                 matlab.ui.control.Spinner
        MaxItersSpinnerLabel            matlab.ui.control.Label
        DisplayCheckBox                 matlab.ui.control.CheckBox
        HypUpdateSpinner                matlab.ui.control.Spinner
        HypUpdateSpinnerLabel           matlab.ui.control.Label
        vbpcaNumPCSpinner               matlab.ui.control.Spinner
        NumberofPCsLabel                matlab.ui.control.Label
        ResultsVisualizationPanel       matlab.ui.container.Panel
        CMEUI_1Axes                     matlab.ui.control.UIAxes
        RMSUIAxes                       matlab.ui.control.UIAxes
        CorrelationUIAxes               matlab.ui.control.UIAxes
        SpatialResponseUIAxes           matlab.ui.control.UIAxes
        CMEUIAxes                       matlab.ui.control.UIAxes
        ContributionUIAxes              matlab.ui.control.UIAxes
        ImportDataPanel                 matlab.ui.container.Panel
        ResidualMatButton               matlab.ui.control.Button
        ResidualDataLabel               matlab.ui.control.Label
        OriginMatButton                 matlab.ui.control.Button
        OriginalDataLabel               matlab.ui.control.Label
    end

    properties (Access = public)
        %% UItree
        activeStationData
        activeStationName
        stationDataCache
        activePlotSignature

        %% Outliers
        outlierProcessedPath
        OutliersTsPath

        %% Environmental Loading
        csvSavePath
        loadingSavePath
        correctionWorkPath
        staInfoPath

        %% ExtractCME
        originData
        residualData
        filterResult
        removeCmeSavePath

        %% Resource paths
        appPath
        iconPath
        ResourcePath
    end

    methods (Access = private)

        function updateWindowSizeControl(app)
            isWindowMethod = ismember( ...
                app.MethodDropDown.Value, ...
                {'WindowIQR', 'WindowMAD'});

            if isWindowMethod
                enableState = 'on';
            else
                enableState = 'off';
            end

            app.WindowSizeEditField.Enable = enableState;
            app.WindowsizeLabel.Enable = enableState;
        end

        %% UItree
        function appRoot = getAppRoot_(~)
            % Locate resources both in the original MLAPP folder and when
            % this exported class is run from the project root.
            sourceRoot = fileparts(mfilename('fullpath'));
            if isfolder(fullfile(sourceRoot, 'logo'))
                appRoot = sourceRoot;
            elseif isfolder(fullfile(sourceRoot, 'Install', 'logo'))
                appRoot = fullfile(sourceRoot, 'Install');
            else
                appRoot = sourceRoot;
            end
        end

        function projectRoot = getProjectRoot_(app)
            sourceRoot = fileparts(mfilename('fullpath'));
            candidates = {app.appPath, fileparts(app.appPath), sourceRoot};
            projectRoot = sourceRoot;

            for candidateIdx = 1:numel(candidates)
                candidate = candidates{candidateIdx};
                if ~isempty(candidate) && isfolder(fullfile(candidate, 'PCA'))
                    projectRoot = candidate;
                    return;
                end
            end
        end

        function ensureRuntimePaths_(app)
            % Always prefer the algorithms shipped beside this app over
            % stale copies that may already be on the MATLAB search path.
            projectRoot = getProjectRoot_(app);
            runtimeFolders = {'OutlierDetection', 'DisError', 'PCA', ...
                'VBPCA', 'VBRPCA', 'FastICA', 'VBICA'};

            for folderIdx = numel(runtimeFolders):-1:1
                folderPath = fullfile(projectRoot, runtimeFolders{folderIdx});
                if isfolder(folderPath)
                    addpath(genpath(folderPath), '-begin');
                end
            end
        end

        function runBundledApp_(app, relativePath)
            sourceRoot = fileparts(mfilename('fullpath'));
            roots = { ...
                app.appPath, ...
                fileparts(app.appPath), ...
                sourceRoot, ...
                fileparts(sourceRoot)};

            [~, launchName, launchExtension] = fileparts(relativePath);
            launchPath = '';

            for rootIdx = 1:numel(roots)
                candidates = { ...
                    fullfile(roots{rootIdx}, relativePath), ...
                    fullfile(roots{rootIdx}, [launchName, launchExtension])};

                for candidateIdx = 1:numel(candidates)
                    if isfile(candidates{candidateIdx})
                        launchPath = candidates{candidateIdx};
                        break;
                    end
                end

                if ~isempty(launchPath)
                    break;
                end
            end

            if isempty(launchPath)
                error('PrideGTS:ModuleNotFound', ...
                    'Unable to locate bundled module: %s', relativePath);
            end

            if strcmpi(launchExtension, '.m')
                addpath(fileparts(launchPath), '-begin');
                feval(launchName);
            else
                run(launchPath);
            end
        end

        function settings = getDrawingSettings(app)
            % Get Usr drawing setting.
            settings.xtick = lower(app.XtickDropDown.Value);           % MJD / year
            settings.marker = lower(app.MarkerDropDown.Value);         % Point / Line / Circle
            settings.color = lower(app.ColorDropDown.Value);    % R / G / B
            settings.lineStyle = lower(app.LineStyleDropDown.Value);   % Solid / Dashed / Dotted


            %If the user selects a point (Point or Circle), disable Linestyle
            if strcmp(settings.marker, 'point') || strcmp(settings.marker, 'circle')
                settings.lineStyle = 'none';
            end
        end

        %% Drawing setting

        function [Mneudata, offsetMJDs, headerInfo] = LoadData(app, Path, filename)
            % Load Hector .mom files into PrideGTS order: [MJD, North, East, Up].
            Mneudata = [];
            offsetMJDs = cell(1, 3);
            headerInfo = repmat(struct('samplingPeriod', NaN, 'offsetMJD', [], 'commentLines', {{}}), 1, 3);

            try
                emptyComponent = struct( ...
                    'exists', false, ...
                    'mjd', [], ...
                    'observation', [], ...
                    'model', [], ...
                    'hasModel', false, ...
                    'headerInfo', struct('samplingPeriod', NaN, 'offsetMJD', [], 'commentLines', {{}}));

                componentData = repmat(emptyComponent, 1, 3);
                globalMinMJD = inf;
                globalMaxMJD = -inf;
                includeModel = false;
                componentFileIds = prideComponentFileIds_(app);

                for prideIdx = 1:3
                    fileIdx = componentFileIds(prideIdx);
                    currentFilePath = fullfile(Path, sprintf("%s_%d.mom", filename, fileIdx));

                    if ~isfile(currentFilePath)
                        error('File not found: %s', currentFilePath);
                    end

                    componentData(prideIdx) = readMomComponent_(app, currentFilePath);
                    if ~componentData(prideIdx).exists
                        error('File not found: %s', currentFilePath);
                    end

                    headerInfo(prideIdx) = componentData(prideIdx).headerInfo;
                    offsetMJDs{prideIdx} = headerInfo(prideIdx).offsetMJD;
                    includeModel = includeModel || componentData(prideIdx).hasModel;

                    if ~isempty(componentData(prideIdx).mjd)
                        globalMinMJD = min(globalMinMJD, min(componentData(prideIdx).mjd));
                        globalMaxMJD = max(globalMaxMJD, max(componentData(prideIdx).mjd));
                    end
                end

                if isinf(globalMinMJD) || isinf(globalMaxMJD)
                    return;
                end

                MJDfull = (globalMinMJD : 1 : globalMaxMJD)';
                nColsPerComponent = 1 + double(includeModel);
                alignedData = nan(numel(MJDfull), 3 * nColsPerComponent);

                for prideIdx = 1:3
                    componentGrid = alignMomComponentToGrid_(app, componentData(prideIdx), MJDfull);
                    col0 = (prideIdx - 1) * nColsPerComponent + 1;
                    alignedData(:, col0) = componentGrid(:, 1);
                    if includeModel
                        alignedData(:, col0 + 1) = componentGrid(:, 2);
                    end
                end

                Mneudata = [MJDfull, alignedData];

            catch ME
                uialert(app.PrideGTSUIFigure, "Data Load Error: " + ME.message, "Error", ...
                    'Icon', 'error', 'Modal', true);
                Mneudata = [];
                offsetMJDs = cell(1, 3);
                headerInfo = repmat(struct('samplingPeriod', NaN, 'offsetMJD', [], 'commentLines', {{}}), 1, 3);
            end
        end

        function [Mneudata, offsetMJDs, headerInfo] = getCachedStationData_(app, Path, filename)
            if isempty(app.stationDataCache) || ~isa(app.stationDataCache, 'containers.Map')
                app.stationDataCache = containers.Map('KeyType', 'char', 'ValueType', 'any');
            end

            cacheKey = getStationCacheKey_(app, Path, filename);
            fileSignature = getStationFileSignature_(app, Path, filename);

            if isKey(app.stationDataCache, cacheKey)
                cachedStation = app.stationDataCache(cacheKey);
                if isfield(cachedStation, 'signature') && strcmp(cachedStation.signature, fileSignature)
                    Mneudata = cachedStation.data;
                    offsetMJDs = cachedStation.offsetMJDs;
                    headerInfo = cachedStation.headerInfo;
                    return;
                end
            end

            [Mneudata, offsetMJDs, headerInfo] = LoadData(app, Path, filename);
            if ~isempty(Mneudata)
                cachedStation = struct();
                cachedStation.signature = fileSignature;
                cachedStation.data = Mneudata;
                cachedStation.offsetMJDs = offsetMJDs;
                cachedStation.headerInfo = headerInfo;
                app.stationDataCache(cacheKey) = cachedStation;
            end
        end

        function cacheKey = getStationCacheKey_(~, Path, filename)
            cacheKey = lower(char(fullfile(Path, char(filename))));
        end

        function fileSignature = getStationFileSignature_(app, Path, filename)
            componentFileIds = prideComponentFileIds_(app);
            parts = strings(1, numel(componentFileIds));

            for signatureIdx = 1:numel(componentFileIds)
                fileIdx = componentFileIds(signatureIdx);
                fileInfo = dir(fullfile(Path, sprintf("%s_%d.mom", filename, fileIdx)));

                if isempty(fileInfo)
                    parts(signatureIdx) = sprintf('%d:missing', fileIdx);
                else
                    parts(signatureIdx) = sprintf('%d:%.12g:%d', fileIdx, fileInfo.datenum, fileInfo.bytes);
                end
            end

            fileSignature = char(strjoin(parts, '|'));
        end

        function plotSignature = getStationPlotSignature_(app, Path, filename)
            settings = getDrawingSettings(app);
            plotSignature = strjoin({ ...
                char(getStationCacheKey_(app, Path, filename)), ...
                char(getStationFileSignature_(app, Path, filename)), ...
                char(settings.xtick), ...
                char(settings.marker), ...
                char(settings.color), ...
                char(settings.lineStyle)}, '|');
        end


        function componentData = readMomComponent_(app, path1)
            componentData = struct( ...
                'exists', false, ...
                'mjd', [], ...
                'observation', [], ...
                'model', [], ...
                'hasModel', false, ...
                'headerInfo', struct('samplingPeriod', NaN, 'offsetMJD', [], 'commentLines', {{}}));

            fileID = fopen(path1, 'r');
            if fileID == -1
                return;
            end

            componentData.exists = true;
            cleaner = onCleanup(@() fclose(fileID)); %#ok<NASGU>

            componentData.headerInfo = parseMomHeaderFromOpenFile_(app, fileID);
            frewind(fileID);

            momdata = textscan(fileID, '%f %f %f', 'CommentStyle', '#');
            componentData.mjd = momdata{1};
            componentData.observation = momdata{2};

            rawModel = momdata{3};
            if ~isempty(rawModel) && any(~isnan(rawModel))
                componentData.model = rawModel;
                componentData.hasModel = true;
            end
        end

        function headerInfo = parseMomHeaderFromOpenFile_(~, fileID)
            headerInfo = struct('samplingPeriod', NaN, 'offsetMJD', [], 'commentLines', {{}});

            while ~feof(fileID)
                line = fgetl(fileID);
                if ~ischar(line)
                    break;
                end

                line = strtrim(line);
                if isempty(line)
                    continue;
                end

                if ~startsWith(line, '#')
                    break;
                end

                headerInfo.commentLines{end + 1} = line; %#ok<AGROW>

                samplingToken = regexpi(line, '^#\s*sampling\s+period\s+([+-]?\d*\.?\d+(?:[eE][+-]?\d+)?)', 'tokens', 'once');
                if ~isempty(samplingToken)
                    headerInfo.samplingPeriod = str2double(samplingToken{1});
                    continue;
                end

                offsetToken = regexpi(line, '^#\s*offset\s+([+-]?\d*\.?\d+(?:[eE][+-]?\d+)?)', 'tokens', 'once');
                if ~isempty(offsetToken)
                    headerInfo.offsetMJD(end + 1) = str2double(offsetToken{1}); %#ok<AGROW>
                end
            end
        end

        function fullData = alignMomComponentToGrid_(~, componentData, MJDfull)
            fullData = nan(numel(MJDfull), 2);
            if isempty(componentData.mjd)
                return;
            end

            [found, loc] = ismember(componentData.mjd, MJDfull);
            if ~any(found)
                return;
            end

            loc = loc(found);
            fullData(loc, 1) = componentData.observation(found);
            if componentData.hasModel
                fullData(loc, 2) = componentData.model(found);
            end
        end

        function fileIds = prideComponentFileIds_(~)
            % PrideGTS stores columns as North/East/Up.
            fileIds = [0, 1, 2];
        end

        function componentNames = prideComponentNames_(~)
            componentNames = {'North', 'East', 'Up'};
        end

        function columns = prideObservationColumns_(~, data)
            if size(data, 2) == 4
                columns = [2, 3, 4];
            elseif size(data, 2) == 7
                columns = [2, 4, 6];
            else
                error('Unsupported number of columns');
            end
        end


        function plotPrideData_(app, full_neudata, offsetMJDs, headerInfo, stationName)
            if isempty(full_neudata)
                return;
            end

            settings = getDrawingSettings(app);
            observationColumns = prideObservationColumns_(app, full_neudata);
            includeModel = size(full_neudata, 2) == 7;
            modelColumns = observationColumns + 1;

            MJD = full_neudata(:, 1);
            if strcmp(settings.xtick, 'mjd')
                xData = MJD;
                xLabelText = 'MJD';
            else
                xData = mjdToDecimalYear_(app, MJD);
                xLabelText = 'Year';
            end

            components = prideComponentNames_(app);
            axesList = {app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes};

            for prideIdx = 1:3
                observedData = full_neudata(:, observationColumns(prideIdx));
                modelData = [];
                if includeModel
                    modelData = full_neudata(:, modelColumns(prideIdx));
                end

                plotPrideComponentAxis_(app, axesList{prideIdx}, xData, observedData, modelData, ...
                    offsetMJDs{prideIdx}, headerInfo(prideIdx), components{prideIdx}, stationName, settings);
            end

            xlabel(app.NorthUIAxes, '');
            xlabel(app.EastUIAxes, '');
            xlabel(app.UpUIAxes, xLabelText);
        end

        function plotPrideComponentAxis_(app, ax, xData, observedData, modelData, componentOffsets, componentHeader, componentName, stationName, settings)
            markerSize = 6;
            lineWidth = 1;
            colorMap = struct('red', '#C00000', 'blue', '#0000CC', 'black', 'k');
            markerMap = struct('point', '.', 'line', 'none');
            lineStyleMap = struct('solid', '-', 'none', 'none');

            plotColor = colorMap.(settings.color);
            plotMarker = markerMap.(settings.marker);
            plotLineStyle = lineStyleMap.(settings.lineStyle);

            delete(findall(ax, 'Tag', 'PrideOutlierMarker'));
            plotHandles = getPrideAxisHandles_(app, ax);
            validObserved = ~isnan(observedData);
            showObserved = (~strcmp(plotMarker, 'none') || ~strcmp(plotLineStyle, 'none')) && any(validObserved);
            observedVisible = 'off';
            if showObserved
                observedVisible = 'on';
            end
            set(plotHandles.observed, ...
                'XData', xData, ...
                'YData', observedData, ...
                'Marker', plotMarker, ...
                'Color', plotColor, ...
                'LineStyle', plotLineStyle, ...
                'LineWidth', lineWidth, ...
                'MarkerSize', markerSize, ...
                'Visible', observedVisible);

            hasModel = ~isempty(modelData) && any(~isnan(modelData));
            if hasModel
                set(plotHandles.model, ...
                    'XData', xData, ...
                    'YData', modelData, ...
                    'Visible', 'on');
            else
                set(plotHandles.model, ...
                    'XData', NaN, ...
                    'YData', NaN, ...
                    'Visible', 'off');
            end

            yLimits = getPrideYLimits_(app, observedData, modelData);
            xLimits = getPrideXLimits_(app, xData);
            if ~isempty(xLimits)
                ax.XLim = xLimits;
            end
            ax.YLim = yLimits;

            [offsetX, offsetY] = buildPrideOffsetLineData_(app, componentOffsets, settings.xtick, yLimits);
            hasOffsets = ~isempty(offsetX);
            if hasOffsets
                set(plotHandles.offset, ...
                    'XData', offsetX, ...
                    'YData', offsetY, ...
                    'Visible', 'on');
            else
                set(plotHandles.offset, ...
                    'XData', NaN, ...
                    'YData', NaN, ...
                    'Visible', 'off');
            end

            applyPrideAxisTitle_(app, ax, componentName, stationName, componentHeader, componentOffsets);
            legend(ax, "off");

            ax.Box = 'on';
        end

        function plotHandles = getPrideAxisHandles_(app, ax)
            if isappdata(ax, 'PridePlotHandles')
                plotHandles = getappdata(ax, 'PridePlotHandles');
                if isPridePlotHandlesValid_(app, plotHandles)
                    return;
                end
            end

            cla(ax);
            hold(ax, 'on');

            plotHandles = struct();
            plotHandles.observed = plot(ax, NaN, NaN, ...
                'Marker', '.', ...
                'Color', 'k', ...
                'LineStyle', 'none', ...
                'LineWidth', 1, ...
                'MarkerSize', 6);
            plotHandles.model = plot(ax, NaN, NaN, ...
                'Color', [0.85 0.2 0.1], ...
                'LineWidth', 1.5, ...
                'DisplayName', 'Model', ...
                'Visible', 'off');
            plotHandles.offset = plot(ax, NaN, NaN, ...
                'Color', 'blue', ...
                'LineWidth', 1, ...
                'LineStyle', '--', ...
                'DisplayName', 'Offset', ...
                'Visible', 'off');

            hold(ax, 'off');
            setappdata(ax, 'PridePlotHandles', plotHandles);
        end

        function tf = isPridePlotHandlesValid_(~, plotHandles)
            requiredFields = {'observed', 'model', 'offset'};
            tf = isstruct(plotHandles);
            if ~tf
                return;
            end

            for fieldIdx = 1:numel(requiredFields)
                fieldName = requiredFields{fieldIdx};
                tf = isfield(plotHandles, fieldName) && isvalid(plotHandles.(fieldName));
                if ~tf
                    return;
                end
            end
        end

        function yLimits = getPrideYLimits_(~, observedData, modelData)
            yValues = observedData(isfinite(observedData));
            if ~isempty(modelData)
                yValues = [yValues; modelData(isfinite(modelData))];
            end

            if isempty(yValues)
                yLimits = [0, 1];
                return;
            end

            minY = min(yValues);
            maxY = max(yValues);
            if minY == maxY
                padding = max(1, abs(minY) * 0.05);
            else
                padding = (maxY - minY) * 0.05;
            end
            yLimits = [minY - padding, maxY + padding];
        end

        function xLimits = getPrideXLimits_(~, xData)
            xValues = xData(isfinite(xData));
            if isempty(xValues)
                xLimits = [];
                return;
            end

            minX = min(xValues);
            maxX = max(xValues);
            if minX == maxX
                padding = 0.5;
            else
                padding = (maxX - minX) * 0.01;
            end
            xLimits = [minX - padding, maxX + padding];
        end

        function [offsetX, offsetY] = buildPrideOffsetLineData_(app, componentOffsets, xtickMode, yLimits)
            offsetX = [];
            offsetY = [];
            if isempty(componentOffsets)
                return;
            end

            if strcmp(xtickMode, 'mjd')
                xValues = componentOffsets(:);
            else
                xValues = mjdToDecimalYear_(app, componentOffsets(:));
            end

            xValues = xValues(isfinite(xValues));
            if isempty(xValues)
                return;
            end

            nOffsets = numel(xValues);
            offsetX = reshape([xValues.'; xValues.'; nan(1, nOffsets)], 1, []);
            offsetY = repmat([yLimits(1), yLimits(2), nan], 1, nOffsets);
        end

        function applyPrideAxisTitle_(~, ax, componentName, stationName, ~, ~)
            if strcmp(componentName, 'North') && ~isempty(stationName)
                title(ax, stationName, ...
                    'FontSize', 16, ...
                    'FontName', 'Times New Roman', ...
                    'Interpreter', 'none', ...
                    'FontWeight', 'bold');
            else
                title(ax, '');
            end
        end

        function years = mjdToDecimalYear_(~, mjd)
            years = 1970 + (mjd - 40587 + 0.5) / 365.25;
        end


        % This function retrieves and validates .mat data.
        function [Src_data, MatNameWithTag, MatDirectory] = GetMatData(app)
            Src_data = []; % Initialize Src_data as empty

            try
                [MatNameWithTag, MatDirectory] = uigetfile({'*.mat','Mat Files'},...
                    'Select a mat file');
                if MatNameWithTag == 0
                    errorDlg(app, 'You should select a Mat File');
                    return;
                end

                % Load data
                Src_data = load(fullfile(MatDirectory, MatNameWithTag));

                filedNames = fieldnames(Src_data);
                if length(filedNames) ~= 1
                    errorDlg(app, 'There should be only one struct in Mat File!');
                    Src_data = [];
                    return;
                end

                Src_data = Src_data.(filedNames{1});

                % Check required fields
                miss_InterpFields = {'day', 'lat', 'lon', 'site', 'dn', 'de', 'du'};
                for i = 1:length(miss_InterpFields)
                    if ~isfield(Src_data, miss_InterpFields{i})
                        errorDlg(app, strcat(miss_InterpFields{i}, ' Field Not Found!'));
                        Src_data = [];
                        return;
                    end
                end

                % Check day field
                if isempty(Src_data.day)
                    errorDlg(app, 'There is no data in Mat File!');
                    Src_data = [];
                    return;
                elseif ~isvector(Src_data.day)
                    errorDlg(app, 'day field value must be a vector!');
                    Src_data = [];
                    return;
                end

                % Check site field
                if isempty(Src_data.site)
                    errorDlg(app, 'There is no site in Mat File!');
                    Src_data = [];
                    return;
                elseif ~ischar(Src_data.site)
                    errorDlg(app, 'Site Name must be char value!');
                    Src_data = [];
                    return;
                end

                lenDay = length(Src_data.day);
                numSite = size(Src_data.site, 1);

                % Check N/E/U data
                if isempty(Src_data.dn)
                    errorDlg(app, 'There is no data in N direction!');
                    Src_data = [];
                    return;
                elseif ~ismatrix(Src_data.dn)
                    errorDlg(app, 'dn field value must be a two-dimensional matrix!');
                    Src_data = [];
                    return;
                elseif size(Src_data.dn, 1) ~= lenDay || size(Src_data.dn, 2) ~= numSite
                    errorDlg(app, {'dn field value size does not match';...
                        'length of day or site!'});
                    Src_data = [];
                    return;
                end

                if isempty(Src_data.de)
                    errorDlg(app, 'There is no data in E direction!');
                    Src_data = [];
                    return;
                elseif ~ismatrix(Src_data.de)
                    errorDlg(app, 'de field value must be a two-dimensional matrix!');
                    Src_data = [];
                    return;
                elseif size(Src_data.de, 1) ~= lenDay || size(Src_data.de, 2) ~= numSite
                    errorDlg(app, {'de field value size does not match';...
                        'length of day or site!'});
                    Src_data = [];
                    return;
                end

                if isempty(Src_data.du)
                    errorDlg(app, 'There is no data in U direction!');
                    Src_data = [];
                    return;
                elseif ~ismatrix(Src_data.du)
                    errorDlg(app, 'du field value must be a two-dimensional matrix!');
                    Src_data = [];
                    return;
                elseif size(Src_data.du, 1) ~= lenDay || size(Src_data.du, 2) ~= numSite
                    errorDlg(app, {'du field value size does not match';...
                        'length of day or site!'});
                    Src_data = [];
                    return;
                end
            catch ME
                Src_data = [];
                MatNameWithTag = [];
                MatDirectory = [];
                errorDlg(app, sprintf('Failed to import MAT file:\n%s', ME.message));
                return;
            end
        end

        function errorDlg(~, errorMsg)
            msgbox(errorMsg, 'Error', 'error');
        end


        % ReconCME
        function cme = rcme1(app, Components, SpatialResponseMatrix)
            % RCME1 Reconstruct Common Mode Error (CME) from decomposition results.
            % Syntax: cme = rcme1(app, Components, SpatialResponseMatrix)
            %
            % Mathematical Principle:
            %   CME = \sum_{i=1}^{k} PC_i \cdot SR_i^T
            %   where PC is the Principal Component and SR is the Spatial Response.
            %
            % Inputs:
            %   Components            - Selected Principal Components matrix [Epochs x k]
            %   SpatialResponseMatrix - Corresponding Spatial Response matrix [Stations x k]
            %
            % Output:
            %   cme                   - Reconstructed CME time series [Epochs x Stations]

            cme = Components * SpatialResponseMatrix';
        end

        % AverageResponseRate
        function [avg_sr, norm_coeff] = rsr(app, coeff, threshold)
            % RSR Calculate the Average and Normalized Spatial Response Rate.
            % This function helps identify the spatial consistency of filtered components.
            %
            % Inputs:
            %   coeff     - Original spatial response coefficients [Stations x k]
            %   threshold - Minimal value to avoid division by zero (default: 1e-5)
            %
            % Outputs:
            %   avg_sr     - Average spatial response (%) for each component
            %   norm_coeff - Normalized coefficients scaled to 100%

            if nargin < 3
                threshold = 1e-5;
            end

            num_components = size(coeff, 2);
            avg_sr = zeros(1, num_components);
            norm_coeff = zeros(size(coeff));

            for k = 1:num_components
                max_abs = max(abs(coeff(:, k)));
                if max_abs < threshold
                    avg_sr(k) = 0;
                else
                    % Perform normalization and scale to percentage for geophysical interpretation
                    norm_coeff_k = (coeff(:, k) / max_abs) * 100;
                    norm_coeff(:, k) = norm_coeff_k;
                    avg_sr(k) = mean(norm_coeff_k);
                end
            end
        end

        function Correlation = EachDisCorr(app, X, refStation)
            NumCol = size(X, 2);
            Correlation = zeros(NumCol, 1);
            for i = 1:NumCol
                Correlation(i) = distcorr(X(:, refStation), X(:, i));
            end
        end


        function RMS = EachRMS(app, X, filteredX)
            RMS = struct;
            numcol = size(X, 2);
            ratio = zeros(numcol, 1);
            beforeRms = zeros(numcol, 1);
            afterRms = zeros(numcol, 1);

            for colum = 1:numcol
                index = find(~isnan(X(:, colum)) & ~isnan(filteredX(:, colum)));

                if length(index) < 10
                    beforeRms(colum) = NaN;
                    afterRms(colum) = NaN;
                    ratio(colum) = 0;
                    continue;
                end

                beforeValues = X(index, colum);
                afterValues = filteredX(index, colum);
                beforeRms(colum) = sqrt(mean(beforeValues .^ 2));
                afterRms(colum) = sqrt(mean(afterValues .^ 2));

                if beforeRms(colum) ~= 0
                    ratio(colum) = ((beforeRms(colum) - afterRms(colum)) / beforeRms(colum)) * 100;
                else
                    ratio(colum) = 0;
                end
            end
            RMS.ratio = ratio;
            RMS.beforeRms = beforeRms;
            RMS.afterRms = afterRms;
        end

        function stationName = getStationNameFromMomFile_(~, fileName)
            [~, baseName, ext] = fileparts(char(fileName));

            if strcmpi(ext, '.mom')
                tokens = regexp(baseName, '^(.*)_\d+$', 'tokens', 'once');
                if ~isempty(tokens)
                    stationName = tokens{1};
                    return;
                end
            end

            stationName = baseName;
        end

        function closeDialogIfValid_(~, dialogHandle)
            if ~isempty(dialogHandle) && isvalid(dialogHandle)
                close(dialogHandle);
            end
        end


        function [residualTimeSeriesData, ok] = getSelectedResidualData_(app)
            residualTimeSeriesData = [];
            ok = false;

            if isempty(app.residualData) || ~isstruct(app.residualData)
                uialert(app.PrideGTSUIFigure, ...
                    'Please import residual data before running the analysis.', ...
                    'Data Import Error', 'Icon', 'warning');
                return;
            end

            switch app.ComponentDropDown.Value
                case 'North'
                    fieldName = 'dn';
                case 'East'
                    fieldName = 'de';
                case 'Up'
                    fieldName = 'du';
                otherwise
                    uialert(app.PrideGTSUIFigure, ...
                        'Please select a valid component.', ...
                        'Component Error', 'Icon', 'warning');
                    return;
            end

            if ~isfield(app.residualData, fieldName) || ...
                    isempty(app.residualData.(fieldName))
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('Residual data for %s is empty.', ...
                    app.ComponentDropDown.Value), ...
                    'Data Import Error', 'Icon', 'warning');
                return;
            end

            residualTimeSeriesData = app.residualData.(fieldName);
            ok = true;
        end

        function result = runSelectedFilter_(app, residualTimeSeriesData, numReconPcs)
            switch app.SelectFilterDropDown.Value
                case 'PCA'
                    result = RunPCA(residualTimeSeriesData, numReconPcs);

                case 'VBPCA'
                    result = RunVBPCA(residualTimeSeriesData, ...
                        app.vbpcaNumPCSpinner.Value, numReconPcs, ...
                        app.HypUpdateSpinner.Value, ...
                        app.MaxItersSpinner.Value, ...
                        app.DisplayCheckBox.Value);

                case 'VBRPCA'
                    result = RunVBRPCA(residualTimeSeriesData, [], ...
                        numReconPcs, app.MaxItersSpinner_2.Value, ...
                        app.DisplayCheckBox_2.Value, ...
                        app.InitializationDropDown.Value);

                case 'FastICA'
                    result = RunFastICA(residualTimeSeriesData, numReconPcs);

                case 'VBICA'
                    if app.GaussModelSpinner.Value < 2
                        error('PrideGTS:InvalidGaussianModelCount', ...
                            'VBICA requires between 2 and 5 Gaussian mixture models.');
                    end
                    result = RunvbICA(residualTimeSeriesData, numReconPcs, ...
                        app.GaussModelSpinner.Value, ...
                        app.SourceModelDropDown.Value, ...
                        app.DisplayCheckBox_3.Value, ...
                        app.ToleranceEditField.Value, ...
                        app.MaxItersSpinner_3.Value, ...
                        app.ARDCheckBox.Value);

                otherwise
                    error('PrideGTS:UnsupportedFilter', ...
                        'Unsupported filter method: %s', ...
                        app.SelectFilterDropDown.Value);
            end
        end

        function [numReconPcs, refStation, ok] = validateSelectionBounds_(app, maxComponents, numStations)
            numReconPcs = app.PCsSpinner.Value;
            refStation = app.RefStationSpinner.Value;
            ok = false;

            if ~isscalar(numReconPcs) || ~isfinite(numReconPcs) || ...
                    numReconPcs < 1 || numReconPcs ~= fix(numReconPcs) || ...
                    numReconPcs > maxComponents
                uialert(app.PrideGTSUIFigure, ...
                    sprintf(['The number of reconstructed components must be ' ...
                    'an integer from 1 to %d.'], maxComponents), ...
                    'Component Error', 'Icon', 'warning');
                return;
            end

            if ~isscalar(refStation) || ~isfinite(refStation) || ...
                    refStation < 1 || refStation ~= fix(refStation) || ...
                    refStation > numStations
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('The reference station must be an integer from 1 to %d.', ...
                    numStations), ...
                    'Reference Station Error', 'Icon', 'warning');
                return;
            end

            ok = true;
        end

        function [numReconPcs, refStation, ok] = validateAnalysisSelection_(app)
            numReconPcs = [];
            refStation = [];
            ok = false;

            requiredFields = {'Components', 'SpatialResponsesMatrix', ...
                'OriginTimeSeries'};
            if isempty(app.filterResult) || ~isstruct(app.filterResult) || ...
                    ~all(isfield(app.filterResult, requiredFields))
                uialert(app.PrideGTSUIFigure, ...
                    'Please run the filter calculation first.', ...
                    'Analysis Result Error', 'Icon', 'warning');
                return;
            end

            maxComponents = min(size(app.filterResult.Components, 2), ...
                size(app.filterResult.SpatialResponsesMatrix, 2));
            numStations = size(app.filterResult.OriginTimeSeries, 2);
            [numReconPcs, refStation, ok] = validateSelectionBounds_(app, ...
                maxComponents, numStations);
            if ~ok
                return;
            end

            if size(app.filterResult.Components, 1) ~= ...
                    size(app.filterResult.OriginTimeSeries, 1) || ...
                    size(app.filterResult.SpatialResponsesMatrix, 1) ~= numStations
                ok = false;
                uialert(app.PrideGTSUIFigure, ...
                    'The decomposition result dimensions do not match the time series.', ...
                    'Analysis Result Error', 'Icon', 'error');
                return;
            end

        end

        function [cme, filteredData, refStation, ok] = reconstructCurrentCME_(app)
            cme = [];
            filteredData = [];
            [numReconPcs, refStation, ok] = validateAnalysisSelection_(app);
            if ~ok
                return;
            end

            cme = rcme1(app, ...
                app.filterResult.Components(:, 1:numReconPcs), ...
                app.filterResult.SpatialResponsesMatrix(:, 1:numReconPcs));
            filteredData = app.filterResult.OriginTimeSeries - cme;

            % Keep every downstream calculation synchronized with the selected PCs.
            app.filterResult.FilteredData = filteredData;
            assignin('base', 'CME', cme);
            assignin('base', 'Result', app.filterResult);
        end

        function plotContributions_(app)

            contribution = app.filterResult.Contribution(:).';
            cumulativeContribution = cumsum(contribution);
            x = 1:numel(contribution);

            ax = app.ContributionUIAxes;

            hold(ax, 'off');
            delete(allchild(ax));

            if isempty(contribution)
                return;
            end

            yyaxis(ax, 'left');

            bar(ax, x, contribution, 0.72, ...
                'FaceColor', [0.31 0.55 0.76], ...
                'EdgeColor', [0.16 0.24 0.30], ...
                'LineWidth', 1.0);

            text(ax, x(1), contribution(1), ...
                sprintf('%.1f%%', contribution(1)), ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'bottom', ...
                'FontName', 'Times New Roman', ...
                'FontWeight', 'bold', ...
                'FontSize', 11, ...
                'Color', [0 0 0]);

            yyaxis(ax, 'right');

            plot(ax, x, cumulativeContribution, '-o', ...
                'Color', [1.00 0.45 0.05], ...
                'MarkerFaceColor', [1.00 0.45 0.05], ...
                'MarkerEdgeColor', [1.00 0.45 0.05], ...
                'LineWidth', 2.0, ...
                'MarkerSize', 5.5);

        end

        function plotReconstructedCME_(app, cme)
            ax = app.CMEUIAxes;

            % Remove every object from the previous reconstruction.  Using
            % allchild also removes objects whose handles are hidden.
            hold(ax, 'off');
            delete(allchild(ax));
            colorbar(ax, 'off');

            finiteCme = cme(isfinite(cme));

            numEpochs = size(cme, 1);
            numStations = size(cme, 2);

            imageHandle = imagesc(ax, ...
                1:numEpochs, 1:numStations, cme');
            imageHandle.AlphaData = isfinite(cme');

            if isempty(finiteCme)
                colorLimit = 1;
            else
                sortedMagnitude = sort(abs(finiteCme));
                colorIndex = max(1, ceil(0.98 * numel(sortedMagnitude)));
                colorLimit = sortedMagnitude(colorIndex);

                if ~isfinite(colorLimit) || colorLimit <= eps
                    colorLimit = max(sortedMagnitude);
                end
                if ~isfinite(colorLimit) || colorLimit <= eps
                    colorLimit = 1;
                end
            end

            clim(ax, [-colorLimit, colorLimit]);

            % Match the axes limits to the outer pixel edges.  This avoids
            % the blank band produced by MATLAB's rounded automatic limits.
            xlim(ax, [0.5, numEpochs + 0.5]);
            ylim(ax, [0.5, numStations + 0.5]);
            ax.YDir = 'reverse';

            colorAnchors = [ ...
                0.230, 0.299, 0.754; ...
                0.970, 0.970, 0.970; ...
                0.706, 0.016, 0.150];

            colorMap = interp1( ...
                [0, 0.5, 1], colorAnchors, ...
                linspace(0, 1, 256), 'linear');

            colormap(ax, colorMap);

            colorBar = colorbar(ax, 'eastoutside');
            colorBar.Label.String = 'CME (mm)';
        end

        function plotCmeAmplitudeDistribution_(app, cme)
            ax = app.CMEAmplitudeDistributionUIAxes;

            % cla(ax) does not remove the bars/lines below because their
            % HandleVisibility is off.  Delete all children so changing the
            % reconstruction order always replaces the previous result.
            hold(ax, 'off');
            delete(allchild(ax));

            cmeValues = cme(isfinite(cme));
            cmeValues = cmeValues(:);

            if isempty(cmeValues)
                xlim(ax, [-1, 1]);
                ylim(ax, [0, 1]);
                text(ax, 0.5, 0.5, 'No finite CME data', ...
                    'Units', 'normalized', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');
                return;
            end

            sortedValues = sort(cmeValues);
            probabilities = [0.005, 0.05, 0.50, 0.95, 0.995];
            positions = 1 + (numel(sortedValues) - 1) * probabilities;
            lowerIndices = floor(positions);
            upperIndices = ceil(positions);
            interpolationWeights = positions - lowerIndices;
            lowerValues = reshape(sortedValues(lowerIndices), 1, []);
            upperValues = reshape(sortedValues(upperIndices), 1, []);
            percentileValues = lowerValues .* (1 - interpolationWeights) + ...
                upperValues .* interpolationWeights;

            lower95 = percentileValues(2);
            medianCme = percentileValues(3);
            upper95 = percentileValues(4);

            displayLimit = max(abs(percentileValues([1, 5])));
            if ~isfinite(displayLimit) || displayLimit <= eps
                displayLimit = max(abs(cmeValues));
            end
            if ~isfinite(displayLimit) || displayLimit <= eps
                displayLimit = 1;
            end

            displayRange = [-displayLimit, displayLimit];
            displayValues = cmeValues( ...
                cmeValues >= displayRange(1) & cmeValues <= displayRange(2));

            numBins = min(32, max(12, ...
                round(sqrt(numel(displayValues)) / 5)));
            binEdges = linspace(displayRange(1), displayRange(2), numBins + 1);
            binDensity = histcounts(displayValues, binEdges, ...
                'Normalization', 'pdf');
            binCenters = (binEdges(1:end - 1) + binEdges(2:end)) / 2;

            kernelX = -3:3;
            smoothingKernel = exp(-0.5 * (kernelX / 1.15) .^ 2);
            smoothingKernel = smoothingKernel / sum(smoothingKernel);
            smoothDensity = conv(binDensity, smoothingKernel, 'same');

            maximumDensity = max([binDensity(:); smoothDensity(:)]);
            if ~isfinite(maximumDensity) || maximumDensity <= 0
                maximumDensity = 1;
            end
            yMaximum = 1.10 * maximumDensity;

            hold(ax, 'on');

            patch(ax, ...
                [lower95, upper95, upper95, lower95], ...
                [0, 0, yMaximum, yMaximum], ...
                [0.81, 0.91, 0.96], ...
                'FaceAlpha', 0.35, ...
                'EdgeColor', 'none', ...
                'HandleVisibility', 'off');

            bar(ax, binCenters, binDensity, 1, ...
                'FaceColor', [0.62, 0.79, 0.88], ...
                'FaceAlpha', 0.82, ...
                'EdgeColor', 'white', ...
                'HandleVisibility', 'off');

            plot(ax, binCenters, smoothDensity, ...
                'Color', [0.09, 0.41, 0.67], ...
                'LineWidth', 1.8, ...
                'HandleVisibility', 'off');

            plot(ax, [0, 0], [0, yMaximum], '--', ...
                'Color', [0.40, 0.40, 0.40], ...
                'LineWidth', 0.9, ...
                'HandleVisibility', 'off');

            plot(ax, [medianCme, medianCme], [0, yMaximum], '-', ...
                'Color', [0.82, 0.29, 0.36], ...
                'LineWidth', 1.2, ...
                'HandleVisibility', 'off');

            plot(ax, [lower95, lower95], [0, yMaximum], ':', ...
                'Color', [0.36, 0.61, 0.76], ...
                'LineWidth', 1.0, ...
                'HandleVisibility', 'off');

            plot(ax, [upper95, upper95], [0, yMaximum], ':', ...
                'Color', [0.36, 0.61, 0.76], ...
                'LineWidth', 1.0, ...
                'HandleVisibility', 'off');

            hold(ax, 'off');

            % Fix the visible range for the current reconstruction only.
            xlim(ax, displayRange);
            ylim(ax, [0, yMaximum]);

            text(ax, 0.97, 0.95, ...
                {sprintf('Median: %.2f mm', medianCme), ...
                sprintf('95%%: [%.2f, %.2f]', lower95, upper95)}, ...
                'Units', 'normalized', ...
                'HorizontalAlignment', 'right', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 7.5, ...
                'Color', [0.20, 0.35, 0.45], ...
                'BackgroundColor', 'white', ...
                'EdgeColor', [0.62, 0.79, 0.88], ...
                'Margin', 2);
        end

        function stationLabel = getAnalysisStationLabel_(app, stationIndex)
            stationLabel = sprintf('Station %d', stationIndex);

            if isstruct(app.residualData) && ...
                    isfield(app.residualData, 'site') && ...
                    size(app.residualData.site, 1) >= stationIndex
                stationName = strtrim(app.residualData.site(stationIndex, :));
                if ~isempty(stationName)
                    stationLabel = sprintf('%s (Station %d)', ...
                        stationName, stationIndex);
                end
            end
        end

        function timeYears = getAnalysisTimeYears_(app, numEpochs)
            % Fall back to daily sampling when the imported day vector is unavailable.
            timeDays = (0:numEpochs - 1)';

            if isstruct(app.residualData) && ...
                    isfield(app.residualData, 'day') && ...
                    isnumeric(app.residualData.day) && ...
                    numel(app.residualData.day) == numEpochs
                importedDays = double(app.residualData.day(:));
                firstValidDay = find(isfinite(importedDays), 1);
                if ~isempty(firstValidDay)
                    timeDays = importedDays - importedDays(firstValidDay);
                end
            end

            timeYears = timeDays / 365.25;
        end
        function plotResidualSpectra_(app, filteredData, refStation)
            originalSeries = ...
                app.filterResult.OriginTimeSeries(:, refStation);
            filteredSeries = filteredData(:, refStation);
            timeYears = getAnalysisTimeYears_(app, numel(originalSeries));

            ax = app.psdUIAxes;
            cla(ax);
            hold(ax, 'on');

            series = {originalSeries, filteredSeries};
            colors = { ...
                [0.9765, 0.2549, 0.2667], ...
                [0.0549, 0.6784, 0.4118]};

            for seriesIndex = 1:2
                values = series{seriesIndex};
                validTime = isfinite(values) & isfinite(timeYears);

                if nnz(validTime) < 3
                    continue;
                end

                sampleTime = timeYears(validTime);
                sampleValues = values(validTime);
                [sampleTime, sortOrder] = sort(sampleTime);
                sampleValues = sampleValues(sortOrder);
                [sampleTime, uniqueIndices] = unique(sampleTime, 'stable');
                sampleValues = sampleValues(uniqueIndices);

                if numel(sampleTime) < 3
                    continue;
                end

                trendCoefficients = polyfit(sampleTime, sampleValues, 1);
                detrendedValues = sampleValues - ...
                    polyval(trendCoefficients, sampleTime);
                
                [pxx, frequency] = pridegts_lomb_psd( ...
                    detrendedValues, sampleTime);

                validSpectrum = ...
                    frequency > 0 & ...
                    pxx > 0 & ...
                    isfinite(frequency) & ...
                    isfinite(pxx);

                if any(validSpectrum)
                    plot(ax, ...
                        frequency(validSpectrum), ...
                        pxx(validSpectrum), ...
                        'Color', colors{seriesIndex}, ...
                        'LineWidth', 1.2);
                end
            end
            hold(ax, 'off');
            ax.XScale = 'log';
            ax.YScale = 'log';
            grid(ax, 'on');

            stationLabel = getAnalysisStationLabel_(app, refStation);
            title(ax, {stationLabel, ...
                'Detrended-TS PSD Before/After Filtering'}, ...
                'Interpreter', 'none', 'FontSize', 8);
        end

        function plotSpatialResponses_(app)
            [averageResponse, normalizedResponse] = rsr(app, ...
                app.filterResult.SpatialResponsesMatrix);
            assignin('base', 'SR', normalizedResponse);

            cla(app.SpatialResponseUIAxes);
            plot(app.SpatialResponseUIAxes, averageResponse, ...
                'LineStyle', '-', 'Color', 'black', 'LineWidth', 1.5, ...
                'Marker', 'o', 'MarkerSize', 8);
        end

           function plotDistanceCorrelations_(app, filteredData, refStation)
            if app.DisplayHeatMapButton.Value
                % Reuse the heatmap column instead of computing it twice.
                [beforeCorrelation, afterCorrelation] = ...
                    showCorrelationHeatmap_(app, filteredData, refStation);
                if isempty(beforeCorrelation) || isempty(afterCorrelation)
                    return;
                end
            else
                progress = uiprogressdlg(app.PrideGTSUIFigure, ...
                    'Icon', app.iconPath, 'Cancelable', 'on', ...
                    'Title', 'Calculating Correlations');
                progressCleanup = onCleanup( ...
                    @() closeDialogIfValid_(app, progress));

                progress.Value = 0;
                progress.Message = 'Computing correlations before filtering...';
                beforeCorrelation = EachDisCorr(app, ...
                    app.filterResult.OriginTimeSeries, refStation);
                if progress.CancelRequested
                    return;
                end

                progress.Value = 0.5;
                progress.Message = 'Computing correlations after filtering...';
                afterCorrelation = EachDisCorr(app, filteredData, refStation);
                progress.Value = 1;
            end

            cla(app.CorrelationUIAxes);
            plot(app.CorrelationUIAxes, beforeCorrelation, ...
                'LineStyle', '-', 'Color', '#f94144', ...
                'LineWidth', 1.5, 'Marker', 'o', 'MarkerSize', 8, ...
                'DisplayName', 'Before filtering');
            hold(app.CorrelationUIAxes, 'on');
            plot(app.CorrelationUIAxes, afterCorrelation, ...
                'LineStyle', '-', 'Color', '#0ead69', ...
                'LineWidth', 1.5, 'Marker', 's', 'MarkerSize', 7, ...
                'DisplayName', 'After filtering');
            hold(app.CorrelationUIAxes, 'off');
            legend(app.CorrelationUIAxes, 'show', 'Location', 'northeast');
        end

        function [beforeCorrelation, afterCorrelation] = showCorrelationHeatmap_(app, filteredData, refStation)
            beforeCorrelation = [];
            afterCorrelation = [];
            originalData = app.filterResult.OriginTimeSeries;
            numStations = size(originalData, 2);
            progress = uiprogressdlg(app.PrideGTSUIFigure, ...
                'Icon', app.iconPath, 'Cancelable', 'on', ...
                'Title', 'Calculating Correlation Heatmap');
            progressCleanup = onCleanup( ...
                @() closeDialogIfValid_(app, progress));

            correlationMatrices = cell(1, 2);
            dataSets = {originalData, filteredData};
            phaseNames = {'before', 'after'};
            totalSteps = numStations * (numStations + 1);
            completedSteps = 0;

            for phase = 1:2
                correlationMatrix = zeros(numStations);
                for station = 1:numStations
                    progress.Message = sprintf( ...
                        'Calculating %s correlation for station %d/%d...', ...
                        phaseNames{phase}, station, numStations);
                    for otherStation = station:numStations
                        if progress.CancelRequested
                            return;
                        end

                        % Distance correlation is symmetric: compute each
                        % unordered station pair once, including the diagonal.
                        coefficient = distcorr( ...
                            dataSets{phase}(:, station), ...
                            dataSets{phase}(:, otherStation));
                        correlationMatrix(otherStation, station) = coefficient;
                        correlationMatrix(station, otherStation) = coefficient;
                        completedSteps = completedSteps + 1;
                        progress.Value = completedSteps / totalSteps;
                    end
                end
                correlationMatrices{phase} = correlationMatrix;
            end

            if progress.CancelRequested
                return;
            end
            beforeCorrelation = correlationMatrices{1}(:, refStation);
            afterCorrelation = correlationMatrices{2}(:, refStation);
            combinedCorrelation = tril(correlationMatrices{1}) + ...
                triu(correlationMatrices{2}, 1);
            assignin('base', 'HeatMap', combinedCorrelation);

            % Hide the uninformative self-correlation diagonal in the display.
            displayCorrelation = combinedCorrelation;
            displayCorrelation(1:numStations + 1:end) = NaN;

            progress.Message = 'Generating heatmap visualization...';
            figureHandle = figure('Name', 'Correlation Heatmap', ...
                'NumberTitle', 'off', 'Color', 'white');
            setappdata(figureHandle, 'PrideGTSOwner', app.PrideGTSUIFigure);
            heatmapHandle = heatmap(figureHandle, displayCorrelation, ...
                'Title', ['Distance correlation: before filtering (lower) / ' ...
                'after filtering (upper)'], ...
                'XLabel', 'Station', 'YLabel', 'Station');
            heatmapHandle.Colormap = correlationColormap_(app, 256);
            heatmapHandle.ColorLimits = [0, 1];
            heatmapHandle.ColorbarVisible = 'on';
            heatmapHandle.CellLabelColor = 'none';
            heatmapHandle.GridVisible = 'off';
            heatmapHandle.MissingDataColor = [0.92, 0.93, 0.95];
            heatmapHandle.MissingDataLabel = 'Self';
            heatmapHandle.FontName = 'Times New Roman';
            heatmapHandle.FontSize = 11;
        end

        function colorMap = correlationColormap_(~, numColors)
            % Color-blind-friendly sequential palette for coefficients in [0, 1].
            anchors = [0.055, 0.118, 0.247; ...
                0.063, 0.302, 0.459; ...
                0.055, 0.510, 0.525; ...
                0.318, 0.690, 0.478; ...
                0.761, 0.820, 0.357; ...
                0.992, 0.906, 0.329];
            anchorPositions = linspace(0, 1, size(anchors, 1));
            colorMap = interp1(anchorPositions, anchors, ...
                linspace(0, 1, numColors), 'pchip');
            colorMap = min(max(colorMap, 0), 1);
        end

        function plotRmsDiagnostics_(app, filteredData)
            rmsResult = EachRMS(app, ...
                app.filterResult.OriginTimeSeries, filteredData);
            assignin('base', 'RMS', rmsResult);

            cla(app.RMSUIAxes);
            plot(app.RMSUIAxes, rmsResult.beforeRms, ...
                'LineStyle', '-', 'Color', '#f94144', ...
                'LineWidth', 1.5, 'Marker', 'o', 'MarkerSize', 8, ...
                'DisplayName', 'Before filtering');
            hold(app.RMSUIAxes, 'on');
            plot(app.RMSUIAxes, rmsResult.afterRms, ...
                'LineStyle', '-', 'Color', '#0ead69', ...
                'LineWidth', 1.5, 'Marker', 's', 'MarkerSize', 7, ...
                'DisplayName', 'After filtering');
            hold(app.RMSUIAxes, 'off');
            legend(app.RMSUIAxes, 'show', 'Location', 'northeast');
        end

        function refreshCmeAnalysisPlots_(app)
            [cme, filteredData, refStation, ok] = reconstructCurrentCME_(app);
            if ~ok
                return;
            end

            plotReconstructedCME_(app, cme);
            plotSelectedStationCME_(app, cme, refStation);
            plotCmeAmplitudeDistribution_(app, cme);
            plotResidualSpectra_(app, filteredData, refStation);
            plotSpatialResponses_(app);
            plotDistanceCorrelations_(app, filteredData, refStation);
            plotRmsDiagnostics_(app, filteredData);
        end

        function refreshCorrelationPlots_(app)
            [cme, filteredData, refStation, ok] = reconstructCurrentCME_(app);
            if ~ok
                return;
            end

            plotDistanceCorrelations_(app, filteredData, refStation);
            plotSelectedStationCME_(app, cme, refStation);
            plotResidualSpectra_(app, filteredData, refStation);
        end

        function importAnalysisData_(app, targetProperty, baseVariable, displayName)
            try
                [importedData, fileName, directory] = GetMatData(app);
                if isempty(importedData)
                    uialert(app.PrideGTSUIFigure, ...
                        'Please import valid structure data.', ...
                        'Data Import Error', 'Icon', 'warning');
                    return;
                end

                app.(targetProperty) = importedData;
                assignin('base', baseVariable, importedData);
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('The %s has been set\n%s%s.', ...
                    displayName, directory, fileName), ...
                    'Success', 'Icon', 'success', 'Modal', false);
            catch ME
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('Failed to import %s:\n%s', displayName, ME.message), ...
                    'Data Import Error', 'Icon', 'error');
            end
        end

        function plotSelectedStationCME_(app, cme, refStation)
            ax = app.CMEUI_1Axes;
            stationLabel = getAnalysisStationLabel_(app, refStation);

            epoch = 1:size(cme, 1);
            cla(ax);
            plot(ax, epoch, cme(:, refStation), ...
                'Color', [0.09, 0.41, 0.67], 'LineWidth', 1.2);
            yline(ax, 0, ':', 'Color', [0.45, 0.45, 0.45]);
            title(ax, sprintf('Reconstructed CME - %s', stationLabel), ...
                'Interpreter', 'none')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.PrideGTSUIFigure.Position(3);
            windowHeight = app.PrideGTSUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.PrideGTSUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

            app.appPath = getAppRoot_(app);
            app.ResourcePath = app.appPath;
            ensureRuntimePaths_(app);

            loadingIconPath = fullfile(app.appPath, 'logo', 'loading.png');
            if isfile(loadingIconPath)
                app.iconPath = loadingIconPath;
            else
                app.iconPath = 'info';
            end

            updateWindowSizeControl(app);
        end

        % Menu selected function: LoadAnalysisFilesMenu
        function LoadAnalysisFilesMenuSelected(app, event)
            % Load Time series files
            try
                loadpath = uigetdir("*.*");
                if loadpath == 0
                    return % If the user cancels
                end

            catch ME
                uialert(app.PrideGTSUIFigure, "Error loading files: " + ME.message, "Error", ...
                    'Icon', 'error', 'Modal', true);
            end


            try
                momlist = dir(fullfile(loadpath, "*.mom"));
                [~, folderName] = fileparts(loadpath);

                if ~isempty(momlist)
                    % Initial container.
                    fileGroups = containers.Map();

                    fatertree = uitreenode(app.Tree, "Text", folderName, ...
                        "NodeData", loadpath);

                    for i = 1:length(momlist)

                        baseName = getStationNameFromMomFile_(app, momlist(i).name);
                        if isKey(fileGroups, baseName)
                            fileGroups(baseName) = [fileGroups(baseName), momlist(i)]; % if the file name already exists,append it.
                        else
                            fileGroups(baseName) = momlist(i);  % New key value pairs.
                        end
                    end

                    groupNames = keys(fileGroups);
                    for i = 1:length(groupNames)
                        groupName = groupNames{i};
                        filesInGroup = fileGroups(groupName);

                        firstFile = filesInGroup(1);
                        firstFilePath = fullfile(firstFile.folder, firstFile.name);
                        groupNode = uitreenode(fatertree, "Text", groupName, "NodeData",firstFilePath);

                        for j = 1:length(filesInGroup)
                            file = filesInGroup(j);
                            uitreenode(groupNode, "Text", file.name, ...
                                "NodeData", fullfile(file.folder, file.name));
                        end
                    end
                else
                    uialert(app.PrideGTSUIFigure, "File type mismatch, Must be '.mom'.", "Warning", ...
                        'Icon', 'warning', 'Modal', true);
                    return
                end
            catch ME
                errMsg = sprintf("Error:%s",ME.message);
                uialert(app.PrideGTSUIFigure, errMsg, 'Error', ...
                    'Icon', 'error', 'Modal', true);
            end

        end

        % Close request function: PrideGTSUIFigure
        function PrideGTSUIFigureCloseRequest(app, event)
            % Prevent accidental triggering that may cause application shutdown
            choice = uiconfirm(app.PrideGTSUIFigure, 'Do you want to exit the application ?', 'Tip', ...
                'Options',{'Yes','No'} ,...
                'DefaultOption',2);

            if strcmp(choice,"Yes")
                delete(app);
            else
                return
            end

        end

        % Menu selected function: DataFormattingModuleMenu
        function DataFormattingModuleMenuSelected(app, event)
            runBundledApp_(app, fullfile('DataFormatting', 'DataFormatting.mlapp'));
        end

        % Window key press function: PrideGTSUIFigure
        function PrideGTSUIFigureWindowKeyPress(app, event)
            key = event.Key;
            modifiers = event.Modifier;

            % Set accelerator 'Ctrl+o' to load file
            if ismember('control', modifiers) && strcmpi(key, 'o')
                LoadAnalysisFilesMenuSelected(app, []);
            end

            % Set accelerator 'delete' to delete selected node
            node = app.Tree.SelectedNodes;
            try
                if strcmpi(key, 'delete')
                    selection = uiconfirm(app.PrideGTSUIFigure, ...
                        ['Are you sure you want to delete the node: ', node.Text, '?'], ...
                        'Delete Node', ...
                        'Options', {'Yes', 'No'}, ...
                        'DefaultOption', 2);

                    if strcmp(selection, 'Yes')
                        try
                            delete(node);
                            app.activeStationData = []; % Clear selected data
                            app.activePlotSignature = [];
                            app.stationDataCache = [];
                        catch ME
                            uialert(app.PrideGTSUIFigure, ...
                                ['Error deleting node: ', ME.message], ...
                                'Error', ...
                                'Icon', 'error', ...
                                'Modal', true);
                        end
                    end
                end
            catch
                return;
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % Get clicked value.
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes) || isempty(selectedNodes.NodeData)
                return
            end
            filePath = selectedNodes.NodeData;

            if isfile(filePath)
                try

                    % 1. Load data and extract components
                    [Path, ~, ~] = fileparts(filePath);
                    filename = getStationNameFromMomFile_(app, filePath);
                    app.activeStationName = filename;
                    plotSignature = getStationPlotSignature_(app, Path, filename);

                    if strcmp(app.activePlotSignature, plotSignature) && ...
                            ~isempty(app.activeStationData) && ...
                            isappdata(app.NorthUIAxes, 'PridePlotHandles') && ...
                            isPridePlotHandlesValid_(app, getappdata(app.NorthUIAxes, 'PridePlotHandles'))
                        return;
                    end

                    [full_neudata, offsetMJDs, headerInfo] = getCachedStationData_(app, Path, filename);
                    app.activeStationData = full_neudata;

                    % Validate data existence
                    if isempty(app.activeStationData)
                        app.PrideGTSUIFigure.Pointer = 'arrow';
                        uialert(app.PrideGTSUIFigure, "No data available for plotting", "Warning", ...
                            'Icon', 'warning', 'Modal', true);
                        return;
                    end

                    plotPrideData_(app, full_neudata, offsetMJDs, headerInfo, char(filename));
                    app.activePlotSignature = plotSignature;

                catch ME
                    app.PrideGTSUIFigure.Pointer = 'arrow';
                    uialert(app.PrideGTSUIFigure, "Failed to load or plot file: " + ME.message, "Error", ...
                        'Icon', 'error', 'Modal', true);
                end

            else
                return
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.MarkerDropDown.Value = 'Point';
            app.ColorDropDown.Value = 'Black';
            app.XtickDropDown.Value = 'Year';
            app.LineStyleDropDown.Value = 'None';
        end

        % Callback function: HomePushTool
        function HomePushToolClicked(app, event)
            % Restore the all coordinate axis.
            ax = findall(app.PrideGTSUIFigure,'type','axes');
            for i  = 1:numel(ax)
                axis(ax(i),'auto');
            end

        end

        % Callback function: ClearPushTool
        function ClearPushToolClicked(app, event)
            choice = uiconfirm(app.PrideGTSUIFigure, ...
                ['Clear imported data, cached results and exported workspace variables? ' ...
                'Source files will not be deleted.'], 'Confirm Data Deletion', ...
                'Options',{'Delete','Cancel'} ,...
                'DefaultOption',2, 'CancelOption',2);

            if strcmp(choice, "Delete")
                app.Tree.SelectedNodes = [];
                delete(app.Tree.Children(:));
                app.activeStationData = [];
                app.activeStationName = [];
                app.activePlotSignature = [];
                app.stationDataCache = [];
                app.outlierProcessedPath = [];
                app.staInfoPath = [];
                app.csvSavePath = [];
                app.originData = [];
                app.residualData = [];
                app.filterResult = [];
                app.removeCmeSavePath = [];
                app.loadingSavePath = [];
                app.correctionWorkPath = [];
                app.OutliersTsPath = [];

                % These copies are created by this app's assignin calls.
                evalin('base', ['clear OriginData ResData CME Result SR ' ...
                    'HeatMap RMS clearIndex outlierIterationHistory']);
                app.ImportStationInfo.Value = false;

                % Only close result windows created by this app instance.
                resultFigures = findall(groot, '-depth', 1, 'Type', 'figure');
                for figureIdx = 1:numel(resultFigures)
                    resultFigure = resultFigures(figureIdx);
                    if isappdata(resultFigure, 'PrideGTSOwner') && ...
                            isequal(getappdata(resultFigure, 'PrideGTSOwner'), ...
                            app.PrideGTSUIFigure)
                        delete(resultFigure);
                    end
                end
                clearAllAxes(app);
                drawnow nocallbacks;

            end

            function clearAllAxes(app)
                % Use known axes; do not traverse a potentially large tree.
                ax = [app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes, ...
                    app.ContributionUIAxes, app.CMEUIAxes, ...
                    app.SpatialResponseUIAxes, app.CorrelationUIAxes, ...
                    app.RMSUIAxes, app.CMEUI_1Axes, ...
                    app.CMEAmplitudeDistributionUIAxes, app.psdUIAxes];
                for axesIdx = 1:numel(ax)
                    currentAx = ax(axesIdx);
                    delete(currentAx.Legend);
                    delete(currentAx.Colorbar);
                    if isappdata(currentAx, 'PridePlotHandles')
                        rmappdata(currentAx, 'PridePlotHandles');
                    end

                    if numel(currentAx.YAxis) > 1
                        % Only a dual-y axes needs a full reset to remove
                        % the right ruler. Preserve its UI styling/labels.
                        styleNames = {'FontName', 'FontSize', 'FontWeight', ...
                            'FontAngle', 'XGrid', 'YGrid', 'ZGrid', ...
                            'Box', 'TickDir', 'LineWidth', ...
                            'TitleFontSizeMultiplier', 'LabelFontSizeMultiplier', ...
                            'SubtitleFontWeight'};
                        styleValues = get(currentAx, styleNames);
                        xLabelText = currentAx.XLabel.String;
                        yLabelText = currentAx.YAxis(1).Label.String;
                        zLabelText = currentAx.ZLabel.String;
                        titleText = currentAx.Title.String;
                        cla(currentAx, 'reset');
                        set(currentAx, styleNames, styleValues);
                        currentAx.XLabel.String = xLabelText;
                        currentAx.YLabel.String = yLabelText;
                        currentAx.ZLabel.String = zLabelText;
                        currentAx.Title.String = titleText;
                    else
                        % Include hidden marks, but keep label/title objects
                        % instead of rebuilding every axes and its rulers.
                        children = allchild(currentAx);
                        labels = [currentAx.Title; currentAx.Subtitle; ...
                            currentAx.XLabel; currentAx.YLabel; currentAx.ZLabel];
                        delete(children(~ismember(children, labels)));
                    end
                    currentAx.Subtitle.String = '';
                    set(currentAx, 'NextPlot', 'replacechildren', ...
                        'XLimMode', 'auto', 'YLimMode', 'auto', ...
                        'ZLimMode', 'auto', 'CLimMode', 'auto', ...
                        'XTickMode', 'auto', 'YTickMode', 'auto', ...
                        'ZTickMode', 'auto', 'XTickLabelMode', 'auto', ...
                        'YTickLabelMode', 'auto', 'ZTickLabelMode', 'auto');
                end

                app.NorthUIAxes.Title.String = '';
                app.psdUIAxes.Title.String = '';
                app.CMEUI_1Axes.Title.String = 'Common-Mode Error (mm)';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                if isempty(app.activeStationData)
                    uialert(app.PrideGTSUIFigure, "No data available for processing", "Warning", ...
                        'Icon', 'warning', 'Modal', true);
                    return;
                end

                if isempty(app.outlierProcessedPath)
                    uialert(app.PrideGTSUIFigure, "No file save path set", "Warning", ...
                        'Icon', 'warning', 'Modal', true);
                    return;
                end

                selectedNodes = app.Tree.SelectedNodes;
                if isempty(selectedNodes) || isempty(selectedNodes.NodeData) || ~isfile(selectedNodes.NodeData)
                    uialert(app.PrideGTSUIFigure, ...
                        'Please select a .mom file node before running outlier processing.', ...
                        'Selection Error', 'Icon', 'warning', 'Modal', true);
                    return;
                end

                filePath = selectedNodes.NodeData;
                [Path,~,~] = fileparts(filePath);

                uniqueStations = getStationsFromPath(Path);
                Method = lower(app.MethodDropDown.Value);
                ProModel = app.ProcessingScopeDropDown.Value;
                detectionStrategy = app.DetectionStrategyDropDown.Value;
                coefficient = double(app.CoefficientEditField.Value);
                winsize = double(app.WindowSizeEditField.Value);
                maxIterations = app.MaxIterationsEditField.Value;

                if ~isscalar(coefficient) || ~isfinite(coefficient) || coefficient <= 0
                    error('Coefficient must be a positive finite number.');
                end
                if startsWith(Method, 'window') && ...
                        (~isscalar(winsize) || ~isfinite(winsize) || winsize < 1)
                    error('Window size must be a positive finite number.');
                end

                if isempty(maxIterations) || ~isscalar(maxIterations) || ...
                        ~isfinite(maxIterations) || maxIterations < 1
                    error('Max Iterations must be a positive integer.');
                end
                maxIterations = floor(maxIterations);

                if strcmpi(detectionStrategy, "Single-pass")
                    effectiveMaxIterations = 1;
                elseif strcmpi(detectionStrategy, "Iterative")
                    effectiveMaxIterations = maxIterations;
                else
                    error('Unknown detection strategy: %s', detectionStrategy);
                end

                if strcmpi(ProModel,"Batch")
                    fig = app.PrideGTSUIFigure;
                    p = uiprogressdlg(fig,"Icon",app.iconPath);
                    p.Message = "Processing...";

                    for numstation = 1:length(uniqueStations)
                        currentStation = uniqueStations{numstation};
                        singleStationData = LoadData(app, Path, currentStation);

                        [~, CsingleStationData, ~] = processOutliers( ...
                            singleStationData, coefficient, winsize, Method, Path, ...
                            currentStation, effectiveMaxIterations);

                        writeProcessedMomFilesWithHeader( ...
                            Path, app.outlierProcessedPath, currentStation, CsingleStationData);

                        p.Value = (numstation / length(uniqueStations));
                    end

                    close(p)
                    uialert(app.PrideGTSUIFigure, "Processing completed", "Tip", ...
                        'Icon', 'success', 'Modal', false);

                elseif strcmpi(ProModel,"Single")
                    singleStationData = LoadData(app, Path, app.activeStationName);
                    originalStationData = singleStationData;
                    observationColumns = prideObservationColumns_(app, originalStationData);

                    [clearIndex, CsingleStationData, iterationHistory] = processOutliers( ...
                        singleStationData, coefficient, winsize, Method, Path, ...
                        app.activeStationName, effectiveMaxIterations);

                    if strcmpi(app.XtickDropDown.Value,"mjd")
                        outlierX = originalStationData(:, 1);
                    else
                        outlierX = mjdToDecimalYear_(app, originalStationData(:, 1));
                    end

                    outlierY = cell(1, 3);
                    for outlierComponentIdx = 1:3
                        outlierY{outlierComponentIdx} = originalStationData( ...
                            clearIndex{outlierComponentIdx}, observationColumns(outlierComponentIdx));
                    end

                    writeProcessedMomFilesWithHeader( ...
                        Path, app.outlierProcessedPath, app.activeStationName, CsingleStationData);

                    app.activePlotSignature = [];

                    TreeSelectionChanged(app,[]);
                    delete(findall(app.NorthUIAxes, 'Tag', 'PrideOutlierMarker'));
                    delete(findall(app.EastUIAxes, 'Tag', 'PrideOutlierMarker'));
                    delete(findall(app.UpUIAxes, 'Tag', 'PrideOutlierMarker'));

                    hold(app.NorthUIAxes,'on');
                    hold(app.EastUIAxes,'on');
                    hold(app.UpUIAxes,'on');

                    plot(app.NorthUIAxes, outlierX(clearIndex{1}), outlierY{1}, ...
                        "r*", 'MarkerSize', 8, 'LineWidth', 1.2, ...
                        'Tag', 'PrideOutlierMarker', 'HandleVisibility', 'off');
                    plot(app.EastUIAxes, outlierX(clearIndex{2}), outlierY{2}, ...
                        "r*", 'MarkerSize', 8, 'LineWidth', 1.2, ...
                        'Tag', 'PrideOutlierMarker', 'HandleVisibility', 'off');
                    plot(app.UpUIAxes, outlierX(clearIndex{3}), outlierY{3}, ...
                        "r*", 'MarkerSize', 8, 'LineWidth', 1.2, ...
                        'Tag', 'PrideOutlierMarker', 'HandleVisibility', 'off');

                    hold(app.NorthUIAxes,'off');
                    hold(app.EastUIAxes,'off');
                    hold(app.UpUIAxes,'off');

                    componentNames = prideComponentNames_(app);
                    summaryLines = strings(1, numel(clearIndex));
                    for summaryComponentIdx = 1:numel(clearIndex)
                        roundCounts = cellfun(@numel, iterationHistory{summaryComponentIdx});
                        if isempty(roundCounts)
                            roundText = '0';
                        else
                            roundText = char(strjoin(string(roundCounts), ' + '));
                        end
                        summaryLines(summaryComponentIdx) = sprintf( ...
                            '%s: %s = %d', componentNames{summaryComponentIdx}, ...
                            roundText, numel(clearIndex{summaryComponentIdx}));
                    end

                    drawnow;
                    uialert(app.PrideGTSUIFigure, ...
                        strjoin(cellstr(summaryLines), newline), ...
                        sprintf('Outliers (%s)', detectionStrategy), ...
                        'Icon', 'success', 'Modal', false);
                else
                    error('Unknown processing scope: %s', ProModel);
                end

            catch ME
                errorMsg = sprintf('Error: %s', ME.message);
                uialert(app.PrideGTSUIFigure, errorMsg, 'Error', 'Icon', 'error');
            end

            function uniqueStations = getStationsFromPath(Path)
                momfiles = dir(fullfile(Path, "*.mom"));
                stations = cellfun(@(name) getStationNameFromMomFile_(app, name), ...
                    {momfiles.name}, 'UniformOutput', false);
                uniqueStations = unique(stations);
            end

            function [clearIndex, singleStationData, iterationHistory] = processOutliers(singleStationData, coefficient, winsize, method, dataPath, stationName, maxIterations)
                observationColumns = prideObservationColumns_(app, singleStationData);
                clearIndex = cell(1, numel(observationColumns));
                iterationHistory = cell(1, numel(observationColumns));

                for componentIdx = 1:numel(observationColumns)
                    column = observationColumns(componentIdx);
                    componentData = singleStationData(:, column);
                    removedMask = false(size(componentData));
                    componentHistory = cell(1, 0);

                    componentId = componentIdx - 1;
                    momFile = fullfile(dataPath, sprintf('%s_%d.mom', stationName, componentId));

                    if ~isfile(momFile)
                        momFile = '';
                    end

                    for iterationIdx = 1:maxIterations
                        if strcmpi(method, "iqr")
                            results = CommonIQR( ...
                                singleStationData(:, 1), ...
                                componentData, ...
                                coefficient, ...
                                momFile);

                        elseif strcmpi(method, "windowiqr")
                            results = WindowIQR( ...
                                singleStationData(:, 1), ...
                                componentData, ...
                                winsize, ...
                                coefficient, ...
                                momFile);

                        elseif strcmpi(method, "mad")
                            results = CommonMAD( ...
                                singleStationData(:, 1), ...
                                componentData, ...
                                coefficient, ...
                                momFile);

                        elseif strcmpi(method, "windowmad")
                            results = WindowMAD( ...
                                singleStationData(:, 1), ...
                                componentData, ...
                                winsize, ...
                                coefficient, ...
                                momFile);

                        else
                            error('Unknown outlier method: %s', method);
                        end

                        newRemovedIndices = results.removedIndices(:);
                        if isempty(newRemovedIndices)
                            break;
                        end

                        newRemovedIndices = newRemovedIndices( ...
                            newRemovedIndices >= 1 & newRemovedIndices <= numel(componentData));
                        newRemovedIndices = newRemovedIndices(~removedMask(newRemovedIndices));
                        if isempty(newRemovedIndices)
                            break;
                        end

                        removedMask(newRemovedIndices) = true;
                        componentData(newRemovedIndices) = NaN;
                        componentHistory{end + 1} = newRemovedIndices; %#ok<AGROW>
                    end

                    singleStationData(:, column) = componentData;
                    clearIndex{componentIdx} = find(removedMask);
                    iterationHistory{componentIdx} = componentHistory;
                end

                assignin("base","clearIndex",clearIndex);
                assignin("base","outlierIterationHistory",iterationHistory);
            end

            function writeProcessedMomFilesWithHeader(inputPath, outputPath, stationName, stationData)
                observationColumns = prideObservationColumns_(app, stationData);

                for componentIdx = 1:numel(observationColumns)
                    componentId = componentIdx - 1;
                    writecolumn = observationColumns(componentIdx);

                    momdata = [stationData(:,1), stationData(:,writecolumn)];

                    sourceMomFile = fullfile(inputPath, sprintf('%s_%d.mom', stationName, componentId));
                    saveFilePath = fullfile(outputPath, sprintf('%s_%d.mom', stationName, componentId));

                    writeMomWithHeader(sourceMomFile, saveFilePath, momdata);
                end
            end

            function writeMomWithHeader(sourceMomFile, saveFilePath, momdata)
                fileID = fopen(saveFilePath, 'w');
                if fileID == -1
                    error('Failed to open file: %s', saveFilePath);
                end

                cleanupObj = onCleanup(@() fclose(fileID));

                if isfile(sourceMomFile)
                    sourceID = fopen(sourceMomFile, 'r');
                    if sourceID == -1
                        error('Failed to open source MOM file: %s', sourceMomFile);
                    end

                    sourceCleanup = onCleanup(@() fclose(sourceID));

                    while true
                        line = fgetl(sourceID);
                        if ~ischar(line)
                            break;
                        end

                        lineTrim = strtrim(line);
                        if isempty(lineTrim)
                            continue;
                        end

                        if lineTrim(1) == '#'
                            fprintf(fileID, '%s\n', line);
                        else
                            break;
                        end
                    end
                end

                validRows = isfinite(momdata(:,1)) & isfinite(momdata(:,2));
                momdata = momdata(validRows, :);

                fprintf(fileID, '%.6f %.6f\n', momdata');
            end
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            % Calculate statistics for ALL stations in the tree with a progress bar.

            % 1. Get the path from the selected node
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes) || isempty(selectedNodes.NodeData)
                return
            end
            inputPath = selectedNodes.NodeData;

            % 2. Determine the target folder
            if isfolder(inputPath)
                targetFolder = inputPath;
            elseif isfile(inputPath)
                [targetFolder, ~, ~] = fileparts(inputPath);
            else
                uialert(app.PrideGTSUIFigure, "Invalid path selected.", "Error");
                return;
            end

            % 3. Find unique stations (Search for *_0.mom files)
            filePattern = fullfile(targetFolder, '*_0.mom');
            listing = dir(filePattern);

            numStations = length(listing);
            if numStations == 0
                uialert(app.PrideGTSUIFigure, "No .mom files found in the selected folder.", "Warning");
                return;
            end

            % 4. Initialize Progress Dialog
            d = uiprogressdlg(app.PrideGTSUIFigure, ...
                'Title', 'Batch Statistics', ...
                'Message', 'Scanning folder...', ...
                'Cancelable', true);

            % 5. Initialize Results Table Data (Now 10 columns)
            % Cols: Station, Start MJD, End MJD, Total(Exp), N_Act, N_Miss%, E_Act, E_Miss%, U_Act, U_Miss%
            resultsData = cell(numStations, 10);

            try
                for i = 1:numStations
                    if d.CancelRequested
                        break;
                    end
                    d.Value = i / numStations;

                    fullFileName = listing(i).name;
                    stationName = getStationNameFromMomFile_(app, fullFileName);
                    d.Message = sprintf('Processing %d/%d: %s...', i, numStations, stationName);

                    try
                        % --- Call your existing LoadData ---
                        data = LoadData(app, targetFolder, stationName);

                        if isempty(data)
                            resultsData(i, :) = {stationName, NaN, NaN, 0, 0, 100.00, 0, 100.00, 0, 100.00};
                            continue;
                        end

                        % 1. Time Span Calculation
                        MJD = data(:, 1);
                        startMJD = min(MJD);
                        endMJD = max(MJD);
                        totalExpected = endMJD - startMJD + 1;

                        % 2. Identify Columns
                        cols = prideObservationColumns_(app, data);

                        % 3. Calculate Independent Statistics
                        % We check NaNs for each column separately
                        n_valid = sum(~isnan(data(:, cols(1))));
                        e_valid = sum(~isnan(data(:, cols(2))));
                        u_valid = sum(~isnan(data(:, cols(3))));

                        % --- DEBUG: Print to Command Window to verify differences ---
                        fprintf('Station: %s | N_val:%d, E_val:%d, U_val:%d | Total:%d\n', ...
                            stationName, n_valid, e_valid, u_valid, totalExpected);

                        % 4. Calculate Missing Rates (Rounded to 2 decimal places)
                        n_miss = round(100 * (1 - n_valid / totalExpected), 2);
                        e_miss = round(100 * (1 - e_valid / totalExpected), 2);
                        u_miss = round(100 * (1 - u_valid / totalExpected), 2);

                        % 5. Store in Results Array
                        resultsData(i, :) = {stationName, startMJD, endMJD, totalExpected, ...
                            n_valid, n_miss, ...
                            e_valid, e_miss, ...
                            u_valid, u_miss};

                    catch ME
                        resultsData(i, :) = {stationName, NaN, NaN, 0, 0, NaN, 0, NaN, 0, NaN};
                    end
                end
            catch ME
                close(d);
                uialert(app.PrideGTSUIFigure, "Error: " + ME.message, "Batch Error");
                return;
            end
            close(d);

            % 6. Display Results in UITable (Centered & Auto-Adjusted)
            if ~isempty(resultsData)
                figWidth = 1050;
                calculatedHeight = (numStations * 25) + 100;
                figHeight = max(300, min(800, calculatedHeight));

                fig = uifigure('Name', 'Batch Station Statistics', ...
                    'Position', [100, 100, figWidth, figHeight], ...
                    'Visible', 'off');
                setappdata(fig, 'PrideGTSOwner', app.PrideGTSUIFigure);
                movegui(fig, 'center');

                t = uitable(fig);
                t.Position = [20 20 figWidth-40 figHeight-40];
                t.Data = resultsData;
                t.ColumnName = {'Station', 'Start MJD', 'End MJD', 'Expected', ...
                    'N Act', 'N Miss(%)', ...
                    'E Act', 'E Miss(%)', ...
                    'U Act', 'U Miss(%)'};


                t.ColumnFormat = {'char', 'numeric', 'numeric', 'numeric', ...
                    'numeric', 'numeric', ...
                    'numeric', 'numeric', ...
                    'numeric', 'numeric'};

                t.ColumnSortable = true;
                t.RowName = 'numbered';
                t.ColumnWidth = 'auto';


                missCols = [6, 8, 10];
                maxStyle = uistyle('BackgroundColor', [1 0.7 0.7], 'FontWeight', 'bold'); % Light Red
                minStyle = uistyle('BackgroundColor', [0.7 1 0.7], 'FontWeight', 'bold'); % Light Green

                missMatrix = cell2mat(resultsData(:, missCols));
                for j = 1:length(missCols)
                    colData = missMatrix(:, j);

                    % Find indices of max and min (handle potential NaNs)
                    validIdx = ~isnan(colData);
                    if any(validIdx)
                        maxVal = max(colData(validIdx));
                        minVal = min(colData(validIdx));

                        % Highlight Max
                        maxRows = find(colData == maxVal);
                        addStyle(t, maxStyle, 'cell', [maxRows, repmat(missCols(j), length(maxRows), 1)]);

                        % Highlight Min
                        minRows = find(colData == minVal);
                        addStyle(t, minStyle, 'cell', [minRows, repmat(missCols(j), length(minRows), 1)]);
                    end
                end

                fig.Visible = 'on';
            end
        end

        % Menu selected function: MissingDataReconstructionModuleMenu
        function MissingDataReconstructionModuleMenuSelected(app, event)
            runBundledApp_(app, fullfile('MissingDataReconstruction', ...
                'MissingDataReconstruction.mlapp'));
        end

        % Menu selected function: HectorModuleMenu
        function HectorModuleMenuSelected(app, event)
            runBundledApp_(app, fullfile('Hector', 'Hector.mlapp'));
        end

        % Button pushed function: SavePathButton
        function SavePathButtonPushed(app, event)
            dialogTitle = 'Select the directory for saving environmental load data';
            savepath1 = uigetdir(pwd, dialogTitle);

            if isequal(savepath1, 0)
                return
            end

            app.loadingSavePath = savepath1;
            app.csvSavePath = savepath1;
            uialert(app.PrideGTSUIFigure, sprintf('The save path has been set to:\n%s', app.loadingSavePath), ...
                'Success', 'Icon', 'success', 'Modal', false);
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            % Replace the ENTIRE editable body of DownloadButtonPushed, including the
            % old nested helper functions. Keep App Designer's function line and its end.
            % Put gfz_loading_download.m and gfz_http_get.m on the MATLAB path first.

            fig = app.PrideGTSUIFigure;
            lockKey = 'GFZLoadingDownloadBusy';
            if isappdata(fig, lockKey) && getappdata(fig, lockKey)
                return
            end
            setappdata(fig, lockKey, true);
            lockCleanup = onCleanup(@() releaseDownloadLock(fig, lockKey));

            try
                cfg = struct();
                cfg.StationFile = app.staInfoPath;
                isPathText = @(p) (ischar(p) || isstring(p)) && ...
                    isscalar(string(p)) && ~ismissing(string(p)) && ...
                    strlength(strtrim(string(p))) > 0;
                cfg.SaveRoot = app.loadingSavePath;
                if ~isPathText(cfg.SaveRoot)
                    cfg.SaveRoot = app.csvSavePath;
                end
                
                if ~isPathText(cfg.SaveRoot)
                    uialert(fig, 'Please click SavePath first to select the folder for download saving.', ...
                        'Download path not set', 'Icon', 'warning');
                    return
                end

                cfg.SaveRoot = char(strtrim(string(cfg.SaveRoot)));
                cfg.StartDate = app.StartDateDatePicker.Value;
                cfg.EndDate = app.EndDateDatePicker.Value; % End date remains exclusive.
                cfg.Types = {};
                if app.HYDLButton_2.Value, cfg.Types{end+1} = 'HYDL'; end
                if app.NTALButton_2.Value, cfg.Types{end+1} = 'NTAL'; end
                if app.NTOLButton_2.Value, cfg.Types{end+1} = 'NTOL'; end
                cfg.Directions = {};
                if app.EastWestCheckBox.Value, cfg.Directions{end+1} = 'duEW'; end
                if app.NorthSouthCheckBox.Value, cfg.Directions{end+1} = 'duNS'; end
                if app.VerticalCheckBox.Value, cfg.Directions{end+1} = 'duV'; end

                cfg.UseProcessPool = true; % PCT available: one process worker; else sync.
                cfg.ChunkDays = 90;        % Initial chunk size, never crosses an annual file.
                cfg.Timeout = 60;
                cfg.MaxAttempts = 2;       % Includes the first attempt, NOT two extra retries.
                result = gfz_loading_download(cfg, fig);
                if ~isvalid(fig), return; end
                if result.Canceled
                    titleText = 'Canceled'; icon = 'warning';
                elseif isempty(result.MergedFiles) && isempty(result.PartialFiles)
                    titleText = 'Download failed'; icon = 'error';
                elseif ~isempty(result.Failures) || ~isempty(result.PartialFiles)
                    titleText = 'Completed with gaps'; icon = 'warning';
                else
                    titleText = 'Download complete'; icon = 'success';
                end
                message = sprintf(['Complete files: %d\nPartial files: %d\nIssues: %d' ...
                    '\nHTTP requests: %d\nCache hits: %d\n\nLog: %s'], ...
                    numel(result.MergedFiles), numel(result.PartialFiles), ...
                    numel(result.Failures), result.Requests, result.CacheHits, result.LogFile);
                if ~isempty(result.Failures)
                    message = sprintf('%s\n\nFirst issue: %s', message, result.Failures{1});
                end
                uialert(fig, message, titleText, 'Icon', icon);
            catch ME
                if isvalid(fig)
                    uialert(fig, ME.message, 'GFZ download error', 'Icon', 'error');
                end
            end

            function releaseDownloadLock(figHandle, keyName)
                if isvalid(figHandle) && isappdata(figHandle, keyName)
                    rmappdata(figHandle, keyName);
                end
            end
        end

        % Button pushed function: savepath
        function savepathButtonPushed(app, event)
            % Set Time series files save path
            try
                loadpath = uigetdir("*.*"); % Such as: C:\Users\*\Desktop\folder

                if loadpath == 0
                    return % User canceled
                end
                app.outlierProcessedPath = loadpath;

            catch ME
                uialert(app.PrideGTSUIFigure, "Error loading files: " + ME.message, "Error", ...
                    'Icon', 'error', 'Modal', false);
                return
            end
        end

        % Value changed function: ImportStationInfo
        function ImportStationInfoValueChanged(app, event)
            [file, path] = uigetfile('*.txt', 'Select station info file');
            if isequal(file, 0)
                return
            end
            fullpath = fullfile(path, file);
            app.staInfoPath = fullpath;
            uialert(app.PrideGTSUIFigure, 'The path of the station information file has been imported', 'Info', ...
                'Icon', 'success', 'Modal', true);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            selectedPath = uigetdir(pwd, 'Select Environmental Loading Correction Working Directory');

            if isequal(selectedPath, 0)
                return;
            end

            app.correctionWorkPath = selectedPath;

            uialert(app.PrideGTSUIFigure, ...
                sprintf('The correction working directory has been set:\n%s', selectedPath), ...
                'Success', 'Icon', 'success', 'Modal', true);
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            workPath = app.correctionWorkPath;

            if isempty(workPath)
                workPath = app.csvSavePath;
            end

            if isempty(workPath) || isequal(workPath, 0)
                uialert(app.PrideGTSUIFigure, ...
                    'Please import the correction working directory first.', ...
                    'Directory Error', ...
                    'Icon', 'warning', ...
                    'Modal', true);
                return;
            end


            % Check the MOM directory

            momPath = fullfile(workPath, 'mom');

            if ~isfolder(momPath)
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('mom folder not found:\n%s', momPath), ...
                    'Directory Error', ...
                    'Icon', 'error', ...
                    'Modal', true);
                return;
            end


            % Resolve the NetCDF root directory

            netcdfRoot = resolve_netcdf_root_(workPath);

            if isempty(netcdfRoot)
                uialert(app.PrideGTSUIFigure, ...
                    sprintf(['NetCDF folder not found under:\n%s\n\n' ...
                    'Expected folder name: NetCDF or data.'], workPath), ...
                    'Directory Error', ...
                    'Icon', 'error', ...
                    'Modal', true);
                return;
            end


            % Obtain the station list

            momFiles0 = dir(fullfile(momPath, '*_0.mom'));

            stations = cellfun( ...
                @(fileName) getStationNameFromMomFile_(app, fileName), ...
                {momFiles0.name}, ...
                'UniformOutput', false);

            stations = stations(~cellfun('isempty', stations));
            stations = unique(stations, 'stable');

            if isempty(stations)
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('No station *_0.mom files found in:\n%s', momPath), ...
                    'File Error', ...
                    'Icon', 'warning', ...
                    'Modal', true);
                return;
            end


            % Obtain the selected loading products

            selectedProducts = selected_products_();

            if isempty(selectedProducts)
                uialert(app.PrideGTSUIFigure, ...
                    ['Please enable at least one loading product switch: ' ...
                    'HYDL, NTAL, or NTOL.'], ...
                    'Selection Error', ...
                    'Icon', 'warning', ...
                    'Modal', true);
                return;
            end


            % Configure loading directions and MOM columns

            directions = {'duEW', 'duNS', 'duV'};
            correctionDirections = {'duNS', 'duEW', 'duV'};

            directionComponentMap = containers.Map( ...
                {'duNS', 'duEW', 'duV'}, ...
                {1, 2, 3});


            % Create the progress dialog

            fig = app.PrideGTSUIFigure;

            p = uiprogressdlg( ...
                fig, ...
                'Icon', app.iconPath, ...
                'Title', 'Loading Correction', ...
                'Message', 'Processing NetCDF loading correction...', ...
                'Value', 0);

            cleanupProgress = onCleanup( ...
                @() closeDialogIfValid_(app, p)); %#ok<NASGU>


            % Create the output directory

            savePath = fullfile(workPath, 'corrected');

            if ~exist(savePath, 'dir')
                mkdir(savePath);
            end


            % Process each station

            for stationIdx = 1:numel(stations)

                station = stations{stationIdx};

                if isempty(station)
                    p.Value = stationIdx / numel(stations);
                    continue;
                end

                p.Message = sprintf( ...
                    'Processing station %s (%d/%d)...', ...
                    station, stationIdx, numel(stations));


                % Load MOM data

                try
                    [momData, ~] = LoadData(app, momPath, station);
                catch ME
                    warning( ...
                        'Failed to load MOM data for station %s: %s', ...
                        station, ME.message);

                    p.Value = stationIdx / numel(stations);
                    continue;
                end

                if isempty(momData) || size(momData, 2) < 4
                    warning( ...
                        ['Invalid or incomplete MOM data for station %s. ' ...
                        'At least four columns are required. Station skipped.'], ...
                        station);

                    p.Value = stationIdx / numel(stations);
                    continue;
                end

                observationColumns = prideObservationColumns_(app, momData);

                rowCount = size(momData, 1);

                % Use the integer part of MJD for daily loading matching.
                momDayKeys = floor(momData(:, 1));


                % Initialize correction accumulators and availability masks

                totalCorrection = struct( ...
                    'duNS', zeros(rowCount, 1), ...
                    'duEW', zeros(rowCount, 1), ...
                    'duV', zeros(rowCount, 1));

                correctionAvailable = struct( ...
                    'duNS', true(rowCount, 1), ...
                    'duEW', true(rowCount, 1), ...
                    'duV', true(rowCount, 1));


                % Process each selected loading product

                for productIdx = 1:numel(selectedProducts)

                    product = selectedProducts{productIdx};

                    ncFile = find_station_netcdf_file_( ...
                        netcdfRoot, station, product);


                    % Handle a missing NetCDF file

                    if isempty(ncFile)

                        for directionIdx = 1:numel(correctionDirections)

                            direction = correctionDirections{directionIdx};

                            correctionAvailable.(direction)(:) = false;
                        end

                        warning( ...
                            ['NetCDF file not found for station %s, product %s. ' ...
                            'All affected corrected values will be written as NaN.'], ...
                            station, product);

                        continue;
                    end


                    % Read the NetCDF loading file

                    try
                        daily = read_daily_loading_from_netcdf_( ...
                            ncFile, product, directions);

                    catch ME

                        for directionIdx = 1:numel(correctionDirections)

                            direction = correctionDirections{directionIdx};

                            correctionAvailable.(direction)(:) = false;
                        end

                        warning( ...
                            ['Failed to read NetCDF file for station %s, ' ...
                            'product %s: %s. All affected corrected values ' ...
                            'will be written as NaN.'], ...
                            station, product, ME.message);

                        continue;
                    end


                    % Match each loading direction

                    for productDirectionIdx = 1:numel(directions)

                        direction = directions{productDirectionIdx};


                        % Handle a missing direction variable

                        if ~isfield(daily, direction)

                            correctionAvailable.(direction)(:) = false;

                            warning( ...
                                ['Variable %s not found in NetCDF file %s. ' ...
                                'Affected corrected values will be written as NaN.'], ...
                                direction, ncFile);

                            continue;
                        end


                        % Match MOM dates with loading dates

                        [dateMatched, loadingRowIdx] = ismember( ...
                            momDayKeys, daily.mjd);

                        correctionAvailable.(direction)(~dateMatched) = false;

                        matchedMomRows = find(dateMatched);

                        if isempty(matchedMomRows)
                            continue;
                        end

                        matchedValues = daily.(direction)( ...
                            loadingRowIdx(dateMatched));

                        finiteMatched = isfinite(matchedValues);

                        invalidValueRows = matchedMomRows(~finiteMatched);

                        if ~isempty(invalidValueRows)
                            correctionAvailable.(direction)( ...
                                invalidValueRows) = false;
                        end


                        % Accumulate only valid finite loading corrections

                        validValueRows = matchedMomRows(finiteMatched);

                        if ~isempty(validValueRows)
                            totalCorrection.(direction)(validValueRows) = ...
                                totalCorrection.(direction)(validValueRows) + ...
                                matchedValues(finiteMatched) * 1000.0;
                        end
                    end
                end


                % Apply loading corrections

                for correctionDirectionIdx = 1:numel(correctionDirections)

                    direction = correctionDirections{ ...
                        correctionDirectionIdx};

                    componentIndex = directionComponentMap(direction);
                    column = observationColumns(componentIndex);

                    validRows = correctionAvailable.(direction);
                    invalidRows = ~validRows;

                    momData(validRows, column) = ...
                        momData(validRows, column) - ...
                        totalCorrection.(direction)(validRows);

                    momData(invalidRows, column) = NaN;
                end


                % Write the corrected MOM files
                % Preserve all original header lines before the first data row,
                % including comments such as "# offset 59295.000000".

                for counter = 0:2

                    writeColumn = observationColumns(counter + 1);

                    writeData = [ ...
                        momData(:, 1), ...
                        momData(:, writeColumn)];

                    saveName = sprintf( ...
                        '%s_%d.mom', station, counter);

                    sourceFilePath = fullfile( ...
                        momPath, saveName);

                    headerLines = read_mom_header_lines_( ...
                        sourceFilePath);

                    saveFilePath = fullfile( ...
                        savePath, saveName);

                    fileID = fopen(saveFilePath, 'w');

                    if fileID == -1
                        error( ...
                            'Failed to open output file: %s', ...
                            saveFilePath);
                    end

                    fileCleanup = onCleanup( ...
                        @() fclose_if_valid_(fileID)); %#ok<NASGU>


                    % Write the original header comments first

                    for headerIdx = 1:numel(headerLines)
                        fprintf(fileID, '%s\n', headerLines{headerIdx});
                    end


                    % Write the corrected numerical data

                    fprintf(fileID, ...
                        '%.6f %.6f\n', ...
                        writeData');

                    fclose(fileID);
                    fileID = -1;

                    clear fileCleanup
                end

                p.Value = stationIdx / numel(stations);
            end


            % Close the progress dialog

            clear cleanupProgress


            % Notify completion

            uialert(app.PrideGTSUIFigure, ...
                sprintf([ ...
                'Loading correction finished.\n\n' ...
                'Corrected MOM files were saved to:\n%s\n\n' ...
                'Original MOM header comments were preserved. ' ...
                'See the Command Window for any processing warnings.'], ...
                savePath), ...
                'Correction Complete', ...
                'Icon', 'success');


            % Resolve the NetCDF root directory

            function root = resolve_netcdf_root_(basePath)

                candidates = { ...
                    fullfile(basePath, 'NetCDF'), ...
                    fullfile(basePath, 'data')};

                root = '';

                for idx = 1:numel(candidates)

                    if isfolder(candidates{idx})
                        root = candidates{idx};
                        return;
                    end
                end
            end


            % Obtain the selected loading products

            function products = selected_products_()

                products = {};

                if strcmp(app.HYDLSwitch.Value, 'On')
                    products{end + 1} = 'HYDL';
                end

                if strcmp(app.NTALSwitch.Value, 'On')
                    products{end + 1} = 'NTAL';
                end

                if strcmp(app.NTOLSwitch.Value, 'On')
                    products{end + 1} = 'NTOL';
                end
            end


            % Locate a station NetCDF file

            function ncFile = find_station_netcdf_file_( ...
                    netcdfRoot, station, product)

                candidates = { ...
                    fullfile( ...
                    netcdfRoot, ...
                    'merged', ...
                    sprintf('%s_%s.nc', station, product))

                    fullfile( ...
                    netcdfRoot, ...
                    sprintf('%s_%s.nc', station, product))

                    fullfile( ...
                    netcdfRoot, ...
                    product, ...
                    sprintf('%s_%s.nc', station, product))

                    fullfile( ...
                    netcdfRoot, ...
                    product, ...
                    sprintf('%s.nc', station))
                    };

                ncFile = '';

                for candidateIdx = 1:numel(candidates)

                    if isfile(candidates{candidateIdx})
                        ncFile = candidates{candidateIdx};
                        return;
                    end
                end
            end


            % Read daily loading data from a NetCDF file

            function daily = read_daily_loading_from_netcdf_( ...
                    filename, product, directions)

                fileInfo = ncinfo(filename);
                varNames = string({fileInfo.Variables.Name});

                if ~any(varNames == "time")
                    error( ...
                        'The NetCDF file does not contain a time variable: %s', ...
                        filename);
                end

                rawTime = double(ncread(filename, 'time'));
                units = ncreadatt(filename, 'time', 'units');

                times = netcdf_time_to_datetime_(rawTime, units);
                times = times(:);

                dayStart = dateshift(times, 'start', 'day');

                mjd = round(days( ...
                    dayStart - ...
                    datetime(1858, 11, 17, 'TimeZone', 'UTC')));

                daily = struct();
                daily.mjd = unique(mjd, 'stable');

                for dailyDirectionIdx = 1:numel(directions)

                    direction = directions{dailyDirectionIdx};

                    if ~any(varNames == string(direction))
                        continue;
                    end

                    values = double(ncread(filename, direction));
                    values = values(:);

                    if numel(values) ~= numel(mjd)
                        error( ...
                            ['Variable %s in %s contains %d values, ' ...
                            'but the time variable contains %d values. ' ...
                            'The loading series cannot be aligned safely.'], ...
                            direction, ...
                            filename, ...
                            numel(values), ...
                            numel(mjd));
                    end

                    dailyValues = NaN( ...
                        numel(daily.mjd), 1);

                    for dayIdx = 1:numel(daily.mjd)

                        mask = mjd == daily.mjd(dayIdx);

                        dayValues = values(mask);
                        dayValues = dayValues(isfinite(dayValues));

                        if isempty(dayValues)
                            continue;
                        end

                        if strcmp(product, 'NTAL') || ...
                                strcmp(product, 'NTOL')

                            dailyValues(dayIdx) = mean( ...
                                dayValues, 'omitnan');

                        else

                            dailyValues(dayIdx) = dayValues(1);
                        end
                    end

                    daily.(direction) = dailyValues;
                end
            end


            % Convert a NetCDF time variable to datetime

            function times = netcdf_time_to_datetime_(rawTime, units)

                unitsText = strtrim(char(units));

                tokens = regexp( ...
                    unitsText, ...
                    ['^(hours|days|minutes|seconds)\s+since\s+' ...
                    '(\d{4})-(\d{1,2})-(\d{1,2})' ...
                    '(?:[ T](\d{1,2}):(\d{1,2}):(\d{1,2}(?:\.\d+)?))?'], ...
                    'tokens', ...
                    'once');

                if isempty(tokens)
                    error( ...
                        'Unsupported NetCDF time units: %s', ...
                        unitsText);
                end

                timeUnit = lower(tokens{1});

                yearValue = str2double(tokens{2});
                monthValue = str2double(tokens{3});
                dayValue = str2double(tokens{4});

                if numel(tokens) < 7 || isempty(tokens{5})
                    hourValue = 0;
                    minuteValue = 0;
                    secondValue = 0;
                else
                    hourValue = str2double(tokens{5});
                    minuteValue = str2double(tokens{6});
                    secondValue = str2double(tokens{7});
                end

                base = datetime( ...
                    yearValue, ...
                    monthValue, ...
                    dayValue, ...
                    hourValue, ...
                    minuteValue, ...
                    secondValue, ...
                    'TimeZone', 'UTC');

                switch timeUnit

                    case 'hours'
                        times = base + hours(rawTime);

                    case 'days'
                        times = base + days(rawTime);

                    case 'minutes'
                        times = base + minutes(rawTime);

                    case 'seconds'
                        times = base + seconds(rawTime);

                    otherwise
                        error( ...
                            'Unsupported NetCDF time unit: %s', ...
                            timeUnit);
                end
            end


            % Read all original MOM header lines

            function headerLines = read_mom_header_lines_(filename)

                headerLines = {};

                fileID = fopen(filename, 'r');

                if fileID == -1
                    warning( ...
                        ['Unable to read the original MOM header because ' ...
                        'the source file could not be opened: %s'], ...
                        filename);
                    return;
                end

                fileCleanup = onCleanup( ...
                    @() fclose_if_valid_(fileID)); %#ok<NASGU>

                while true

                    currentLine = fgetl(fileID);

                    if ~ischar(currentLine)
                        break;
                    end

                    trimmedLine = strtrim(currentLine);

                    if isempty(trimmedLine)
                        headerLines{end + 1} = currentLine; %#ok<AGROW>
                        continue;
                    end

                    numericValues = sscanf(trimmedLine, '%f');

                    if numel(numericValues) >= 2
                        break;
                    end

                    headerLines{end + 1} = currentLine; %#ok<AGROW>
                end

                fclose(fileID);
                fileID = -1;

                clear fileCleanup
            end


            % Close an open file safely
            function fclose_if_valid_(fileID)

                if isnumeric(fileID) && ...
                        isscalar(fileID) && ...
                        fileID > 0

                    try
                        fclose(fileID);
                    catch

                    end
                end
            end
        end

        % Button pushed function: OriginMatButton
        function OriginMatButtonPushed(app, event)
            importAnalysisData_(app, 'originData', 'OriginData', 'OriginData');
        end

        % Button pushed function: ResidualMatButton
        function ResidualMatButtonPushed(app, event)
            importAnalysisData_(app, 'residualData', 'ResData', 'ResidualData');
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            [residualTimeSeriesData, ok] = getSelectedResidualData_(app);
            if ~ok
                return;
            end

            numEpochs = size(residualTimeSeriesData, 1);
            numStations = size(residualTimeSeriesData, 2);
            if strcmp(app.SelectFilterDropDown.Value, 'PCA')
                maxComponents = min(numEpochs - 1, numStations);
            else
                maxComponents = min(numEpochs, numStations);
            end

            if strcmp(app.SelectFilterDropDown.Value, 'VBPCA')
                requestedComponents = app.vbpcaNumPCSpinner.Value;
                if requestedComponents > maxComponents
                    uialert(app.PrideGTSUIFigure, ...
                        sprintf(['The VBPCA component count must not exceed ' ...
                        'min(epochs, stations) = %d.'], maxComponents), ...
                        'Component Error', 'Icon', 'warning');
                    return;
                end
                maxComponents = min(maxComponents, requestedComponents);
            end

            if maxComponents < 1
                uialert(app.PrideGTSUIFigure, ...
                    'At least two epochs are required for this analysis.', ...
                    'Data Error', 'Icon', 'warning');
                return;
            end
            [numReconPcs, ~, ok] = validateSelectionBounds_(app, ...
                maxComponents, numStations);
            if ~ok
                return;
            end

            try
                app.filterResult = runSelectedFilter_(app, ...
                    residualTimeSeriesData, numReconPcs);
                if ~isstruct(app.filterResult) || ...
                        ~isfield(app.filterResult, 'Contribution')
                    error('PrideGTS:InvalidFilterResult', ...
                        'The selected filter returned an invalid result.');
                end

                plotContributions_(app);
                refreshCmeAnalysisPlots_(app);
            catch ME
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('The analysis failed:\n%s', ME.message), ...
                    'Analysis Error', 'Icon', 'error');
            end
        end

        % Button pushed function: Discorr
        function DiscorrPushed(app, event)
            try
                refreshCorrelationPlots_(app);
            catch ME
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('The correlation calculation failed:\n%s', ME.message), ...
                    'Correlation Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ReconCME
        function ReconCMEButtonPushed(app, event)
            try
                refreshCmeAnalysisPlots_(app);
            catch ME
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('The CME reconstruction failed:\n%s', ME.message), ...
                    'CME Reconstruction Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ComponentDropDown.Value = 'North';
            app.SelectFilterDropDown.Value = 'PCA';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                % Check if input data is empty
                if isempty(app.originData)
                    uialert(app.PrideGTSUIFigure, 'Please import the data to be corrected.', 'Data Import Error', 'Icon', 'warning');
                    return
                end

                if isempty(app.residualData)
                    uialert(app.PrideGTSUIFigure, 'Please import residual data.', 'Data Import Error', 'Icon', 'warning');
                    return
                end

                if isempty(app.filterResult)
                    uialert(app.PrideGTSUIFigure, 'Please perform the filter calculation.', 'Filter Calculation Error', 'Icon', 'warning');
                    return
                end

                % Prompt user to select save directory
                SavePath = uigetdir(pwd, 'Select directory to save CME-removed time series');
                if isequal(SavePath, 0)
                    uialert(app.PrideGTSUIFigure, 'No save path selected. Operation canceled.', 'Operation Canceled', 'Icon', 'info');
                    return
                end

                app.removeCmeSavePath = SavePath;

                % Retrieve configuration parameters
                numPCs = app.PCsSpinner.Value; % Number of principal components
                selectedComponent = app.ComponentDropDown.Value; % Selected component

                % Select data based on the chosen component
                switch selectedComponent
                    case 'North'
                        originalTimeSeriesData = app.originData.dn;
                        componentNum = 0;
                    case 'East'
                        originalTimeSeriesData = app.originData.de;
                        componentNum = 1;
                    case 'Up'
                        originalTimeSeriesData = app.originData.du;
                        componentNum = 2;
                end

                % Calculate and remove Common Mode Error (CME)
                CME = rcme1(app, app.filterResult.Components(:, 1:numPCs), ...
                    app.filterResult.SpatialResponsesMatrix(:, 1:numPCs));

                FilteredData = originalTimeSeriesData - CME;

                % Get number of stations
                numStations = size(app.originData.site, 1);

                % Save data for each station
                for i = 1:numStations
                    stationNames = app.originData.site(i, :);
                    try
                        save_component_to_file(stationNames, SavePath, app.originData.day, FilteredData, i, componentNum);
                    catch ME
                        warning('Failed to save data for station %s: %s', stationNames, ME.message);
                        uialert(app.PrideGTSUIFigure, sprintf('Failed to save station %s: %s', stationNames, ME.message), ...
                            'Save Error', 'Icon', 'warning');
                    end
                end

                % Close progress bar and notify user of completion
                uialert(app.PrideGTSUIFigure, 'All files have been successfully saved.', 'Operation Completed', 'Icon', 'success');

            catch ME
                % Handle any unexpected errors
                uialert(app.PrideGTSUIFigure, sprintf('An error occurred: %s', ME.message), 'Error', 'Icon', 'error');
            end


            function save_component_to_file(stationName, Savepath, day, filtered, component_index, component_id)
                stationName = strtrim(char(stationName));
                name = sprintf("%s_%d.mom", stationName, component_id);
                FilesavePath = fullfile(Savepath, name);

                fileID = fopen(FilesavePath, 'w');
                if fileID == -1
                    error('Failed to open file: %s', FilesavePath);
                end
                fileCleanup = onCleanup(@() fclose(fileID));

                writeData = [day, filtered(:,component_index)];
                fprintf(fileID, '%f %f\n',writeData' );
            end
        end

        % Menu selected function: AboutMenu_2
        function AboutMenu_2Selected(app, event)
            runBundledApp_(app, fullfile('About', 'About.mlapp'));
        end

        % Button pushed function: ViewButton_2
        function ViewButton_2Pushed(app, event)
            if isempty(app.removeCmeSavePath) || isequal(app.removeCmeSavePath, 0) || ~isfolder(app.removeCmeSavePath)
                uialert(app.PrideGTSUIFigure, 'Please set a valid save path first.', ...
                    'Setting Error', 'Icon', 'warning');
                return;
            end

            winopen(app.removeCmeSavePath);
        end

        % Menu selected function: ThreeComponentOffsetDetectionMenu
        function ThreeComponentOffsetDetectionMenuSelected(app, event)
            runBundledApp_(app, fullfile('MGLR', 'MGLR.mlapp'));
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            updateWindowSizeControl(app);
        end

        % Menu selected function: KnownEventImportModuleMenu
        function KnownEventImportModuleMenuSelected(app, event)
            runBundledApp_(app, fullfile('knownEvents/', 'KnownEventImport.mlapp'));
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            try
                % The processed MOM files are always written to the folder
                % selected by savepathButtonPushed.
                if isempty(app.outlierProcessedPath) || ...
                        ~isfolder(app.outlierProcessedPath)
                    uialert(app.PrideGTSUIFigure, ...
                        'Please set a valid save folder before importing data.', ...
                        'Save Path Required', 'Icon', 'warning', 'Modal', true);
                    return;
                end

                loadpath = uigetdir(pwd, ...
                    'Select a MOM time-series folder to import and process');
                if isequal(loadpath, 0)
                    return;
                end
                loadpath = char(loadpath);

                if strcmpi(strtrim(loadpath), ...
                        strtrim(char(app.outlierProcessedPath)))
                    uialert(app.PrideGTSUIFigure, ...
                        ['The input folder and save folder must be different ' ...
                        'to avoid overwriting the original MOM files.'], ...
                        'Invalid Save Path', 'Icon', 'warning', 'Modal', true);
                    return;
                end

                momFiles = dir(fullfile(loadpath, '*.mom'));
                if isempty(momFiles)
                    uialert(app.PrideGTSUIFigure, ...
                        "File type mismatch, Must be '.mom'.", 'Warning', ...
                        'Icon', 'warning', 'Modal', true);
                    return;
                end

                % Build the tree directly in this callback.  Keep the normal
                % folder -> station -> MOM-file hierarchy without calling
                % LoadAnalysisFilesMenuSelected with an extra input argument.
                [~, folderName] = fileparts(loadpath);
                importedRoot = uitreenode(app.Tree, ...
                    'Text', folderName, 'NodeData', loadpath);
                fileGroups = containers.Map();

                for fileIndex = 1:numel(momFiles)
                    [~, momBaseName] = fileparts(momFiles(fileIndex).name);
                    stationToken = regexp(momBaseName, ...
                        '^(.*)_\d+$', 'tokens', 'once');
                    if isempty(stationToken)
                        stationName = momBaseName;
                    else
                        stationName = stationToken{1};
                    end

                    if isKey(fileGroups, stationName)
                        fileGroups(stationName) = ...
                            [fileGroups(stationName), momFiles(fileIndex)];
                    else
                        fileGroups(stationName) = momFiles(fileIndex);
                    end
                end

                stationNames = sort(keys(fileGroups));
                for stationIndex = 1:numel(stationNames)
                    stationName = stationNames{stationIndex};
                    stationFiles = fileGroups(stationName);
                    stationNode = uitreenode(importedRoot, ...
                        'Text', stationName, ...
                        'NodeData', fullfile(stationFiles(1).folder, ...
                        stationFiles(1).name));

                    for fileIndex = 1:numel(stationFiles)
                        uitreenode(stationNode, ...
                            'Text', stationFiles(fileIndex).name, ...
                            'NodeData', fullfile(stationFiles(fileIndex).folder, ...
                            stationFiles(fileIndex).name));
                    end
                end

                if isempty(importedRoot.Children)
                    error('No station nodes were created for the selected folder.');
                end

                % Select and display the first imported MOM file.  A station
                % node also points to a MOM file, but selecting the leaf makes
                % the current input explicit in the tree.
                firstStationNode = importedRoot.Children(1);
                if isempty(firstStationNode.Children)
                    firstFileNode = firstStationNode;
                else
                    firstFileNode = firstStationNode.Children(1);
                end
                expand(importedRoot);
                expand(firstStationNode);
                app.Tree.SelectedNodes = firstFileNode;
                TreeSelectionChanged(app, event);

                if isempty(app.activeStationData)
                    error('The first imported station contains no data to process.');
                end

                % Run with the method, scope, coefficient, window size,
                % detection strategy, and iteration count currently preset
                % in the Outlier Removal panel.
                RunButtonPushed(app, event);

            catch ME
                uialert(app.PrideGTSUIFigure, ...
                    "Failed to import or process MOM files: " + ME.message, ...
                    'Error', 'Icon', 'error', 'Modal', true);
            end
        end

        % Menu selected function: FeedbackMenu
        function FeedbackMenuSelected(app, event)
            url = 'https://github.com/bibinbing/PrideGTS';

            try
                web(url, '-browser');
            catch ME
                uialert(app.PrideGTSUIFigure, ...
                    sprintf('Unable to open the help page:\n%s', ME.message), ...
                    'Open Failed', ...
                    'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PrideGTSUIFigure = uifigure('Visible', 'off');
            app.PrideGTSUIFigure.Name = 'PrideGTS';
            app.PrideGTSUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'Main.png');
            app.PrideGTSUIFigure.CloseRequestFcn = createCallbackFcn(app, @PrideGTSUIFigureCloseRequest, true);
            app.PrideGTSUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @PrideGTSUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.PrideGTSUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadAnalysisFilesMenu = uimenu(app.FileMenu);
            app.LoadAnalysisFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAnalysisFilesMenuSelected, true);
            app.LoadAnalysisFilesMenu.Text = 'Load Analysis Files';
            app.ToolsMenu = uimenu(app.PrideGTSUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DataFormattingModuleMenu = uimenu(app.ToolsMenu);
            app.DataFormattingModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @DataFormattingModuleMenuSelected, true);
            app.DataFormattingModuleMenu.Text = 'Data Formatting Module';
            app.MissingDataReconstructionModuleMenu = uimenu(app.ToolsMenu);
            app.MissingDataReconstructionModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @MissingDataReconstructionModuleMenuSelected, true);
            app.MissingDataReconstructionModuleMenu.Text = 'Missing Data Reconstruction Module';
            app.KnownEventImportModuleMenu = uimenu(app.ToolsMenu);
            app.KnownEventImportModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @KnownEventImportModuleMenuSelected, true);
            app.KnownEventImportModuleMenu.Text = 'Known Event Import Module';
            app.ThreeComponentOffsetDetectionMenu = uimenu(app.ToolsMenu);
            app.ThreeComponentOffsetDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ThreeComponentOffsetDetectionMenuSelected, true);
            app.ThreeComponentOffsetDetectionMenu.Text = 'Three-Component Offset Detection';
            app.HectorModuleMenu = uimenu(app.ToolsMenu);
            app.HectorModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @HectorModuleMenuSelected, true);
            app.HectorModuleMenu.Text = 'Hector Module';
            app.HelpMenu_2 = uimenu(app.PrideGTSUIFigure);
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu_2 = uimenu(app.HelpMenu_2);
            app.AboutMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AboutMenu_2Selected, true);
            app.AboutMenu_2.Text = 'About';
            app.FeedbackMenu = uimenu(app.HelpMenu_2);
            app.FeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @FeedbackMenuSelected, true);
            app.FeedbackMenu.Text = 'Feedback';
            app.Toolbar = uitoolbar(app.PrideGTSUIFigure);
            app.HomePushTool = uipushtool(app.Toolbar);
            app.HomePushTool.Tooltip = {'Restore All Coordinate Axes'};
            app.HomePushTool.ClickedCallback = createCallbackFcn(app, @HomePushToolClicked, true);
            app.HomePushTool.Icon = fullfile(pathToMLAPP, 'logo', '3d-house.png');
            app.ClearPushTool = uipushtool(app.Toolbar);
            app.ClearPushTool.Tooltip = {'Clear All Datasets'};
            app.ClearPushTool.ClickedCallback = createCallbackFcn(app, @ClearPushToolClicked, true);
            app.ClearPushTool.Icon = fullfile(pathToMLAPP, 'logo', 'brush.png');
            app.TabGroup = uitabgroup(app.PrideGTSUIFigure);
            app.TimeSeriesVisualizationandPreprocessingTab = uitab(app.TabGroup);
            app.TimeSeriesVisualizationandPreprocessingTab.Title = 'Time Series Visualization and Preprocessing';
            app.TimeSeriesFilesLabel = uilabel(app.TimeSeriesVisualizationandPreprocessingTab);
            app.TimeSeriesFilesLabel.Text = 'Time-Series Files';
            app.VisualPanel = uitabgroup(app.TimeSeriesVisualizationandPreprocessingTab);
            app.DataVisualizationTab = uitab(app.VisualPanel);
            app.DataVisualizationTab.Title = 'Data Visualization';
            app.EastUIAxes = uiaxes(app.DataVisualizationTab);
            ylabel(app.EastUIAxes, 'East (mm)')
            zlabel(app.EastUIAxes, 'Z')
            app.UpUIAxes = uiaxes(app.DataVisualizationTab);
            ylabel(app.UpUIAxes, 'Up (mm)')
            zlabel(app.UpUIAxes, 'Z')
            app.NorthUIAxes = uiaxes(app.DataVisualizationTab);
            ylabel(app.NorthUIAxes, 'North (mm)')
            zlabel(app.NorthUIAxes, 'Z')
            app.OutlierRemovalPanel = uipanel(app.TimeSeriesVisualizationandPreprocessingTab);
            app.OutlierRemovalPanel.Title = 'Outlier Removal';
            app.savepath = uibutton(app.OutlierRemovalPanel, 'push');
            app.savepath.ButtonPushedFcn = createCallbackFcn(app, @savepathButtonPushed, true);
            app.savepath.Icon = fullfile(pathToMLAPP, 'logo', 'diskette.png');
            app.savepath.Text = '';
            app.MethodDropDownLabel = uilabel(app.OutlierRemovalPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.OutlierRemovalPanel);
            app.MethodDropDown.Items = {'IQR', 'MAD', 'WindowIQR', 'WindowMAD'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'IQR';
            app.ProcessingScopeDropDownLabel = uilabel(app.OutlierRemovalPanel);
            app.ProcessingScopeDropDownLabel.Text = 'Processing Scope';
            app.ProcessingScopeDropDown = uidropdown(app.OutlierRemovalPanel);
            app.ProcessingScopeDropDown.Items = {'Single', 'Batch'};
            app.ProcessingScopeDropDown.Value = 'Batch';
            app.RunButton = uibutton(app.OutlierRemovalPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'logo', 'fire.png');
            app.RunButton.Text = 'Run';
            app.MaxIterationsEditFieldLabel = uilabel(app.OutlierRemovalPanel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.OutlierRemovalPanel, 'numeric');
            app.MaxIterationsEditField.Limits = [1 Inf];
            app.MaxIterationsEditField.RoundFractionalValues = 'on';
            app.MaxIterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxIterationsEditField.Value = 5;
            app.DetectionStrategyDropDownLabel = uilabel(app.OutlierRemovalPanel);
            app.DetectionStrategyDropDownLabel.Text = 'Detection Strategy';
            app.DetectionStrategyDropDown = uidropdown(app.OutlierRemovalPanel);
            app.DetectionStrategyDropDown.Items = {'Single-pass', 'Iterative'};
            app.DetectionStrategyDropDown.Value = 'Iterative';
            app.CoefficientEditField_2Label_2 = uilabel(app.OutlierRemovalPanel);
            app.CoefficientEditField_2Label_2.Text = 'Coefficient';
            app.CoefficientEditField = uieditfield(app.OutlierRemovalPanel, 'numeric');
            app.CoefficientEditField.Limits = [1 Inf];
            app.CoefficientEditField.ValueDisplayFormat = '%.0f';
            app.CoefficientEditField.Value = 3;
            app.WindowsizeLabel = uilabel(app.OutlierRemovalPanel);
            app.WindowsizeLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.OutlierRemovalPanel, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.ValueDisplayFormat = '%.0f';
            app.WindowSizeEditField.Value = 730;
            app.ImportButton_2 = uibutton(app.OutlierRemovalPanel, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Icon = 'file.png';
            app.ImportButton_2.Tooltip = {''};
            app.ImportButton_2.Text = 'Import';
            app.Tree = uitree(app.TimeSeriesVisualizationandPreprocessingTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.EnvironmentalLoadingPanel = uipanel(app.TimeSeriesVisualizationandPreprocessingTab);
            app.EnvironmentalLoadingPanel.Title = 'Environmental Loading ';
            app.ImportStationInfo = uibutton(app.EnvironmentalLoadingPanel, 'state');
            app.ImportStationInfo.ValueChangedFcn = createCallbackFcn(app, @ImportStationInfoValueChanged, true);
            app.ImportStationInfo.Tooltip = {'Path of station information file'};
            app.ImportStationInfo.Icon = fullfile(pathToMLAPP, 'logo', 'import.png');
            app.ImportStationInfo.Text = '';
            app.DownloadSettingsPanel = uipanel(app.EnvironmentalLoadingPanel);
            app.DownloadSettingsPanel.Title = 'Download Settings';
            app.StartDateDatePickerLabel = uilabel(app.DownloadSettingsPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.DownloadSettingsPanel);
            app.EndDateDatePickerLabel = uilabel(app.DownloadSettingsPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.DownloadSettingsPanel);
            app.NTALButton_2 = uibutton(app.DownloadSettingsPanel, 'state');
            app.NTALButton_2.Text = 'NTAL';
            app.NorthSouthCheckBox = uicheckbox(app.DownloadSettingsPanel);
            app.NorthSouthCheckBox.Text = 'North-South';
            app.NTOLButton_2 = uibutton(app.DownloadSettingsPanel, 'state');
            app.NTOLButton_2.Text = 'NTOL';
            app.EastWestCheckBox = uicheckbox(app.DownloadSettingsPanel);
            app.EastWestCheckBox.Text = 'East-West';
            app.HYDLButton_2 = uibutton(app.DownloadSettingsPanel, 'state');
            app.HYDLButton_2.Text = 'HYDL';
            app.VerticalCheckBox = uicheckbox(app.DownloadSettingsPanel);
            app.VerticalCheckBox.Text = 'Vertical';
            app.SavePathButton = uibutton(app.DownloadSettingsPanel, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = 'SavePath';
            app.DownloadButton = uibutton(app.DownloadSettingsPanel, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.CorrectionPanel = uipanel(app.EnvironmentalLoadingPanel);
            app.CorrectionPanel.Title = 'Correction';
            app.NTALButton = uibutton(app.CorrectionPanel, 'push');
            app.NTALButton.Text = 'NTAL';
            app.NTALSwitch = uiswitch(app.CorrectionPanel, 'slider');
            app.NTALSwitch.Value = 'On';
            app.NTOLButton = uibutton(app.CorrectionPanel, 'push');
            app.NTOLButton.Text = 'NTOL';
            app.NTOLSwitch = uiswitch(app.CorrectionPanel, 'slider');
            app.NTOLSwitch.Value = 'On';
            app.HYDLButton = uibutton(app.CorrectionPanel, 'push');
            app.HYDLButton.Text = 'HYDL';
            app.HYDLSwitch = uiswitch(app.CorrectionPanel, 'slider');
            app.HYDLSwitch.Value = 'On';
            app.ImportButton = uibutton(app.CorrectionPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CorrectButton = uibutton(app.CorrectionPanel, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct';
            app.DrawingSettingsPanel = uipanel(app.TimeSeriesVisualizationandPreprocessingTab);
            app.DrawingSettingsPanel.Title = 'Drawing Settings';
            app.XtickDropDown = uidropdown(app.DrawingSettingsPanel);
            app.XtickDropDown.Items = {'MJD', 'Year'};
            app.XtickDropDown.Value = 'Year';
            app.XtickDropDownLabel = uilabel(app.DrawingSettingsPanel);
            app.XtickDropDownLabel.Text = 'Xtick';
            app.MarkerDropDownLabel = uilabel(app.DrawingSettingsPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.DrawingSettingsPanel);
            app.MarkerDropDown.Items = {'Point', 'Line'};
            app.MarkerDropDown.Value = 'Point';
            app.ColorDropDown = uidropdown(app.DrawingSettingsPanel);
            app.ColorDropDown.Items = {'Black', 'Blue', 'Red'};
            app.ColorDropDown.Value = 'Black';
            app.ColorDropDownLabel = uilabel(app.DrawingSettingsPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.LineStyleLabel = uilabel(app.DrawingSettingsPanel);
            app.LineStyleLabel.Text = 'Line Style';
            app.LineStyleDropDown = uidropdown(app.DrawingSettingsPanel);
            app.LineStyleDropDown.Items = {'None', 'Solid'};
            app.LineStyleDropDown.Value = 'None';
            app.DefaultButton = uibutton(app.DrawingSettingsPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Icon = fullfile(pathToMLAPP, 'logo', 'scatter-graph.png');
            app.DefaultButton.Tooltip = {'Restore Default Drawing Settings'};
            app.DefaultButton.Text = 'Default';
            app.StatisticsButton = uibutton(app.DrawingSettingsPanel, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Icon = fullfile(pathToMLAPP, 'logo', 'cleaning.png');
            app.StatisticsButton.Tooltip = {'Calculate Statistics for All Stations'};
            app.StatisticsButton.Text = 'Statistics';
            app.Image = uiimage(app.DrawingSettingsPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo', 'settings.png');
            app.CommonModeErrorExtractionTab = uitab(app.TabGroup);
            app.CommonModeErrorExtractionTab.Title = 'Common-Mode Error Extraction';
            app.ImportDataPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.ImportDataPanel.Title = 'Import Data';
            app.OriginalDataLabel = uilabel(app.ImportDataPanel);
            app.OriginalDataLabel.Text = 'Original Data';
            app.OriginMatButton = uibutton(app.ImportDataPanel, 'push');
            app.OriginMatButton.ButtonPushedFcn = createCallbackFcn(app, @OriginMatButtonPushed, true);
            app.OriginMatButton.Text = 'mat';
            app.ResidualDataLabel = uilabel(app.ImportDataPanel);
            app.ResidualDataLabel.Text = 'Residual Data';
            app.ResidualMatButton = uibutton(app.ImportDataPanel, 'push');
            app.ResidualMatButton.ButtonPushedFcn = createCallbackFcn(app, @ResidualMatButtonPushed, true);
            app.ResidualMatButton.Text = 'mat';
            app.ResultsVisualizationPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.ResultsVisualizationPanel.Title = 'Results Visualization';
            app.ContributionUIAxes = uiaxes(app.ResultsVisualizationPanel);
            title(app.ContributionUIAxes, 'Contribution (%)')
            xlabel(app.ContributionUIAxes, 'PCs')
            app.ContributionUIAxes.SubtitleFontWeight = 'bold';
            app.CMEUIAxes = uiaxes(app.ResultsVisualizationPanel);
            title(app.CMEUIAxes, 'Common-Mode Error (mm)')
            xlabel(app.CMEUIAxes, 'Epoch')
            ylabel(app.CMEUIAxes, 'Station ID')
            app.CMEUIAxes.SubtitleFontWeight = 'bold';
            app.SpatialResponseUIAxes = uiaxes(app.ResultsVisualizationPanel);
            title(app.SpatialResponseUIAxes, 'Spatial Response (%)')
            xlabel(app.SpatialResponseUIAxes, 'PCs')
            app.SpatialResponseUIAxes.SubtitleFontWeight = 'bold';
            app.CorrelationUIAxes = uiaxes(app.ResultsVisualizationPanel);
            title(app.CorrelationUIAxes, 'Correlation')
            xlabel(app.CorrelationUIAxes, 'Station ID')
            app.CorrelationUIAxes.SubtitleFontWeight = 'bold';
            app.RMSUIAxes = uiaxes(app.ResultsVisualizationPanel);
            title(app.RMSUIAxes, 'RMS (mm)')
            xlabel(app.RMSUIAxes, 'Station ID')
            app.RMSUIAxes.SubtitleFontWeight = 'bold';
            app.CMEUI_1Axes = uiaxes(app.ResultsVisualizationPanel);
            title(app.CMEUI_1Axes, 'Common-Mode Error (mm)')
            xlabel(app.CMEUI_1Axes, 'Epoch')
            app.CMEUI_1Axes.SubtitleFontWeight = 'bold';
            app.VBPCAPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.VBPCAPanel.Title = 'VBPCA';
            app.NumberofPCsLabel = uilabel(app.VBPCAPanel);
            app.NumberofPCsLabel.Text = 'Number of PCs';
            app.vbpcaNumPCSpinner = uispinner(app.VBPCAPanel);
            app.vbpcaNumPCSpinner.Limits = [3 Inf];
            app.vbpcaNumPCSpinner.ValueDisplayFormat = '%.0f';
            app.vbpcaNumPCSpinner.Value = 10;
            app.HypUpdateSpinnerLabel = uilabel(app.VBPCAPanel);
            app.HypUpdateSpinnerLabel.Text = 'HypUpdate';
            app.HypUpdateSpinner = uispinner(app.VBPCAPanel);
            app.HypUpdateSpinner.Limits = [1 Inf];
            app.HypUpdateSpinner.ValueDisplayFormat = '%.0f';
            app.HypUpdateSpinner.Value = 100;
            app.DisplayCheckBox = uicheckbox(app.VBPCAPanel);
            app.DisplayCheckBox.Text = 'Display';
            app.DisplayCheckBox.Value = true;
            app.MaxItersSpinnerLabel = uilabel(app.VBPCAPanel);
            app.MaxItersSpinnerLabel.Text = 'MaxIters';
            app.MaxItersSpinner = uispinner(app.VBPCAPanel);
            app.MaxItersSpinner.Limits = [1000 Inf];
            app.MaxItersSpinner.ValueDisplayFormat = '%.0f';
            app.MaxItersSpinner.Value = 2000;
            app.AnalysisPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.Image2 = uiimage(app.AnalysisPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo', 'methodology.png');
            app.ComponentDropDownLabel = uilabel(app.AnalysisPanel);
            app.ComponentDropDownLabel.Text = 'Component';
            app.ComponentDropDown = uidropdown(app.AnalysisPanel);
            app.ComponentDropDown.Items = {'North', 'East', 'Up'};
            app.ComponentDropDown.Value = 'North';
            app.SelectFilterDropDownLabel = uilabel(app.AnalysisPanel);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.AnalysisPanel);
            app.SelectFilterDropDown.Items = {'PCA', 'VBPCA', 'VBRPCA', 'FastICA', 'VBICA'};
            app.SelectFilterDropDown.Value = 'PCA';
            app.ConfirmButton = uibutton(app.AnalysisPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.ResetButton = uibutton(app.AnalysisPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.VBRPCAPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.VBRPCAPanel.Title = 'VBRPCA';
            app.MaxItersSpinner_2Label = uilabel(app.VBRPCAPanel);
            app.MaxItersSpinner_2Label.Text = 'MaxIters';
            app.MaxItersSpinner_2 = uispinner(app.VBRPCAPanel);
            app.MaxItersSpinner_2.Limits = [50 Inf];
            app.MaxItersSpinner_2.ValueDisplayFormat = '%.0f';
            app.MaxItersSpinner_2.Value = 100;
            app.DisplayCheckBox_2 = uicheckbox(app.VBRPCAPanel);
            app.DisplayCheckBox_2.Text = 'Display';
            app.DisplayCheckBox_2.Value = true;
            app.InitializationDropDownLabel = uilabel(app.VBRPCAPanel);
            app.InitializationDropDownLabel.Text = 'Initialization';
            app.InitializationDropDown = uidropdown(app.VBRPCAPanel);
            app.InitializationDropDown.Items = {'ml', 'rand'};
            app.InitializationDropDown.Value = 'ml';
            app.ReconstructedCMEPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.ReconstructedCMEPanel.Title = 'Reconstructed CME';
            app.PCsSpinnerLabel = uilabel(app.ReconstructedCMEPanel);
            app.PCsSpinnerLabel.Text = 'PCs';
            app.PCsSpinner = uispinner(app.ReconstructedCMEPanel);
            app.PCsSpinner.Limits = [1 Inf];
            app.PCsSpinner.ValueDisplayFormat = '%.0f';
            app.PCsSpinner.Value = 1;
            app.ReconCME = uibutton(app.ReconstructedCMEPanel, 'push');
            app.ReconCME.ButtonPushedFcn = createCallbackFcn(app, @ReconCMEButtonPushed, true);
            app.ReconCME.Icon = fullfile(pathToMLAPP, 'logo', 'comfirm.png');
            app.ReconCME.Text = '';
            app.VBICAPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.VBICAPanel.Title = 'VBICA';
            app.MaxItersSpinner_3Label = uilabel(app.VBICAPanel);
            app.MaxItersSpinner_3Label.Text = 'MaxIters';
            app.MaxItersSpinner_3 = uispinner(app.VBICAPanel);
            app.MaxItersSpinner_3.Limits = [50 Inf];
            app.MaxItersSpinner_3.ValueDisplayFormat = '%.0f';
            app.MaxItersSpinner_3.Value = 500;
            app.SourceModelDropDownLabel = uilabel(app.VBICAPanel);
            app.SourceModelDropDownLabel.Text = 'Source Model';
            app.SourceModelDropDown = uidropdown(app.VBICAPanel);
            app.SourceModelDropDown.Items = {'g'};
            app.SourceModelDropDown.Value = 'g';
            app.ToleranceEditFieldLabel = uilabel(app.VBICAPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.VBICAPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.ValueDisplayFormat = '%0.0e';
            app.ToleranceEditField.Value = 0.0001;
            app.DisplayCheckBox_3 = uicheckbox(app.VBICAPanel);
            app.DisplayCheckBox_3.Text = 'Display';
            app.DisplayCheckBox_3.Value = true;
            app.ARDCheckBox = uicheckbox(app.VBICAPanel);
            app.ARDCheckBox.Text = 'ARD';
            app.ARDCheckBox.Value = true;
            app.GaussModelSpinnerLabel = uilabel(app.VBICAPanel);
            app.GaussModelSpinnerLabel.Text = 'GaussModel';
            app.GaussModelSpinner = uispinner(app.VBICAPanel);
            app.GaussModelSpinner.Limits = [2 5];
            app.GaussModelSpinner.ValueDisplayFormat = '%.0f';
            app.GaussModelSpinner.Value = 3;
            app.DistanceCorrelationPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.DistanceCorrelationPanel.Title = 'Distance Correlation';
            app.RefStationSpinnerLabel = uilabel(app.DistanceCorrelationPanel);
            app.RefStationSpinnerLabel.Text = 'RefStation';
            app.RefStationSpinner = uispinner(app.DistanceCorrelationPanel);
            app.RefStationSpinner.Limits = [1 Inf];
            app.RefStationSpinner.ValueDisplayFormat = '%.0f';
            app.RefStationSpinner.Value = 1;
            app.Discorr = uibutton(app.DistanceCorrelationPanel, 'push');
            app.Discorr.ButtonPushedFcn = createCallbackFcn(app, @DiscorrPushed, true);
            app.Discorr.Icon = fullfile(pathToMLAPP, 'logo', 'comfirm.png');
            app.Discorr.Text = '';
            app.DisplayHeatMapButton = uibutton(app.DistanceCorrelationPanel, 'state');
            app.DisplayHeatMapButton.Text = 'Display HeatMap';
            app.ExportPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.ExportPanel.Title = 'Export';
            app.SaveButton = uibutton(app.ExportPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ViewButton_2 = uibutton(app.ExportPanel, 'push');
            app.ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_2Pushed, true);
            app.ViewButton_2.Text = 'View';
            app.CMEAmplitudeDistributionPanel = uipanel(app.CommonModeErrorExtractionTab);
            app.CMEAmplitudeDistributionPanel.Title = 'CME Amplitude Distribution';
            app.CMEAmplitudeDistributionUIAxes = uiaxes(app.CMEAmplitudeDistributionPanel);
            xlabel(app.CMEAmplitudeDistributionUIAxes, 'CME (mm)')
            ylabel(app.CMEAmplitudeDistributionUIAxes, 'Density')
            app.Panel = uipanel(app.CommonModeErrorExtractionTab);
            app.psdUIAxes = uiaxes(app.Panel);
            xlabel(app.psdUIAxes, 'Frequency (cpy)')
            ylabel(app.psdUIAxes, 'PSD (mm^2/cpy)')
            % Show the figure after all components are created
            app.PrideGTSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrideGTS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrideGTSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrideGTSUIFigure)
        end
    end
end