classdef MGLR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MGLRUIFigure            matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        LoadFilesMenu           matlab.ui.container.Menu
        AnalysisMenu            matlab.ui.container.Menu
        RunOffsetDetectionMenu  matlab.ui.container.Menu
        StationListPanel        matlab.ui.container.Panel
        Tree                    matlab.ui.container.Tree
        VerticalUIAxes          matlab.ui.control.UIAxes
        NorthUIAxes             matlab.ui.control.UIAxes
        EastUIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        loadpath
        momlist
        selectedData
    end

    methods (Access = private)

        function [Mneudata,offsetMJDs] = LoadData(~,Path, filename)
            % This function is to Load three component .mom file data.
            Mneudata = [];
            offsetMJDs = cell(1, 3);
            try
                counter = 0;
                neudata = [];
                offsetMJDs = {};


                % Initialize min and max MJD
                bmaxMJD = -inf;
                bminMJD = inf;

                % Extract three components data.
                for i = 1:3
                    % textscan is more robust and fast than importdata
                    path1 = fullfile(Path, sprintf('%s_%d.mom', filename, counter));
                    fileID = fopen(path1,'r');

                    if fileID == -1
                        msgbox("No files","Warning","warn");
                        return
                    end

                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);

                    data = [momdata{1}];

                    bminMJD = min(bminMJD, min(data));
                    bmaxMJD = max(bmaxMJD, max(data));

                    counter = counter+1;
                end

                counter = 0;
                for file = 1:3
                    path1 = fullfile(Path, sprintf('%s_%d.mom', filename, counter));
                    fileID = fopen(path1,'r');

                    if fileID == -1
                        msgbox("No files","Warning","warn");
                        return
                    end

                    offsetMJD = [];
                    % Read all # offset lines
                    while ~feof(fileID)
                        line = fgetl(fileID);
                        if contains(line, "# offset")
                            parts = strsplit(line);
                            offsetMJD = [offsetMJD,str2double(parts{3})];
                        end
                    end

                    % If no offset found, store an empty array
                    if isempty(offsetMJD)
                        offsetMJDs{file} = [];
                    else
                        offsetMJDs{file} = offsetMJD;
                    end

                    % Reset file pointer and read data
                    frewind(fileID);
                    momdata = textscan(fileID, "%f %f %f", 'CommentStyle', '#');
                    fclose(fileID);
                    if ~isempty(momdata{3}) && all(isnan(momdata{3}))
                        data = [momdata{1},momdata{2}];
                    else
                        data = [momdata{1},momdata{2},momdata{3}];
                    end

                    MJDfull = (bminMJD : 1 : bmaxMJD);
                    if size(data,2) < 3
                        fullData = nan(length(MJDfull),2);
                    else
                        fullData = nan(length(MJDfull),3);
                    end
                    [~,loc] = ismember(data(:,1),MJDfull);
                    fullData(loc,2:end) = data(:,2:end);

                    if size(data,2) < 3
                        neudata = [neudata,fullData(:,2)];
                    else
                        neudata = [neudata,fullData(:,2),fullData(:,3)];
                    end

                    counter = counter + 1;
                end

                Mneudata = [MJDfull',neudata];

            catch ME
                Mneudata = [];
                offsetMJDs = cell(1, 3);
                errMsg = sprintf("Error: %s",ME.message);
                h = msgbox(errMsg,'Error','error');
                waitfor(h)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.MGLRUIFigure.Position(3);
            windowHeight = app.MGLRUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.MGLRUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

        end

        % Menu selected function: LoadFilesMenu
        function LoadFilesMenuSelected(app, event)
            % Load Time series files
            try
                % Prompt user to select a directory
                app.loadpath = uigetdir("*.*");
                if app.loadpath == 0
                    return % If the user cancels
                end
            catch ME
                % Display error message using uialert
                uialert(app.MGLRUIFigure, sprintf('Error loading files: %s', ME.message), 'Error', ...
                    'Icon', 'error');
                return
            end

            try
                % Clear existing tree nodes if any
                if ~isempty(app.Tree.Children)
                    delete(app.Tree.Children);
                end

                % Get list of .mom files
                app.momlist = dir(fullfile(app.loadpath, "*.mom"));
                [~, folderName] = fileparts(app.loadpath);

                if ~isempty(app.momlist)
                    % Initialize container for file groups
                    fileGroups = containers.Map();

                    % Create parent node for the folder
                    fatertree = uitreenode(app.Tree, 'Text', folderName, ...
                        'NodeData', app.loadpath);

                    % Group files by base name
                    for i = 1:length(app.momlist)
                        baseName = extractBefore(app.momlist(i).name, '_');
                        if isKey(fileGroups, baseName)
                            fileGroups(baseName) = [fileGroups(baseName), app.momlist(i)]; % Append to existing group
                        else
                            fileGroups(baseName) = app.momlist(i); % Create new group
                        end
                    end

                    % Create tree nodes for each group and its files
                    groupNames = keys(fileGroups);
                    for i = 1:length(groupNames)
                        groupName = groupNames{i};
                        filesInGroup = fileGroups(groupName);

                        firstFile = filesInGroup(1);
                        firstFilePath = fullfile(firstFile.folder, firstFile.name);
                        groupNode = uitreenode(fatertree, 'Text', groupName, 'NodeData', firstFilePath);

                        for j = 1:length(filesInGroup)
                            file = filesInGroup(j);
                            uitreenode(groupNode, 'Text', file.name, ...
                                'NodeData', fullfile(file.folder, file.name));
                        end
                    end
                else
                    % Display warning if no .mom files found
                    uialert(app.MGLRUIFigure, 'File type mismatch, must be ''.mom''.', 'Warning', ...
                        'Icon', 'warning');
                    return
                end
            catch ME
                % Display error message using uialert
                uialert(app.MGLRUIFigure, sprintf('Error: %s', ME.message), 'Error', ...
                    'Icon', 'error');
                return
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            if isempty(selectedNodes) || isempty(selectedNodes.NodeData)
                return
            end

            filePath = selectedNodes.NodeData; % Such as:C:\Users\xx\Desktop\gx\station_1.mom
            % Load three componet data, is a [MJD North East Up] matrix.
            % Passing to global variable: app.selectedData.
            if isfile(filePath)
                try

                    [Path,filename,~] = fileparts(filePath);% Path :C:\Users\xx\Desktop\gx  filename :BJFS_1.mom
                    filename = extractBefore(filename, '_'); % Extract the base file name before '-' char.

                    [full_neudata,offsetMJDs] = LoadData(app,Path,filename);

                    app.selectedData = full_neudata;

                catch ME
                    errMsg = sprintf('Failed to load file: %s', ME.message);
                    uialert(app.MGLRUIFigure, errMsg, 'Error', 'Icon', 'error', 'Modal', true);
                    return
                end
            else
                return
            end

            if isempty(full_neudata) || size(full_neudata, 2) < 4
                uialert(app.MGLRUIFigure, 'The selected station does not have a complete three-component data set.', ...
                    'Invalid Data', 'Icon', 'warning', 'Modal', true);
                return
            end

            % Recall function.
            plotData(app,full_neudata,offsetMJDs);

            function plotData(app, full_neudata, offsetMJDs)
                if size(full_neudata, 2) == 4
                    % Basic plot when there are 4 columns
                    plotBasicData(app, full_neudata);

                    % If offsetMJDs is provided, draw vertical lines
                    if nargin > 2 && ~isempty(offsetMJDs)
                        plotOffsetLines(app, offsetMJDs,full_neudata);
                    end

                else
                    % Clear the axes before plotting new data
                    cla(app.NorthUIAxes);
                    cla(app.EastUIAxes);
                    cla(app.VerticalUIAxes);

                    hold(app.NorthUIAxes, 'on');
                    hold(app.EastUIAxes, 'on');
                    hold(app.VerticalUIAxes, 'on');

                    % Plot origin data (first 2, 4, 6 columns)
                    plotOriginData(app, full_neudata);

                    % Plot model fix data (3, 5, 7 columns)
                    plotModelFixData(app, full_neudata);

                    % If offsetMJDs is provided, draw vertical lines
                    if nargin > 2 && ~isempty(offsetMJDs)
                        plotOffsetLines(app, offsetMJDs, full_neudata);
                    end

                    hold(app.NorthUIAxes, 'off');
                    hold(app.EastUIAxes, 'off');
                    hold(app.VerticalUIAxes, 'off');
                end
            end

            % 1970 + (MJD - 40587 + 0.5) / 365.25;
            % Function to plot basic data when there are 4 columns
            function plotBasicData(app, full_neudata)
                plot(app.NorthUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 2), 'black.');
                plot(app.EastUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 3), 'black.');
                plot(app.VerticalUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 4), 'black.');
            end

            % Function to plot origin data (first 2, 4, 6 columns)
            function plotOriginData(app, full_neudata)
                plot(app.NorthUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 2), 'black.');
                plot(app.EastUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 4), 'black.');
                plot(app.VerticalUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 6), 'black.');
            end

            % Function to plot model fix data (3, 5, 7 columns)
            function plotModelFixData(app, full_neudata)
                % Plot the model fix data
                plot(app.NorthUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 3), 'Color', 'red', 'LineWidth', 2);
                plot(app.EastUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 5), 'Color', 'red', 'LineWidth', 2);
                plot(app.VerticalUIAxes, (1970+(full_neudata(:, 1)-40587+0.5)/365.25), full_neudata(:, 7), 'Color', 'red', 'LineWidth', 2);
            end


            % Function to plot offset MJD lines
            function plotOffsetLines(app, offsetMJDs, ~)
                % Loop through each axis (North, East, Vertical)
                axes = {app.NorthUIAxes, app.EastUIAxes, app.VerticalUIAxes};

                if ~iscell(offsetMJDs)
                    offsetMJDs = {offsetMJDs};
                end

                % Loop through the 3 components
                for i = 1:min(3, length(offsetMJDs))
                    if ~isempty(offsetMJDs{i}) && isvalid(axes{i})
                        mjd = offsetMJDs{i};
                        xValues = 1970 + (mjd - 40587 + 0.5) / 365.25;
                        for x = xValues(:)'
                            xline(axes{i}, x, 'Color', 'blue', 'LineWidth', 1, 'LineStyle', '--');
                        end
                    end
                end
            end
        end

        % Menu selected function: RunOffsetDetectionMenu
        function RunOffsetDetectionMenuSelected(app, event)
            % Detect offsets for every loaded station and add only the new
            % accepted epochs to the headers of its three MOM components.
            if isempty(app.loadpath) || ~isfolder(app.loadpath)
                uialert(app.MGLRUIFigure, ...
                    'Load a folder containing MOM files before running.', ...
                    'Run', 'Icon', 'warning');
                return
            end

            appFile = which(class(app));
            if isempty(appFile)
                appFile = mfilename('fullpath');
            end
            codeFolder = fullfile(fileparts(appFile), 'code');
            if ~isfolder(codeFolder)
                uialert(app.MGLRUIFigure, sprintf( ...
                    'The offset-detection code folder was not found:\n%s', ...
                    codeFolder), 'Run', 'Icon', 'error');
                return
            end
            addpath(codeFolder);

            try
                stationFiles = offset_inputs({app.loadpath});
            catch ME
                uialert(app.MGLRUIFigure, ME.message, 'Run', ...
                    'Icon', 'error');
                return
            end
            if isempty(stationFiles)
                uialert(app.MGLRUIFigure, ...
                    'No station MOM groups were found in the loaded folder.', ...
                    'Run', 'Icon', 'warning');
                return
            end

            app.RunOffsetDetectionMenu.Enable = 'off';
            try
                progressDialog = uiprogressdlg(app.MGLRUIFigure, ...
                    'Title', 'MGLR offset detection', ...
                    'Message', 'Preparing station data...', 'Value', 0);
            catch ME
                app.RunOffsetDetectionMenu.Enable = 'on';
                uialert(app.MGLRUIFigure, ME.message, 'Run', ...
                    'Icon', 'error');
                return
            end
            runCleanup = onCleanup(@() restoreRunUi(app, progressDialog));

            opts = offset_defaults();
            opts.figures.enable = false;
            successCount = 0;
            totalAddedCount = 0;
            failureMessages = cell(0, 1);

            for stationIndex = 1:numel(stationFiles)
                inputFile = stationFiles{stationIndex};
                [~, stationName] = fileparts(inputFile);
                stationName = regexprep(stationName, '_[012]$', '');
                progressDialog.Value = (stationIndex - 1) / numel(stationFiles);
                progressDialog.Message = sprintf( ...
                    'Processing %d of %d: %s', stationIndex, ...
                    numel(stationFiles), stationName);
                drawnow limitrate;

                try
                    timeSeries = offset_read(inputFile);
                    detection = offset_detect(timeSeries, opts);

                    % appliedIndices contains known and newly detected
                    % persistent offsets.  Intersecting it with
                    % detectedIndices excludes pre-existing header offsets
                    % as well as rejected low-support/transient candidates.
                    newIndices = detection.appliedIndices(ismember( ...
                        detection.appliedIndices, detection.detectedIndices));
                    newOffsetMjd = timeSeries.mjd(newIndices);
                    addedCount = appendOffsetHeaders( ...
                        timeSeries.metadata.mom, newOffsetMjd);

                    successCount = successCount + 1;
                    totalAddedCount = totalAddedCount + addedCount;
                catch ME
                    failureMessages{end + 1, 1} = sprintf( ...
                        '%s: %s', stationName, ME.message); %#ok<AGROW>
                end

                progressDialog.Value = stationIndex / numel(stationFiles);
                drawnow limitrate;
            end

            % Refresh the selected station so newly added offset lines are
            % immediately visible on the three axes.
            if successCount > 0 && ~isempty(app.Tree.SelectedNodes)
                TreeSelectionChanged(app, []);
            end

            failureCount = numel(failureMessages);
            summary = sprintf([ ...
                'Processed %d of %d station(s).\n', ...
                'Added %d new offset epoch(s) to their MOM headers.'], ...
                successCount, numel(stationFiles), totalAddedCount);
            if failureCount > 0
                shownFailureCount = min(failureCount, 5);
                summary = sprintf('%s\n\nFailed station(s):\n%s', ...
                    summary, strjoin( ...
                    failureMessages(1:shownFailureCount), newline));
                if failureCount > shownFailureCount
                    summary = sprintf('%s\n... and %d more.', summary, ...
                        failureCount - shownFailureCount);
                end
            end

            clear runCleanup
            if failureCount == 0
                uialert(app.MGLRUIFigure, summary, 'Run completed', ...
                    'Icon', 'success');
            elseif successCount == 0
                uialert(app.MGLRUIFigure, summary, 'Run failed', ...
                    'Icon', 'error');
            else
                uialert(app.MGLRUIFigure, summary, ...
                    'Run completed with warnings', 'Icon', 'warning');
            end

            function addedCount = appendOffsetHeaders(mom, offsetMjd)
                % Prepend new offset comments without changing the existing
                % bytes in any header, observation row, or line ending.
                offsetMjd = unique(offsetMjd(isfinite(offsetMjd)), 'sorted');
                addedCount = 0;
                if isempty(offsetMjd)
                    return
                end

                componentFiles = mom.componentFiles;
                tempFiles = repmat({''}, 1, numel(componentFiles));
                backupFiles = repmat({''}, 1, numel(componentFiles));
                filesToUpdate = false(1, numel(componentFiles));
                epochWasAdded = false(size(offsetMjd));

                try
                    for componentIndex = 1:numel(componentFiles)
                        existingMjd = mom.components(componentIndex).offsetMjd(:);
                        duplicate = false(size(offsetMjd));
                        for offsetIndex = 1:numel(offsetMjd)
                            duplicate(offsetIndex) = any(abs(existingMjd - ...
                                offsetMjd(offsetIndex)) <= 5e-5);
                        end
                        offsetsToAdd = offsetMjd(~duplicate);
                        if isempty(offsetsToAdd)
                            continue
                        end

                        targetFile = componentFiles{componentIndex};
                        originalBytes = readBinaryFile(targetFile);
                        updatedBytes = prependOffsetLines( ...
                            originalBytes, offsetsToAdd);
                        targetFolder = fileparts(targetFile);
                        tempFiles{componentIndex} = tempname(targetFolder);
                        backupFiles{componentIndex} = tempname(targetFolder);

                        [copied, copyMessage] = copyfile(targetFile, ...
                            backupFiles{componentIndex}, 'f');
                        if ~copied
                            error('MGLR:BackupFailed', ...
                                'Could not back up %s: %s', ...
                                targetFile, copyMessage);
                        end
                        writeBinaryFile(tempFiles{componentIndex}, updatedBytes);
                        filesToUpdate(componentIndex) = true;
                        epochWasAdded(~duplicate) = true;
                    end

                    for componentIndex = find(filesToUpdate)
                        [moved, moveMessage] = movefile( ...
                            tempFiles{componentIndex}, ...
                            componentFiles{componentIndex}, 'f');
                        if ~moved
                            error('MGLR:HeaderWriteFailed', ...
                                'Could not update %s: %s', ...
                                componentFiles{componentIndex}, moveMessage);
                        end
                        tempFiles{componentIndex} = '';
                    end
                catch writeError
                    rollbackMessages = cell(0, 1);
                    for componentIndex = find(filesToUpdate)
                        backupFile = backupFiles{componentIndex};
                        if isempty(backupFile) || ~isfile(backupFile)
                            continue
                        end
                        [restored, restoreMessage] = copyfile(backupFile, ...
                            componentFiles{componentIndex}, 'f');
                        if ~restored
                            rollbackMessages{end + 1, 1} = sprintf( ...
                                '%s: %s', componentFiles{componentIndex}, ...
                                restoreMessage); %#ok<AGROW>
                        end
                    end
                    deleteArtifacts(tempFiles, backupFiles);
                    if isempty(rollbackMessages)
                        rethrow(writeError)
                    end
                    error('MGLR:RollbackFailed', '%s\nRollback failed:\n%s', ...
                        writeError.message, strjoin(rollbackMessages, newline));
                end

                deleteArtifacts(tempFiles, backupFiles);
                addedCount = nnz(epochWasAdded);
            end

            function bytes = readBinaryFile(fileName)
                fileId = fopen(fileName, 'rb');
                if fileId < 0
                    error('MGLR:ReadFailed', ...
                        'Could not open %s for reading.', fileName);
                end
                fileCleanup = onCleanup(@() fclose(fileId));
                bytes = fread(fileId, inf, '*uint8').';
                clear fileCleanup
            end

            function writeBinaryFile(fileName, bytes)
                fileId = fopen(fileName, 'wb');
                if fileId < 0
                    error('MGLR:WriteFailed', ...
                        'Could not open %s for writing.', fileName);
                end
                fileCleanup = onCleanup(@() fclose(fileId));
                writtenCount = fwrite(fileId, bytes, 'uint8');
                if writtenCount ~= numel(bytes)
                    error('MGLR:IncompleteWrite', ...
                        'The temporary file was not written completely: %s', ...
                        fileName);
                end
                clear fileCleanup
            end

            function updatedBytes = prependOffsetLines(originalBytes, offsetMjd)
                if numel(originalBytes) > 1 && any( ...
                        originalBytes(1:end - 1) == 13 & ...
                        originalBytes(2:end) == 10)
                    lineEnding = char([13, 10]);
                else
                    lineEnding = newline;
                end

                headerText = '';
                for offsetIndex = 1:numel(offsetMjd)
                    headerText = [headerText, sprintf( ...
                        '# offset %.4f%s', offsetMjd(offsetIndex), ...
                        lineEnding)]; %#ok<AGROW>
                end
                headerBytes = uint8(unicode2native(headerText, 'UTF-8'));
                headerBytes = headerBytes(:).';

                utf8Bom = uint8([239, 187, 191]);
                if numel(originalBytes) >= 3 && ...
                        isequal(originalBytes(1:3), utf8Bom)
                    updatedBytes = [utf8Bom, headerBytes, ...
                        originalBytes(4:end)];
                else
                    updatedBytes = [headerBytes, originalBytes];
                end
            end

            function deleteArtifacts(tempFiles, backupFiles)
                artifactFiles = [tempFiles, backupFiles];
                for artifactIndex = 1:numel(artifactFiles)
                    artifactFile = artifactFiles{artifactIndex};
                    if isempty(artifactFile) || ~isfile(artifactFile)
                        continue
                    end
                    try
                        delete(artifactFile);
                    catch
                        % Best-effort cleanup must not hide the run result.
                    end
                end
            end

            function restoreRunUi(app, progressDialog)
                try
                    if ~isempty(progressDialog) && isvalid(progressDialog)
                        close(progressDialog);
                    end
                catch
                    % The progress dialog may already have been closed.
                end
                if isvalid(app) && ~isempty(app.RunOffsetDetectionMenu) && ...
                        isvalid(app.RunOffsetDetectionMenu)
                    app.RunOffsetDetectionMenu.Enable = 'on';
                    drawnow limitrate;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MGLRUIFigure = uifigure('Visible', 'off');
            app.MGLRUIFigure.Name = 'MGLR';
            app.FileMenu = uimenu(app.MGLRUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadFilesMenu = uimenu(app.FileMenu);
            app.LoadFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFilesMenuSelected, true);
            app.LoadFilesMenu.Text = 'Load Files';
            app.AnalysisMenu = uimenu(app.MGLRUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.RunOffsetDetectionMenu = uimenu(app.AnalysisMenu);
            app.RunOffsetDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @RunOffsetDetectionMenuSelected, true);
            app.RunOffsetDetectionMenu.Text = 'Run Offset Detection';
            app.EastUIAxes = uiaxes(app.MGLRUIFigure);
            ylabel(app.EastUIAxes, 'East (mm)')
            app.NorthUIAxes = uiaxes(app.MGLRUIFigure);
            ylabel(app.NorthUIAxes, 'North (mm)')
            app.VerticalUIAxes = uiaxes(app.MGLRUIFigure);
            ylabel(app.VerticalUIAxes, 'Up (mm)')
            app.StationListPanel = uipanel(app.MGLRUIFigure);
            app.StationListPanel.Title = 'Station List';
            app.Tree = uitree(app.StationListPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            % Show the figure after all components are created
            app.MGLRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MGLR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MGLRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MGLRUIFigure)
        end
    end
end