classdef EMInterp_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        Interp               matlab.ui.control.Button
        iters                matlab.ui.control.EditField
        text2                matlab.ui.control.Label
        uipanel2             matlab.ui.container.ButtonGroup
        radio_Kalman_filter  matlab.ui.control.RadioButton
        radio_Kalman_smooth  matlab.ui.control.RadioButton
        uipanel1             matlab.ui.container.ButtonGroup
        check_U              matlab.ui.control.CheckBox
        check_E              matlab.ui.control.CheckBox
        check_N              matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        function interp = EMEst(app, lon, lat, Z, VarigPar, ToUnitFlag, ToFiterFlag, iters, direction)
            %%
            if isempty(Z)
                interp = [];
                return;
            end
            
            %% initial interpolate missing data
            Ztmp = zeros(size(Z));
            %interpolated
            for i=1:size(Z,2)
                tmpZ = Z(:,i);
                day = (1:size(Z,1))';
                ij = isnan(tmpZ);
                tmpZ(ij)=[];
                day(ij) = [];
                Ztmp(:,i) = interp1(day,tmpZ,(1:size(Z,1))','linear');
            end
            %extrapolated
            for i=1:size(Z,2)
                tmpZ = Ztmp(:,i);
                day = (1:size(Z,1))';
                ij = isnan(tmpZ);
                tmpZ(ij)=[];
                day(ij) = [];
                p = polyfit(day,tmpZ,1);
                day = (1:size(Z,1))';
                Ztmp(ij,i) = polyval(p,day(ij));
            end
            
            
            %% %%%%%%%%%%%%compute EM initial values%%%%%%%%%%%%%%%%%%
            if isempty(VarigPar) || ToUnitFlag == 1
                %unit matrix instead spatial filed
                H = eye(size(Z,2));
            
                %observation noise covariance matrix
                sei2_error = 25;
                R = sei2_error*eye(size(H,1));
            
                %covariance matrix of initial state vector
                P0 = 0.5*R;
            
                %state transition matrix
                C1 = zeros(size(Z,2),size(Z,2));
                for i=1:(size(Z,1)-1)
                    C1 = C1 + (Ztmp(i+1,:)'*Ztmp(i,:))/size(Z,1);
                end
                C0 = zeros(size(Z,2),size(Z,2));
                for i=1:size(Z,1)
                    C0 = C0 + (Ztmp(i,:)'*Ztmp(i,:))/size(Z,1);
                end
                F = C1/C0;
            
                %system state noise covariance matrix
                Q = C0-C1/C0*C1';
            
            else
                %spatial filed
                if ~isfield(VarigPar, 'sill') || isempty(VarigPar.sill)
                    error('EMInterp_App:InvalidVariogramSill', ...
                        'The fitted semi-variogram does not contain a sill value.');
                end
                sillValue = double(VarigPar.sill);
                if ~isscalar(sillValue) || ~isfinite(sillValue) || sillValue <= 0
                    error('EMInterp_App:InvalidVariogramSill', ...
                        'The semi-variogram sill must be a positive finite scalar.');
                end
                if ~isfield(VarigPar, 'nugget') || isempty(VarigPar.nugget)
                    % An empty nugget means that no nugget component was fitted.
                    nuggetValue = 0;
                else
                    nuggetValue = double(VarigPar.nugget);
                end
                if ~isscalar(nuggetValue) || ~isfinite(nuggetValue) || nuggetValue < 0
                    error('EMInterp_App:InvalidVariogramNugget', ...
                        'The semi-variogram nugget must be a nonnegative finite scalar.');
                end
                H = SpatialFiled(lon,lat,VarigPar,0.98);
                p = size(H,2);
            
                %observation noise covariance matrix
                % Keep R positive definite when the fitted model has no nugget.
                % The floor is only numerical regularization; the fitted nugget
                % remains zero in the semi-variogram parameters.
                observationNugget = max(nuggetValue, ...
                    max(eps(sillValue), 1e-6*sillValue));
                R = observationNugget*eye(size(H,1));
            
                %covariance matrix of initial state vector
                [Qs,Rs]=qr(H,0);
                P0 = inv(Rs)*Qs'*(sillValue*eye(size(H,1)))*Qs*(inv(Rs))';
            
                % Initialize the spatial state dynamics from the filled data.
                % The former fixed F = 0.8*I forced the latent state toward
                % zero through long gaps and caused large one-iteration arcs.
                spatialScale = norm(H, 'fro')^2;
                spatialRidge = max(eps(spatialScale), ...
                    1e-8*spatialScale/max(p, 1));
                stateHistory = ((H'*H + spatialRidge*speye(p)) \ ...
                    (H'*Ztmp'))';
                if size(stateHistory, 1) < 2 || ...
                        any(~isfinite(stateHistory(:)))
                    error('EMInterp_App:InvalidSpatialInitialization', ...
                        'Unable to initialize spatial KKF states from the data.');
                end
                previousState = stateHistory(1:end-1, :);
                nextState = stateHistory(2:end, :);
                stateScatter = previousState'*previousState;
                transitionScale = trace(stateScatter)/max(p, 1);
                transitionRidge = max(eps(norm(stateScatter, 'fro')), ...
                    1e-8*transitionScale);
                F = (nextState'*previousState) / ...
                    (stateScatter + transitionRidge*eye(p));
                spectralRadius = max(abs(eig(full(F))));
                if isfinite(spectralRadius) && spectralRadius > 1.02
                    F = F*(1.02/spectralRadius);
                end
                stateResidual = nextState' - F*previousState';
                Q = (stateResidual*stateResidual')/size(previousState, 1);
                Q = (Q + Q')/2;
                processFloor = max(eps(sillValue), 1e-9*sillValue);
                Q = Q + processFloor*eye(p);
    
            end
            
            alpha0 = zeros(size(H,2),1);
            
            %% %%%%%%%%%%%%%%%%%%EM iteration%%%%%%%%%%%%%%%%%%
            if ToFiterFlag==1
                interp = EMEst_filter(Z,H,...
                    F,alpha0,P0,R,Q,iters,direction);
            else
                interp = EMEst_smooth(Z,H,...
                    F,alpha0,P0,R,Q,iters,direction);
            end
        end
        
        function errorDlg(app, str)
            uialert(app.figure1, str, 'Error', 'Icon', 'error');
        end
        
    end

    methods (Access = public)
        function runModal(app, Src_data, VarigPar, outputMode)
            % Configure this KKF dialog from the parent, then wait for
            % interpolation or a normal user cancellation.
            if nargin < 4 || isempty(outputMode)
                outputMode = 'missing-only';
            end
            if ~any(strcmp(outputMode, {'missing-only', 'full-series'}))
                error('EMInterp_App:InvalidOutputMode', ...
                    'Unsupported KKF output mode: %s', outputMode);
            end

            app.figure1.UserData = struct('Src_data', Src_data, ...
                'VarigPar', VarigPar, 'outputMode', outputMode);
            app.Interp.UserData = struct('status', 'pending');
            uiwait(app.figure1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Interp
        function Interp_Callback(app, event)
            % Execute KKF and return a structured result to the parent app.
            request = app.figure1.UserData;
            requiredRequestFields = {'Src_data', 'VarigPar', 'outputMode'};
            if ~isstruct(request) || any(~isfield(request, requiredRequestFields))
                errorDlg(app, 'KKF must be started from MissingDataReconstruction.');
                return;
            end
            Src_data = request.Src_data;
            VarigPar = request.VarigPar;
            outputMode = request.outputMode;
            if isempty(Src_data)
                errorDlg(app, 'Please input data first!');
                return;
            end
            
            if isempty(VarigPar)
                VarigPar.VariogFitPar_N = [];
                VarigPar.VariogFitPar_E = [];
                VarigPar.VariogFitPar_U = [];
            end
            
            iterationText = strtrim(char(app.iters.Value));
            if isempty(iterationText)
                iterationCount = 20;
            else
                iterationCount = str2double(iterationText);
            end
            if ~isscalar(iterationCount) || ~isfinite(iterationCount) || ...
                    iterationCount <= 0 || iterationCount ~= fix(iterationCount)
                errorDlg(app, 'Number of EM iterations must be a positive integer!');
                return;
            end
            
            ToUnitFlag_N = app.check_N.Value;
            ToUnitFlag_E = app.check_E.Value;
            ToUnitFlag_U = app.check_U.Value;
            ToFiterFlag = app.radio_Kalman_filter.Value;
            
            originalButtonText = app.Interp.Text;
            app.Interp.Enable = 'off';
            app.Interp.Text = 'Interpolating...';
            drawnow;
            try
                interp_n = EMEst(app, Src_data.lon, Src_data.lat, Src_data.dn, ...
                    VarigPar.VariogFitPar_N, ToUnitFlag_N, ToFiterFlag, iterationCount, 1);
                interp_e = EMEst(app, Src_data.lon, Src_data.lat, Src_data.de, ...
                    VarigPar.VariogFitPar_E, ToUnitFlag_E, ToFiterFlag, iterationCount, 2);
                interp_u = EMEst(app, Src_data.lon, Src_data.lat, Src_data.du, ...
                    VarigPar.VariogFitPar_U, ToUnitFlag_U, ToFiterFlag, iterationCount, 3);
                
                if strcmp(outputMode, 'missing-only')
                    observed = ~isnan(Src_data.dn);
                    interp_n(observed) = Src_data.dn(observed);
                    observed = ~isnan(Src_data.de);
                    interp_e(observed) = Src_data.de(observed);
                    observed = ~isnan(Src_data.du);
                    interp_u(observed) = Src_data.du(observed);
                end
                
                Interp_data = struct('status', 'success', ...
                    'outputMode', outputMode, ...
                    'interpN', interp_n, 'interpE', interp_e, ...
                    'interpU', interp_u);
            catch ME
                Interp_data = struct('status', 'error', ...
                    'identifier', ME.identifier, 'message', ME.message);
            end
            
            if isvalid(app.Interp)
                app.Interp.Enable = 'on';
                app.Interp.Text = originalButtonText;
                app.Interp.UserData = Interp_data;
            end
            if isvalid(app.figure1)
                uiresume(app.figure1);
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Closing the dialog is a normal cancellation, not an error.
            app.Interp.UserData = struct('status', 'cancelled');
            uiresume(app.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'EMInterp';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Instead Spatial Field  by Unit Matrix';
            app.uipanel1.Tag = 'uipanel1';
            app.check_N = uicheckbox(app.uipanel1);
            app.check_N.Tag = 'check_N';
            app.check_N.Text = 'in N direction';
            app.check_E = uicheckbox(app.uipanel1);
            app.check_E.Tag = 'check_E';
            app.check_E.Text = 'in E direction';
            app.check_U = uicheckbox(app.uipanel1);
            app.check_U.Tag = 'check_U';
            app.check_U.Text = 'in U direction';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Title = 'EM with';
            app.uipanel2.Tag = 'uipanel2';
            app.radio_Kalman_smooth = uiradiobutton(app.uipanel2);
            app.radio_Kalman_smooth.Tag = 'radio_Kalman_smooth';
            app.radio_Kalman_smooth.Text = 'Kalman Smooth';
            app.radio_Kalman_smooth.Value = true;
            app.radio_Kalman_filter = uiradiobutton(app.uipanel2);
            app.radio_Kalman_filter.Tag = 'radio_Kalman_filter';
            app.radio_Kalman_filter.Text = 'Kalman Filter';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'number of EM iterations:';
            app.iters = uieditfield(app.figure1, 'text');
            app.iters.Tag = 'iters';
            app.iters.Value = '20';
            app.Interp = uibutton(app.figure1, 'push');
            app.Interp.ButtonPushedFcn = createCallbackFcn(app, @Interp_Callback, true);
            app.Interp.Tag = 'Interp';
            app.Interp.Text = 'You can interpolate your data now！';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMInterp_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end