classdef SemiVariog_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        CLOSE               matlab.ui.control.Button
        text16              matlab.ui.control.Label
        uipanel7            matlab.ui.container.ButtonGroup
        radio_spherical     matlab.ui.control.RadioButton
        radio_gaussian      matlab.ui.control.RadioButton
        radio_exponential   matlab.ui.control.RadioButton
        nugget              matlab.ui.control.EditField
        text15              matlab.ui.control.Label
        sill                matlab.ui.control.EditField
        text14              matlab.ui.control.Label
        FitExpVariogValue   matlab.ui.control.Button
        text13              matlab.ui.control.Label
        range               matlab.ui.control.EditField
        text12              matlab.ui.control.Label
        uipanel8            matlab.ui.container.ButtonGroup
        radio_none          matlab.ui.control.RadioButton
        radio_mcbratney86   matlab.ui.control.RadioButton
        radio_cressie85     matlab.ui.control.RadioButton
        uipanel6            matlab.ui.container.ButtonGroup
        Radio_constant      matlab.ui.control.RadioButton
        Radio_linear        matlab.ui.control.RadioButton
        Radio_cubic         matlab.ui.control.RadioButton
        text11              matlab.ui.control.Label
        PlotExpVariogValue  matlab.ui.control.Button
        maxdist             matlab.ui.control.EditField
        text10              matlab.ui.control.Label
        nrbins              matlab.ui.control.EditField
        text9               matlab.ui.control.Label
        uipanel_Direction   matlab.ui.container.ButtonGroup
        radio_N             matlab.ui.control.RadioButton
        radio_E             matlab.ui.control.RadioButton
        radio_U             matlab.ui.control.RadioButton
        VarigPlot           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function errorDlg(app, str)
            
            h = errordlg(str);
            set(h,'windowStyle','modal')
            g = handle(h);
            g.javaFrame.fHG1Client.getWindow.setAlwaysOnTop(true);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SemiVariog_OpeningFcn(app, varargin)
            % --- Executes just before SemiVariog is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SemiVariog (see VARARGIN)
            
            % Choose default command line output for SemiVariog
            handles.output = hObject;
            if ~isempty(varargin)
                handles.Src_data = varargin{1};
            end
            % Update handles structure
            guidata(hObject, handles);
            app.CLOSE.UserData = struct('status', 'pending');
            
            % UIWAIT makes SemiVariog wait for user response (see UIRESUME)
            uiwait(handles.figure1);
        end

        % Button pushed function: CLOSE
        function CLOSE_Callback(app, event)
            % --- Executes on button press in CLOSE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CLOSE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            VarigPar = struct('VariogFitPar_N', [], ...
                'VariogFitPar_E', [], 'VariogFitPar_U', []);
            if isfield(handles, 'VariogFitPar_N')
                VarigPar.VariogFitPar_N = handles.VariogFitPar_N;
            end
            if isfield(handles, 'VariogFitPar_E')
                VarigPar.VariogFitPar_E = handles.VariogFitPar_E;
            end
            if isfield(handles, 'VariogFitPar_U')
                VarigPar.VariogFitPar_U = handles.VariogFitPar_U;
            end
            app.CLOSE.UserData = struct('status', 'success', ...
                'VarigPar', VarigPar);
            uiresume(handles.figure1);
        end

        % Button pushed function: FitExpVariogValue
        function FitExpVariogValue_Callback(app, event)
            % --- Executes on button press in FitExpVariogValue.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FitExpVariogValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            direct_flag_N = get(handles.radio_N,'Value');
            direct_flag_E = get(handles.radio_E,'Value');
            direct_flag_U = get(handles.radio_U,'Value');
            if direct_flag_N == 1
                S = get(handles.radio_N,'UserData');
                if isempty(S)
                    errorDlg(app, 'You should calucate N direction Exp-Variogram Value firstly!');
                    return;
                end
            elseif direct_flag_E == 1
                S = get(handles.radio_E,'UserData');
                if isempty(S)
                    errorDlg(app, 'You should calucate E direction Exp-Variogram Value firstly!');
                    return;
                end
            else
                S = get(handles.radio_U,'UserData');
                if isempty(S)
                    errorDlg(app, 'You should calucate E direction Exp-Variogram Value firstly!');
                    return;
                end
            end
            
            model_flag_spherical = get(handles.radio_spherical,'Value');
            model_flag_gaussian = get(handles.radio_gaussian,'Value');
            if model_flag_spherical == 1
                model_str = 'spherical';
            elseif model_flag_gaussian == 1
                model_str = 'gaussian';
            else
                model_str = 'exponential';
            end
            
            range = str2num(get(handles.range,'String'));
            sill = str2num(get(handles.sill,'String'));
            nugget = str2num(get(handles.nugget,'String'));
            
            weight_flag_none = get(handles.radio_none,'Value');
            weight_flag_cressie85 = get(handles.radio_cressie85,'Value');
            if weight_flag_none == 1
                weight_str = 'none';
            elseif weight_flag_cressie85 == 1
                weight_str = 'cressie85';
            else
                weight_str = 'mcbratney86';
            end
            
            cla(handles.VarigPlot,'reset')
            axes(handles.VarigPlot)
            plot(S.distance,S.val,'^')
            hold on
            [a,c,n,SFit] = variogramfit(S.distance,S.val,range,sill,S.num,...
                'nugget',nugget,'weightfun',weight_str,'model',model_str,'plotit',false);
            SFit.trendfun = S.trendfun;
            if isempty(SFit.nugget)
                % No fitted nugget component is represented numerically as zero.
                SFit.nugget = 0;
            end
            % Evaluate the fitted model on a dense distance grid. Plotting only
            % SFit.gammahat at bin centers hides the curved 0-to-range segment
            % whenever the fitted range is shorter than the first bin distance.
            finiteDistance = SFit.h(isfinite(SFit.h));
            if isempty(finiteDistance) || ~isscalar(SFit.range) || ...
                    ~isfinite(SFit.range) || SFit.range <= 0
                errorDlg(app, 'The fitted variogram range is invalid.');
                return;
            end
            maximumDistance = max(finiteDistance);
            fitDistance = linspace(0, maximumDistance, 400);
            modelParameters = [SFit.range, SFit.sill];
            if strcmpi(SFit.type, 'bounded')
                fitGamma = (SFit.nugget + SFit.sill)*ones(size(fitDistance));
                withinRange = fitDistance <= SFit.range;
                fitGamma(withinRange) = SFit.nugget + ...
                    SFit.func(modelParameters, fitDistance(withinRange));
            else
                fitGamma = SFit.nugget + ...
                    SFit.func(modelParameters, fitDistance);
            end
            plot(fitDistance, fitGamma, '-', 'LineWidth', 1.2)
            xlim(handles.VarigPlot, [0 maximumDistance])
            if direct_flag_N == 1
                handles.VariogFitPar_N = SFit;
            elseif direct_flag_E == 1
                handles.VariogFitPar_E = SFit;
            else
                handles.VariogFitPar_U = SFit;
            end
            guidata(hObject, handles);
        end

        % Button pushed function: PlotExpVariogValue
        function PlotExpVariogValue_Callback(app, event)
            % --- Executes on button press in PlotExpVariogValue.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PlotExpVariogValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isempty(handles.Src_data)
                errorDlg(app, 'Please Input data firstly!');
                return;
            end
            Src_data = handles.Src_data;
            direct_flag_N = get(handles.radio_N,'Value');
            direct_flag_E = get(handles.radio_E,'Value');
            direct_flag_U = get(handles.radio_U,'Value');
            if direct_flag_N == 1 && ~isempty(Src_data.dn)
                data = Src_data.dn;
            elseif direct_flag_E == 1 && ~isempty(Src_data.de)
                data = Src_data.de;
            elseif direct_flag_U == 1 && ~isempty(Src_data.du)
                data = Src_data.du;
            else
                errorDlg(app, 'No Data in this direction!');
                return;
            end

            numSite = size(data, 2);
            hasCoordinates = isfield(Src_data, 'lat') && isfield(Src_data, 'lon') && ...
                isnumeric(Src_data.lat) && isnumeric(Src_data.lon) && ...
                isreal(Src_data.lat) && isreal(Src_data.lon) && ...
                isvector(Src_data.lat) && isvector(Src_data.lon) && ...
                numel(Src_data.lat) == numSite && numel(Src_data.lon) == numSite && ...
                numSite >= 2 && all(isfinite(double(Src_data.lat(:)))) && ...
                all(isfinite(double(Src_data.lon(:))));
            if hasCoordinates
                lat = double(Src_data.lat(:));
                lon = double(Src_data.lon(:));
                hasCoordinates = std(lat) > 0 && std(lon) > 0;
            end
            if ~hasCoordinates
                errorDlg(app, { ...
                    'A semi-variogram requires valid station coordinates.'; ...
                    'Fields lat and lon must each contain one finite value per station.'});
                return;
            end
            
            [nrbins,nrbins_flag] = str2num(get(handles.nrbins,'String'));
            if nrbins~=fix(nrbins)
                errorDlg(app, 'number bins the distance should be an integer!');
                return;
            end
            
            [maxdist,maxdist_flag] = str2num(get(handles.maxdist,'String'));
            trend_flag_C = get(handles.Radio_constant,'Value');
            trend_flag_L = get(handles.Radio_linear,'Value');
            if trend_flag_C == 1
                fun_handle = @trendpoly0;
            elseif trend_flag_L == 1
                fun_handle = @trendpoly1;
            else
                fun_handle = @trendpoly3;
            end
            
            try
                S = variog([lon lat], data, fun_handle, nrbins, maxdist);
            catch ME
                errorDlg(app, sprintf( ...
                    'Unable to calculate the semi-variogram: %s', ME.message));
                return;
            end
            S.trendfun = fun_handle;
            S.nrbins = nrbins;
            S.maxdist = maxdist;
            if direct_flag_N == 1
                set(handles.radio_N,'UserData',S);
            elseif direct_flag_E == 1
                set(handles.radio_E,'UserData',S);
            else
                set(handles.radio_U,'UserData',S);
            end
            cla(handles.VarigPlot,'reset')
            axes(handles.VarigPlot)
            plot(S.distance,S.val,'^')
            xlabel('lag distance h')
            ylabel('\gamma(h)')
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            % delete(hObject);
            app.CLOSE.UserData = struct('status', 'cancelled');
            uiresume(handles.figure1);
        end

        % Selection changed function: uipanel_Direction
        function uipanel_Direction_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in uipanel_Direction.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uipanel_Direction
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            direct_flag_N = get(handles.radio_N,'Value');
            direct_flag_E = get(handles.radio_E,'Value');
            
            if direct_flag_N == 1
                S = get(handles.radio_N,'UserData');
                if isempty(S)
                    cla(handles.VarigPlot,'reset');
                    set(handles.Radio_constant,'Value',1);
                    set(handles.nrbins,'String','');
                    set(handles.maxdist,'String','');
                    return;
                else
                    if isequal(S.trendfun,@trendpoly0)
                        set(handles.Radio_constant,'Value',1);
                    elseif isequal(S.trendfun,@trendpoly1)
                        set(handles.Radio_linear,'Value',1);
                    else
                        set(handles.Radio_Cubic,'Value',1);
                    end
                    set(handles.nrbins,'String',num2str(S.nrbins));
                    set(handles.maxdist,'String',num2str(S.maxdist));
            
                    cla(handles.VarigPlot,'reset')
                    axes(handles.VarigPlot)
                    plot(S.distance,S.val,'^')
                    xlabel('lag distance h')
                    ylabel('\gamma(h)')
                end
            elseif direct_flag_E == 1
                S = get(handles.radio_E,'UserData');
                if isempty(S)
                    cla(handles.VarigPlot,'reset');
                    set(handles.Radio_constant,'Value',1);
                    set(handles.nrbins,'String','');
                    set(handles.maxdist,'String','');
                    return;
                else
                    if isequal(S.trendfun,@trendpoly0)
                        set(handles.Radio_constant,'Value',1);
                    elseif isequal(S.trendfun,@trendpoly1)
                        set(handles.Radio_linear,'Value',1);
                    else
                        set(handles.Radio_Cubic,'Value',1);
                    end
                    set(handles.nrbins,'String',num2str(S.nrbins));
                    set(handles.maxdist,'String',num2str(S.maxdist));
            
                    cla(handles.VarigPlot,'reset')
                    axes(handles.VarigPlot)
                    plot(S.distance,S.val,'^')
                    xlabel('lag distance h')
                    ylabel('\gamma(h)')
                end
            else
                S = get(handles.radio_U,'UserData');
                if isempty(S)
                    cla(handles.VarigPlot,'reset');
                    set(handles.Radio_constant,'Value',1);
                    set(handles.nrbins,'String','');
                    set(handles.maxdist,'String','');
                    return;
                else
                    if isequal(S.trendfun,@trendpoly0)
                        set(handles.Radio_constant,'Value',1);
                    elseif isequal(S.trendfun,@trendpoly1)
                        set(handles.Radio_linear,'Value',1);
                    else
                        set(handles.Radio_Cubic,'Value',1);
                    end
                    set(handles.nrbins,'String',num2str(S.nrbins));
                    set(handles.maxdist,'String',num2str(S.maxdist));
            
                    cla(handles.VarigPlot,'reset')
                    axes(handles.VarigPlot)
                    plot(S.distance,S.val,'^')
                    xlabel('lag distance h')
                    ylabel('\gamma(h)')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'SemiVariog';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.VarigPlot = uiaxes(app.figure1);
            app.VarigPlot.Tag = 'VarigPlot';
            app.uipanel_Direction = uibuttongroup(app.figure1);
            app.uipanel_Direction.SelectionChangedFcn = createCallbackFcn(app, @uipanel_Direction_SelectionChangeFcn, true);
            app.uipanel_Direction.Title = 'Direction';
            app.uipanel_Direction.Tag = 'uipanel_Direction';
            app.radio_U = uiradiobutton(app.uipanel_Direction);
            app.radio_U.Tag = 'radio_U';
            app.radio_U.Text = 'U';
            app.radio_E = uiradiobutton(app.uipanel_Direction);
            app.radio_E.Tag = 'radio_E';
            app.radio_E.Text = 'E';
            app.radio_N = uiradiobutton(app.uipanel_Direction);
            app.radio_N.Tag = 'radio_N';
            app.radio_N.Text = 'N';
            app.radio_N.Value = true;
            app.uipanel6 = uibuttongroup(app.figure1);
            app.uipanel6.Title = 'Exp-Varig Value';
            app.uipanel6.Tag = 'uipanel6';
            app.text9 = uilabel(app.uipanel6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'number bins the distance:';
            app.nrbins = uieditfield(app.uipanel6, 'text');
            app.nrbins.Tag = 'nrbins';
            app.text10 = uilabel(app.uipanel6);
            app.text10.Tag = 'text10';
            app.text10.Text = 'maximum distance for variogram calculation:';
            app.maxdist = uieditfield(app.uipanel6, 'text');
            app.maxdist.Tag = 'maxdist';
            app.PlotExpVariogValue = uibutton(app.uipanel6, 'push');
            app.PlotExpVariogValue.ButtonPushedFcn = createCallbackFcn(app, @PlotExpVariogValue_Callback, true);
            app.PlotExpVariogValue.Tag = 'PlotExpVariogValue';
            app.PlotExpVariogValue.Text = 'Plot Exp-Variog Value';
            app.text11 = uilabel(app.uipanel6);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Trend:';
            app.Radio_cubic = uiradiobutton(app.uipanel6);
            app.Radio_cubic.Tag = 'Radio_cubic';
            app.Radio_cubic.Text = 'Cubic';
            app.Radio_linear = uiradiobutton(app.uipanel6);
            app.Radio_linear.Tag = 'Radio_linear';
            app.Radio_linear.Text = 'Linear';
            app.Radio_constant = uiradiobutton(app.uipanel6);
            app.Radio_constant.Tag = 'Radio_constant';
            app.Radio_constant.Text = 'Constant';
            app.Radio_constant.Value = true;
            app.uipanel7 = uibuttongroup(app.figure1);
            app.uipanel7.Title = 'Variogram Fit';
            app.uipanel7.Tag = 'uipanel7';
            app.uipanel8 = uibuttongroup(app.uipanel7);
            app.uipanel8.Title = 'Weight function:';
            app.uipanel8.Tag = 'uipanel8';
            app.radio_cressie85 = uiradiobutton(app.uipanel8);
            app.radio_cressie85.Tag = 'radio_cressie85';
            app.radio_cressie85.Text = 'cressie85';
            app.radio_mcbratney86 = uiradiobutton(app.uipanel8);
            app.radio_mcbratney86.Tag = 'radio_mcbratney86';
            app.radio_mcbratney86.Text = 'mcbratney86';
            app.radio_none = uiradiobutton(app.uipanel8);
            app.radio_none.Tag = 'radio_none';
            app.radio_none.Text = 'none';
            app.radio_none.Value = true;
            app.text12 = uilabel(app.uipanel7);
            app.text12.Tag = 'text12';
            app.text12.Text = 'initial range:';
            app.range = uieditfield(app.uipanel7, 'text');
            app.range.Tag = 'range';
            app.text13 = uilabel(app.uipanel7);
            app.text13.Tag = 'text13';
            app.text13.Text = 'initial sill:';
            app.FitExpVariogValue = uibutton(app.uipanel7, 'push');
            app.FitExpVariogValue.ButtonPushedFcn = createCallbackFcn(app, @FitExpVariogValue_Callback, true);
            app.FitExpVariogValue.Tag = 'FitExpVariogValue';
            app.FitExpVariogValue.Text = 'Fit Exp-Variog Value';
            app.text14 = uilabel(app.uipanel7);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Model:';
            app.sill = uieditfield(app.uipanel7, 'text');
            app.sill.Tag = 'sill';
            app.text15 = uilabel(app.uipanel7);
            app.text15.Tag = 'text15';
            app.text15.Text = 'initial nugget:';
            app.nugget = uieditfield(app.uipanel7, 'text');
            app.nugget.Tag = 'nugget';
            app.radio_exponential = uiradiobutton(app.uipanel7);
            app.radio_exponential.Tag = 'radio_exponential';
            app.radio_exponential.Text = 'exponential';
            app.radio_gaussian = uiradiobutton(app.uipanel7);
            app.radio_gaussian.Tag = 'radio_gaussian';
            app.radio_gaussian.Text = 'gaussian';
            app.radio_spherical = uiradiobutton(app.uipanel7);
            app.radio_spherical.Tag = 'radio_spherical';
            app.radio_spherical.Text = 'spherical';
            app.radio_spherical.Value = true;
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Semi-Variogram Fit';
            app.CLOSE = uibutton(app.figure1, 'push');
            app.CLOSE.ButtonPushedFcn = createCallbackFcn(app, @CLOSE_Callback, true);
            app.CLOSE.Tag = 'CLOSE';
            app.CLOSE.Text = 'Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SemiVariog_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SemiVariog_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end