classdef EMInterp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        Interp               matlab.ui.control.Button
        iters                matlab.ui.control.EditField
        text2                matlab.ui.control.Label
        text1                matlab.ui.control.Label
        uipanel2             matlab.ui.container.ButtonGroup
        radio_Kalman_filter  matlab.ui.control.RadioButton
        radio_Kalman_smooth  matlab.ui.control.RadioButton
        uipanel1             matlab.ui.container.ButtonGroup
        check_U              matlab.ui.control.CheckBox
        check_E              matlab.ui.control.CheckBox
        check_N              matlab.ui.control.CheckBox
    end


    methods (Access = private)
        function interp = EMEst(app, lon, lat, Z, VarigPar, ToUnitFlag, ToFiterFlag, iters, direction)
            %%
            if isempty(Z)
                interp = [];
                return;
            end

            %% initial interpolate missing data
            Ztmp = zeros(size(Z));
            %interpolated
            for i=1:size(Z,2)
                tmpZ = Z(:,i);
                day = (1:size(Z,1))';
                ij = isnan(tmpZ);
                tmpZ(ij)=[];
                day(ij) = [];
                Ztmp(:,i) = interp1(day,tmpZ,(1:size(Z,1))','linear');
            end
            %extrapolated
            for i=1:size(Z,2)
                tmpZ = Ztmp(:,i);
                day = (1:size(Z,1))';
                ij = isnan(tmpZ);
                tmpZ(ij)=[];
                day(ij) = [];
                p = polyfit(day,tmpZ,1);
                day = (1:size(Z,1))';
                Ztmp(ij,i) = polyval(p,day(ij));
            end


            %% %%%%%%%%%%%%compute EM initial values%%%%%%%%%%%%%%%%%%
            if isempty(VarigPar) || ToUnitFlag == 1
                %unit matrix instead spatial filed
                H = eye(size(Z,2));

                %observation noise covariance matrix
                sei2_error = 25;
                R = sei2_error*eye(size(H,1));

                %covariance matrix of initial state vector
                P0 = 0.5*R;

                %state transition matrix
                C1 = zeros(size(Z,2),size(Z,2));
                for i=1:(size(Z,1)-1)
                    C1 = C1 + (Ztmp(i+1,:)'*Ztmp(i,:))/size(Z,1);
                end
                C0 = zeros(size(Z,2),size(Z,2));
                for i=1:size(Z,1)
                    C0 = C0 + (Ztmp(i,:)'*Ztmp(i,:))/size(Z,1);
                end
                F = C1/C0;

                %system state noise covariance matrix
                Q = C0-C1/C0*C1';

            else
                %spatial filed
                H = SpatialFiled(lon,lat,VarigPar,0.98);
                p = size(H,2);

                %observation noise covariance matrix
                R = VarigPar.nugget*eye(size(H,1));

                %covariance matrix of initial state vector
                [Qs,Rs]=qr(H,0);
                P0 = inv(Rs)*Qs'*(VarigPar.sill*eye(size(H,1)))*Qs*(inv(Rs))';

                %state transition matrix
                r = 0.8;
                F = r*eye(size(H,2));

                %system state noise covariance matrix
                Q = 0.9*(VarigPar.sill+VarigPar.nugget)*eye(p);
                %   Q = 5*(VarigPar.sill+VarigPar.nugget)*eye(p);

            end

            alpha0 = zeros(size(H,2),1);

            %% %%%%%%%%%%%%%%%%%%EM iteration%%%%%%%%%%%%%%%%%%
            if ToFiterFlag==1
                interp = EMEst_filter(Z,H,...
                    F,alpha0,P0,R,Q,iters,direction);
            else
                interp = EMEst_smooth(Z,H,...
                    F,alpha0,P0,R,Q,iters,direction);
            end
        end

        function errorDlg(app, str)
            h = errordlg(str);
            set(h,'windowStyle','modal')
            g = handle(h);
            g.javaFrame.fHG1Client.getWindow.setAlwaysOnTop(true);
        end

        % Old version funtion "figure1_CloseRequestFcn" migrated to here
        function figure1_CloseRequestFcn(app, event)
            uiresume(app.figure1);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EMInterp_OpeningFcn(app, varargin)
            % --- Executes just before EMInterp is made visible.
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.figure1.Position(3);
            windowHeight = app.figure1.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.figure1.Position = [centerX, centerY, windowWidth, windowHeight];

            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to EMInterp (see VARARGIN)

            % Choose default command line output for EMInterp
            handles.output = hObject;

            handles.Src_data = varargin{1};
            handles.VarigPar = varargin{2};
            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes EMInterp wait for user response (see UIRESUME)
            uiwait(handles.figure1);
        end

        % Button pushed function: Interp
        function Interp_Callback(app, event)
            % --- Executes on button press in Interp.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to Interp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            Src_data = handles.Src_data;
            VarigPar = handles.VarigPar;
            if isempty(Src_data)
                errorDlg(app, 'Please Input data firstly!');
                return;
            end

            if isempty(VarigPar)
                VarigPar.VariogFitPar_N = [];
                VarigPar.VariogFitPar_E = [];
                VarigPar.VariogFitPar_U = [];
            end

            iters = str2num(get(handles.iters,'String'));
            if iters~=fix(iters)
                errorDlg(app, 'number of EM iterations should be an integer!');
                return;
            end

            if isempty(iters)
                iters = 20;
            end

            ToUnitFlag_N = get(handles.check_N,'Value');
            ToUnitFlag_E = get(handles.check_E,'Value');
            ToUnitFlag_U = get(handles.check_U,'Value');

            ToFiterFlag = get(handles.radio_Kalman_filter,'Value');
            interp_n = EMEst(app, Src_data.lon,Src_data.lat,Src_data.dn,...
                VarigPar.VariogFitPar_N,ToUnitFlag_N,ToFiterFlag,iters,1);
            interp_e = EMEst(app, Src_data.lon,Src_data.lat,Src_data.de,...
                VarigPar.VariogFitPar_E,ToUnitFlag_E,ToFiterFlag,iters,2);
            interp_u = EMEst(app, Src_data.lon,Src_data.lat,Src_data.du,...
                VarigPar.VariogFitPar_U,ToUnitFlag_U,ToFiterFlag,iters,3);
            Interp_data.interpN = interp_n;
            Interp_data.interpE = interp_e;
            Interp_data.interpU = interp_u;

            set(app.Interp,'UserData',Interp_data);
            uiresume(handles.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'EMInterp';
            app.figure1.Icon = 'filter.png';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Instead Spatial Field  by Unit Matrix';
            app.uipanel1.Tag = 'uipanel1';
            app.check_N = uicheckbox(app.uipanel1);
            app.check_N.Tag = 'check_N';
            app.check_N.Text = 'in N direction';
            app.check_E = uicheckbox(app.uipanel1);
            app.check_E.Tag = 'check_E';
            app.check_E.Text = 'in E direction';
            app.check_U = uicheckbox(app.uipanel1);
            app.check_U.Tag = 'check_U';
            app.check_U.Text = 'in U direction';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Title = 'EM with';
            app.uipanel2.Tag = 'uipanel2';
            app.radio_Kalman_smooth = uiradiobutton(app.uipanel2);
            app.radio_Kalman_smooth.Tag = 'radio_Kalman_smooth';
            app.radio_Kalman_smooth.Text = 'Kalman Smooth';
            app.radio_Kalman_smooth.Value = true;
            app.radio_Kalman_filter = uiradiobutton(app.uipanel2);
            app.radio_Kalman_filter.Tag = 'radio_Kalman_filter';
            app.radio_Kalman_filter.Text = 'Kalman Filter';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Interpolation Mode';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'number of EM iterations:';
            app.iters = uieditfield(app.figure1, 'text');
            app.iters.Tag = 'iters';
            app.iters.Value = '10';
            app.Interp = uibutton(app.figure1, 'push');
            app.Interp.ButtonPushedFcn = createCallbackFcn(app, @Interp_Callback, true);
            app.Interp.Tag = 'Interp';
            app.Interp.Text = 'You can interpolate your data now！';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMInterp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)EMInterp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end