classdef GMIS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        FILE           matlab.ui.container.Menu
        OPEN           matlab.ui.container.Menu
        SAVE           matlab.ui.container.Menu
        CLOSE          matlab.ui.container.Menu
        SEMIVARIOGFIT  matlab.ui.container.Menu
        KKFINTERP      matlab.ui.container.Menu
        ABOUT          matlab.ui.container.Menu
        text1          matlab.ui.control.Label
        List_Seris     matlab.ui.control.ListBox
        axes_N         matlab.ui.control.UIAxes
        axes_U         matlab.ui.control.UIAxes
        axes_E         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function drawSerisFig(app, Sel, handles)
            if strcmp(get(handles.List_Seris,'string'), '...');
                return;
            end
            
            cla(handles.axes_N,'reset')
            set(handles.axes_N,'box', 'on','Xticklabel',[])
            
            cla(handles.axes_E,'reset')
            set(handles.axes_E,'box', 'on','Xticklabel',[])
            
            cla(handles.axes_U,'reset')
            set(handles.axes_U,'box', 'on')
            
            Src_data = get(handles.List_Seris,'UserData');
            if isempty(Src_data)
                return;
            end
            axes(handles.axes_N);
            if ~isempty(Src_data.dn)
                plot(Src_data.day,Src_data.dn(:,Sel),'k');
            end
            Interp_dn = get(handles.OPEN,'UserData');
            if ~isempty(Interp_dn)
                hold on
                plot(Src_data.day,Interp_dn(:,Sel),'r');
                h = legend('Original data','Filter and Interpolated data',...
                    'Orientation','horizontal');
                set(h,'Position', [112.8333   41.6026   67.5333    1.8462])
                hold off
            end
            
            
            datetick(handles.axes_N,'x','yyyy/mm');
            set(handles.axes_N, 'XTickLabel',[],'box','on')
            ylabel(handles.axes_N,'N','FontSize',10);
            xlim(handles.axes_N,[Src_data.day(1),Src_data.day(end)])
            
            axes(handles.axes_E);
            if ~isempty(Src_data.de)
                plot(Src_data.day,Src_data.de(:,Sel),'k');
            end
            Interp_de = get(handles.SAVE,'UserData');
            if ~isempty(Interp_de)
                hold on
                plot(Src_data.day,Interp_de(:,Sel),'r');
                if ~ishandle(h)
                    h = legend('Original data','Filter and Interpolated data',...
                    'Orientation','horizontal');
                    set(h,'Position', [112.8333   41.6026   67.5333    1.8462])
                end
                hold off
            end
            datetick(handles.axes_E,'x','yyyy/mm');
            set(handles.axes_E,'XTickLabel',[],'box','on')
            ylabel(handles.axes_E,'E','FontSize',10);
            xlim(handles.axes_E,[Src_data.day(1),Src_data.day(end)])
            
            axes(handles.axes_U);
            if ~isempty(Src_data.du)
            plot(Src_data.day,Src_data.du(:,Sel),'k');
            end
            Interp_du = get(handles.CLOSE,'UserData');
            if ~isempty(Interp_du)
                hold on
                plot(Src_data.day,Interp_du(:,Sel),'r');
                if ~ishandle(h)
                    h = legend('Original data','Filter and Interpolated data',...
                    'Orientation','horizontal');
                    set(h,'Position', [112.8333   41.6026   67.5333    1.8462])
                end
                hold off
            end
            datetick(handles.axes_U,'x','yyyy/mm');
            ylabel(handles.axes_U,'U','FontSize',10);
            xlabel(handles.axes_U,'Year/month','FontSize',10)
            xlim(handles.axes_U,[Src_data.day(1),Src_data.day(end)])
        end
        
        function errorDlg(app, str)
            h = errordlg(str);
            set(h,'windowStyle','modal')
            g = handle(h);
            g.javaFrame.fHG1Client.getWindow.setAlwaysOnTop(true);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GMIS_OpeningFcn(app, varargin)
            % --- Executes just before GMIS is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GMIS (see VARARGIN)
            
            % Choose default command line output for GMIS
            handles.output = hObject;
            set(handles.List_Seris,'string','...');
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: ABOUT
        function ABOUT_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ABOUT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            About();
        end

        % Menu selected function: CLOSE
        function CLOSE_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CLOSE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figure1);
        end

        % Menu selected function: KKFINTERP
        function KKFINTERP_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to KKFINTERP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            VarigPar = get(handles.SEMIVARIOGFIT,'UserData');
            Src_data = get(handles.List_Seris,'UserData');
            InterpResult = EMInterp(Src_data,VarigPar);
            assignin("caller","InterpResult",InterpResult);
            if isempty(InterpResult)
                InterpResult.interpN = [];
                InterpResult.interpE = [];
                InterpResult.interpU = [];
            end
            set(handles.OPEN,'UserData',InterpResult.interpN);
            set(handles.SAVE,'UserData',InterpResult.interpE);
            set(handles.CLOSE,'UserData',InterpResult.interpU);
            currentSel = get(handles.List_Seris,'Value');
            drawSerisFig(app, currentSel,handles);
        end

        % Value changed function: List_Seris
        function List_Seris_Callback(app, event)
            % --- Executes on selection change in List_Seris.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to List_Seris (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns List_Seris contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from List_Seris
            
            currentSel = get(handles.List_Seris,'Value');
            if isempty(currentSel)
                return;
            end
            drawSerisFig(app, currentSel,handles)
        end

        % Menu selected function: OPEN
        function OPEN_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OPEN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Open a mat File
            [MatNameWithTag, MatDirectory] = uigetfile({'*.mat','Mat Files'},...
                        'Select a mat file');
            if MatNameWithTag == 0
                errorDlg(app, 'You should select an Mat File');
                return;
            end
            
            %load data
            Src_data = load(strcat(MatDirectory,MatNameWithTag));

            % View data
            assignin("base","Src_data",Src_data);

            filedNames = fieldnames(Src_data);
            if length(filedNames) ~= 1
                errorDlg(app, 'There should be only one struct in Mat File!');
                return;
            end
            expr = strcat('Src_data = Src_data.',filedNames,';');
            eval(expr{:});
            
            %check Input Filed Names
            miss_InterpFileds = {'day'
                'lat'
                'lon'
                'site'
                'dn'
                'de'
                'du'};
            for i=1:length(miss_InterpFileds)
                if ~isfield(Src_data, miss_InterpFileds{i})
                    errorDlg(app, strcat(miss_InterpFileds{ i },' Field Not Found!'));
                    return;
                end
            end
            
            %check value day
            if isempty(Src_data.day)
                errorDlg(app, 'There is no data in Mat File!');
                return;
            elseif ~isvector(Src_data.day)
                errorDlg(app, 'day filed value must be a vector1');
                return;
            end
            
            %check value site
            if isempty(Src_data.site)
                errorDlg(app, 'There is no site in Mat File!');
                return;
            elseif ~ischar(Src_data.site)
                errorDlg(app, 'Site Name must be char value!');
                return;
            end
            
            lenDay = length(Src_data.day);
            numSite = size(Src_data.site,1);
            
            %check N/E/U data
            if isempty(Src_data.dn)
            %     warning('There is no data in N direction!');
            elseif ~ismatrix(Src_data.dn)
                errorDlg(app, 'dn filed value must be a two dimensional matrix!');
                return;
            elseif size(Src_data.dn,1) ~= lenDay || ...
                    size(Src_data.dn,2) ~= numSite
                errorDlg(app, {'dn filed value size can not compare';...
                    ' length of day or site!'});
                return;
            end
            
            if isempty(Src_data.de)
            %     warning('There is no data in E direction!');
            elseif ~ismatrix(Src_data.de)
                errorDlg(app, 'de filed value must be a two dimensional matrix!');
                return;
            elseif size(Src_data.de,1) ~= lenDay || ...
                    size(Src_data.de,2) ~= numSite
                errorDlg(app, {'de filed value size can not compare ';...
                    ' length of day or site!'});
                return;
            end
            
            if isempty(Src_data.du)
            %     warning('There is no data in U direction!');
            elseif ~ismatrix(Src_data.du)
                errorDlg(app, 'de filed value must be a two dimensional matrix!');
                return;
            elseif size(Src_data.du,1) ~= lenDay || ...
                    size(Src_data.du,2) ~= numSite
                errorDlg(app, {'de filed value size can not compare';...
                    ' length of day or site!'});
                return;
            end
            
            %
            set(handles.OPEN,'UserData',[]);
            set(handles.SAVE,'UserData',[]);
            set(handles.CLOSE,'UserData',[]);
            set(handles.List_Seris,'string','...');
            set(handles.List_Seris,'Value',1);
            set(handles.SEMIVARIOGFIT,'UserData',[]);
            
            %show source data in List box
            set(handles.List_Seris,'string',Src_data.site);
            currentSel = get(handles.List_Seris,'Value');
            set(handles.List_Seris,'UserData',Src_data);
            drawSerisFig(app, currentSel,handles);
        end

        % Menu selected function: SAVE
        function SAVE_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SAVE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [MatFileName,MatPathName]=uiputfile({
                '*.mat','Mat Files(*.mat)';'*.*','All Files(*.*)'},'choose a File');
            if MatFileName == 0
                errordlg('You should save any Mat File');
                return;
            end
            Interp_data = get(handles.List_Seris,'UserData');
            Interp_data.interp_dn = get(handles.OPEN,'UserData');
            Interp_data.interp_de = get(handles.SAVE,'UserData');
            Interp_data.interp_du = get(handles.CLOSE,'UserData');
            Interp_data.VarigPar = get(handles.SEMIVARIOGFIT,'UserData');
            save(strcat(MatPathName,MatFileName),'Interp_data');
        end

        % Menu selected function: SEMIVARIOGFIT
        function SEMIVARIOGFIT_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SEMIVARIOGFIT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Src_data = get(handles.List_Seris,'UserData');
            VarigPar = SemiVariog(Src_data);
            set(handles.SEMIVARIOGFIT,'UserData',VarigPar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'GMIS';
            app.figure1.Tag = 'figure1';
            app.FILE = uimenu(app.figure1);
            app.FILE.Text = 'File';
            app.FILE.Tag = 'FILE';
            app.OPEN = uimenu(app.FILE);
            app.OPEN.MenuSelectedFcn = createCallbackFcn(app, @OPEN_Callback, true);
            app.OPEN.Text = 'Open';
            app.OPEN.Tag = 'OPEN';
            app.SAVE = uimenu(app.FILE);
            app.SAVE.MenuSelectedFcn = createCallbackFcn(app, @SAVE_Callback, true);
            app.SAVE.Text = 'Save';
            app.SAVE.Tag = 'SAVE';
            app.CLOSE = uimenu(app.FILE);
            app.CLOSE.MenuSelectedFcn = createCallbackFcn(app, @CLOSE_Callback, true);
            app.CLOSE.Text = 'Close';
            app.CLOSE.Tag = 'CLOSE';
            app.SEMIVARIOGFIT = uimenu(app.figure1);
            app.SEMIVARIOGFIT.MenuSelectedFcn = createCallbackFcn(app, @SEMIVARIOGFIT_Callback, true);
            app.SEMIVARIOGFIT.Text = 'Semi-Variog Fit';
            app.SEMIVARIOGFIT.Tag = 'SEMIVARIOGFIT';
            app.KKFINTERP = uimenu(app.figure1);
            app.KKFINTERP.MenuSelectedFcn = createCallbackFcn(app, @KKFINTERP_Callback, true);
            app.KKFINTERP.Text = 'KKF interp';
            app.KKFINTERP.Tag = 'KKFINTERP';
            app.ABOUT = uimenu(app.figure1);
            app.ABOUT.MenuSelectedFcn = createCallbackFcn(app, @ABOUT_Callback, true);
            app.ABOUT.Text = 'About';
            app.ABOUT.Tag = 'ABOUT';
            app.axes_E = uiaxes(app.figure1);
            app.axes_E.XTickLabel = '';
            app.axes_E.Tag = 'axes_E';
            app.axes_U = uiaxes(app.figure1);
            app.axes_U.Tag = 'axes_U';
            app.axes_N = uiaxes(app.figure1);
            app.axes_N.XTickLabel = '';
            app.axes_N.Tag = 'axes_N';
            app.List_Seris = uilistbox(app.figure1);
            app.List_Seris.Items = {};
            app.List_Seris.ValueChangedFcn = createCallbackFcn(app, @List_Seris_Callback, true);
            app.List_Seris.Tag = 'List_Seris';
            app.List_Seris.Value = {};
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Time Series';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GMIS_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GMIS_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end