classdef MissingDataReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MissingDataReconstructionUIFigure  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        LoadFilesMenu           matlab.ui.container.Menu
        SaveFilesMenu           matlab.ui.container.Menu
        MethodMenu              matlab.ui.container.Menu
        KrigedKalmanFilterMenu  matlab.ui.container.Menu
        PCAIterationMenu        matlab.ui.container.Menu
        RegEMMenu               matlab.ui.container.Menu
        VBPCAMenu               matlab.ui.container.Menu
        CoordinatePanel         matlab.ui.container.Panel
        UpUIAxes                matlab.ui.control.UIAxes
        EastUIAxes              matlab.ui.control.UIAxes
        NorthUIAxes             matlab.ui.control.UIAxes
        StationListPanel        matlab.ui.container.Panel
        Tree                    matlab.ui.container.Tree
    end


    properties (Access = private)
        %% Completed on 2025/1/17 %%
        LoadPath % Load .mat file path.
        Src_data % This is .mat file data.
        stationIndex % This is selected station name index.
        SavePath % This is compelted interpolation of files save path.
    end

    methods (Access = private)
        function resourcePath = resolveResource(app, varargin) %#ok<INUSD>
            % Locate resources both beside the editable MLAPP and beside a
            % root-level exported class used by the automated tests.
            classDir = fileparts(mfilename('fullpath'));
            parentDir = fileparts(classDir);
            candidates = { ...
                fullfile(classDir, varargin{:}), ...
                fullfile(classDir, 'MissingDataReconstruction', varargin{:}), ...
                fullfile(parentDir, 'MissingDataReconstruction', varargin{:})};
            resourcePath = candidates{1};
            for candidateIndex = 1:numel(candidates)
                if isfile(candidates{candidateIndex}) || isfolder(candidates{candidateIndex})
                    resourcePath = candidates{candidateIndex};
                    return
                end
            end
        end

        % This function retrieves and validates .mat data.
        function [Src_data, MatNameWithTag, MatDirectory] = GetMatData(app)
            Src_data       = []; % Initialize outputs as empty
            MatNameWithTag = [];
            MatDirectory   = [];

            try
                [fileName, fileDir] = uigetfile({'*.mat','Mat Files'},...
                    'Select a mat file');
                % User pressed Cancel (uigetfile returns 0): abort silently,
                % do NOT pop an error dialog and do NOT return a numeric 0.
                if isequal(fileName, 0) || isequal(fileDir, 0)
                    return;
                end
                MatNameWithTag = fileName;
                MatDirectory   = fileDir;

                % Load data
                Src_data = load(fullfile(MatDirectory, MatNameWithTag));

                filedNames = fieldnames(Src_data);
                if length(filedNames) ~= 1
                    errorDlg(app, 'There should be only one struct in Mat File!');
                    Src_data = [];
                    return;
                end

                Src_data = Src_data.(filedNames{1});

                % Check required fields
                miss_InterpFields = {'day', 'dn', 'de', 'du', 'site'};
                for i = 1:length(miss_InterpFields)
                    if ~isfield(Src_data, miss_InterpFields{i})
                        errorDlg(app, strcat(miss_InterpFields{i}, ' Field Not Found!'));
                        Src_data = [];
                        return;
                    end
                end

                % Check day field
                if isempty(Src_data.day)
                    errorDlg(app, 'There is no data in Mat File!');
                    Src_data = [];
                    return;
                elseif ~isvector(Src_data.day)
                    errorDlg(app, 'day field value must be a vector!');
                    Src_data = [];
                    return;
                end

                % Check site field
                if isempty(Src_data.site)
                    errorDlg(app, 'There is no site in Mat File!');
                    Src_data = [];
                    return;
                elseif ~ischar(Src_data.site)
                    errorDlg(app, 'Site Name must be char value!');
                    Src_data = [];
                    return;
                end

                lenDay = length(Src_data.day);
                numSite = size(Src_data.site, 1);

                % Station coordinates are optional for non-spatial processing.
                % Accept either row or column vectors when one finite lat/lon pair
                % is supplied per station. Invalid coordinates are represented
                % by empty fields so KKF can offer its unit-matrix fallback.
                hasCoordinates = isfield(Src_data, 'lat') && isfield(Src_data, 'lon') && ...
                    isnumeric(Src_data.lat) && isnumeric(Src_data.lon) && ...
                    isreal(Src_data.lat) && isreal(Src_data.lon) && ...
                    isvector(Src_data.lat) && isvector(Src_data.lon) && ...
                    numel(Src_data.lat) == numSite && numel(Src_data.lon) == numSite && ...
                    numSite >= 2 && all(isfinite(double(Src_data.lat(:)))) && ...
                    all(isfinite(double(Src_data.lon(:))));
                if hasCoordinates
                    normalizedLat = double(Src_data.lat(:));
                    normalizedLon = double(Src_data.lon(:));
                    hasCoordinates = std(normalizedLat) > 0 && std(normalizedLon) > 0;
                end
                if hasCoordinates
                    Src_data.lat = normalizedLat;
                    Src_data.lon = normalizedLon;
                else
                    Src_data.lat = [];
                    Src_data.lon = [];
                end

                % Check N/E/U data
                if isempty(Src_data.dn)
                    errorDlg(app, 'There is no data in N direction!');
                    Src_data = [];
                    return;
                elseif ~ismatrix(Src_data.dn)
                    errorDlg(app, 'dn field value must be a two-dimensional matrix!');
                    Src_data = [];
                    return;
                elseif size(Src_data.dn, 1) ~= lenDay || size(Src_data.dn, 2) ~= numSite
                    errorDlg(app, {'dn field value size does not match';...
                        'length of day or site!'});
                    Src_data = [];
                    return;
                end

                if isempty(Src_data.de)
                    errorDlg(app, 'There is no data in E direction!');
                    Src_data = [];
                    return;
                elseif ~ismatrix(Src_data.de)
                    errorDlg(app, 'de field value must be a two-dimensional matrix!');
                    Src_data = [];
                    return;
                elseif size(Src_data.de, 1) ~= lenDay || size(Src_data.de, 2) ~= numSite
                    errorDlg(app, {'de field value size does not match';...
                        'length of day or site!'});
                    Src_data = [];
                    return;
                end

                if isempty(Src_data.du)
                    errorDlg(app, 'There is no data in U direction!');
                    Src_data = [];
                    return;
                elseif ~ismatrix(Src_data.du)
                    errorDlg(app, 'du field value must be a two-dimensional matrix!');
                    Src_data = [];
                    return;
                elseif size(Src_data.du, 1) ~= lenDay || size(Src_data.du, 2) ~= numSite
                    errorDlg(app, {'du field value size does not match';...
                        'length of day or site!'});
                    Src_data = [];
                    return;
                end
            catch
                Src_data = [];
                MatNameWithTag = [];
                MatDirectory = [];
                return;
            end
        end

        function errorDlg(~, errorMsg)
            msgbox(errorMsg, 'Error', 'error');
        end

        % Clear the three coordinate axes (curves + legend) WITHOUT
        % resetting their labels / grid / font. Called whenever new data
        % is loaded so no residual plot from the previous dataset stays
        % on screen before a station is selected.
        function clearAxes(app)
            axList = [app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes];
            for k = 1:numel(axList)
                ax = axList(k);
                cla(ax);              % remove plotted lines / markers
                lg = ax.Legend;       % existing legend or [] (does not create one)
                if ~isempty(lg) && isvalid(lg)
                    delete(lg);       % remove stale legend
                end
                ax.XLimMode = 'auto'; % drop leftover axis limits
                ax.YLimMode = 'auto';
            end
        end

        % This function is to ploting interpolated result.
        function DrawingResults(app, index)
            try
                if isempty(index) || ~isscalar(index) || index < 1 || index ~= fix(index)
                    return
                end

                axList     = [app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes];
                interpTag  = 'InterpolationResult';

                % Remove only the previous interpolation overlay.  The
                % observed curve remains in place, so rerunning a method
                % cannot leave stale markers or duplicate legend entries.
                for k = 1:numel(axList)
                    delete(findall(axList(k), 'Tag', interpTag));
                end

                interpFields = {'interp_dn', 'interp_de', 'interp_du'};
                if any(~isfield(app.Src_data, interpFields)) ...
                        || isempty(app.Src_data.interp_dn) ...
                        || isempty(app.Src_data.interp_de) ...
                        || isempty(app.Src_data.interp_du)
                    return
                end

                % Force a column time axis.  The loader accepts either a
                % row or column vector for day, while each station series
                % is stored by rows.
                dayData    = app.Src_data.day(:);
                dayData    = 1970 + (dayData - 40587 + 0.5) / 365.25;
                interpList = {app.Src_data.interp_dn, app.Src_data.interp_de, app.Src_data.interp_du};
                origList   = {app.Src_data.dn, app.Src_data.de, app.Src_data.du};
                accent     = [0.86 0.20 0.18];

                for k = 1:3
                    ax      = axList(k);
                    if index > size(origList{k}, 2) || index > size(interpList{k}, 2) ...
                            || size(origList{k}, 1) ~= numel(dayData) ...
                            || size(interpList{k}, 1) ~= numel(dayData)
                        return
                    end

                    iy      = interpList{k}(:, index);
                    oy      = origList{k}(:, index);
                    missing = isnan(oy) & isfinite(iy);
                    observedLine = findall(ax, 'Tag', 'ObservedSeries');

                    isFullSeries = isfield(app.Src_data, 'interp_output_mode') && ...
                        strcmp(app.Src_data.interp_output_mode, 'full-series');
                    if isFullSeries
                        if ~any(isfinite(iy))
                            continue
                        end

                        % Compare the original observations with the complete
                        % filtered/interpolated KKF series.
                        set(observedLine, 'DisplayName', 'Original (before KKF)');
                        hold(ax, 'on');
                        plot(ax, dayData, iy, '-', 'Color', accent, 'LineWidth', 1.3, ...
                            'DisplayName', 'KKF filtered/interpolated', 'Tag', interpTag);
                        if any(missing)
                            plot(ax, dayData(missing), iy(missing), 'o', 'MarkerSize', 3, ...
                                'MarkerFaceColor', accent, 'MarkerEdgeColor', 'none', ...
                                'HandleVisibility', 'off', 'Tag', interpTag);
                        end
                        hold(ax, 'off');
                    else
                        set(observedLine, 'DisplayName', 'Observed');
                        if ~any(missing)
                            continue
                        end

                        hold(ax, 'on');

                        % Preserve the original missing-only plot: draw the
                        % reconstructed path only around each missing gap.
                        segmentMask = missing ...
                            | [false; missing(1:end-1)] ...
                            | [missing(2:end); false];
                        seg = iy;
                        seg(~segmentMask) = NaN;
                        plot(ax, dayData, seg, '-', 'Color', accent, 'LineWidth', 1.3, ...
                            'HandleVisibility', 'off', 'Tag', interpTag);

                        plot(ax, dayData(missing), iy(missing), 'o', 'MarkerSize', 3, ...
                            'MarkerFaceColor', accent, 'MarkerEdgeColor', 'none', ...
                            'DisplayName', 'Interpolated', 'Tag', interpTag);
                        hold(ax, 'off');
                    end


                    lg = legend(ax, 'show');
                    lg.Location = 'best';
                    lg.Box      = 'off';
                    lg.FontSize = 9;
                end
            catch
                return
            end
        end


        function [resid, trend] = detrendColumns(~, X)
            [n, m] = size(X);
            t      = (1:n)';
            resid  = X;
            trend  = zeros(n, m);

            for j = 1:m
                col   = X(:, j);
                valid = ~isnan(col);
                if nnz(valid) >= 2
                    pcoef        = polyfit(t(valid), col(valid), 1);
                    trend(:, j)  = polyval(pcoef, t);
                elseif nnz(valid) == 1
                    trend(:, j)  = col(valid);
                end
                resid(:, j) = col - trend(:, j);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.MissingDataReconstructionUIFigure.Position(3);
            windowHeight = app.MissingDataReconstructionUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.MissingDataReconstructionUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];
        end

        % Menu selected function: LoadFilesMenu
        function LoadFilesMenuSelected(app, event)
            try
                [src_data, MatNameWithTag, MatDirectory] = GetMatData(app);

                % If the user canceled the dialog or the file failed
                % validation, src_data is empty. Abort here so we never
                % call fullfile() on the numeric 0 that cancel returns.
                if isempty(src_data)
                    return;
                end

                app.LoadPath = fullfile(MatDirectory, MatNameWithTag);

                % Attention: This is char NOT is string.
                app.Src_data = src_data;

                % Remove any leftover curves / legends from the previously
                % loaded dataset so the axes start clean.
                clearAxes(app);
                app.stationIndex = [];

                % Clear all of tree nodes.
                app.Tree.Children.delete;

                % NOTE: site is an N-by-charWidth char matrix, so iterate
                % over rows with size(...,1), not length().
                siteNames = src_data.site;
                for name = 1:size(siteNames, 1)
                    uitreenode('Parent', app.Tree, 'Text', strtrim(siteNames(name,:)), ...
                        'NodeData', fullfile(MatDirectory, MatNameWithTag));
                end
            catch
                return
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end

            selectedName = strtrim(string(selectedNodes.Text));
            stationName  = strtrim(cellstr(app.Src_data.site));
            index = find(strcmp(selectedName, stationName), 1);
            app.stationIndex = index;                 % Get station name index in Src_data.site
            if isempty(index)
                return
            end

            dayData  = 1970 + (app.Src_data.day - 40587 + 0.5) / 365.25;
            axList   = [app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes];
            dataList = {app.Src_data.dn, app.Src_data.de, app.Src_data.du};
            obsColor = [0.15 0.15 0.15];

            for k = 1:3
                ax = axList(k);
                y  = dataList{k}(:, index);
                cla(ax);
                plot(ax, dayData, y, '-', 'Color', obsColor, 'LineWidth', 1.3, ...
                    'DisplayName', 'Observed', 'Tag', 'ObservedSeries');
            end

            % Show result when switch station name.
            DrawingResults(app, index);
        end

        % Menu selected function: PCAIterationMenu
        function PCAIterationMenuSelected(app, event)
            try
                % Check if source data exists
                if isempty(app.Src_data)
                    uialert(app.MissingDataReconstructionUIFigure, ...
                        'No data are available for interpolation.', 'Warning', 'Icon', 'warning');
                    return;
                end

                % Verify required fields
                requiredFields = {'dn','de','du'};
                for i = 1:numel(requiredFields)
                    if ~isfield(app.Src_data, requiredFields{i})
                        uialert(app.MissingDataReconstructionUIFigure, ...
                            sprintf("Missing field: app.Src_data.%s", requiredFields{i}), ...
                            'Error', 'Icon', 'error');
                        return;
                    end
                end

                % Setup progress dialog
                fig = app.MissingDataReconstructionUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');

                p = uiprogressdlg(fig, "Icon", iconPath, ...
                    "Indeterminate", "on", ...
                    "Message", "Interpolating data...", ...
                    "Cancelable", "on");

                % Ensure progress dialog closes safely
                c = onCleanup(@() safeCloseProgress(p)); %#ok<NASGU>

                % Prepare output fields
                if ~isfield(app.Src_data, 'interp_dn'); app.Src_data.interp_dn = []; end
                if ~isfield(app.Src_data, 'interp_de'); app.Src_data.interp_de = []; end
                if ~isfield(app.Src_data, 'interp_du'); app.Src_data.interp_du = []; end

                components = {'dn','de','du'};
                k_components = 3;
                tol = 1e-6;
                maxIter = 200;

                % Process each component
                for comp_idx = 1:numel(components)
                    if p.CancelRequested
                        uialert(fig, "Operation canceled by user.", "Canceled", "Icon", "info");
                        return;
                    end

                    comp = components{comp_idx};
                    original_data = app.Src_data.(comp);

                    % Short gaps: spline fill (<=2 missing)
                    interp_data = original_data;
                    for station = 1:size(original_data, 2)
                        ts = original_data(:, station);
                        interp_data(:, station) = fillmissing(ts, 'spline', 'MaxGap', 2);
                    end

                    % Long gaps: iterative PCA reconstruction
                    interp_data = fillPCA_iter(interp_data, k_components, tol, maxIter, p);

                    app.Src_data.(['interp_', comp]) = interp_data;
                app.Src_data.interp_output_mode = 'missing-only';
                end

                uialert(fig, 'Interpolation completed.', 'Tip', 'Icon', 'success');
                DrawingResults(app, app.stationIndex);
            catch ME
                uialert(app.MissingDataReconstructionUIFigure, ...
                    sprintf('Error: %s', ME.message), 'Error', 'Icon', 'error');
            end


            function safeCloseProgress(p)
                % Close progress dialog safely
                try
                    if exist('p','var') && ~isempty(p) && isvalid(p)
                        close(p);
                    end
                catch
                    % ignore errors
                end
            end

            function X_filled = fillPCA_iter(X, k_components, tol, maxIter, p)
                % Iterative PCA-based missing data reconstruction
                X_filled = X;
                missing_mask = isnan(X);

                % Exit if nothing to fill
                if ~any(missing_mask(:))
                    return;
                end

                % Initialize missing by row mean
                for t1 = 1:size(X_filled,1)
                    valid = ~missing_mask(t1,:);
                    if any(valid)
                        X_filled(t1, missing_mask(t1,:)) = mean(X_filled(t1, valid), 'omitnan');
                    else
                        X_filled(t1, missing_mask(t1,:)) = 0;
                    end
                end

                for iter = 1:maxIter
                    if nargin >= 5 && ~isempty(p) && isvalid(p) && p.CancelRequested
                        error("Operation canceled by user.");
                    end

                    X_prev = X_filled;
                    mean_val = mean(X_filled, 1, 'omitnan');
                    X_centered = X_filled - mean_val;

                    B = cov(X_centered, 'partialrows');
                    if any(~isfinite(B(:))); warning("Non-finite covariance. Stop."); break; end

                    [V, D] = eig(B, 'vector');
                    [D_sorted, idx] = sort(D, 'descend');
                    V = V(:, idx);

                    total_variance = sum(D_sorted);
                    if total_variance <= eps; warning("No variance. Stop."); break; end

                    k = min(k_components, size(V,2));
                    V_k = V(:, 1:k);

                    A = X_centered * V_k;
                    X_recon = A * V_k' + mean_val;

                    % Update missing values
                    X_filled(missing_mask) = X_recon(missing_mask);

                    delta = mean(abs(X_filled(missing_mask) - X_prev(missing_mask)), 'all', 'omitnan');
                    if ~isfinite(delta); warning("Delta non-finite. Stop."); break; end
                    if delta < tol; break; end
                end
            end
        end

        % Menu selected function: RegEMMenu
        function RegEMMenuSelected(app, event)
            p = [];
            try
                if isempty(app.Src_data) % Display modern alert dialog using uialert
                    uialert(app.MissingDataReconstructionUIFigure, 'No data are available for interpolation.', 'Warning', ...
                        'Icon', 'warning');
                    % Wait for the alert to close
                    return
                end

                fig = app.MissingDataReconstructionUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig,"Icon", iconPath, ...
                    "Indeterminate","on","Message","Interpolating data...");

                if ~isfield(app.Src_data, 'interp_dn')
                    app.Src_data.interp_dn = [];
                end
                if ~isfield(app.Src_data, 'interp_de')
                    app.Src_data.interp_de = [];
                end
                if ~isfield(app.Src_data, 'interp_du')
                    app.Src_data.interp_du = [];
                end

                % Detrend
                [dn_detr, dn_trend] = app.detrendColumns(app.Src_data.dn);
                [de_detr, de_trend] = app.detrendColumns(app.Src_data.de);
                [du_detr, du_trend] = app.detrendColumns(app.Src_data.du);

                % Get Residual
                regemOptions = struct('stagtol', 1e-6, 'maxit', 100);
                [interp_dn_detr, ~, ~, ~] = regem(dn_detr, regemOptions);
                [interp_de_detr, ~, ~, ~] = regem(de_detr, regemOptions);
                [interp_du_detr, ~, ~, ~] = regem(du_detr, regemOptions);

                % Plus Detrend Value
                app.Src_data.interp_dn = interp_dn_detr + dn_trend;
                app.Src_data.interp_de = interp_de_detr + de_trend;
                app.Src_data.interp_du = interp_du_detr + du_trend;
                app.Src_data.interp_output_mode = 'missing-only';

                % Close Progressbar.
                close(p);
                uialert(app.MissingDataReconstructionUIFigure, 'Interpolation completed.', 'Tip', ...
                    'Icon', 'success');
                DrawingResults(app, app.stationIndex);
            catch ME
                if ~isempty(p) && isvalid(p)
                    close(p);
                end
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.MissingDataReconstructionUIFigure, errMsg, 'Error', ...
                    'Icon', 'error');
            end
        end

        % Menu selected function: VBPCAMenu
        function VBPCAMenuSelected(app, event)
            p = [];
            try
                if isempty(app.Src_data)
                    % Display modern alert dialog using uialert
                    uialert(app.MissingDataReconstructionUIFigure, 'No data are available for interpolation.', 'Warning', ...
                        'Icon', 'warning');

                    % Wait for the alert to close

                    return
                end

                % Create a Uncertainly progress bar.
                fig = app.MissingDataReconstructionUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig,"Icon",iconPath, ...
                    "Indeterminate","on","Message","Interpolating data...");

                if ~isfield(app.Src_data, 'interp_dn')
                    app.Src_data.interp_dn = [];
                end

                if ~isfield(app.Src_data, 'interp_de')
                    app.Src_data.interp_de = [];
                end

                if ~isfield(app.Src_data, 'interp_du')
                    app.Src_data.interp_du = [];
                end

                app.Src_data.interp_dn = ExecuteVBPCA(app.Src_data.dn);
                app.Src_data.interp_de = ExecuteVBPCA(app.Src_data.de);
                app.Src_data.interp_du = ExecuteVBPCA(app.Src_data.du);
                app.Src_data.interp_output_mode = 'missing-only';

                close(p);
                uialert(app.MissingDataReconstructionUIFigure, 'Interpolation completed.', 'Tip', ...
                    'Icon', 'success');
                DrawingResults(app, app.stationIndex);
                % Wait for the alert to close

            catch ME
                if ~isempty(p) && isvalid(p)
                    close(p);
                end
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.MissingDataReconstructionUIFigure, errMsg, 'Error', ...
                    'Icon', 'error');

            end

            function interpData = ExecuteVBPCA(data)
                mask = isnan(data);
                results = vbpca_full(data, size(data,2)-1);
                interpDataFull = results.Mu + results.A * results.S;
                interpData = data;
                interpData(mask) = interpDataFull(mask);
            end

        end

        % Menu selected function: SaveFilesMenu
        function SaveFilesMenuSelected(app, event)
            p = [];
            try
                Savepath = uigetdir();
                if Savepath==0
                    return
                end
                app.SavePath = Savepath;
                fig = app.MissingDataReconstructionUIFigure;
                iconPath = resolveResource(app, 'logo', 'loading.png');
                p = uiprogressdlg(fig,"Icon",iconPath);
                p.Message = "Processing...";

                for i = 1:size(app.Src_data.dn, 2)
                    stationName = app.Src_data.site(i,:);
                    save_component_to_file(stationName, Savepath, app.Src_data.day, app.Src_data.interp_dn, i, 0);
                    save_component_to_file(stationName, Savepath, app.Src_data.day, app.Src_data.interp_de, i, 1);
                    save_component_to_file(stationName, Savepath, app.Src_data.day, app.Src_data.interp_du, i, 2);
                    p.Value = (i/size(app.Src_data.dn,2));
                end
                close(p);
                uialert(app.MissingDataReconstructionUIFigure, 'Successfully saved.', 'Tip', ...
                    'Icon', 'success');
                % Wait for the alert to close


            catch ME
                if ~isempty(p) && isvalid(p)
                    close(p);
                end
                errMsg = sprintf('Error: %s', ME.message);
                uialert(app.MissingDataReconstructionUIFigure, errMsg, 'Error', ...
                    'Icon', 'error');

            end

            function save_component_to_file(stationName, Savepath, day, interp_data, component_index, component_id)
                name = sprintf("%s_%d.mom", stationName, component_id);
                FilesavePath = fullfile(Savepath, name);

                fileID = fopen(FilesavePath, 'w');
                if fileID == -1
                    error('Failed to open file: %s', FilesavePath);
                end

                writeData = [day, interp_data(:,component_index)];
                fprintf(fileID, '%f %f\n',writeData' );
                fclose(fileID);
            end
        end

        % Menu selected function: KrigedKalmanFilterMenu
        function KrigedKalmanFilterMenuSelected(app, event)
            try
                if isempty(app.Src_data)
                    % Display modern alert dialog using uialert
                    uialert(app.MissingDataReconstructionUIFigure, 'No data are available for interpolation.', 'Warning', ...
                        'Icon', 'warning');

                    % Wait for the alert to close

                    return
                end

                outputChoice = uiconfirm(app.MissingDataReconstructionUIFigure, ...
                    ['Choose the KKF output scope before processing:' newline ...
                    '- interpolate only missing epochs, or' newline ...
                    '- filter/interpolate the complete time series.'], ...
                    'KKF output scope', ...
                    'Options', {'Interpolate missing epochs', ...
                    'Filter/interpolate full time series', 'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 3);
                switch outputChoice
                    case 'Interpolate missing epochs'
                        outputMode = 'missing-only';
                    case 'Filter/interpolate full time series'
                        outputMode = 'full-series';
                    otherwise
                        return;
                end

                % Run the bundled KKF interpolation app. EMInterp.m is a
                % legacy GUIDE function with the same name, so use the
                % migrated App Designer class explicitly.
                VarigPar = [];
                gmisPath = resolveResource(app, 'GMIS');
                if ~isfolder(gmisPath)
                    error('MissingDataReconstruction:GMISNotFound', ...
                        'GMIS module folder not found: %s', gmisPath);
                end
                originalPath = path;
                pathCleanup = onCleanup(@() path(originalPath)); %#ok<NASGU>
                addpath(gmisPath, '-begin');

                % A spatial semi-variogram requires one valid lat/lon pair per station.
                % Row vectors are accepted and normalized to column vectors.
                numSite = size(app.Src_data.dn, 2);
                hasCoordinates = isfield(app.Src_data, 'lat') && ...
                    isfield(app.Src_data, 'lon') && ...
                    isnumeric(app.Src_data.lat) && isnumeric(app.Src_data.lon) && ...
                    isreal(app.Src_data.lat) && isreal(app.Src_data.lon) && ...
                    isvector(app.Src_data.lat) && isvector(app.Src_data.lon) && ...
                    numel(app.Src_data.lat) == numSite && ...
                    numel(app.Src_data.lon) == numSite && numSite >= 2 && ...
                    all(isfinite(double(app.Src_data.lat(:)))) && ...
                    all(isfinite(double(app.Src_data.lon(:))));
                if hasCoordinates
                    normalizedLat = double(app.Src_data.lat(:));
                    normalizedLon = double(app.Src_data.lon(:));
                    hasCoordinates = std(normalizedLat) > 0 && std(normalizedLon) > 0;
                end
                if hasCoordinates
                    app.Src_data.lat = normalizedLat;
                    app.Src_data.lon = normalizedLon;
                % Fit the spatial semi-variogram before starting KKF.
                % This restores the original SemiVariog -> EMInterp flow.
                expectedVariogramApp = fullfile(gmisPath, 'SemiVariog_App.mlapp');
                if ~isfile(expectedVariogramApp)
                    error('MissingDataReconstruction:VariogramAppNotFound', ...
                        'Semi-variogram app not found: %s', expectedVariogramApp);
                end
                try
                    clear('SemiVariog_App');
                    rehash;
                catch clearError
                    error('MissingDataReconstruction:StaleVariogramClass', ...
                        ['MATLAB could not unload an old SemiVariog_App class. ' ...
                        'Close any open SemiVariog windows and try again. %s'], ...
                        clearError.message);
                end
                resolvedVariogramApp = which('SemiVariog_App');
                normalizedExpectedVariogram = ...
                    strrep(expectedVariogramApp, '/', filesep);
                normalizedResolvedVariogram = ...
                    strrep(resolvedVariogramApp, '/', filesep);
                if isempty(resolvedVariogramApp) || ...
                        ~strcmpi(normalizedResolvedVariogram, normalizedExpectedVariogram)
                    error('MissingDataReconstruction:WrongVariogramClass', ...
                        ['MATLAB resolved the wrong SemiVariog_App. Expected: %s; ' ...
                        'resolved: %s'], expectedVariogramApp, resolvedVariogramApp);
                end

                semiApp = SemiVariog_App(app.Src_data);
                SemiResult = get(semiApp.CLOSE, 'UserData');
                if isstruct(SemiResult) && isfield(SemiResult, 'status') && ...
                        strcmp(SemiResult.status, 'cancelled')
                    if isvalid(semiApp)
                        delete(semiApp);
                    end
                    clear semiApp
                    return;
                end
                if ~isstruct(SemiResult) || ...
                        ~isfield(SemiResult, 'status') || ...
                        ~strcmp(SemiResult.status, 'success') || ...
                        ~isfield(SemiResult, 'VarigPar')
                    error('MissingDataReconstruction:InvalidVariogramResult', ...
                        ['The semi-variogram window closed without a valid result. ' ...
                        'Fit the desired directions and click Close.']);
                end
                VarigPar = SemiResult.VarigPar;
                if isvalid(semiApp)
                    delete(semiApp);
                end
                clear semiApp
                else
                    app.Src_data.lat = [];
                    app.Src_data.lon = [];
                    coordinateChoice = uiconfirm( ...
                        app.MissingDataReconstructionUIFigure, ...
                        ['Station coordinate fields lat/lon are missing, invalid, or do not ' ...
                        'contain one finite value per station. A spatial ' ...
                        'semi-variogram cannot be fitted.' newline ...
                        'Continue KKF with unit spatial matrices?'], ...
                        'Station coordinates unavailable', ...
                        'Options', {'Continue with unit matrices', 'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
                    if ~strcmp(coordinateChoice, 'Continue with unit matrices')
                        return;
                    end
                    VarigPar = struct('VariogFitPar_N', [], ...
                        'VariogFitPar_E', [], 'VariogFitPar_U', []);
                end

                % EMInterp_App has several same-named copies in common
                % project folders. Unload any cached old definition, then
                % verify that MATLAB resolves the bundled fixed app.
                expectedChildApp = fullfile(gmisPath, 'EMInterp_App.mlapp');
                if ~isfile(expectedChildApp)
                    error('MissingDataReconstruction:KKFAppNotFound', ...
                        'KKF app not found: %s', expectedChildApp);
                end
                try
                    clear('EMInterp_App');
                    rehash;
                catch clearError
                    error('MissingDataReconstruction:StaleKKFClass', ...
                        ['MATLAB could not unload an old EMInterp_App class. ' ...
                        'Close any open EMInterp windows and try again. %s'], ...
                        clearError.message);
                end
                resolvedChildApp = which('EMInterp_App');
                normalizedExpected = strrep(expectedChildApp, '/', filesep);
                normalizedResolved = strrep(resolvedChildApp, '/', filesep);
                if isempty(resolvedChildApp) || ...
                        ~strcmpi(normalizedResolved, normalizedExpected)
                    error('MissingDataReconstruction:WrongKKFClass', ...
                        ['MATLAB resolved the wrong EMInterp_App. Expected: %s; ' ...
                        'resolved: %s'], expectedChildApp, resolvedChildApp);
                end
                myApp = EMInterp_App();
                if ~ismethod(myApp, 'runModal')
                    delete(myApp);
                    clear myApp
                    error('MissingDataReconstruction:StaleKKFInterface', ...
                        ['The loaded EMInterp_App is an old version without runModal. ' ...
                        'Close any open EMInterp windows and retry.']);
                end
                myApp.runModal(app.Src_data, VarigPar, outputMode);

                if isempty(myApp)
                    return;
                end

                InterpResult = get(myApp.Interp,'UserData');
                if isstruct(InterpResult) && isfield(InterpResult, 'status')
                    resultStatus = char(string(InterpResult.status));
                    if strcmp(resultStatus, 'cancelled')
                        if isvalid(myApp)
                            delete(myApp);
                        end
                        return;
                    elseif strcmp(resultStatus, 'error')
                        childMessage = 'KKF interpolation failed.';
                        if isfield(InterpResult, 'message') && ~isempty(InterpResult.message)
                            childMessage = char(string(InterpResult.message));
                        end
                        error('MissingDataReconstruction:KKFFailed', '%s', childMessage);
                    end
                end

                requiredResultFields = {'interpN', 'interpE', 'interpU'};
                if ~isstruct(InterpResult) || ...
                        any(~isfield(InterpResult, requiredResultFields))
                    error('MissingDataReconstruction:InvalidKKFResult', ...
                        ['The GMIS interpolation window closed without a valid result. ' ...
                        'Click the interpolation button and wait for processing to finish.']);
                end

                if ~isfield(app.Src_data, 'interp_dn')
                    app.Src_data.interp_dn = [];
                end

                if ~isfield(app.Src_data, 'interp_de')
                    app.Src_data.interp_de = [];
                end

                if ~isfield(app.Src_data, 'interp_du')
                    app.Src_data.interp_du = [];
                end

                % Store the interpolation results in app.Src_data.
                app.Src_data.interp_dn = InterpResult.interpN;
                app.Src_data.interp_de = InterpResult.interpE;
                app.Src_data.interp_du = InterpResult.interpU;
                app.Src_data.interp_output_mode = 'missing-only';
                if isfield(InterpResult, 'outputMode') && ...
                        strcmp(InterpResult.outputMode, 'full-series')
                    app.Src_data.interp_output_mode = 'full-series';
                end
                uialert(app.MissingDataReconstructionUIFigure, 'Interpolation completed.', 'Tip', ...
                    'Icon', 'success');
                % Wait for the alert to close


                % Close EMInterp.mlapp
                % Do Not delete("EMInterp.mlapp")!!!
                if isvalid(myApp)
                    delete(myApp);
                end

                % Show result.
                DrawingResults(app,app.stationIndex);
            catch ME
                if exist('semiApp', 'var') && ~isempty(semiApp) && isvalid(semiApp)
                    delete(semiApp);
                end
                if exist('myApp', 'var') && ~isempty(myApp) && isvalid(myApp)
                    delete(myApp);
                end
                errMsg = sprintf('Error [%s]: %s', ME.identifier, ME.message);
                uialert(app.MissingDataReconstructionUIFigure, errMsg, 'Error', ...
                    'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MissingDataReconstructionUIFigure = uifigure('Visible', 'off');
            app.MissingDataReconstructionUIFigure.Name = 'MissingDataReconstruction';
            app.MissingDataReconstructionUIFigure.Icon = 'insert.png';
            app.FileMenu = uimenu(app.MissingDataReconstructionUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadFilesMenu = uimenu(app.FileMenu);
            app.LoadFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFilesMenuSelected, true);
            app.LoadFilesMenu.Text = 'Load Files';
            app.SaveFilesMenu = uimenu(app.FileMenu);
            app.SaveFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFilesMenuSelected, true);
            app.SaveFilesMenu.Text = 'Save Files';
            app.MethodMenu = uimenu(app.MissingDataReconstructionUIFigure);
            app.MethodMenu.Text = 'Method';
            app.KrigedKalmanFilterMenu = uimenu(app.MethodMenu);
            app.KrigedKalmanFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @KrigedKalmanFilterMenuSelected, true);
            app.KrigedKalmanFilterMenu.Text = ' Kriged Kalman Filter';
            app.PCAIterationMenu = uimenu(app.MethodMenu);
            app.PCAIterationMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAIterationMenuSelected, true);
            app.PCAIterationMenu.Text = 'PCA Iteration';
            app.RegEMMenu = uimenu(app.MethodMenu);
            app.RegEMMenu.MenuSelectedFcn = createCallbackFcn(app, @RegEMMenuSelected, true);
            app.RegEMMenu.Text = 'RegEM';
            app.VBPCAMenu = uimenu(app.MethodMenu);
            app.VBPCAMenu.MenuSelectedFcn = createCallbackFcn(app, @VBPCAMenuSelected, true);
            app.VBPCAMenu.Text = 'VBPCA';
            app.StationListPanel = uipanel(app.MissingDataReconstructionUIFigure);
            app.StationListPanel.Title = 'Station List';
            app.Tree = uitree(app.StationListPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.CoordinatePanel = uipanel(app.MissingDataReconstructionUIFigure);
            app.NorthUIAxes = uiaxes(app.CoordinatePanel);
            ylabel(app.NorthUIAxes, 'North (mm)')
            zlabel(app.NorthUIAxes, 'Z')
            app.EastUIAxes = uiaxes(app.CoordinatePanel);
            ylabel(app.EastUIAxes, 'East (mm)')
            zlabel(app.EastUIAxes, 'Z')
            app.UpUIAxes = uiaxes(app.CoordinatePanel);
            ylabel(app.UpUIAxes, 'Up (mm)')
            zlabel(app.UpUIAxes, 'Z')
            % Show the figure after all components are created
            app.MissingDataReconstructionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MissingDataReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MissingDataReconstructionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MissingDataReconstructionUIFigure)
        end
    end
end