classdef KnownEventImport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KnownEventImportUIFigure       matlab.ui.Figure
        KnownEventImportPanel          matlab.ui.container.Panel
        WriteEventstoMOMHeadersButton  matlab.ui.control.StateButton
        TextArea                       matlab.ui.control.TextArea
        TimeSeriesViewPanel            matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        NTab                           matlab.ui.container.Tab
        NorthUIAxes                    matlab.ui.control.UIAxes
        ETab                           matlab.ui.container.Tab
        EastUIAxes                     matlab.ui.control.UIAxes
        UTab                           matlab.ui.container.Tab
        UpUIAxes                       matlab.ui.control.UIAxes
        KnownEventsPanel               matlab.ui.container.Panel
        KnownEventTable                matlab.ui.control.Table
        DataSourcePanel                matlab.ui.container.Panel
        AnalyzeEventsButton            matlab.ui.control.StateButton
        MOMDataDirectoryLabel          matlab.ui.control.Label
        MOMFileDirctoryEditField       matlab.ui.control.EditField
        MOMFileDirctoryBrowseButton    matlab.ui.control.StateButton
        FileDirctoryBrowseButton       matlab.ui.control.StateButton
        FileDirctoryEditField          matlab.ui.control.EditField
        SourcePathLabel                matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        EventCatalog
        StationRecords
        CurrentPreviewStation
        AnalysisReady = false
    end

    methods (Access = private)

        function initializeAppState(app)
            app.EventCatalog = app.emptyEventCatalog();
            app.StationRecords = app.emptyStationRecords();
            app.CurrentPreviewStation = "";
            app.AnalysisReady = false;

            app.FileDirctoryEditField.Value = '';
            app.FileDirctoryEditField.Tooltip = '';
            app.MOMFileDirctoryEditField.Value = '';
            app.MOMFileDirctoryEditField.Tooltip = '';
            app.clearAnalysis();
            app.setStatus('Select source files and a MOM directory, then click Analyze.');
        end


        function sourceFiles = resolveSourceFiles(app)
            rawPath = strtrim(app.FileDirctoryEditField.Value);
            if isempty(rawPath)
                error('KnownEventImporApp:MissingSource', ...
                    'Select a known-event source.');
            end

            if strcmp(app.DataTypeDropDown.Value, 'CEA Time-Series Folder')
                if ~isfolder(rawPath)
                    error('KnownEventImporApp:MissingCeaDirectory', ...
                        'Select the folder containing the station CEA TS.txt files.');
                end

                files = dir(fullfile(rawPath, '*.txt'));
                files = files(~[files.isdir]);
                if isempty(files)
                    error('KnownEventImporApp:NoCeaFiles', ...
                        'No CEA TS.txt station files were found in %s.', rawPath);
                end
                [~, order] = sort(lower(string({files.name})));
                files = files(order);
                sourceFiles = arrayfun(@(item) ...
                    fullfile(item.folder, item.name), files, ...
                    'UniformOutput', false);
                return;
            end

            if isfolder(rawPath)
                candidate = fullfile(rawPath, 'steps.txt');
                if ~isfile(candidate)
                    error('KnownEventImporApp:MissingStepsFile', ...
                        'No steps.txt was found in %s.', rawPath);
                end
                sourceFiles = {candidate};
            else
                parts = split(string(rawPath), ';');
                parts = strtrim(parts);
                parts(parts == "") = [];
                sourceFiles = cellstr(parts);
            end

            if isempty(sourceFiles)
                error('KnownEventImporApp:MissingSource', ...
                    'No source files were found.');
            end
            missing = sourceFiles(~cellfun(@isfile, sourceFiles));
            if ~isempty(missing)
                error('KnownEventImporApp:MissingSource', ...
                    'Source file does not exist: %s', missing{1});
            end
            if strcmp(app.DataTypeDropDown.Value, 'NGL Steps File (*.txt)') && ...
                    numel(sourceFiles) ~= 1
                error('KnownEventImporApp:MultipleStepsFiles', ...
                    'Select exactly one NGL steps.txt file.');
            end
        end

        function startAt = sourceBrowseStart(app)
            currentValue = app.FileDirctoryEditField.Value;
            firstPath = strtrim(char(extractBefore(string(currentValue) + ";", ";")));
            if isfile(firstPath)
                startAt = firstPath;
            elseif isfolder(firstPath)
                startAt = firstPath;
            else
                startAt = pwd;
            end
        end

        function records = discoverMomStations(app, momDirectory)
            files = dir(fullfile(momDirectory, '*.mom'));
            stationNames = cell(0, 1);
            for k = 1:numel(files)
                token = regexp(files(k).name, '^(.*)_([012])\.mom$', ...
                    'tokens', 'once', 'ignorecase');
                if ~isempty(token)
                    stationNames{end + 1, 1} = upper(token{1}); %#ok<AGROW>
                end
            end
            stationNames = unique(stationNames, 'stable');
            records = app.emptyStationRecords();
            incomplete = strings(0, 1);
            for k = 1:numel(stationNames)
                momFiles = cell(1, 3);
                complete = true;
                for c = 1:3
                    momFiles{c} = fullfile(momDirectory, ...
                        sprintf('%s_%d.mom', stationNames{k}, c - 1));
                    complete = complete && isfile(momFiles{c});
                end
                if complete
                    record = struct('Station', stationNames{k}, ...
                        'MomFiles', {momFiles}, 'MomRanges', nan(3, 2), ...
                        'MomData', {cell(1, 3)});
                    records(end + 1) = record; %#ok<AGROW>
                else
                    incomplete(end + 1, 1) = string(stationNames{k}); %#ok<AGROW>
                end
            end
            if isempty(records)
                error('KnownEventImporApp:NoCompleteMomStations', ...
                    'No complete station_0/1/2.mom sets were found.');
            end
            if ~isempty(incomplete)
                warning('KnownEventImporApp:IncompleteMomStations', ...
                    'Skipped incomplete MOM station set(s): %s', ...
                    strjoin(cellstr(incomplete), ', '));
            end
        end

        function records = emptyStationRecords(app) %#ok<MANU>
            records = struct('Station', {}, 'MomFiles', {}, ...
                'MomRanges', {}, 'MomData', {});
        end

        function events = parseNglBatch(app, stepsFile, stations, progress)
            fid = fopen(stepsFile, 'r');
            if fid < 0
                error('KnownEventImporApp:CannotOpenSteps', ...
                    'Cannot open NGL steps file: %s', stepsFile);
            end
            closeFile = onCleanup(@() fclose(fid));
            raw = textscan(fid, '%s', 'Delimiter', '\n', 'Whitespace', '');
            lines = raw{1};
            stationMap = containers.Map(stations, ...
                num2cell(true(size(stations))));

            stationList = strings(0, 1);
            mjdList = zeros(0, 1);
            typeList = strings(0, 1);
            descriptionList = strings(0, 1);
            nLines = numel(lines);
            for k = 1:nLines
                if mod(k, 5000) == 0
                    progress.Value = 0.05 + 0.35 * k / max(nLines, 1);
                    progress.Message = sprintf('Scanning NGL catalogue: %.0f%%', ...
                        100 * k / max(nLines, 1));
                    drawnow limitrate;
                    app.throwIfCancelled(progress);
                end
                line = strtrim(lines{k});
                firstSpace = find(isspace(line), 1);
                if isempty(firstSpace)
                    continue;
                end
                station = upper(line(1:firstSpace - 1));
                if ~isKey(stationMap, station)
                    continue;
                end

                token = strsplit(line);
                if numel(token) < 4
                    continue;
                end
                eventCode = str2double(token{3});
                if eventCode ~= 1 && (eventCode ~= 2 || numel(token) < 7)
                    continue;
                end

                stationList(end + 1, 1) = string(station); %#ok<AGROW>
                mjdList(end + 1, 1) = yymmmdd2mjd(token{2}); %#ok<AGROW>
                if eventCode == 1
                    typeList(end + 1, 1) = "EQUIP"; %#ok<AGROW>
                    descriptionList(end + 1, 1) = ...
                        strrep(strjoin(token(4:end), ' '), '_', ' '); %#ok<AGROW>
                else
                    typeList(end + 1, 1) = "EQ"; %#ok<AGROW>
                    descriptionList(end + 1, 1) = ...
                        sprintf('M%s %s', token{6}, token{7}); %#ok<AGROW>
                end

            end
            Station = stationList;
            MJD = mjdList;
            Source = repmat("NGL", numel(mjdList), 1);
            Type = typeList;
            Component = repmat("ALL", numel(mjdList), 1);
            Description = descriptionList;
            events = table(Station, MJD, Source, Type, Component, Description);
            if ~isempty(events)
                events = sortrows(events, {'Station', 'MJD'});
            end
            progress.Value = 0.40;
        end

        function [events, notes] = parseCeaBatch(app, sourceFiles, records, progress)
            events = app.emptyRawEvents();
            notes = strings(0, 1);
            recordStations = {records.Station};
            matchedStations = strings(0, 1);
            for k = 1:numel(sourceFiles)
                station = app.matchCeaStation(sourceFiles{k}, recordStations);
                if isempty(station)
                    [~, fileName, extension] = fileparts(sourceFiles{k});
                    notes(end + 1, 1) = "Skipped unmatched CEA file: " + ...
                        string(fileName) + string(extension); %#ok<AGROW>
                elseif any(matchedStations == string(station))
                    error('KnownEventImporApp:DuplicateCeaStation', ...
                        'More than one CEA file matched station %s.', station);
                else
                    parsed = parseCEAHeader(sourceFiles{k});
                    if isempty(parsed)
                        [~, fileName, extension] = fileparts(sourceFiles{k});
                        notes(end + 1, 1) = "No header offsets found in CEA file: " + ...
                            string(fileName) + string(extension); %#ok<AGROW>
                    else
                        valid = isfinite(parsed.MJD) & ...
                            ismember(string(parsed.Component), ["N", "E", "U"]);
                        if ~all(valid)
                            error('KnownEventImporApp:InvalidCeaOffset', ...
                                ['CEA file %s contains an offset without a valid ' ...
                                'epoch or N/E/U component.'], sourceFiles{k});
                        end
                        n = height(parsed);
                        Station = repmat(string(station), n, 1);
                        MJD = parsed.MJD;
                        Source = repmat("CEA", n, 1);
                        Type = repmat("OFFSET", n, 1);
                        Component = string(parsed.Component);
                        Description = compose('offset %.5g m', parsed.Offset_m);
                        batch = table(Station, MJD, Source, Type, ...
                            Component, Description);
                        events = [events; batch]; %#ok<AGROW>
                    end
                    matchedStations(end + 1, 1) = string(station); %#ok<AGROW>
                end
                progress.Value = 0.05 + 0.35 * k / max(numel(sourceFiles), 1);
                progress.Message = sprintf('Parsing CEA files %d/%d', ...
                    k, numel(sourceFiles));
                drawnow limitrate;
                app.throwIfCancelled(progress);
            end
            if ~isempty(events)
                events = sortrows(events, {'Station', 'MJD', 'Component'});
            end
        end

        function events = emptyRawEvents(app) %#ok<MANU>
            Station = strings(0, 1);
            MJD = zeros(0, 1);
            Source = strings(0, 1);
            Type = strings(0, 1);
            Component = strings(0, 1);
            Description = strings(0, 1);
            events = table(Station, MJD, Source, Type, Component, Description);
        end

        function station = matchCeaStation(app, ceaFile, stationNames)
            station = '';
            [~, fileStem] = fileparts(ceaFile);
            hints = {upper(fileStem), app.readCeaStationHint(ceaFile)};
            for k = 1:numel(hints)
                if isempty(hints{k})
                    continue;
                end
                match = find(strcmpi(stationNames, hints{k}), 1);
                if ~isempty(match)
                    station = stationNames{match};
                    return;
                end
            end
        end

        function station = readCeaStationHint(app, ceaFile) %#ok<INUSL>
            station = '';
            fid = fopen(ceaFile, 'r');
            if fid < 0
                return;
            end
            closeFile = onCleanup(@() fclose(fid));
            for k = 1:30
                line = fgetl(fid);
                if ~ischar(line)
                    break;
                end
                token = regexp(line, ...
                    'Reference\s+position.*\s([A-Za-z0-9]{4})\s*$', ...
                    'tokens', 'once', 'ignorecase');
                if ~isempty(token)
                    station = upper(token{1});
                    return;
                end
            end
        end

        function ranges = readMomRanges(app, momFiles) %#ok<INUSL>
            ranges = nan(3, 2);
            for c = 1:3
                numericData = readmatrix(momFiles{c}, 'FileType', 'text', ...
                    'CommentStyle', '#');
                if isempty(numericData) || size(numericData, 2) < 2
                    error('KnownEventImporApp:InvalidMomFile', ...
                        'No two-column numeric series was found in %s.', momFiles{c});
                end
                validMjd = numericData(isfinite(numericData(:, 1)), 1);
                if isempty(validMjd)
                    error('KnownEventImporApp:InvalidMomFile', ...
                        'No finite MJD samples were found in %s.', momFiles{c});
                end
                ranges(c, :) = [min(validMjd), max(validMjd)];
            end
        end

        function catalog = makeEventCatalog(app, rawEvents, records) %#ok<INUSL>
            n = height(rawEvents);
            inSpan = false(n, 1);
            componentNames = ["N", "E", "U"];
            for row = 1:n
                recordIndex = find(strcmp({records.Station}, ...
                    char(rawEvents.Station(row))), 1);
                ranges = records(recordIndex).MomRanges;
                if rawEvents.Component(row) == "ALL"
                    targetComponents = 1:3;
                else
                    targetComponents = find( ...
                        componentNames == rawEvents.Component(row));
                end
                for c = targetComponents
                    if rawEvents.MJD(row) >= ranges(c, 1) && ...
                            rawEvents.MJD(row) <= ranges(c, 2)
                        inSpan(row) = true;
                        break;
                    end
                end
            end
            Selected = inSpan;
            Station = rawEvents.Station;
            MJD = rawEvents.MJD;
            Source = rawEvents.Source;
            Type = rawEvents.Type;
            Component = rawEvents.Component;
            Description = rawEvents.Description;
            InSpan = inSpan;
            catalog = table(Selected, Station, MJD, Source, Type, ...
                Component, Description, InSpan);
            catalog = sortrows(catalog, {'Station', 'MJD', 'Component'});
        end

        function catalog = emptyEventCatalog(app) %#ok<MANU>
            Selected = false(0, 1);
            Station = strings(0, 1);
            MJD = zeros(0, 1);
            Source = strings(0, 1);
            Type = strings(0, 1);
            Component = strings(0, 1);
            Description = strings(0, 1);
            InSpan = false(0, 1);
            catalog = table(Selected, Station, MJD, Source, Type, ...
                Component, Description, InSpan);
        end

        function refreshEventTable(app)
            n = height(app.EventCatalog);
            eventText = strings(n, 1);
            for row = 1:n
                if app.EventCatalog.Component(row) == "ALL"
                    eventText(row) = app.EventCatalog.Type(row) + ": " + ...
                        app.EventCatalog.Description(row);
                else
                    eventText(row) = "[" + app.EventCatalog.Component(row) + ...
                        "] " + app.EventCatalog.Description(row);
                end
                if ~app.EventCatalog.InSpan(row)
                    eventText(row) = eventText(row) + " (outside span)";
                end
            end
            Use = app.EventCatalog.Selected;
            Station = app.EventCatalog.Station;
            MJD = app.EventCatalog.MJD;
            Source = app.EventCatalog.Source;
            Event = eventText;
            app.KnownEventTable.Data = table(Use, Station, MJD, Source, Event);
            try
                removeStyle(app.KnownEventTable);
                outsideRows = find(~app.EventCatalog.InSpan);
                if ~isempty(outsideRows)
                    mutedStyle = uistyle('FontColor', [0.60, 0.60, 0.60]);
                    addStyle(app.KnownEventTable, mutedStyle, 'row', outsideRows);
                end
            catch
                % Per-row styles are unavailable in older MATLAB releases.
            end
        end

        function refreshPlots(app)
            axesList = [app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes];
            componentNames = ["N", "E", "U"];
            eventColor = [0.8500, 0.3250, 0.0980];
            recordIndex = find(strcmp({app.StationRecords.Station}, ...
                char(app.CurrentPreviewStation)), 1);
            if isempty(recordIndex)
                app.clearAxes();
                return;
            end

            record = app.StationRecords(recordIndex);
            for c = 1:3
                ax = axesList(c);
                cla(ax);
                if isempty(record.MomData{c})
                    numericData = readmatrix(record.MomFiles{c}, ...
                        'FileType', 'text', 'CommentStyle', '#');
                    numericData = numericData(:, 1:2);
                    numericData = numericData(all(isfinite(numericData), 2), :);
                    record.MomData{c} = numericData;
                end
                data = record.MomData{c};
                plot(ax, data(:, 1), data(:, 2), 'Color', ...
                    [0, 0.4470, 0.7410], 'LineWidth', 0.8);
                hold(ax, 'on');
                selected = app.EventCatalog.Selected & app.EventCatalog.InSpan & ...
                    app.EventCatalog.Station == app.CurrentPreviewStation & ...
                    (app.EventCatalog.Component == "ALL" | ...
                    app.EventCatalog.Component == componentNames(c));
                rows = find(selected);
                for row = rows(:)'
                    xline(ax, app.EventCatalog.MJD(row), '--', ...
                        app.eventPlotLabel(app.EventCatalog(row, :)), ...
                        'Color', eventColor, 'LineWidth', 1.2, ...
                        'LabelHorizontalAlignment', 'left', ...
                        'LabelVerticalAlignment', 'bottom');
                end
                hold(ax, 'off');
                grid(ax, 'on');
                xlim(ax, record.MomRanges(c, :));
                title(ax, sprintf('%s - %s', record.Station, componentNames(c)));
                xlabel(ax, 'MJD');
                ylabel(ax, 'Displacement (mm)');
            end
            app.StationRecords(recordIndex) = record;
            drawnow limitrate;
        end

        function label = eventPlotLabel(app, eventRow) %#ok<INUSL>
            if eventRow.Type == "EQ"
                label = sprintf('%.0f %s', eventRow.MJD, eventRow.Description);
            else
                label = sprintf('%.0f %s', eventRow.MJD, eventRow.Type);
            end
        end

        function outputEvents = headerEventTable(app, rows) %#ok<INUSL>
            if isempty(rows)
                MJD = zeros(0, 1);
                Source = strings(0, 1);
                Description = strings(0, 1);
                outputEvents = table(MJD, Source, Description);
                return;
            end
            MJD = rows.MJD;
            Source = rows.Source;
            Description = rows.Description;
            nglRows = Source == "NGL";
            Description(nglRows) = rows.Type(nglRows) + ": " + ...
                rows.Description(nglRows);
            outputEvents = table(MJD, Source, Description);
            outputEvents = sortrows(outputEvents, 'MJD');
        end

        function refreshStatus(app, notes)
            if ~app.AnalysisReady
                return;
            end
            selected = app.EventCatalog.Selected & app.EventCatalog.InSpan;
            statusLines = [ ...
                string(sprintf(['Batch: %d station(s), %d event(s), ' ...
                '%d in span, %d selected.'], ...
                numel(app.StationRecords), height(app.EventCatalog), ...
                sum(app.EventCatalog.InSpan), sum(selected))); ...
                "Preview station: " + app.CurrentPreviewStation];
            if ~isempty(notes)
                statusLines = [statusLines; string(notes)];
            end
            app.TextArea.Value = cellstr(statusLines);
        end

        function clearAnalysis(app)
            app.EventCatalog = app.emptyEventCatalog();
            app.StationRecords = app.emptyStationRecords();
            app.CurrentPreviewStation = "";
            app.AnalysisReady = false;
            app.KnownEventTable.Data = table(false(0, 1), strings(0, 1), ...
                zeros(0, 1), strings(0, 1), strings(0, 1), ...
                'VariableNames', {'Use', 'Station', 'MJD', 'Source', 'Event'});
            try
                removeStyle(app.KnownEventTable);
            catch
            end
            app.clearAxes();
            app.WriteEventstoMOMHeadersButton.Enable = 'off';
        end

        function clearAxes(app)
            axesList = [app.NorthUIAxes, app.EastUIAxes, app.UpUIAxes];
            componentNames = ["N", "E", "U"];
            for c = 1:3
                cla(axesList(c));
                title(axesList(c), componentNames(c));
                xlabel(axesList(c), 'MJD');
                ylabel(axesList(c), 'Displacement (mm)');
                grid(axesList(c), 'on');
            end
        end

        function setStatus(app, message)
            if isstring(message)
                message = char(message);
            end
            app.TextArea.Value = {message};
        end

        function throwIfCancelled(app, progress) %#ok<INUSL>
            if ~isempty(progress) && isvalid(progress) && progress.CancelRequested
                error('KnownEventImporApp:Cancelled', 'Operation cancelled by user.');
            end
        end

        function closeProgress(app, progress) %#ok<INUSL>
            if ~isempty(progress) && isvalid(progress)
                close(progress);
            end
        end

        function deleteTemporaryFiles(app, files) %#ok<INUSL>
            for k = 1:numel(files)
                if ~isempty(files{k}) && isfile(files{k})
                    delete(files{k});
                end
            end
        end

        function value = onOff(app, condition) %#ok<INUSL>
            if condition
                value = 'on';
            else
                value = 'off';
            end
        end

        function preserveExistingOffsetHeaders(app, originalFile, newFile)

            keepPreviousKnownEvents = true;
            mjdTolerance = 1e-4;
            beginMarker  = "# BEGIN KNOWN EVENTS";
            endMarker    = "# END KNOWN EVENTS";

            origLines   = app.readTextLines(originalFile);
            origIsOff   = app.isOffsetHeaderLine(origLines);
            origInBlock = app.knownEventBlockMask(origLines);

            outsideOld = origLines(origIsOff & ~origInBlock);
            if keepPreviousKnownEvents
                blockOld = origLines(origIsOff & origInBlock);
            else
                blockOld = strings(0, 1);
            end
            if isempty(outsideOld) && isempty(blockOld)
                return;
            end
            outsideOld  = outsideOld(:);
            blockOld    = blockOld(:);
            outsideText = strtrim(outsideOld);
            outsideMjd  = app.offsetHeaderMjd(outsideOld);

            [newLines, eol, hasTrailingNewline] = app.readTextLines(newFile);
            newInBlock  = app.knownEventBlockMask(newLines);
            isNewOffset = app.isOffsetHeaderLine(newLines);
            newMjd      = app.offsetHeaderMjd(newLines);

            dropNew = false(numel(newLines), 1);
            for k = find(isNewOffset).'
                sameText = any(outsideText == strtrim(newLines(k)));
                sameMjd  = ~isnan(newMjd(k)) && ...
                    any(abs(outsideMjd - newMjd(k)) < mjdTolerance);
                dropNew(k) = sameText || sameMjd;
            end
            newLines(dropNew)    = [];
            newInBlock(dropNew)  = [];
            isNewOffset(dropNew) = [];
            newMjd(dropNew)      = [];

            if ~isempty(blockOld)
                blockText = strtrim(blockOld);
                blockMjd  = app.offsetHeaderMjd(blockOld);
                refText   = [outsideText; strtrim(newLines(isNewOffset))];
                refMjd    = [outsideMjd; newMjd(isNewOffset)];
                keepOld   = true(numel(blockOld), 1);
                for k = 1:numel(blockOld)
                    sameText = any(refText == blockText(k));
                    sameMjd  = ~isnan(blockMjd(k)) && ...
                        any(abs(refMjd - blockMjd(k)) < mjdTolerance);
                    keepOld(k) = ~(sameText || sameMjd);
                end
                blockOld = blockOld(keepOld);
            end

            newBlockLines = strings(0, 1);
            blockStart = find(newInBlock, 1, 'first');
            if ~isempty(blockOld)
                if ~isempty(blockStart)
                    endIdx = find(newInBlock & startsWith(strtrim(newLines), ...
                        endMarker, 'IgnoreCase', true), 1, 'first');
                    if isempty(endIdx)
                        endIdx = find(newInBlock, 1, 'last') + 1;
                    end
                    n = numel(blockOld);
                    newLines    = [newLines(1:endIdx-1); blockOld; newLines(endIdx:end)];
                    newInBlock  = [newInBlock(1:endIdx-1); true(n, 1); newInBlock(endIdx:end)];
                    isNewOffset = [isNewOffset(1:endIdx-1); true(n, 1); isNewOffset(endIdx:end)];
                else
                    newBlockLines = [beginMarker; blockOld; endMarker];
                end
            end


            blockStart    = find(newInBlock, 1, 'first');
            outsideOffset = find(isNewOffset & ~newInBlock, 1, 'first');
            candidates    = [blockStart; outsideOffset];
            if ~isempty(candidates)
                insertAt = min(candidates);
            else
                trimmed   = strtrim(newLines);
                isHeader  = startsWith(trimmed, "#");
                firstData = find(~isHeader & strlength(trimmed) > 0, 1, 'first');
                if isempty(firstData)
                    firstData = numel(newLines) + 1;
                end
                lastHeader = find(isHeader(1:firstData-1), 1, 'last');
                if isempty(lastHeader)
                    insertAt = 1;
                else
                    insertAt = lastHeader + 1;
                end
            end

            merged = [newLines(1:insertAt-1); outsideOld; newBlockLines; ...
                newLines(insertAt:end)];
            app.writeTextLines(newFile, merged, eol, hasTrailingNewline);
        end

        function mask = knownEventBlockMask(~, lines)
            beginMarker = "# BEGIN KNOWN EVENTS";
            endMarker   = "# END KNOWN EVENTS";

            mask = false(numel(lines), 1);
            inside = false;
            for k = 1:numel(lines)
                t = strtrim(lines(k));
                if startsWith(t, beginMarker, 'IgnoreCase', true)
                    inside = true;
                end
                mask(k) = inside;
                if inside && startsWith(t, endMarker, 'IgnoreCase', true)
                    inside = false;
                end
            end
        end

        function [lines, eol, hasTrailingNewline] = readTextLines(~, fileName)
            text = string(fileread(fileName));
            if contains(text, sprintf('\r\n'))
                eol = sprintf('\r\n');
            else
                eol = newline;
            end
            hasTrailingNewline = endsWith(text, newline);
            lines = splitlines(text);
            if hasTrailingNewline && ~isempty(lines) && lines(end) == ""
                lines(end) = [];
            end
        end

        function writeTextLines(~, fileName, lines, eol, hasTrailingNewline)
            text = strjoin(lines, eol);
            if hasTrailingNewline
                text = text + eol;
            end
            fid = fopen(fileName, 'w');
            if fid < 0
                error('KnownEventImporApp:TempWriteFailed', ...
                    'Could not write temporary file %s.', fileName);
            end
            closer = onCleanup(@() fclose(fid));
            fprintf(fid, '%s', char(text));
        end

        function tf = isOffsetHeaderLine(~, lines)
            if isempty(lines)
                tf = false(0, 1);
                return;
            end
            tf = ~cellfun(@isempty, ...
                regexpi(cellstr(lines), '^\s*#\s*offset(\s|$)', 'once'));
            tf = tf(:);
        end

        function mjd = offsetHeaderMjd(~, lines)
            mjd = nan(numel(lines), 1);
            for k = 1:numel(lines)
                tok = regexpi(char(lines(k)), ...
                    '^\s*#\s*offset\s+([-+]?\d*\.?\d+(?:[eE][-+]?\d+)?)', ...
                    'tokens', 'once');
                if ~isempty(tok)
                    mjd(k) = str2double(tok{1});
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeAppState(app);

            % Place the application in the middle of the screen.
            % Get Screensize,etc:1 1 2048 1152.
            screenSize = get(0,"ScreenSize");
            windowWidth = app.KnownEventImportUIFigure.Position(3);
            windowHeight = app.KnownEventImportUIFigure.Position(4);

            centerX = (screenSize(3) - windowWidth) / 2;
            centerY = (screenSize(4) - windowHeight) / 2;

            app.KnownEventImportUIFigure.Position = [centerX, centerY, windowWidth, windowHeight];

        end

        % Value changed function: AnalyzeEventsButton
        function AnalyzeEventsButtonValueChanged(app, event)
            if ~app.AnalyzeEventsButton.Value
                return;
            end

            progress = [];
            try
                momDirectory = strtrim(app.MOMFileDirctoryEditField.Value);
                if ~isfolder(momDirectory)
                    error('KnownEventImporApp:MissingMomDirectory', ...
                        'Select a directory containing station_0/1/2.mom files.');
                end

                sourceFiles = app.resolveSourceFiles();
                allRecords = app.discoverMomStations(momDirectory);
                progress = uiprogressdlg(app.KnownEventImportUIFigure, ...
                    'Title', 'Analyzing known events', ...
                    'Message', 'Preparing batch analysis...', ...
                    'Value', 0.02, 'Cancelable', 'on');
                drawnow;

                if strcmp(app.DataTypeDropDown.Value, 'NGL Steps File (*.txt)')
                    rawEvents = app.parseNglBatch( ...
                        sourceFiles{1}, {allRecords.Station}, progress);
                    notes = strings(0, 1);
                else
                    [rawEvents, notes] = app.parseCeaBatch( ...
                        sourceFiles, allRecords, progress);
                end

                if isempty(rawEvents)
                    error('KnownEventImporApp:NoEvents', ...
                        'No known events matched the complete MOM station sets.');
                end
                app.throwIfCancelled(progress);

                progress.Message = 'Reading MOM observation spans...';
                activeStations = unique(cellstr(rawEvents.Station), 'stable');
                keepRecord = ismember({allRecords.Station}, activeStations);
                records = allRecords(keepRecord);
                for k = 1:numel(records)
                    records(k).MomRanges = app.readMomRanges(records(k).MomFiles);
                    records(k).MomData = cell(1, 3);
                    progress.Value = 0.45 + 0.40 * k / max(numel(records), 1);
                    progress.Message = sprintf('Reading MOM span %d/%d: %s', ...
                        k, numel(records), records(k).Station);
                    drawnow limitrate;
                    app.throwIfCancelled(progress);
                end

                app.StationRecords = records;
                app.EventCatalog = app.makeEventCatalog(rawEvents, records);
                app.AnalysisReady = true;
                inRangeRows = find(app.EventCatalog.InSpan, 1);
                if isempty(inRangeRows)
                    app.CurrentPreviewStation = app.EventCatalog.Station(1);
                else
                    app.CurrentPreviewStation = ...
                        app.EventCatalog.Station(inRangeRows);
                end

                progress.Value = 0.90;
                progress.Message = 'Building event table and preview...';
                app.refreshEventTable();
                app.refreshPlots();
                app.refreshStatus(notes);
                app.WriteEventstoMOMHeadersButton.Enable = ...
                    app.onOff(any(app.EventCatalog.Selected));
                progress.Value = 1;
                progress.Message = 'Analysis complete.';
                drawnow;
                app.closeProgress(progress);
            catch ME
                app.closeProgress(progress);
                app.clearAnalysis();
                if strcmp(ME.identifier, 'KnownEventImporApp:Cancelled')
                    app.setStatus('Batch analysis cancelled.');
                else
                    app.setStatus("Analysis failed: " + string(ME.message));
                    uialert(app.KnownEventImportUIFigure, ME.message, ...
                        'Known-event analysis failed');
                end
            end
            app.AnalyzeEventsButton.Value = false;
        end

        % Value changed function: FileDirctoryBrowseButton
        function FileDirctoryBrowseButtonValueChanged(app, event)
            if ~app.FileDirctoryBrowseButton.Value
                return;
            end

            startAt = app.sourceBrowseStart();
            if strcmp(app.DataTypeDropDown.Value, 'NGL Steps File (*.txt)')
                [fileName, pathName] = uigetfile( ...
                    {'*.txt', 'NGL steps file (*.txt)'; '*.*', 'All files'}, ...
                    'Select NGL steps.txt', startAt, 'MultiSelect', 'off');
                if ~isequal(fileName, 0)
                    fullName = fullfile(pathName, fileName);
                    app.FileDirctoryEditField.Value = fullName;
                    app.FileDirctoryEditField.Tooltip = fullName;
                    app.clearAnalysis();
                    app.setStatus('NGL steps.txt selected.');
                end
            else
                if isfile(startAt)
                    startAt = fileparts(startAt);
                end
                folderName = uigetdir(startAt, ...
                    'Select the folder containing station CEA TS.txt files');
                if ~isequal(folderName, 0)
                    app.FileDirctoryEditField.Value = folderName;
                    app.FileDirctoryEditField.Tooltip = folderName;
                    app.clearAnalysis();
                    app.setStatus("CEA source folder selected: " + ...
                        string(folderName));
                end
            end
            app.FileDirctoryBrowseButton.Value = false;
        end

        % Value changed function: MOMFileDirctoryBrowseButton
        function MOMFileDirctoryBrowseButtonValueChanged(app, event)
            if ~app.MOMFileDirctoryBrowseButton.Value
                return;
            end

            startAt = app.MOMFileDirctoryEditField.Value;
            if isempty(startAt) || ~isfolder(startAt)
                startAt = pwd;
            end
            folderName = uigetdir(startAt, 'Select MOM file directory');
            if ~isequal(folderName, 0)
                app.MOMFileDirctoryEditField.Value = folderName;
                app.clearAnalysis();
                app.setStatus("MOM directory selected: " + string(folderName));
            end
            app.MOMFileDirctoryBrowseButton.Value = false;
        end

        % Value changed function: TextArea, WriteEventstoMOMHeadersButton
        function Write2MOMHeadButtonValueChanged(app, event)
            if ~app.WriteEventstoMOMHeadersButton.Value
                return;
            end

            progress = [];
            temporaryFiles = cell(0, 1);
            try
                if ~app.AnalysisReady
                    error('KnownEventImporApp:AnalyzeFirst', ...
                        'Analyze the batch before writing.');
                end
                selected = app.EventCatalog.Selected & app.EventCatalog.InSpan;
                if ~any(selected)
                    error('KnownEventImporApp:NoSelection', ...
                        'Select at least one in-range event before writing.');
                end

                targetStations = unique( ...
                    cellstr(app.EventCatalog.Station(selected)), 'stable');
                componentNames = ["N", "E", "U"];
                totalFiles = numel(targetStations) * 3;
                tempTargets = cell(totalFiles, 1);
                tempCounter = 0;
                progress = uiprogressdlg(app.KnownEventImportUIFigure, ...
                    'Title', 'Writing MOM headers', ...
                    'Message', 'Preparing output files...', ...
                    'Value', 0.01, 'Cancelable', 'on');
                drawnow;

                for s = 1:numel(targetStations)
                    recordIndex = find(strcmp({app.StationRecords.Station}, ...
                        targetStations{s}), 1);
                    record = app.StationRecords(recordIndex);
                    stationRows = selected & ...
                        app.EventCatalog.Station == string(targetStations{s});
                    for c = 1:3
                        app.throwIfCancelled(progress);
                        componentRows = stationRows & ...
                            (app.EventCatalog.Component == "ALL" | ...
                            app.EventCatalog.Component == componentNames(c));
                        outputEvents = app.headerEventTable( ...
                            app.EventCatalog(componentRows, :));
                        tempCounter = tempCounter + 1;
                        temporaryFiles{tempCounter, 1} = ...
                            [tempname(fileparts(record.MomFiles{c})), '.mom'];
                        tempTargets{tempCounter, 1} = record.MomFiles{c};
                        writeKnownEventsToMom(record.MomFiles{c}, ...
                            outputEvents, char(componentNames(c)), ...
                            temporaryFiles{tempCounter});

                        app.preserveExistingOffsetHeaders( ...
                            record.MomFiles{c}, temporaryFiles{tempCounter});

                        progress.Value = 0.80 * tempCounter / totalFiles;
                        progress.Message = sprintf('Preparing %s %s (%d/%d)', ...
                            targetStations{s}, componentNames(c), ...
                            tempCounter, totalFiles);
                        drawnow limitrate;
                    end
                end

                app.throwIfCancelled(progress);
                progress.Cancelable = 'off';
                progress.Message = 'Committing completed MOM files...';
                for k = 1:tempCounter
                    [ok, message] = movefile(temporaryFiles{k}, ...
                        tempTargets{k}, 'f');
                    if ~ok
                        error('KnownEventImporApp:ReplaceFailed', ...
                            'Could not replace %s: %s', tempTargets{k}, message);
                    end
                    progress.Value = 0.80 + 0.20 * k / tempCounter;
                    drawnow limitrate;
                end
                temporaryFiles = cell(0, 1);
                app.closeProgress(progress);
                app.setStatus(sprintf(['Batch write complete: %d station(s), ' ...
                    '%d selected event(s).'], numel(targetStations), sum(selected)));
                uialert(app.KnownEventImportUIFigure, ...
                    sprintf('Known-event headers were written for %d station(s).', ...
                    numel(targetStations)), ...
                    'Batch write complete', 'Icon', 'success');
            catch ME
                app.closeProgress(progress);
                app.deleteTemporaryFiles(temporaryFiles);
                if strcmp(ME.identifier, 'KnownEventImporApp:Cancelled')
                    app.setStatus('Batch write cancelled; MOM files were not changed.');
                else
                    app.setStatus("Write failed: " + string(ME.message));
                    uialert(app.KnownEventImportUIFigure, ME.message, 'MOM batch write failed');
                end
            end
            app.WriteEventstoMOMHeadersButton.Value = false;
        end

        % Cell edit callback: KnownEventTable
        function KnownEventTableCellEdit(app, event)
            if isempty(event.Indices) || isempty(app.EventCatalog) || ...
                    event.Indices(2) ~= 1
                return;
            end
            row = event.Indices(1);
            app.EventCatalog.Selected(row) = logical(event.NewData) & ...
                app.EventCatalog.InSpan(row);
            app.refreshEventTable();
            app.refreshPlots();
            app.refreshStatus(strings(0, 1));
            app.WriteEventstoMOMHeadersButton.Enable = app.onOff( ...
                any(app.EventCatalog.Selected & app.EventCatalog.InSpan));
        end

        % Cell selection callback: KnownEventTable
        function KnownEventTableCellSelection(app, event)
            if isempty(event.Indices) || isempty(app.EventCatalog)
                return;
            end
            row = event.Indices(1);
            station = app.EventCatalog.Station(row);
            if station ~= app.CurrentPreviewStation
                app.CurrentPreviewStation = station;
                app.refreshPlots();
                app.refreshStatus(strings(0, 1));
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            app.FileDirctoryEditField.Value = '';
            app.FileDirctoryEditField.Tooltip = '';
            app.clearAnalysis();
            if strcmp(app.DataTypeDropDown.Value, 'CEA Time-Series Folder')
                app.setStatus(['CEA mode: click Browse and select the folder ' ...
                    'containing the station CEA TS.txt files.']);
            else
                app.setStatus('NGL mode: click Browse and select one steps.txt file.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KnownEventImportUIFigure = uifigure('Visible', 'off');
            app.KnownEventImportUIFigure.Name = 'KnownEventImport';
            app.KnownEventImportPanel = uipanel(app.KnownEventImportUIFigure);
            app.KnownEventImportPanel.Title = 'KnownEventImport';
            app.DataSourcePanel = uipanel(app.KnownEventImportPanel);
            app.DataSourcePanel.Title = 'Data Source';
            app.DataTypeDropDownLabel = uilabel(app.DataSourcePanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.DataSourcePanel);
            app.DataTypeDropDown.Items = {'NGL Steps File (*.txt)', 'CEA Time-Series Folder'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'NGL Steps File (*.txt)';
            app.SourcePathLabel = uilabel(app.DataSourcePanel);
            app.SourcePathLabel.Text = 'Source Path';
            app.FileDirctoryEditField = uieditfield(app.DataSourcePanel, 'text');
            app.FileDirctoryBrowseButton = uibutton(app.DataSourcePanel, 'state');
            app.FileDirctoryBrowseButton.ValueChangedFcn = createCallbackFcn(app, @FileDirctoryBrowseButtonValueChanged, true);
            app.FileDirctoryBrowseButton.Text = 'Browse';
            app.MOMFileDirctoryBrowseButton = uibutton(app.DataSourcePanel, 'state');
            app.MOMFileDirctoryBrowseButton.ValueChangedFcn = createCallbackFcn(app, @MOMFileDirctoryBrowseButtonValueChanged, true);
            app.MOMFileDirctoryBrowseButton.Text = 'Browse';
            app.MOMFileDirctoryEditField = uieditfield(app.DataSourcePanel, 'text');
            app.MOMDataDirectoryLabel = uilabel(app.DataSourcePanel);
            app.MOMDataDirectoryLabel.Text = 'MOM Data Directory';
            app.AnalyzeEventsButton = uibutton(app.DataSourcePanel, 'state');
            app.AnalyzeEventsButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzeEventsButtonValueChanged, true);
            app.AnalyzeEventsButton.Text = 'Analyze Events';
            app.KnownEventsPanel = uipanel(app.KnownEventImportPanel);
            app.KnownEventsPanel.Title = 'Known Events';
            app.KnownEventTable = uitable(app.KnownEventsPanel);
            app.KnownEventTable.ColumnName = {'Use'; 'Station'; 'MJD'; 'Source'; 'Event'};
            app.KnownEventTable.RowName = {};
            app.KnownEventTable.ColumnEditable = [true false false false false];
            app.KnownEventTable.CellEditCallback = createCallbackFcn(app, @KnownEventTableCellEdit, true);
            app.KnownEventTable.CellSelectionCallback = createCallbackFcn(app, @KnownEventTableCellSelection, true);
            app.TimeSeriesViewPanel = uipanel(app.KnownEventImportPanel);
            app.TimeSeriesViewPanel.Title = 'Time-Series View';
            app.TabGroup = uitabgroup(app.TimeSeriesViewPanel);
            app.NTab = uitab(app.TabGroup);
            app.NTab.Title = 'N';
            app.NorthUIAxes = uiaxes(app.NTab);
            app.ETab = uitab(app.TabGroup);
            app.ETab.Title = 'E';
            app.EastUIAxes = uiaxes(app.ETab);
            app.UTab = uitab(app.TabGroup);
            app.UTab.Title = 'U';
            app.UpUIAxes = uiaxes(app.UTab);
            app.TextArea = uitextarea(app.KnownEventImportPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @Write2MOMHeadButtonValueChanged, true);
            app.WriteEventstoMOMHeadersButton = uibutton(app.KnownEventImportPanel, 'state');
            app.WriteEventstoMOMHeadersButton.ValueChangedFcn = createCallbackFcn(app, @Write2MOMHeadButtonValueChanged, true);
            app.WriteEventstoMOMHeadersButton.Text = 'Write Events to MOM Headers';
            % Show the figure after all components are created
            app.KnownEventImportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KnownEventImport

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KnownEventImportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KnownEventImportUIFigure)
        end
    end
end